classdef MSAreplacement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportfromfilesMenu            matlab.ui.container.Menu
        ExporttowavMenu                matlab.ui.container.Menu
        SaveSettingstoFileMenu         matlab.ui.container.Menu
        LoadSettingsfromFileMenu       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        BottomPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        HidecyclesButton               matlab.ui.control.StateButton
        SelectedAreaDurationEditField  matlab.ui.control.NumericEditField
        DurationEditFieldLabel         matlab.ui.control.Label
        SelectedAreaEndEditField       matlab.ui.control.NumericEditField
        SelectionendLabel              matlab.ui.control.Label
        SelectedAreaStartEditField     matlab.ui.control.NumericEditField
        SelectionstartLabel            matlab.ui.control.Label
        SelectedAreaLabel              matlab.ui.control.Label
        showedgraphswithoutscrollingDropDown  matlab.ui.control.DropDown
        showedgraphswithoutscrollingDropDownLabel  matlab.ui.control.Label
        DeleteselectedsignalsButton    matlab.ui.control.Button
        DeselectallButton              matlab.ui.control.Button
        SelectallButton                matlab.ui.control.Button
        availableChannelsTable         matlab.ui.control.Table
        TabGroup                       matlab.ui.container.TabGroup
        GraphTab                       matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        plottingPanelContainer         matlab.ui.container.Panel
        plottingPanel                  matlab.ui.container.Panel
        UndoallshiftsButton            matlab.ui.control.Button
        Button13                       matlab.ui.control.Button
        InteractionModeButtonGroup     matlab.ui.container.ButtonGroup
        MoveGraphButton                matlab.ui.control.RadioButton
        MarkAreaButton                 matlab.ui.control.RadioButton
        normalButton                   matlab.ui.control.RadioButton
        ResultsTab                     matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        parameterResultsUITable        matlab.ui.control.Table
        TopPanel                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        openfileaftersavingCheckBox    matlab.ui.control.CheckBox
        Button8                        matlab.ui.control.Button
        Button7                        matlab.ui.control.Button
        SoundVolumeSlider              matlab.ui.control.Slider
        StopSoundButton                matlab.ui.control.Button
        weightchannelsevenlyCheckBox   matlab.ui.control.CheckBox
        PlaySoundButton                matlab.ui.control.Button
        SaveResultsButton              matlab.ui.control.Button
        CalculateParametersButton      matlab.ui.control.Button
        Button2                        matlab.ui.control.Button
        OpenShiftToolButton            matlab.ui.control.Button
        ParameterResultsTableContextMenu  matlab.ui.container.ContextMenu
        CopyleftclickedCellMenu        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        tlTime; % Tiled Layout for time plots
        channelYValues = {}; % "y-values" of each channel
        channelXValues = {};
        channelYValuesHistory = {}; % filtered versions of each channel and the original
        channelNames = {};
        channelSampleRates = [];
        channelSource = {}; % Description
        channelSourcePossibilities =      {'Audio','Flow','Pressure','GAW','EGG','Larynx Height','Accelerometer','Raw'}; % Description
        channelSourcePossibilitiesColor = [  255     0.0      0.0     245   128       128            220          0.0;...
                                             225     130      130     130   0.0       0.0            190          0.0;...
                                              25     200      200      48   0.0       0.0            255          0.0]'/255;
        channelAxes = gobjects(0); % Description
        xlimListener = []; % Description
        DraggingData = struct('Dragging', false, 'StartX', [], 'EndX', [],...
                'CurrentRectangles', [], 'PreviousRectangles', []);
        timePlotHandles = gobjects(0); % Description
        channelLag = []; % Description
        executeXLimListener=1; % Description
        audioPlayerObj % Description
        audioPlayerData % Description
        calculationSettings = [];% Description
        calculationCalledAsPreview = [];
        calculationResults = [];
        cycleBorderHandles=gobjects(0);
        keepMSAsettingsOverSession = 1; % Description
        xlimBeforeDeletion =[]; % Description
        parameterResultsUITableLastValue = ''; % Description
    end
    
    methods (Access = private)
        
        function [data,meta] = importPhotronWVD(~,path,numberOutputStyle)

            if nargin>1 && ~isempty(numberOutputStyle) && (isstring(numberOutputStyle)|| ischar(numberOutputStyle)) && strcmpi(numberOutputStyle,'native')
                native=1;
            else
                native=0;
            end

            id=fopen(path,'r');

            if native==0
                meta.headerSize=fread(id,1,"long=>double");
                meta.WVDversion=fread(id,1,"long=>double");

                meta.vendorName=fread(id,[1 64],"char*1=>char");
                meta.vendorName=strrep(meta.vendorName,char(0),'');

                meta.deviceName=fread(id,[1 64],"char*1=>char");
                meta.deviceName=strrep(meta.deviceName,char(0),'');

                meta.deviceID=fread(id,1,"long=>double");
                meta.sampleRate=fread(id,1,"float=>double");
                meta.numberOfData=fread(id,1,"ulong=>double");
                meta.numberOfChannels=fread(id,1,"ulong=>double");

                meta.channelNumber=nan(1,meta.numberOfChannels);
                meta.channelName=cell(1,meta.numberOfChannels);
                for nr=1:meta.numberOfChannels
                    meta.channelNumber(nr)=fread(id,1,"ulong=>double");
                end

                for nr=1:meta.numberOfChannels
                    meta.channelName{nr}=fread(id,[1 64],"char*1=>char");
                end
                meta.channelName=strtrim(meta.channelName);

                meta.startFrame=fread(id,1,"long=>double");

                data=fread(id,[meta.numberOfChannels meta.numberOfData],"float=>double");


            else
                meta.headerSize=fread(id,1,"long=>long");
                meta.WVDversion=fread(id,1,"long=>long");

                meta.vendorName=fread(id,[1 64],"char*1=>char*1");

                meta.deviceName=fread(id,[1 64],"char*1=>char*1");

                meta.deviceID=fread(id,1,"long=>long");
                meta.sampleRate=fread(id,1,"float=>float");
                meta.numberOfData=fread(id,1,"ulong=>ulong");
                meta.numberOfChannels=fread(id,1,"ulong=>ulong");

                meta.channelNumber=nan(1,meta.numberOfChannels);
                meta.channelName=cell(1,meta.numberOfChannels);
                for nr=1:meta.numberOfChannels
                    meta.channelNumber(nr)=fread(id,1,"ulong=>ulong");
                end

                for nr=1:meta.numberOfChannels
                    meta.channelName{nr}=fread(id,[1 64],"char*1=>char*1");
                end

                meta.startFrame=fread(id,1,"long=>long");

                data=fread(id,[meta.numberOfChannels meta.numberOfData],"float=>float");

            end

            fclose(id);

        end

        function [header,allData]=readPhotronGraphExportData(~,filepath)
            disp('reading photron csv header...')
        
            header=readcell(filepath,'Range','A1:K23');
            header(end+1,:)=header(end,:);
            header(end-1,:)={[]};
        
            header(cellfun(@(in) all(ismissing(in)),header))={[]};
        
            disp('reading photron csv measurement data...')
            allData=readmatrix(filepath,'Range','A24');
        end


        function rescaleTimePlotTilesVerticallyPanel(app)
            numberOfMaxPlotsToView=str2double(app.showedgraphswithoutscrollingDropDown.Value);

            % this can also be 0 when nothing is selected in the table
            numberOfSelectedChannels=numel(app.availableChannelsTable.Selection);
            bestMax=min(numberOfMaxPlotsToView,numberOfSelectedChannels);

            if bestMax>0
                app.plottingPanel.Position(4)=app.plottingPanelContainer.Position(4)*numberOfSelectedChannels/bestMax*0.95;
                for dispChannelNr=1:numel(app.channelAxes)
                    
                    if dispChannelNr~=numel(app.channelAxes) && mod(dispChannelNr,str2double(app.showedgraphswithoutscrollingDropDown.Value))~=0
                        xticklabels(app.channelAxes(dispChannelNr),{''});
                    else
                        xticklabels(app.channelAxes(dispChannelNr),'auto');
                        
                    end

                    if dispChannelNr==numel(app.channelAxes)
                        xlabel(app.channelAxes(dispChannelNr),'time (s)');
                    end
                    xticks(app.channelAxes(dispChannelNr),'auto');
                    %ylim(app.channelAxes(dispChannelNr),'padded');
                end
            else

            end

            
        end
        
        function refreshTimePlotData(app)
            if ~isempty(app.channelAxes)
                oldXLim=app.channelAxes(1).XLim;
            elseif ~isempty(app.xlimBeforeDeletion)
                oldXLim=app.xlimBeforeDeletion;
            else
                oldXLim=[];
            end

            app.executeXLimListener=0;

            delete(app.tlTime);
            app.timePlotHandles=gobjects(0);
            app.cycleBorderHandles=gobjects(0);
            
            app.channelAxes=gobjects(0);
            if isempty(app.availableChannelsTable.Selection)
                return;
            end

            app.tlTime=tiledlayout(app.plottingPanel,numel(app.availableChannelsTable.Selection),1,'TileSpacing','tight','Padding','tight','Visible','on');

            plotNr=1;

            
            for channelNr=app.availableChannelsTable.Selection

                %% create plot line and set labels
                currAx=nexttile(app.tlTime,plotNr);
                

                app.channelAxes(plotNr)=currAx;
                

                currYData=app.channelYValues{channelNr};
                currFs=app.channelSampleRates(channelNr);
                currTData=(0:numel(currYData)-1)/currFs;
                app.timePlotHandles(plotNr)=plot(currAx,currTData+app.channelLag(channelNr),currYData,'-','LineWidth',1);

                currAx.UserData.plotNr=plotNr;
                currAx.UserData.channelNr=channelNr;

                if channelNr~=app.availableChannelsTable.Selection(end) && mod(plotNr,str2double(app.showedgraphswithoutscrollingDropDown.Value))~=0
                    xticklabels(currAx,{''});
                end
                xticks(currAx,'auto');

                title(currAx,app.splitCharArray(app.channelNames{channelNr},110),'Interpreter','none');
                ylabel(currAx,app.channelSource{channelNr},'Interpreter','none');

                app.timePlotHandles(plotNr).Color=app.channelSourcePossibilitiesColor(ismember(app.channelSourcePossibilities,app.channelSource{channelNr}),:);

                %ylim(currAx,'padded');


                %% create context menus for each axis

                
                cmenu = uicontextmenu(app.UIFigure);

                % change source node
                changeSourceNode = uimenu(cmenu, 'Text', 'Change Source');
                subNodesText = app.channelSourcePossibilities;
                subHandles = gobjects(size(subNodesText));
                for dispNr = 1:numel(subNodesText)
                   subHandles(dispNr) = uimenu(changeSourceNode, 'Text', subNodesText{dispNr}, ...
                       'Callback',@app.uiMenuChangeColorCallback);
                end

                subHandles(ismember(app.channelSourcePossibilities,app.channelSource{channelNr})).Checked='on';

                % filter channel node
                filteringNode = uimenu(cmenu, 'Text', 'Filtering...');
                filterToolboxNode = uimenu(filteringNode, 'Text', 'Process with Filter Toolbox...','Callback',@(~,~) app.uiMenuFilterDataCallback(plotNr,channelNr));
                filterUndoNode = uimenu(filteringNode, 'Text', 'Go back to previous version','Callback',@(~,~) app.uiMenuFilterDataUndoCallback(plotNr,channelNr));

 
%                 % edit selected area
%                 basicEditingNode = uimenu(cmenu,'Text','Basic Editing');
%                 basicEditingThisChannelOnlySubnode = uimenu(basicEditingNode,'Text','Edit this channel only');
%                 basicEditingAllDisplayedChannelsSubnode = uimenu(basicEditingNode,'Text','Edit all displayed channels');
%                 basicEditingAllChannelsSubnode = uimenu(basicEditingNode,'Text','Edit all available channels');
% 
%                 basicEditingTypes={'this','displayed','all'};
%                 nodes=[basicEditingThisChannelOnlySubnode basicEditingAllDisplayedChannelsSubnode basicEditingAllChannelsSubnode];
%                 for typeNr=1:3
%                     editWholeChannelDataNode = uimenu(nodes(typeNr),'Text','whole channel data');
%                     markedAreaOnlyNode = uimenu(nodes(typeNr),'Text','selected area only');
% 
%                     editArea={'whole' 'marked'};
%                     editAreaNodes=[editWholeChannelDataNode markedAreaOnlyNode];
%                     for areaTypeNr=1:numel(editArea)
%                         uimenu(editAreaNodes(areaTypeNr),'Text','Set to 0','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'toZero'));
%                         if areaTypeNr>1
%                             uimenu(editAreaNodes(areaTypeNr),'Text','Delete','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'delete'));
%                         end
%                         uimenu(editAreaNodes(areaTypeNr),'Text','Overwrite with linear ramp','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'linearRamp'));
%                         setToNode=uimenu(editAreaNodes(areaTypeNr),'Text','Set to ...');
%                         uimenu(setToNode,'Text','Channel min','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'setToChannelMin'));
%                         uimenu(setToNode,'Text','Channel marked area min','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'setToSelectionMin'));
% 
%                         uimenu(setToNode,'Text','Channel max','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'setToChannelMax'));
%                         uimenu(setToNode,'Text','Channel marked area max','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'setToSelectionMax'));
% 
%                         uimenu(setToNode,'Text','Channel mean','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'setToChannelMean'));
%                         uimenu(setToNode,'Text','Channel marked area mean','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'setToSelectionMean'));
% 
%                         uimenu(setToNode,'Text','Channel median','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'setToChannelMedian'));
%                         uimenu(setToNode,'Text','Channel marked area median','Callback',@(src,evt) app.performBasicChannelEditing(plotNr,channelNr,basicEditingTypes{typeNr},editArea{areaTypeNr},'setToSelectionMedian'));
%                     end
% 
%                 end


                app.channelAxes(plotNr).ContextMenu=cmenu; 


                plotNr=plotNr+1;
            end


            %% set basic layout of axes and labels
            % use a basic grid
            grid(app.channelAxes,"on");

            % do not show the axtoolbars
            arrayfun(@(in) axtoolbar(in,'Visible','off'),app.channelAxes);
            %arrayfun(@(in) axtoolbar(in,["pan" "zoomin" "zoomout" "restoreview"]),app.channelAxes);


            % using linkaxes updates only when stopping dragging
            %linkaxes(app.channelAxes,'x');
            %linkprop(app.channelAxes,'XTicks');


            % i want to have the full y scale visible also after zooming
            delete(app.xlimListener);
            app.xlimListener=listener(app.channelAxes, 'XLim', 'PostSet', @(~, evt) minimizeYLims(app,evt));


            app.rescaleTimePlotTilesVerticallyPanel();
            %scroll(app.plottingPanelContainer,"top");

            app.refreshInteractionMode();


            %% add rectangles
            if ~isempty(app.DraggingData.StartX)
                rectangles = [];
            end


            for axisNr=1:numel(app.channelAxes)
            % draw rectangle if data already existed
                if ~isempty(app.DraggingData.StartX)

                    currExcerpt=app.channelXValues{app.availableChannelsTable.Selection(axisNr)}>=oldXLim(1) & app.channelXValues{app.availableChannelsTable.Selection(axisNr)}<=oldXLim(2);
                    yMin = min(app.channelYValues{app.availableChannelsTable.Selection(axisNr)}(currExcerpt));
                    yMax = max(app.channelYValues{app.availableChannelsTable.Selection(axisNr)}(currExcerpt));
                    padval=0.1*range(yMax-yMin);
                    yMin=yMin-padval;
                    yMax=yMax+padval;

                    %yMin=app.channelAxes(axisNr).YLim(1);
                    %yMax=app.channelAxes(axisNr).YLim(2);
                    rect = rectangle(app.channelAxes(axisNr), ...
                            'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                            'FaceColor', [0.8, 0.8, 1, 0.5], ...
                            'EdgeColor', 'none', ...
                            'PickableParts', 'none');
                    rectangles = [rectangles rect];

                    app.channelAxes(axisNr).YLim=[yMin yMax];
                end
            end

            % save rectangles after creation
            if ~isempty(app.DraggingData.StartX)
                arrayfun(@(in)uistack(in,"bottom"),rectangles);
                app.DraggingData.CurrentRectangles = rectangles;
            end

            %% set selection border edit values
            app.updateSelectionAreaEditFieldsValues()

            %% add cycle Borders if availble
            plotNr=1;
            for channelNr=app.availableChannelsTable.Selection
                if ~isempty(app.calculationResults) && numel(app.calculationResults.channelResults)>=channelNr
                    if ~isempty(app.calculationResults.channelResults(channelNr).cycleBorderVals)
                        if app.HidecyclesButton.Value==1
                            vis='off';
                        else
                            vis='on';
                        end
                        app.cycleBorderHandles(plotNr)=app.addManyXlinesAsSingleObj(app.channelAxes(plotNr),app.calculationResults.channelResults(channelNr).cycleBorderVals,vis);
                    end
                end
                plotNr=plotNr+1;
            end

            %% load new data for audio player
            app.createAudioPlayerData();

            %% set old xlims and activate listener again
            if ~isempty(oldXLim)
                %drawnow;
                %xlim(app.channelAxes,oldXLim);
                set(app.channelAxes,'XLim',oldXLim);
            end

            %% make tiledlayout visible again
            app.tlTime.Visible='on';
            drawnow;% pause(0.1)

            %drawnow;
            app.executeXLimListener=1;
        end
        
        function minimizeYLims(app,evt)
            %drawnow;

            if ~app.executeXLimListener
                return;
            end


            % with pause it seems to work, but seems not to be smooth
            %pause(0.01);


            newXLim=evt.AffectedObject.XLim;

            % update only the axes of the calling xlim axis, since each
            % axis has its own listener and linked, each axis should be
            % called anyways
            % ok now the listener is only attached to the first axes, so we
            % do have less listener callbacks, but all axes are updated
%                 channelIdx=find(app.channelAxes==evt.AffectedObject,1);
% 
%                 currDataExcerpt=app.channelYValues{channelIdx}(app.channelXValues{channelIdx}>=newXLim(1)-app.channelLag(channelIdx) & app.channelXValues{channelIdx}<=newXLim(2)-app.channelLag(channelIdx));
%                 
%                 minVal=min(currDataExcerpt);
%                 maxVal=max(currDataExcerpt);
%                 ylim(evt.AffectedObject,[minVal maxVal]+0.1*[-1 1]*(maxVal-minVal));
%                 yticks(evt.AffectedObject,'auto');

            % listener is only attached to first axis, but since axes are
            % linked with linkaxes, it should execute every time when it is
            % zoomed

            for nr=1:numel(app.availableChannelsTable.Selection)
                channelIdx=app.availableChannelsTable.Selection(nr);

                selectionPart=app.channelXValues{channelIdx}>=newXLim(1) & app.channelXValues{channelIdx}<=newXLim(2);
                
                % no need to add/remove lag here since the XData cell was
                % updated while dragging
                %selectionPart=app.channelXValues{channelIdx}>=newXLim(1)-app.channelLag(channelIdx) & app.channelXValues{channelIdx}<=newXLim(2)-app.channelLag(channelIdx);


                currDataExcerpt=app.channelYValues{channelIdx}(selectionPart);


                minVal=min(currDataExcerpt);
                maxVal=max(currDataExcerpt);
                newYLimits=[minVal maxVal]+0.1*[-1 1]*(maxVal-minVal);
                ylim(app.channelAxes(nr),newYLimits);
                yticks(app.channelAxes(nr),'auto');

                if numel(app.cycleBorderHandles)>= nr && isvalid(app.cycleBorderHandles(nr))
                    app.cycleBorderHandles(nr).YData(1:3:end)=newYLimits(1);
                    app.cycleBorderHandles(nr).YData(2:3:end)=newYLimits(2);
                end

                if ~isempty(app.DraggingData.CurrentRectangles) && isvalid(app.DraggingData.CurrentRectangles(nr))
                    app.DraggingData.CurrentRectangles(nr).Position([2 4])=[newYLimits(1) newYLimits(2)-newYLimits(1)];
                end
            end


            % manual linked x axes

            %ylim([app.channelAxes],'padded')
            newXLim=evt.AffectedObject.XLim;

            app.executeXLimListener=0;
            xlim(app.channelAxes,newXLim);
            
 
%             for chNr=1:numel(app.channelAxes)
%                 if evt.AffectedObject~=app.channelAxes(chNr) && ~isequal(app.channelAxes(chNr).XLim,newXLim)
%                     xlim(app.channelAxes(chNr),newXLim);
%                 end
% 
%             end

            drawnow limitrate;
            app.executeXLimListener=1;
        end
        
        function setToDataMarkMode(app)
                app.executeXLimListener=0;
                set(app.timePlotHandles,'HitTest','off');
                

                % Disable default interactions and set up dragging mode
                for i = 1:numel(app.channelAxes)
                    app.channelAxes(i).Interactions = []; % Disable interactivity
                end

                % Set up callbacks for drag behavior
                app.UIFigure.WindowButtonDownFcn = @(src, ~) startDrag(app,src);
                app.UIFigure.WindowButtonMotionFcn = @(src,~) duringDrag(app,src);
                app.UIFigure.WindowButtonUpFcn = @(src,~) endDrag(app,src);

                % Add listeners for YLim changes
                for i = 1:numel(app.channelAxes)
                    if isfield(app.channelAxes(i).UserData,'Listener')
                        delete(app.channelAxes(i).UserData.Listener);
                    end
                end
                disp('Dragging mode enabled');


            % Dragging Callbacks
            function startDrag(app,src)
                if ~strcmp(src.SelectionType, 'normal'),return;end
                disp('Start data mark');
                ax = app.UIFigure.CurrentObject;
                if isempty(ax) || ~isa(ax, 'matlab.graphics.axis.Axes'), return; end

                % Store drag state
                data = app.DraggingData;
                data.Dragging = true;
                data.StartX = ax.CurrentPoint(1, 1);
                data.PreviousRectangles = data.CurrentRectangles;
                app.DraggingData = data;

                % Create new rectangles
                rectangles = [];
                for axNr = 1:numel(app.channelAxes)
                    yMin = app.channelAxes(axNr).YLim(1);
                    yMax = app.channelAxes(axNr).YLim(2);
                    rect = rectangle(app.channelAxes(axNr), ...
                        'Position', [data.StartX, yMin, 0, yMax - yMin], ...
                        'FaceColor', [0.8, 0.8, 1, 0.5], ...
                        'EdgeColor', 'none', ...
                        'PickableParts', 'none','HandleVisibility','off');
                    rectangles = [rectangles, rect];
                end

                arrayfun(@(in)uistack(in,"bottom"),rectangles);
                
                data.CurrentRectangles = rectangles;
                app.DraggingData = data;
            end

            function duringDrag(app,src)
                if ~strcmp(src.SelectionType, 'normal'),return;end
                data = app.DraggingData;
                if ~data.Dragging, return; end

                %disp('During data mark');
                ax = app.UIFigure.CurrentObject;
                if isempty(ax) || ~isa(ax, 'matlab.graphics.axis.Axes'), return; end

                currX = ax.CurrentPoint(1, 1);
                xPos = min(data.StartX, currX);
                width = abs(currX - data.StartX);
                app.DraggingData.EndX=currX;

                % Update rectangles
                for axNr = 1:numel(data.CurrentRectangles)
                    yMin = app.channelAxes(axNr).YLim(1);
                    yMax = app.channelAxes(axNr).YLim(2);
                    data.CurrentRectangles(axNr).Position = [xPos, yMin, width, yMax - yMin];
                end

                app.updateSelectionAreaEditFieldsValues();

                drawnow limitrate;
            end

            function endDrag(app,src)
                if ~strcmp(src.SelectionType, 'normal'),return;end
                data = app.DraggingData;

                ax=app.UIFigure.CurrentObject;
                if isempty(ax) || ~isa(ax, 'matlab.graphics.axis.Axes'), return; end

                maxMinX=max(cellfun(@(in) in(1),app.channelXValues));
                minMaxX=min(cellfun(@(in) in(end),app.channelXValues));

                if data.Dragging && (isempty(data.CurrentRectangles) || data.CurrentRectangles(1).Position(3)==0)
                    data.Dragging = false;
                    delete(app.DraggingData.PreviousRectangles);
                    delete(app.DraggingData.CurrentRectangles);
                    app.DraggingData.PreviousRectangles=[];
                    app.DraggingData.CurrentRectangles=[];
                    p=ax.CurrentPoint(1,1);
                    app.DraggingData.StartX=[];
                    app.DraggingData.EndX=[];
                    app.createAudioPlayerData();

                    app.DraggingData.Dragging=false;

                    app.updateSelectionAreaEditFieldsValues();
                    return;
                end

                if data.Dragging
                    disp('End data mark');
                    data.Dragging = false;


                    data.StartX=min(app.DraggingData.StartX,app.DraggingData.EndX);
                    data.EndX=max(app.DraggingData.StartX,app.DraggingData.EndX);


                    if sum(data.CurrentRectangles(1).Position([1 3]))<maxMinX || data.CurrentRectangles(1).Position(1)>minMaxX
                        % when the marked region is completly below the
                        % highest minimum or above the smallest maximum
                        % then do not paint
                        delete(data.CurrentRectangles);
                        data.currentRectangles=[];
                        data.StartX=[];
                        data.EndX=[];
                    else
                        if maxMinX>data.CurrentRectangles(1).Position(1)
                            oldEnd=sum(data.CurrentRectangles(1).Position([1 3]));
                            for rectNr=1:numel(data.CurrentRectangles)
                                data.CurrentRectangles(rectNr).Position(1)=maxMinX;
                                data.CurrentRectangles(rectNr).Position(3)=oldEnd-maxMinX;
                            end
                            data.StartX=maxMinX;
    
                        end
    
                        if minMaxX<sum(data.CurrentRectangles(1).Position([1 3]))
                            for rectNr=1:numel(data.CurrentRectangles)
                                data.CurrentRectangles(rectNr).Position(3)=minMaxX-data.CurrentRectangles(rectNr).Position(1);
                            end
                            data.EndX=minMaxX;
    
                        end
                    end


                    % Delete previous rectangles
                    if ~isempty(data.PreviousRectangles)
                        delete(data.PreviousRectangles);
                        data.PreviousRectangles = [];
                    end


                    app.DraggingData = data;

                    app.createAudioPlayerData();


                    % set selection edit field values
                    app.updateSelectionAreaEditFieldsValues();

                    drawnow;


                end

                
            end


        end


        function refreshInteractionMode(app)
            if isempty(app.channelAxes)
                return;
            end

            %drawnow;
            
            
            if contains(app.InteractionModeButtonGroup.SelectedObject.Text,'normal','IgnoreCase',true)
                % normal mode
                %ylim(app.channelAxes,'auto');
                app.setToNormalInteractionMode();
            elseif contains(app.InteractionModeButtonGroup.SelectedObject.Text,'mark','IgnoreCase',true)
                % selection mode
                %ylim(app.channelAxes,'auto');
                app.setToDataMarkMode();
            else
                % move graph mode
                app.setToDataDragMode();
            end
            pause(0.1);
            %drawnow;
            
        end

        function setToDataDragMode(app)
            set(app.timePlotHandles,'HitTest','off');
            app.executeXLimListener=0;
            if isempty(app.availableChannelsTable.Data) || isempty(app.availableChannelsTable.Selection), return,end
            xlim(app.channelAxes, 'manual');
            ylim(app.channelAxes,'manual');
            
            
            % Initialize state variables
            appState.StartPoint = [];
            appState.CurrentGraph = []; % Index of the currently active graph
            
            % Set callbacks for mouse actions
            app.UIFigure.WindowButtonDownFcn = @(~, ~) onMouseDown(app);
            app.UIFigure.WindowButtonMotionFcn = @(~, ~) onMouseDrag(app);
            app.UIFigure.WindowButtonUpFcn = @(~, ~) onMouseUp(app);
            
            % Nested function: onMouseDown
                function onMouseDown(app)
                    clickedObject = app.UIFigure.CurrentObject;
                    if isa(clickedObject, 'matlab.graphics.axis.Axes')
                        % Find the corresponding graph index
                        axesHandles = app.channelAxes;
                        appState.CurrentGraph = find(axesHandles == clickedObject); % Direct indexing for arrays
                        appState.StartPoint = clickedObject.CurrentPoint;
                    else
                        appState.CurrentGraph = [];
                        appState.StartPoint = [];
                    end
                end

    % Nested function: onMouseDrag
            function onMouseDrag(app)
                if ~isempty(appState.StartPoint) && ~isempty(appState.CurrentGraph)
                    % Access the current graph's data
                    idx = appState.CurrentGraph;
                    axesHandles = app.channelAxes;
                    plotHandles = app.timePlotHandles;
                    xData = app.channelXValues;
                    cumulativeShifts = app.channelLag(app.availableChannelsTable.Selection);

                    % Calculate the shift
                    currentPoint = axesHandles(idx).CurrentPoint; % Use array indexing
                    shiftAmount = currentPoint(1, 1) - appState.StartPoint(1, 1);

                    % Update xData and cumulative shift
                    newXData = xData{idx} + shiftAmount; % Keep xData access as a cell array
                    xData{idx} = newXData; % Save updated xData
                    cumulativeShifts(idx) = cumulativeShifts(idx) + shiftAmount;

                    % Update the plot
                    plotHandles(idx).XData = newXData; % Use array indexing for plot handles

                    if numel(app.cycleBorderHandles)>=idx && isvalid(app.cycleBorderHandles(idx))
                        app.cycleBorderHandles(idx).XData([1:3:end 2:3:end])=app.cycleBorderHandles(idx).XData([1:3:end 2:3:end])+shiftAmount;
                    end

                    % Update the axis title with the total shift
                    %title(axesHandles(idx), sprintf('X Shift: %+.2f', cumulativeShifts(idx)));

                    % Save updated data back to UserData
                    app.channelXValues = xData;
                    app.channelLag(app.availableChannelsTable.Selection)= cumulativeShifts;


                    % Update the start point for smooth dragging
                    appState.StartPoint = currentPoint;
                end
            end

            % Nested function: onMouseUp
            function onMouseUp(app)
                % Clear state variables
                appState.StartPoint = [];
                appState.CurrentGraph = [];

                app.updateSelectionAreaEditFieldsLimits();


            end
        end


        function lineObj=addManyXlinesAsSingleObj(~,ax,xvals,vis)
            ylims=ylim(ax);
            plotxvals=repelem(xvals,3); xvals(3:3:end)=NaN;
            yvals=repmat([ylims NaN],[1 numel(xvals)]);

            lineObj=line(ax,plotxvals,yvals,'PickableParts','none','Color',[0.2 0 0],'LineStyle','-','Visible',vis);

        end

        function setToNormalInteractionMode(app)
            set(app.timePlotHandles,'HitTest','on');
            app.executeXLimListener=1;
            for i=1:numel(app.channelAxes)
                app.channelAxes(i).ButtonDownFcn = [];
            end

            app.UIFigure.WindowButtonDownFcn = [];
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
            
            set(app.channelAxes,'Interactions',[panInteraction("Dimensions",'x') zoomInteraction("Dimensions",'x') dataTipInteraction]);
        end
        
        function setChannelShiftToZero(app,channelIdx)
            for nr=channelIdx
                app.channelXValues{nr}=(0:numel(app.channelYValues{nr})-1)/app.channelSampleRates(nr);
            end

            idx=1;
            for nr=app.availableChannelsTable.Selection
                xlim(app.channelAxes(idx),'manual');
                app.timePlotHandles(idx).XData=app.channelXValues{nr};
                if numel(app.cycleBorderHandles)>=idx && isvalid(app.cycleBorderHandles(idx))
                    app.cycleBorderHandles(idx).XData([1:3:end 2:3:end])=app.cycleBorderHandles(idx).XData([1:3:end 2:3:end])-app.channelLag(nr);
                end
                app.timePlotHandles(idx).XData
                idx=idx+1;
            end

            app.channelLag=zeros(size(app.channelLag));

            drawnow;

            
        end
        
        function createAudioPlayerData(app)

            if isempty(app.availableChannelsTable.Selection)
                return;
            end

            % if there is a marked area available
            if ~isempty(app.DraggingData) && ~isempty(app.DraggingData.StartX) && app.DraggingData.StartX~=app.DraggingData.EndX%(app.DraggingData.EndX-app.DraggingData.StartX)>0.1
                startTime = app.DraggingData.StartX;
                endTime = app.DraggingData.EndX;
            else
                % if nothing was marked yet, play the most common points in
                % time
                startTime=max(cellfun(@(in) in(1),app.channelXValues));
                endTime=min(cellfun(@(in) in(end),app.channelXValues));
            end

            % Determine the common sample rate (e.g., maximum of given sample rates)
            commonRate = max(app.channelSampleRates);

            % Resample all traces to the common sample rate and extract the required segment
            
            resampledData = [];
            for channelNr = app.availableChannelsTable.Selection
                trace = app.channelYValues{channelNr};
                time=app.channelXValues{channelNr};

                trace=trace(time>=startTime & time<=endTime);

                originalRate = app.channelSampleRates(channelNr);

                % Calculate the resampling ratio
                [P, Q] = rat(commonRate / originalRate);

                % Resample the trace
                resampledTrace = resample(trace, P, Q);

                % we only play AC parst of the signals
                resampledTrace=resampledTrace-mean(resampledTrace);

                % we can weight the channels evenyl by normalizing each
                % channel by the number of channels selected
                if app.weightchannelsevenlyCheckBox.Value==1
                    resampledTrace=resampledTrace/max(abs(resampledTrace))/numel(app.availableChannelsTable.Selection);
                end

                % Adjust the length of the current trace to match resampledData, if necessary
                if isempty(resampledData)
                    resampledData = resampledTrace; % First trace defines the length
                else
                    if length(resampledTrace) < length(resampledData)
                        % Pad with zeros if shorter
                        resampledTrace = [resampledTrace; zeros(length(resampledData) - length(resampledTrace), 1)];
                    elseif length(resampledTrace) > length(resampledData)
                        % Truncate if longer
                        resampledTrace = resampledTrace(1:length(resampledData));
                    end
                    % Combine the traces
                    resampledData = resampledData + resampledTrace;
                end
            end

            % normalize to keep it to maxmimum abs amplitude of 1

            % Normalize the combined data to avoid clipping
            resampledData = resampledData / max(abs(resampledData));

            app.audioPlayerData=resampledData;


        end


        function uiMenuChangeColorCallback(app,src,~)
            % Uncheck all menu items first
            for nr = 1:numel(src.Parent.Children)
                src.Parent.Children(nr).Checked = 'off';
            end

            dispNr=app.UIFigure.CurrentAxes.UserData.plotNr;
            chNr=app.UIFigure.CurrentAxes.UserData.channelNr;
            
            % Check the selected menu item
            src.Checked = 'on';
            app.timePlotHandles(dispNr).Color=app.channelSourcePossibilitiesColor(ismember(app.channelSourcePossibilities,src.Label),:);
            ylabel(app.channelAxes(dispNr),src.Label);

            app.availableChannelsTable.Data{chNr,2}=src.Label;
            app.channelSource{chNr}=src.Label;

            
            % Display selected entry
            fprintf('Selected Source: %s\n', src.Label);
        end
        
        function updateSelectionAreaEditFieldsValues(app)
            
            if ~isempty(app.DraggingData.StartX)
                %if app.DraggingData.StartX ~= app.DraggingData.EndX
                    app.SelectedAreaStartEditField.Value=min([app.DraggingData.StartX,app.DraggingData.EndX]);
                    app.SelectedAreaEndEditField.Value=max([app.DraggingData.StartX,app.DraggingData.EndX]);
                    app.SelectedAreaDurationEditField.Value=abs(app.DraggingData.EndX-app.DraggingData.StartX); % also called during dragging, so there could be negative values
                else % during mark mode it was not dragged, but only clicked

                    % we could use this to limit the borders to the latest
                    % start end earliest data ending.
%                     app.SelectedAreaStartEditField.Value=max(cellfun(@(in) in(1),app.channelXValues));
%                     app.SelectedAreaEndEditField.Value=min(cellfun(@(in) in(end),app.channelXValues));


                    app.SelectedAreaStartEditField.Value=max(cellfun(@(in) in(1),app.channelXValues));
                    app.SelectedAreaEndEditField.Value=min(cellfun(@(in) in(end),app.channelXValues));
                    app.SelectedAreaDurationEditField.Value=app.SelectedAreaEndEditField.Value-app.SelectedAreaStartEditField.Value;

                %end
            end

            
        end
        
        function updateSelectionAreaEditFieldsLimits(app)
            channelEndTimes=(cellfun(@numel,app.channelYValues)-1)./app.channelSampleRates+app.channelLag;

            if isinf(app.SelectedAreaStartEditField.Value)
                drawRects=0;
            else
                drawRects=1;
            end

            app.SelectedAreaEndEditField.Value=min([channelEndTimes,app.SelectedAreaEndEditField.Value]);
            app.SelectedAreaStartEditField.Value=max([app.channelLag,app.SelectedAreaStartEditField.Value]);

            app.SelectedAreaEndEditField.Value=max([app.channelLag,app.SelectedAreaEndEditField.Value]);
            app.SelectedAreaStartEditField.Value=min([channelEndTimes,app.SelectedAreaStartEditField.Value]);

            app.SelectedAreaDurationEditField.Value=app.SelectedAreaEndEditField.Value-app.SelectedAreaStartEditField.Value;

            % set rect dragging data appropriatly
            %if ~isempty(app.DraggingData.StartX)
                app.DraggingData.StartX=app.SelectedAreaStartEditField.Value;
                app.DraggingData.EndX=app.SelectedAreaEndEditField.Value;

                % also change displayed rectangles, if displayed
                if ~isempty(app.DraggingData.CurrentRectangles)
                    for rectNr=1:numel(app.DraggingData.CurrentRectangles)
                        app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[app.SelectedAreaStartEditField.Value,app.SelectedAreaDurationEditField.Value];
                    end
                elseif drawRects
                    rects=gobjects(0);
                    for rectNr=1:numel(app.channelAxes)
                        ylimits=ylim(app.channelAxes(rectNr));
                        yMin=ylimits(1);
                        yMax=ylimits(2);
                        rects(rectNr)=rectangle(app.channelAxes(rectNr), ...
                            'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                            'FaceColor', [0.8, 0.8, 1, 0.5], ...
                            'EdgeColor', 'none', ...
                            'PickableParts', 'none');
                        app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[app.SelectedAreaStartEditField.Value,app.SelectedAreaDurationEditField.Value];
                    end
                    app.DraggingData.CurrentRectangles=rects;
                    arrayfun(@(in) uistack(in,'bottom'),rects);
                end

            %end
            
        end
        
        function uiMenuFilterDataCallback(app,plotNr,channelNr)
            yDataIn=app.channelYValues{channelNr};
            fsIn=app.channelSampleRates(channelNr);

            filterToolboxApp=interactiveFiltering();
            %filterToolboxApp.UIFigure.WindowStyle='modal';
            filterToolboxApp.originalData=yDataIn;
            filterToolboxApp.originalFs=fsIn;
            filterToolboxApp.setFreqMaxValues();
            filterToolboxApp.refreshFilterPlot();
            filterToolboxApp.refreshDataPlot();
            
            waitfor(filterToolboxApp,'finalOutputData');
            if isvalid(filterToolboxApp) % if filter tools were closed by apply button
                app.channelYValues{channelNr}=filterToolboxApp.finalOutputData;
                app.timePlotHandles(plotNr).YData=filterToolboxApp.finalOutputData;

                % add data to history cell
                nonEmptyCells=~cellfun(@isempty,app.channelYValuesHistory(:,channelNr));
                if ~any(nonEmptyCells)
                    app.channelYValuesHistory(end+1,channelNr)={filterToolboxApp.finalOutputData};
                else
                    app.channelYValuesHistory(find(nonEmptyCells,1,"first")+1,channelNr)={filterToolboxApp.finalOutputData};
                end

                
                delete(filterToolboxApp);

            else % if just the window was closed

                % when doing that, the settings are deleted if calculated
                % before
%                 app.calculationSettings=[];
%                 app.calculationCalledAsPreview=[];
                return;
            end
        end
        
        function performBasicChannelEditing(app,OriginatingPlotNr,OriginatingChannelNr,oneSomeOrAllChannels,wholeOrSelected,actionToPerform)
            
        end
        
        function populateResultsTable(app)
            nWindows=app.calculationResults.numberOfWindows;
            nChannels=numel(app.calculationResults.channelResults);

            % organize by parameter type

            parameterNames=fieldnames(app.calculationResults.channelResults);

            parameterNames(contains(parameterNames,{'curr'},"IgnoreCase",true))=[];
            parameterNames(ismember(parameterNames,{'cycleBorderVals' 'cycleDurations'}))=[];


            app.parameterResultsUITable.Data=[];
            TableHeader={'Channel name','Parameter','Source','Median','Mean','STD','Min','Max'};

            windowHeaders=arrayfun(@(x) ['window ' num2str(x)], 1:nWindows, 'UniformOutput', false);
            TableHeader=[TableHeader windowHeaders {''}];
            app.parameterResultsUITable.ColumnName=TableHeader;
            app.parameterResultsUITable.ColumnWidth=[repelem({'fit'},numel(TableHeader)-1) {'1x'}];
            app.parameterResultsUITable.ColumnFormat=[repelem({''}, 3) repelem({'shortG'}, numel(TableHeader)-3)];

            tableData={};
            for parameterNr=1:numel(parameterNames)
                windowValues={app.calculationResults.channelResults.(parameterNames{parameterNr})}';

                channelsInWhichTheParameterExists=~cellfun(@isempty,windowValues);

                % EGG the quotients are only calucated for EGG and GAW
                windowValues=windowValues(channelsInWhichTheParameterExists);
                
                medians=cellfun(@median,windowValues,'UniformOutput',false);
                means=cellfun(@mean,windowValues,'UniformOutput',false);
                stds=cellfun(@std,windowValues,'UniformOutput',false);
                mins=cellfun(@min,windowValues,'UniformOutput',false);
                maxs=cellfun(@max,windowValues,'UniformOutput',false);

                tableData=[tableData;...
                   app.channelNames(channelsInWhichTheParameterExists)' repelem(parameterNames(parameterNr),sum(channelsInWhichTheParameterExists),1) app.channelSource(channelsInWhichTheParameterExists)' medians means stds mins maxs num2cell(cell2mat(windowValues)) repelem({''},sum(channelsInWhichTheParameterExists),1)];


            end

            % center text
            centerStyle=uistyle('HorizontalAlignment','center');
            addStyle(app.parameterResultsUITable,centerStyle,"column",2:3)


            app.parameterResultsUITable.Data=tableData;

            app.ResultsTab.Parent=app.TabGroup;
            app.TabGroup.SelectedTab=app.ResultsTab;

            app.SaveResultsButton.Enable='on';
            
        end

        function sampEn = calculateSampleEntropy(~,signal, templateLength, stdPercentage)
            % maybe we should use https://github.com/BYO-UPM/AVCA-ByO/blob/master/src/Complexity/CalculateRegularity.m

            
            r=stdPercentage*std(signal);
            N = length(signal);

            
            % Construct embedding matrix for m-length segments:
            X_m = zeros(N-templateLength+1, templateLength);
            for i = 1:(N-templateLength+1)
                X_m(i,:) = signal(i:i+templateLength-1);
            end
            
            % Compute the Chebyshev (maximum) norm between all template pairs:
            dists_m = pdist(X_m, @(xi,xj)  max(abs(xi - xj), [], 2));
            D_m = squareform(dists_m);
            
            % Count the number of matches (exclude the self-match on the diagonal)
            % Note that D_m is symmetric, so we count the entries below (or above) the diagonal.
            B = sum(sum(tril(D_m < r, -1)));
            
            % Now construct the embedding matrix for m+1-length segments:
            X_m1 = zeros(N-templateLength, templateLength+1);
            for i = 1:(N-templateLength)
                X_m1(i,:) = signal(i:i+templateLength);
            end
            
            % Compute distances for (m+1)-length segments
            dists_m1 = pdist(X_m1, @(xi,xj) max(abs(xi - xj), [], 2));
            D_m1 = squareform(dists_m1);
            
            A = sum(sum(tril(D_m1 < r, -1)));
            
            % Avoid division by zero. If no matches are found, return Inf.
            if B == 0
                sampEn = Inf;
            else
                sampEn = -log(A / B);
            end
        end


        function result = splitCharArray(~,inputArray, maxLength)
            % Initialize output cell array
            result = {};
            while ~isempty(inputArray)
                % Check if remaining part is longer than maxLength
                if length(inputArray) > maxLength
                    % Find last space within maxLength to split neatly
                    splitPoint = find(inputArray(1:maxLength) == ' ', 1, 'last');
                    if isempty(splitPoint)
                        % No space found, split at maxLength
                        splitPoint = maxLength;
                    end
                else
                    % Use remaining length if shorter than maxLength
                    splitPoint = length(inputArray);
                end
                % Extract substring and add to result
                result{end+1} = inputArray(1:splitPoint);
                % Remove the processed part from inputArray
                inputArray = strtrim(inputArray(splitPoint+1:end));
            end
        end

        
        function uiMenuFilterDataUndoCallback(app,plotNr,channelNr)

            nonEmptyCells=~cellfun(@isempty,app.channelYValuesHistory(:,channelNr));

            if sum(nonEmptyCells)==1 % if there is still the original data or if only one filtering process was done
                return;
            else
                lastNonEmpty=find(nonEmptyCells,1,'last');
                app.channelYValues(channelNr)=app.channelYValuesHistory(lastNonEmpty-1,channelNr);
                app.channelYValuesHistory{lastNonEmpty,channelNr}=[];
            end

            app.timePlotHandles(plotNr).YData=app.channelYValues{channelNr};

            try
            app.calculationResults.channelResults(channelNr).cycleBorderVals=NaN;
            delete(app.cycleBorderHandles(channelNr));
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tlTime=tiledlayout(app.plottingPanel,1,1,'TileSpacing','tight','Padding','compact');

            app.availableChannelsTable.Data={};
            app.availableChannelsTable.ColumnFormat= {[], app.channelSourcePossibilities};

            app.ResultsTab.Parent=[];
        end

        % Menu selected function: ImportfromfilesMenu
        function importDataFromFile(app, event)
            persistent lastPath;

            listOfFormatsForAudioread=';*.aifc;*.aiff;*.aif;*.au;*.flac;*.ogg;*.opus;*.wav;*.mp3;*.m4a;*.mp4';
            
            fakefig=uifigure('Visible','off');
            [files,path,idx] = uigetfile( ...
                {['*.gaw;*.csv;*.wvd;*.wav' listOfFormatsForAudioread],'gaw or photron csv (type A)/wvd import, audio files, audio from mp4'; ...
                '*.*','any other file'}, ...
                'Select one or more files',lastPath,'MultiSelect','on');
            close(fakefig);

            if isequal(files,0)
                disp('file import aborted by user');
                return;
            end
            lastPath=path;

            % if only one file was selected, convert from char array to cell string
            if ischar(files)
                files={files};
            end


            if idx==1 % if mixed audio, photron csv type A, or photron wvd
                for fileNr=1:numel(files)
                    currFile=[path files{fileNr}];
                    [~,~,currExt]=fileparts(currFile);

                    %% import audio files
                    if ismember(currExt,strsplit(listOfFormatsForAudioread,';*'))

                        [answerIdx,answerValid] = listdlg('ListString',app.channelSourcePossibilities,...
                            'PromptString',{'Choose Import data type of wav channels' files{fileNr}},...
                            'SelectionMode','single','InitialValue',1,...
                            'CancelString','Skip','ListSize',[600 300]);


                        if ~answerValid
                            disp('User aborted list answer. Skipping file');
                            continue;
                        end

                        [newdata,fs]=audioread(currFile,[1 inf],"double");
                        numberOfNewChannelsInThisFile=size(newdata,2);
                        newdataChannelSources=repelem(app.channelSourcePossibilities(answerIdx),numberOfNewChannelsInThisFile);
                        newdataSampleRates=repelem(fs,numberOfNewChannelsInThisFile);
                        newdataChannelNames=append(files(fileNr),' channel ',strsplit(num2str(1:numberOfNewChannelsInThisFile,'%02i ')));


                    %% import photron wvd
                    elseif strcmpi(currExt,'.wvd')
                        [newdata,meta]=app.importPhotronWVD(currFile,'double');
                        newdata=newdata'; % originally the data is given rowwise, so convert to columnwise
                        meta.channelName=regexprep(meta.channelName,char(0),'');
                        fs=meta.sampleRate;

                        numberOfNewChannelsInThisFile=size(newdata,2);
                        newdataSampleRates=repelem(fs,numberOfNewChannelsInThisFile);
                        
                        
                        % check if found channel names are part of the
                        % predefined channel name list
                        % each row is one channel, each column one of the
                        % possibilities in whereToFind
                        whereToFind=false(numberOfNewChannelsInThisFile,numel(app.channelSourcePossibilities));
                        for channelPossNr=1:numel(app.channelSourcePossibilities)
                            whereToFind(:,channelPossNr)=contains(meta.channelName,app.channelSourcePossibilities{channelPossNr});
                        end
                        whereToFind(~any(whereToFind,2),contains(app.channelSourcePossibilities,'Raw','IgnoreCase',true))=true;

                        % maybe better with user input?
                        [row,~]=find(whereToFind'); 
                        newdataChannelSources=app.channelSourcePossibilities(row);

                        % do not use channel x, but the types here
                        newdataChannelNames=append(files(fileNr),' channel ',meta.channelName);

                    %% import photron type A csv
                    elseif strcmpi(currExt,'.csv')
                        [meta,data]=app.readPhotronGraphExportData(currFile);

                        newdata=data(:,4:end);

                        meta(cellfun(@(in) isempty(in) || ~ischar(in),meta(:,1)),1)={''};
                        fs=meta{contains(meta(:,1),'SamplingRate'),3};
                        newdataChannelNames=append(files(fileNr),' channel ',meta(end,4:end));

                        numberOfNewChannelsInThisFile=numel(newdataChannelNames);
                        whereToFind=false(numberOfNewChannelsInThisFile,numel(app.channelSourcePossibilities));
                        for channelPossNr=1:numel(app.channelSourcePossibilities)
                            whereToFind(:,channelPossNr)=contains(newdataChannelNames,app.channelSourcePossibilities{channelPossNr});
                        end
                        whereToFind(~any(whereToFind,2),contains(app.channelSourcePossibilities,'Raw','IgnoreCase',true))=true;

                        % maybe better with user input?
                        [row,~]=find(whereToFind'); 
                        newdataChannelSources=app.channelSourcePossibilities(row);
                        newdataSampleRates=repelem(fs,numberOfNewChannelsInThisFile);

                    %% import GAW
                    elseif strcmpi(currExt,'.gaw')
                        out=readmatrix('E3_messa_250_langsam_20221017_145608-improved[1-118547].gaw','FileType','text','Range','B:C');
                        out(1,:)=[]; % header lines
                        newdataChannelSources=app.channelSourcePossibilities(contains(app.channelSourcePossibilities,'GAW','IgnoreCase',true));
                        newdataChannelNames=files(fileNr);
                        newdataSampleRates=1/mean(diff(out(:,1)));
                        numberOfNewChannelsInThisFile=1;
                        newdata=out(:,2);

                    end

                    %% add new data to property
                    newYValues=mat2cell(newdata, size(newdata, 1), ones(1, numberOfNewChannelsInThisFile));
                    app.channelYValues=[app.channelYValues newYValues];
                    app.channelXValues=[app.channelXValues mat2cell(repmat((0:size(newdata,1)-1)'/newdataSampleRates(1),[1 numberOfNewChannelsInThisFile]), size(newdata, 1), ones(1, numberOfNewChannelsInThisFile))];
                    app.channelNames=[app.channelNames newdataChannelNames];
                    app.channelSource=[app.channelSource newdataChannelSources];
                    app.channelSampleRates=[app.channelSampleRates newdataSampleRates];
                    app.channelLag=[app.channelLag zeros(1,numberOfNewChannelsInThisFile)];

                    app.channelYValuesHistory(1,end+1:end+numberOfNewChannelsInThisFile) = newYValues;
                end
                

            elseif idx==2 % not audio, not gaw, not wvd, not photron csv type A
                disp('generic files not yet supported')
                return;


            end

            figure(app.UIFigure);

            app.availableChannelsTable.Data=[app.channelNames(:) app.channelSource(:)];
            app.availableChannelsTable.Selection=1:numel(app.channelNames);

            app.refreshTimePlotData();

            %drawnow;
            xlim(app.channelAxes,'tight');

            drawnow;
            xlim(app.channelAxes,[min(cellfun(@(in) in(1),app.channelXValues)) max(cellfun(@(in) in(end),app.channelXValues))]);

            %% set selection edit field values
            %app.updateSelectionAreaEditFieldsValues(); % already included
            %in refreshTimePlotData

            if ~isempty(app.DraggingData.StartX)
                app.updateSelectionAreaEditFieldsLimits();
            end

            set([app.SelectedAreaDurationEditField app.SelectedAreaEndEditField app.SelectedAreaStartEditField],'Editable','on');
            
        end

        % Value changed function: showedgraphswithoutscrollingDropDown
        function changeNumberOfGraphsWithoutScrolling(app, event)
            app.rescaleTimePlotTilesVerticallyPanel();
        end

        % Selection changed function: availableChannelsTable
        function changeDisplayedChannels(app, event)
            app.refreshTimePlotData();
            
        end

        % Cell edit callback: availableChannelsTable
        function adjustDataSourceOnTableEdit(app, event)
            % when the source gets changed, also save that info in the
            % internal data source
            
            indices = event.Indices;
            newData = event.NewData;

            app.channelSource{indices(1)}=newData;
            ylabel(app.channelAxes,newData);

            app.timePlotHandles(1).Color=app.channelSourcePossibilitiesColor(ismember(app.channelSourcePossibilities,newData),:);

            
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            if ~isempty(app.availableChannelsTable.Data)
                app.availableChannelsTable.Selection=1:size(app.availableChannelsTable.Data,1);
                app.refreshTimePlotData();
            end
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            if ~isempty(app.availableChannelsTable.Data)
                app.xlimBeforeDeletion=app.channelAxes(1).XLim;
                app.availableChannelsTable.Selection=[];
                app.refreshTimePlotData();
            end
        end

        % Button pushed function: DeleteselectedsignalsButton
        function DeleteselectedsignalsButtonPushed(app, event)
            if ~isempty(app.availableChannelsTable.Data)
                selection=app.availableChannelsTable.Selection;
                app.channelYValues(selection)=[];
                app.channelXValues(selection)=[];
                app.channelYValuesHistory(:,selection)=[];
                app.channelLag(selection)=[];
                app.channelNames(selection)=[];
                app.channelSampleRates(selection)=[];
                app.channelSource(selection)=[];

                % this is needed because the
                % updateSelectionAreaEditFieldsLimits throws an error of
                % invalid objects exist.
                if ~isempty(app.DraggingData.CurrentRectangles)
                    app.DraggingData.CurrentRectangles=[];
                end


                if ~isempty(app.calculationResults)
                    % remove calcultions stored from that channel
                    app.calculationResults.channelResults(selection)=[];
                end
                

                % remove from channel table and set no selection
                app.availableChannelsTable.Selection=[];
                app.availableChannelsTable.Data(selection,:)=[];

                if ~isempty(app.channelAxes)
                    app.xlimBeforeDeletion=app.channelAxes(1).XLim;
                end

                delete(app.tlTime);
                app.channelAxes=gobjects(0);

                app.updateSelectionAreaEditFieldsLimits();
            end
        end

        % Selection changed function: InteractionModeButtonGroup
        function InteractionModeButtonGroupSelectionChanged(app, event)
            app.refreshInteractionMode();
        end

        % Button pushed function: OpenShiftToolButton
        function OpenShiftToolButtonPushed(app, event)
            if isempty(app.availableChannelsTable.Data)
                return;
            end
            
            shifttoolFig=uifigure('WindowStyle','modal','Name','Shifttool','Units','normalized','Position',[0.25 0.25 0.5 0.5]);

            uigrid=uigridlayout(shifttoolFig,[4,2],"ColumnWidth",{'1x' 'fit'},"RowHeight",{'fit','fit','1x','fit'});


            ShiftTypeDropdown=uidropdown(uigrid,"Items",{'Time in ms','Time in s','By Frames'},'ItemsData',{'ms' 's' 'frames'});
            ShiftTypeDropdown.Layout.Row=1;
            ShiftTypeDropdown.Layout.Column=2;

            ShiftTypeDropdownLabel=uilabel(uigrid,"Text",'Unit of shift','WordWrap','on');
            ShiftTypeDropdownLabel.Layout.Row=1;
            ShiftTypeDropdownLabel.Layout.Column=1;

            ShiftAmountEdit=uieditfield(uigrid,"numeric","Value",0,"Tooltip",'Positive values add lag','ValueDisplayFormat',['%+11g ' ShiftTypeDropdown.Value]);
            ShiftAmountEdit.Layout.Row=2;
            ShiftAmountEdit.Layout.Column=2;

            ShiftTypeDropdown.ValueChangedFcn=@(~,~) set(ShiftAmountEdit,'ValueDisplayFormat',['%+11g ' ShiftTypeDropdown.Value],'RoundFractionalValues',contains(ShiftTypeDropdown.Value,'frame'));

            HowMuchEditLabel=uilabel(uigrid,"Text",'How much?','Tooltip',ShiftAmountEdit.Tooltip,'WordWrap','on');
            HowMuchEditLabel.Layout.Row=2;
            HowMuchEditLabel.Layout.Column=1;

            availableSignalsTable = uitable(uigrid,'Data',[app.availableChannelsTable.Data(:,1) num2cell(app.channelLag(:))],'ColumnWidth',{'fit' 'fit'},...
                'ColumnEditable',[false false],'SelectionType','row','ColumnName',{'channel name' 'current shift (s)'},...
                'ColumnSortable',[false false],'Multiselect','on','RowName',[],'Selection',1:numel(app.channelLag),'RowStriping','off');
            availableSignalsTable.Layout.Row=3;
            availableSignalsTable.Layout.Column=2;

            availableSignalsTableLabel= uilabel(uigrid,"Text",'Select signals to shift','WordWrap','on','VerticalAlignment','top');
            availableSignalsTableLabel.Layout.Row=3;
            availableSignalsTableLabel.Layout.Column=1;

            RemoveShiftButton=uibutton(uigrid,'push','Text','Remove Shift','ButtonPushedFcn',@(~,~)removeChannelShift(app),'WordWrap','on');
            RemoveShiftButton.Layout.Row=4;
            RemoveShiftButton.Layout.Column=1;

            function removeChannelShift(app)
                app.setChannelShiftToZero(availableSignalsTable.Selection);
                availableSignalsTable.Data(availableSignalsTable.Selection,2)=num2cell(zeros(1,numel(availableSignalsTable.Selection)));

                drawnow;
            end

            ApplyShiftButton=uibutton(uigrid,'push','Text','Apply Shift');
            ApplyShiftButton.Layout.Row=4;
            ApplyShiftButton.Layout.Column=2;

            ApplyShiftButton.ButtonPushedFcn=@(~,~) adjustSignalShift(app);

            drawnow;

            function adjustSignalShift(app)
                selected=availableSignalsTable.Selection;
                oldLags=app.channelLag(selected);

                % convert to seconds
                if contains(ShiftTypeDropdown.Value,'ms')
                    toBeAdded=repelem(ShiftAmountEdit.Value/1000,numel(selected));
                elseif contains(ShiftTypeDropdown.Value,'s')
                    toBeAdded=repelem(ShiftAmountEdit.Value,numel(selected));
                else % frames
                    toBeAdded=1./app.channelSampleRates(selected)*ShiftAmountEdit.Value;
                end
                newTotalLags=oldLags+toBeAdded;
                app.channelLag(selected)=newTotalLags;

                availableSignalsTable.Data(selected,2)=num2cell(newTotalLags);

                % this is slow and not that handy, try to adjust the
                % plotted handles directly
                %app.refreshTimePlotData();

                xlim(app.channelAxes,'manual');
                for nr=1:numel(selected)
                    newTimevalues=app.channelXValues{selected(nr)}+toBeAdded(nr);
                    app.channelXValues{selected(nr)}=newTimevalues;

                    if ~isempty(find(app.availableChannelsTable.Selection==selected(nr),1))
                        app.timePlotHandles(find(app.availableChannelsTable.Selection==selected(nr),1)).XData=newTimevalues;
                    end

                    if numel(app.cycleBorderHandles)>=nr && isvalid(app.cycleBorderHandles(nr))
                        app.cycleBorderHandles(selected(nr)).XData([1:3:end 2:3:end])=app.cycleBorderHandles(selected(nr)).XData([1:3:end 2:3:end])+toBeAdded(nr);
                    end
                    

                end

                drawnow;
            end

            drawnow;
        end

        % Button pushed function: UndoallshiftsButton
        function UndoallshiftsButtonPushed(app, event)
            app.setChannelShiftToZero(1:numel(app.channelAxes));
        end

        % Button pushed function: StopSoundButton
        function StopSoundButtonPushed(app, event)
            if ~isempty(app.audioPlayerObj) && app.audioPlayerObj.isplaying
                stop(app.audioPlayerObj);
            end
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            if isempty(app.availableChannelsTable.Selection)
                return;
            end

            if ~isempty(app.audioPlayerObj) && app.audioPlayerObj.isplaying
                stop(app.audioPlayerObj);
            end
            
            app.audioPlayerObj=audioplayer(app.SoundVolumeSlider.Value*app.audioPlayerData,max(app.channelSampleRates(app.availableChannelsTable.Selection)));
            play(app.audioPlayerObj);
        end

        % Value changed function: weightchannelsevenlyCheckBox
        function weightchannelsevenlyCheckBoxValueChanged(app, event)
            app.createAudioPlayerData();
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.keepMSAsettingsOverSession~=1 && isfile("lastMSAsettings.mat")
                delete('lastMSAsettings.mat');
            end
            
            delete(app)
            
        end

        % Value changed function: SelectedAreaStartEditField
        function SelectedAreaStartEditFieldValueChanged(app, event)
            if isempty(app.availableChannelsTable.Data)
                return;
            end
            
            if app.SelectedAreaStartEditField.Value>=app.SelectedAreaEndEditField.Value
                app.SelectedAreaEndEditField.Value=app.SelectedAreaStartEditField.Value;
            end
            
            app.updateSelectionAreaEditFieldsLimits();


        end

        % Value changed function: SelectedAreaEndEditField
        function SelectedAreaEndEditFieldValueChanged(app, event)
            if isempty(app.availableChannelsTable.Data)
                return;
            end
            
            if app.SelectedAreaEndEditField.Value<=app.SelectedAreaStartEditField.Value
                app.SelectedAreaStartEditField.Value=app.SelectedAreaEndEditField.Value;
            end
            app.updateSelectionAreaEditFieldsLimits();


        end

        % Value changed function: SelectedAreaDurationEditField
        function SelectedAreaDurationEditFieldValueChanged(app, event)
            if isempty(app.availableChannelsTable.Data)
                return;
            end

            app.SelectedAreaEndEditField.Value=app.SelectedAreaStartEditField.Value+app.SelectedAreaDurationEditField.Value;

            app.updateSelectionAreaEditFieldsLimits();
        end

        % Value changed function: HidecyclesButton
        function HidecyclesButtonValueChanged(app, event)
            value = app.HidecyclesButton.Value;

            if ~isempty(app.cycleBorderHandles)
                if value==1
                    set(app.cycleBorderHandles(isvalid(app.cycleBorderHandles)),'Visible','off');
                else
                    set(app.cycleBorderHandles(isvalid(app.cycleBorderHandles)),'Visible','on');
                end


            end
            
        end

        % Cell selection callback: parameterResultsUITable
        function parameterResultsUITableCellSelection(app, event)
            if isempty(event.Indices)
                return;
            end

            % first style is the centered style of columns 2 and 3. keep
            % that one
            numOfStyles=size(app.parameterResultsUITable.StyleConfigurations,1);
            if numOfStyles>1
                removeStyle(app.parameterResultsUITable,2:numOfStyles);
            end
        
            % Get the selected row and first column value
            selectedRow = event.Indices(1);
            selectedValue = app.parameterResultsUITable.Data{selectedRow, 1};
        
            matchRows = cellfun(@(x) isequal(x, selectedValue), app.parameterResultsUITable.Data(:, 1)); % Matches in first column
            %nonMatchRows = ~matchRows; % Complement of matching rows
        
            % Define styles
            highlightedStyle = uistyle('BackgroundColor', 'yellow');
            %defaultStyle = uistyle('BackgroundColor', 'white');
        
            % Apply styles in two calls
            addStyle(app.parameterResultsUITable, highlightedStyle, 'row', find(matchRows)); % Apply to matching rows


            % to remove that weird color
            %app.parameterResultsUITable.Selection=[];
        end

        % Menu selected function: CopyleftclickedCellMenu
        function CopyleftclickedCellMenuSelected(app, event)
            clipboard("copy",app.parameterResultsUITable.Data{app.parameterResultsUITable.Selection(1),app.parameterResultsUITable.Selection(2)});
        end

        % Button pushed function: CalculateParametersButton
        function CalculateParametersButtonPushed(app, event)
            %% open settings and let the user chosse settings
            if isempty(app.channelYValues)
                return;
            end
            settingsApp=MSAcalculationSettings();
            settingsApp.loadAppSate();

            %% get start and end times of selection if possible
            if isempty(app.DraggingData.CurrentRectangles)
                selectionStart=max(cellfun(@(in) in(1),app.channelXValues));
                selectionEnd=min(cellfun(@(in) in(end),app.channelXValues));
            else
                selectionStart=app.DraggingData.StartX;
                selectionEnd=app.DraggingData.EndX;
            end

            % the maxmimum window size equal to the selected area duration
            settingsApp.setMaximumWindowLength(selectionEnd-selectionStart);
            %settingsApp.UIFigure.WindowStyle='modal';
            drawnow;
            waitfor(settingsApp,'outputSettings');
            if isvalid(settingsApp) % if settings were closed by preview or calculation button
                app.calculationSettings=settingsApp.outputSettings;
                app.calculationCalledAsPreview=settingsApp.calledAsPreview;

                app.keepMSAsettingsOverSession=settingsApp.keepMSAsettingsOverSession;
                settingsApp.saveCurrentAppState();
                delete(settingsApp);

                % also change back to normal mode after parameter
                % calculation
                app.normalButton.Value=1;
                app.setToNormalInteractionMode();

            else % if just the window was closed

                % when doing that, the settings are deleted if calculated
                % before
%                 app.calculationSettings=[];
%                 app.calculationCalledAsPreview=[];
                return;
            end

            %% delete old cycle borders
            delete(app.cycleBorderHandles);
            app.cycleBorderHandles=gobjects(1,numel(app.channelAxes));

            %% delete old calculation results
            app.calculationResults=struct();

            nChannels=numel(app.channelYValues);


            %% create porgress bar that can be canceled
            progDialog = waitbar(0,['0 of ' num2str(nChannels) ' channels complete'],'Name','Calculating parameters...',...
    'CreateCancelBtn','setappdata(gcbf,''canceling'',1)','WindowStyle','modal');

            setappdata(progDialog,'canceling',0);

            %% save applied lag
            app.calculationResults.appliedChannelLag=app.channelLag;


            %% save selected interval
            app.calculationResults.selectionStart=selectionStart;
            app.calculationResults.selectionEnd=selectionEnd;
            app.calculationResults.channelNames=app.channelNames;
            app.calculationResults.channelSource=app.channelSource;

            %% see if we calculate on base of windows, or use only one window
            if app.calculationSettings.useWindows==1
                startTimes=selectionStart:app.calculationSettings.windowHopLength:selectionEnd-app.calculationSettings.windowLength;
                endTimes=selectionStart+app.calculationSettings.windowLength:app.calculationSettings.windowHopLength:selectionEnd;

                % if we would use the following, the last window could
                % be not full length; maybe add option if this is
                % wanted
%                     if endTimes(end)~=selectionEnd
%                         endTimes=[endTimes selectionEnd];
%                     end
            else
                startTimes=selectionStart;
                endTimes=selectionEnd;
            end

            app.calculationResults.numberOfWindows=numel(startTimes);
            app.calculationResults.windowStartTimes=startTimes;
            app.calculationResults.windowEndTimes=endTimes;


            % separate 1/cycleDuration plot 
            if app.calculationSettings.cycleDuration.showSeparate1overCycleDurationPlot==1
                foFig=figure('Units','normalized','OuterPosition',[0.25 0.1 0.5 0.8]);
                tl=tiledlayout(foFig,nChannels+1,1,'Padding','tight','TileSpacing','compact');
                foAxs=gobjects(1,nChannels+1);
            end

            plotNr=1;
            for channelNr=1:nChannels
                if ~isvalid(progDialog) || getappdata(progDialog,'canceling')
                    break;
                end


                %% cycle detection
                excerpt=app.channelXValues{channelNr}>=selectionStart & app.channelXValues{channelNr}<=selectionEnd;
                currYData=app.channelYValues{channelNr}(excerpt);
                currXData=app.channelXValues{channelNr}(excerpt);
                currFs=app.channelSampleRates(channelNr);

                where=islocalmax(currYData,'MinSeparation',round(app.calculationSettings.cycleDuration.Min*currFs));
                cycleBorderVals=currXData(where);
                cycleDurations=diff(cycleBorderVals);
                app.calculationResults.channelResults(channelNr).cycleBorderVals=cycleBorderVals;
                app.calculationResults.channelResults(channelNr).cycleDurations=cycleDurations;
                if ismember(channelNr,app.availableChannelsTable.Selection)
                    if app.HidecyclesButton.Value==1
                        vis='off';
                    else
                        vis='on';
                    end
                    app.cycleBorderHandles(plotNr)=app.addManyXlinesAsSingleObj(app.channelAxes(plotNr),cycleBorderVals,vis);
                    plotNr=plotNr+1;
                end

                % separate 1/cycleDuration plot 
                if app.calculationSettings.cycleDuration.showSeparate1overCycleDurationPlot==1
                    foAxs(channelNr)=nexttile(tl,channelNr);
                    plot(foAxs(channelNr),movmean(cycleBorderVals,2,'Endpoints','discard'),1./cycleDurations);
                    title(foAxs(channelNr),app.channelNames{channelNr},'Interpreter','none');
                    
                    foAxs(end)=nexttile(tl,nChannels+1);
                    plot(foAxs(end),movmean(cycleBorderVals,2,'Endpoints','discard'),1./cycleDurations);
                    hold(foAxs(end),'on');
                end


                if app.calculationCalledAsPreview==1
                    continue;
                end

                %% generate a normalized data copy for sample entropy calculation if requested

                % if not 'global' is chosen, this is just a copy of the
                % regular Y values
                currYDataForSampleEntropyTime=currYData;
                if      strcmp(app.calculationSettings.SampleEntropyTime.normalizationHorizont,'global/selection based')...
                        && ~strcmp(app.calculationSettings.SampleEntropyTime.normalizationMethod,'none')

                    normMethod=app.calculationSettings.SampleEntropyTime.normalizationMethod;
                    currYDataForSampleEntropyTime=normalize(currYDataForSampleEntropyTime,normMethod);
                end


                %% starting here, this are the calculations done for non preview purpose

                nTotalCycles = numel(cycleBorderVals) - 1;
                [allCyclesYDataRange, allCyclesYDataEnergy,allCyclesOQs,allCyclesClQs,allCyclesOpQs,allCyclesGGI,allCyclesPlateauQuotient] = deal(nan(1, nTotalCycles));

                % for multiple values per cycle
                FDnumberOfHarmonics=app.calculationSettings.SampleEntropyFD.numberOfHarmonics;
                [fourierDescriptors] = deal(nan(FDnumberOfHarmonics, nTotalCycles));

                %% precompute values for all cycles
                for cycleNr = 1:nTotalCycles
                    currCycleData = currYData(currXData >= cycleBorderVals(cycleNr) & ...
                                              currXData <= cycleBorderVals(cycleNr + 1));

                    %% required for some many parameters later
                    [cycleMax,cycleMaxIdx]=max(currCycleData);
                    [cycleMin,cycleMinIdx]=min(currCycleData);
                    cycleRange=cycleMax-cycleMin;
                    allCyclesYDataRange(cycleNr) = cycleRange;
                    allCyclesYDataEnergy(cycleNr) = sum(currCycleData .^ 2);
                    allCyclesGGI(cycleNr)=cycleMin/cycleMax;
                    allCyclesPlateauQuotient(cycleNr)=sum(currCycleData>=cycleMin+app.calculationSettings.plateauQuotientThreshold*cycleRange);

                    %% here calculate the basic open, closed, closing and opening quotients
                    if ismember(app.channelSource{channelNr},{'GAW','EGG'})
                        total=numel(currCycleData);

                        % !!!remember!!! the cycles are max based because min
                        % based values are difficult due to noise
                        if strcmpi(app.channelSource{channelNr},'GAW')
                            zeroThreshold=app.calculationSettings.GAWopenThresholdPercentage*cycleMax;
    
                            closedDuration=sum(currCycleData<=zeroThreshold);
                            openDuration=total-closedDuration;
                            openingDuration=sum(currCycleData(cycleMinIdx:end)>zeroThreshold);
                            closingDuration=sum(currCycleData(1:cycleMinIdx)>zeroThreshold);
    
                        elseif strcmpi(app.channelSource{channelNr},'EGG')
                            % howard for the moment % closed according DEGG,
                            % open according 3/7
                            cycleGradient=gradient(currCycleData);
                            [~,closingDuration]=max(cycleGradient);
                            closingDuration=closingDuration-cycleMinIdx;
                            
    
                            openingDuration=find(cycleMin+3/7*(currCycleData(1)-cycleMin)>=currCycleData(1:cycleMinIdx),1,"first");
    
                            openDuration=closingDuration+(cycleMinIdx-openingDuration);
                            closedDuration=total-openDuration;
                        end
                        allCyclesOQs(cycleNr)=openDuration/total;
                        allCyclesClQs(cycleNr)=closingDuration/total;
                        allCyclesOpQs(cycleNr)=openingDuration/total;
                    end
                    
                    cycleFFT=fft(currCycleData,numel(currCycleData));

                    % each column is one cycle
                    fourierDescriptors(:,cycleNr)=(cycleFFT(2:2+FDnumberOfHarmonics-1)); % magnitudes
                end

                for windowNr=1:numel(startTimes)
                    currCycleBorderVals=cycleBorderVals(cycleBorderVals>=startTimes(windowNr) & cycleBorderVals<=endTimes(windowNr));
    
                    app.calculationResults.channelResults(channelNr).currWindowStartTime(windowNr)=startTimes(windowNr);
                    app.calculationResults.channelResults(channelNr).currWindowEndTime(windowNr)=endTimes(windowNr);

                    app.calculationResults.channelResults(channelNr).currWindowCycleBorders{windowNr}=currCycleBorderVals;
                    app.calculationResults.channelResults(channelNr).currWindowCycleDurations{windowNr}=diff(currCycleBorderVals);


                    currWindowYData=currYData(currXData >= startTimes(windowNr) & ...
                                                  currXData <= endTimes(windowNr));
                    currWindowYDataWindowed=hann(numel(currWindowYData),"periodic").*currWindowYData;


                    %% get current cycle values

                    whichCycles=cycleBorderVals(1:end-1)>=startTimes(windowNr) & cycleBorderVals(2:end)<=endTimes(windowNr);
                    
                    cycleYDataRange = allCyclesYDataRange(whichCycles);
                    cycleYDataEnergy = allCyclesYDataEnergy(whichCycles);

                    if ismember(app.channelSource{channelNr},{'GAW','EGG'})
                        cyclesOQValues = allCyclesOQs(whichCycles);
                        cyclesClQValues = allCyclesClQs(whichCycles);
                        cyclesOpQValues = allCyclesOpQs(whichCycles);
                    end

                    cyclesGGI = allCyclesGGI(whichCycles);
                    cyclesPlateauQuotient = allCyclesPlateauQuotient(whichCycles);
                    
                    
                    % Precompute mean values to avoid redundant calculations
                    meanCycleYDataRange     = mean(cycleYDataRange);
                    meanCycleDurations      = mean(cycleDurations);
                    meanCycleYDataEnergy    = mean(cycleYDataEnergy);

                    % assign window means of already precomputed cycle
                    % parameters

                    app.calculationResults.channelResults(channelNr).meanCycleYDataRange(windowNr)=meanCycleYDataRange;
                    app.calculationResults.channelResults(channelNr).meanCycleDurations(windowNr)=meanCycleDurations;
                    app.calculationResults.channelResults(channelNr).meanCycleYDataEnergy(windowNr)=meanCycleYDataEnergy;

                    % quotient values
                    if ismember(app.channelSource{channelNr},{'GAW','EGG'})
                        app.calculationResults.channelResults(channelNr).meanOQ(windowNr)=mean(cyclesOQValues);
                        app.calculationResults.channelResults(channelNr).meanClQ(windowNr)=mean(cyclesClQValues);
                        app.calculationResults.channelResults(channelNr).meanOpQ(windowNr)=mean(cyclesOpQValues);
                        app.calculationResults.channelResults(channelNr).meanSpeedQuotient(windowNr)=mean(cyclesOpQValues./cyclesClQValues);
                        app.calculationResults.channelResults(channelNr).meanSpeedIndex(windowNr)=mean((cyclesOpQValues-cyclesClQValues)./(cyclesOpQValues+cyclesClQValues));
                        app.calculationResults.channelResults(channelNr).meanRateQuotient(windowNr)=mean((1-cyclesOQValues+cyclesOpQValues)./cyclesClQValues);
                        app.calculationResults.channelResults(channelNr).meanAsymmetryQuotient(windowNr)=mean(cyclesOpQValues./(cyclesClQValues+cyclesOpQValues));
    
                        app.calculationResults.channelResults(channelNr).meanGGI(windowNr)=mean(cyclesGGI);
                        app.calculationResults.channelResults(channelNr).meanPlateauQuotient(windowNr)=mean(cyclesPlateauQuotient);
        
                    end
                    %% perturbation measurements
                    
                    % Generic functions for PF, PQ, and VI calculations
                    calculatePerturbationFactor  = @(data) mean(abs(diff(data) ./ data(2:end))) * 100;
                    calculatePerturbationQuotient = @(data, k) mean(abs(1 - ...
                        (k * data((k-1)/2+1:end-(k-1)/2) ./ movmean(data, k, 'Endpoints', 'discard')))) * 100;
                    calculateVariabilityIndex     = @(data, meanData) log10(1000 * var(data) / (meanData ^ 2));
                    
                    % Amplitude Perturbation
                    app.calculationResults.channelResults(channelNr).meanShimmerDB(windowNr)    = 20 * mean(abs(log10(cycleYDataRange(1:end-1) ./ cycleYDataRange(2:end))));
                    app.calculationResults.channelResults(channelNr).shimmerInPercent(windowNr) = mean(abs(diff(cycleYDataRange))) / meanCycleYDataRange * 100;
                    for k = 3:2:11
                        app.calculationResults.channelResults(channelNr).(['APQ' num2str(k)])(windowNr) = calculatePerturbationQuotient(cycleYDataRange, k);
                    end
                    app.calculationResults.channelResults(channelNr).APF(windowNr) = calculatePerturbationFactor(cycleYDataRange);
                    app.calculationResults.channelResults(channelNr).AVI(windowNr) = calculateVariabilityIndex(cycleYDataRange, meanCycleYDataRange);
                    
                    % Period Perturbation
                    app.calculationResults.channelResults(channelNr).meanJitter(windowNr)      = mean(abs(diff(cycleDurations)));
                    app.calculationResults.channelResults(channelNr).jitterInPercent(windowNr) = app.calculationResults.channelResults(channelNr).meanJitter(windowNr) / meanCycleDurations * 100;
                    app.calculationResults.channelResults(channelNr).jitterFactor(windowNr)    = mean(abs(diff(1 ./ cycleDurations))) / mean(1 ./ cycleDurations) * 100;
                    for k = 3:2:11
                        app.calculationResults.channelResults(channelNr).(['PPQ' num2str(k)])(windowNr) = calculatePerturbationQuotient(cycleDurations, k);
                    end
                    app.calculationResults.channelResults(channelNr).PPF(windowNr) = calculatePerturbationFactor(cycleDurations);
                    app.calculationResults.channelResults(channelNr).PVI(windowNr) = calculateVariabilityIndex(cycleDurations, meanCycleDurations);
                    
                    % Energy Perturbation
                    for k = 3:2:11
                        app.calculationResults.channelResults(channelNr).(['EPQ' num2str(k)])(windowNr) = calculatePerturbationQuotient(cycleYDataEnergy, k);
                    end
                    app.calculationResults.channelResults(channelNr).EPF(windowNr) = calculatePerturbationFactor(cycleYDataEnergy);
                    app.calculationResults.channelResults(channelNr).EVI(windowNr) = calculateVariabilityIndex(cycleYDataEnergy,meanCycleYDataEnergy);
                
                    %% noise measurements

                    % harmonic intensity - window based

                    % HNR - cycle based

                    % NNE based - window based

                    % spectral flatness - window based

                    % GNE - window based

                    % WMC - window based

                    % MWMC - window based

                    % SNR v1- window based

                    % SNR v2 - window based

                    % CPP v1 - window based
                    

                    % CPP v2 - window based

                    %% machanical

                    % stiffness - cycle based

                    % peak closing velocity - cycle based

                    % peak acceleration - cycle based

                    % amplitude to length ratio  - cycle based

                    
                    %% calculate sample entropy in time domain for this window
                    currWindowYDataForSampleEntropyTime=currYDataForSampleEntropyTime(currXData >= startTimes(windowNr) & ...
                                                  currXData <= endTimes(windowNr));
                    
                    if          ~strcmp(app.calculationSettings.SampleEntropyTime.normalizationHorizont,'global/selection based')...
                             && ~strcmp(app.calculationSettings.SampleEntropyTime.normalizationMethod,'none')

                        normMethod=app.calculationSettings.SampleEntropyTime.normalizationMethod;
                        currWindowYDataForSampleEntropyTime=normalize(currWindowYDataForSampleEntropyTime,normMethod);
                    end

                    if contains(app.calculationSettings.SampleEntropyTime.templateLengthMethod,'log10')
                        templateLength=round(log10(numel(currWindowYDataForSampleEntropyTime)));
                    else
                        templateLength=app.calculationSettings.SampleEntropyTime.templateLengthFixed;
                    end
                    if templateLength>numel(currWindowYDataForSampleEntropyTime)-2
                        templateLength=numel(currWindowYDataForSampleEntropyTime)-2;
                    end
                    if templateLength<2
                        templateLength=2;
                    end

                    stdThreshold=app.calculationSettings.SampleEntropyTime.distanceSTDthreshold;


                    app.calculationResults.channelResults(channelNr).sampleEntropyTimeDomain(windowNr)=app.calculateSampleEntropy(currWindowYDataForSampleEntropyTime,templateLength,stdThreshold);
                    
                    %% calculate sample entropy on Fourier descriptors for cycles in this window
                    currentFDs=fourierDescriptors(:,whichCycles);

                    if app.calculationSettings.SampleEntropyFD.useRelativeFD
                        currentFDs=currentFDs(2:end,:)-currentFDs(1,:); % now we have one less FD
                    end

                    
                    if contains(app.calculationSettings.SampleEntropyFD.templateLengthMethod,'log10')
                        templateLength=round(log10(size(currentFDs,2)));
                    else
                        templateLength=app.calculationSettings.SampleEntropyFD.templateLengthFixed;
                    end

                    if templateLength>numel(size(currentFDs,2))-2
                        templateLength=numel(size(currentFDs,2))-2;
                    end
                    if templateLength<2
                        templateLength=2;
                    end


                    FSsampEn=nan(size(currentFDs,1),1); % either nHarmonics or nHarmonics-1, depending on the relativeFD setting
                    
                    for FDnr=1:size(currentFDs,1)
                        wrappedAngles=mod(angle(currentFDs(FDnr,:)) + pi, 2*pi) - pi;
                        magnitudes=abs(currentFDs(FDnr,:));

%                         angleSampEn=SampEn_fast_omidvarnia(wrappedAngles,templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdPhase);
%                         magnitudeSampEn=SampEn_fast_omidvarnia(magnitudes,templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdMagnitude);

                        angleSampEn=app.calculateSampleEntropy(wrappedAngles,templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdPhase);
                        magnitudeSampEn=app.calculateSampleEntropy(magnitudes,templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdMagnitude);

                        switch app.calculationSettings.SampleEntropyFD.combinationMethod
                            case 'add'
                                FSsampEn(FDnr)=magnitudeSampEn+angleSampEn;
                            case 'min'
                                FSsampEn(FDnr)=min(magnitudeSampEn,angleSampEn);
                            case 'max'
                                FSsampEn(FDnr)=max(magnitudeSampEn,angleSampEn);
                        end
                        
                        
                    end

                    switch app.calculationSettings.SampleEntropyFD.combinationMethod
                        case 'add'
                            app.calculationResults.channelResults(channelNr).sampleEntropyFourierDomain(windowNr)=sum(FSsampEn);
                        case 'min'
                            app.calculationResults.channelResults(channelNr).sampleEntropyFourierDomain(windowNr)=min(FSsampEn);
                        case 'max'
                            app.calculationResults.channelResults(channelNr).sampleEntropyFourierDomain(windowNr)=max(FSsampEn);
                    end


                    %% list of additional parameters
                    pitchMethod={'NCF','PEF','CEP','LHS','SRH'};
                    for pitchMethodNr=1:numel(pitchMethod)
                        % no pitch needed since pitch function apllies a
                        % window by itself
                        % SRH uses periodic hann, NCF needs no windowing
                        % and all other use periodic hamming window
                        app.calculationResults.channelResults(channelNr).(['fo' pitchMethod{pitchMethodNr}])(windowNr)=pitch(currWindowYData(:),currFs,"Range",[app.calculationSettings.fo.Min app.calculationSettings.fo.Max],'WindowLength',numel(currWindowYDataWindowed),'OverlapLength',0);
                    end

                end

                %% glottal area waveform

                waitbar(channelNr/nChannels,progDialog,sprintf('%i of %i channels complete',channelNr,nChannels))


            end

            pause(1);
            delete(progDialog);

            % if progressbar aborted or closed
            if channelNr~=nChannels
                app.calculationResults=struct();
                if app.calculationSettings.cycleDuration.showSeparate1overCycleDurationPlot==1
                    delete(foFig);
                end
                return;
            end


            % separate 1/cycleDuration plot 
            if app.calculationSettings.cycleDuration.showSeparate1overCycleDurationPlot==1
                title(tl,'1/(cycle length)');
                xlabel(tl,'time (s)');

                linkaxes(foAxs);
    
                lg=legend(foAxs(end),'Orientation','horizontal');
                lg.Layout.Tile='south';
            end

            if app.calculationCalledAsPreview==0
                app.populateResultsTable();
            end

        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            persistent lastPath;

            fakefig=uifigure('Visible','off');

            [file,path]=uiputfile([lastPath 'MSAresults.xlsx'],'Where to save the values');

            close(fakefig);
            if isequal(file,0)
                return;
            else
                lastPath=path;
            end


            %% write to excel
            nWindows=app.calculationResults.numberOfWindows;
            nChannels=numel(app.calculationResults.channelResults);
            windowStartTimes=app.calculationResults.windowStartTimes;

            %% write general info sheet
            generalInfo = [{'Channel Name'}            app.calculationResults.channelNames;...
                           {'Channel Source'}          app.calculationResults.channelSource;...
                           {'Applied lag (s)'}         num2cell(app.calculationResults.appliedChannelLag);...
                           {'Selection start (s)'}     {app.calculationResults.selectionStart} repelem({''},nChannels-1);...
                           {'Selection end (s)'}       {app.calculationResults.selectionEnd} repelem({''},nChannels-1);...
                           {'Cycle border values (s)'} repelem({''},nChannels)];

            % here, the file is replaced!
            writecell(generalInfo',fullfile(path,file),"Sheet",'General','WriteMode','replacefile','Range','A1','AutoFitWidth',true);


            for channelNr=1:nChannels
                cycleBorders=num2cell(app.calculationResults.channelResults(channelNr).cycleBorderVals);
                writecell(cycleBorders',fullfile(path,file),"Sheet",'General','WriteMode','inplace','Range',['F' num2str(1+channelNr)],'AutoFitWidth',true);
            end


            %% organize by channel nr

            parameterNames=fieldnames(app.calculationResults.channelResults);

            parameterNames(contains(parameterNames,{'curr'},"IgnoreCase",true))=[];
            parameterNames(ismember(parameterNames,{'cycleBorderVals' 'cycleDurations'}))=[];
            
            TableHeader={'Parameter','Median','Mean','STD','Min','Max'};
            windowHeaders=arrayfun(@(x) ['window ' num2str(x)], 1:nWindows, 'UniformOutput', false);
            TableHeader=[TableHeader windowHeaders];

            for channelNr=1:nChannels

                tableData=[[{'channel name',                                'channel source',                               '','','','Window start time'} num2cell(app.calculationResults.channelResults(channelNr).currWindowStartTime)];...
                           [{app.calculationResults.channelNames{channelNr},app.calculationResults.channelSource{channelNr},'','','','Window end time'} num2cell(app.calculationResults.channelResults(channelNr).currWindowEndTime)];...
                           [{'',                                            '',                                             '','Total number of cycles in selection',numel(app.calculationResults.channelResults(channelNr).cycleDurations),'number of cycles in this window'} cellfun(@numel,app.calculationResults.channelResults(channelNr).currWindowCycleDurations,'UniformOutput',false)];...
                          TableHeader];
                for parameterNr=1:numel(parameterNames)
                    
                    windowValues={app.calculationResults.channelResults(channelNr).(parameterNames{parameterNr})}';

                    if isempty(windowValues{1})
                        sprintf('the parametter %s in channel %i is empty. skipping...',parameterNames{parameterNr},channelNr);
                        continue;
                    end
                    
                    medians=cellfun(@median,windowValues,'UniformOutput',false);
                    means=cellfun(@mean,windowValues,'UniformOutput',false);
                    stds=cellfun(@std,windowValues,'UniformOutput',false);
                    mins=cellfun(@min,windowValues,'UniformOutput',false);
                    maxs=cellfun(@max,windowValues,'UniformOutput',false);
    
                    tableData=[tableData;...
                       parameterNames(parameterNr) medians means stds mins maxs num2cell(cell2mat(windowValues))];
    
                end

                % do some number replacements, e.g. infinity values and NaN
                whereIsInf=cellfun(@(in) isequal(in,inf),tableData);
                tableData(whereIsInf)={'∞'};
                whereIsMinusInf=cellfun(@(in) isequal(in,-inf),tableData);
                tableData(whereIsMinusInf)={'-∞'};
                whereIsNaN=cellfun(@(in) isequal(in,NaN),tableData);
                tableData(whereIsNaN)={'NaN'};
                
                writecell(tableData,fullfile(path,file),"Sheet",num2str(channelNr),'WriteMode','overwritesheet','Range','A1');

            end

            %% freeze panels
            
            % Freeze four rows and one column
            % Define the full file path to your Excel file
            
            % Create an Excel COM server instance
            excelApp = actxserver('Excel.Application');
            %excelApp.Visible = true;  % Set to true so you can see what's happening
            
            % Open the workbook
            workbook = excelApp.Workbooks.Open(fullfile(path,file));
            
            % Get the collection of sheets
            sheets = workbook.Sheets;
            numSheets = sheets.Count;
            
            % Loop through each sheet
            for idx = 1:numSheets
                sheet = sheets.Item(idx);
                
                % Skip the sheet named 'General'
                if strcmp(sheet.Name, 'General')
                    continue;
                end
                
                % Activate the sheet
                sheet.Activate;
                
                % To freeze the first 4 rows and the first column,
                % select cell B5 (since freeze panes locks rows above and columns to the left)
                sheet.Range('B5').Select;
                
                % Freeze the panes by setting FreezePanes to true on the active window
                excelApp.ActiveWindow.FreezePanes = true;
            end

            % Activate the "General" sheet so it's the active sheet when opening the file
            generalSheet = sheets.Item('General');
            generalSheet.Activate;
            
            % Save the changes if needed
            workbook.Save;
            
            % Optionally, close the workbook and quit Excel
            workbook.Close;
            excelApp.Quit;
            delete(excelApp);

            uialert(app.UIFigure,'Save successfull','Success','Icon','success');

            if app.openfileaftersavingCheckBox.Value==1
                winopen(fullfile(path,file));
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportfromfilesMenu = uimenu(app.FileMenu);
            app.ImportfromfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @importDataFromFile, true);
            app.ImportfromfilesMenu.Text = 'Import from files';
            app.ExporttowavMenu = uimenu(app.FileMenu);
            app.ExporttowavMenu.Text = 'Export to wav';
            app.SaveSettingstoFileMenu = uimenu(app.FileMenu);
            app.SaveSettingstoFileMenu.Text = 'Save Settings to File';
            app.LoadSettingsfromFileMenu = uimenu(app.FileMenu);
            app.LoadSettingsfromFileMenu.Text = 'Load Settings from File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TopPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.TopPanel);
            app.OpenShiftToolButton = uibutton(app.GridLayout3, 'push');
            app.OpenShiftToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpenShiftToolButtonPushed, true);
            app.OpenShiftToolButton.Text = 'Open Shift Tool';
            app.Button2 = uibutton(app.GridLayout3, 'push');
            app.Button2.Text = 'Button2';
            app.CalculateParametersButton = uibutton(app.GridLayout3, 'push');
            app.CalculateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateParametersButtonPushed, true);
            app.CalculateParametersButton.Text = 'Calculate Parameters';
            app.SaveResultsButton = uibutton(app.GridLayout3, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.PlaySoundButton = uibutton(app.GridLayout3, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Tooltip = {'Play channels as audio'; 'The channels'' MEAN is subtracted'};
            app.PlaySoundButton.Text = 'Play Sound';
            app.weightchannelsevenlyCheckBox = uicheckbox(app.GridLayout3);
            app.weightchannelsevenlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @weightchannelsevenlyCheckBoxValueChanged, true);
            app.weightchannelsevenlyCheckBox.Text = {'weight'; 'channels'; 'evenly'};
            app.weightchannelsevenlyCheckBox.Value = true;
            app.StopSoundButton = uibutton(app.GridLayout3, 'push');
            app.StopSoundButton.ButtonPushedFcn = createCallbackFcn(app, @StopSoundButtonPushed, true);
            app.StopSoundButton.Text = 'Stop Sound';
            app.SoundVolumeSlider = uislider(app.GridLayout3);
            app.SoundVolumeSlider.Limits = [0 1];
            app.SoundVolumeSlider.MajorTicks = [0 0.5 1];
            app.SoundVolumeSlider.Tooltip = {'Sound Volume'; 'No effect while playing'};
            app.SoundVolumeSlider.Value = 0.5;
            app.Button7 = uibutton(app.GridLayout3, 'push');
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.GridLayout3, 'push');
            app.Button8.Text = 'Button8';
            app.openfileaftersavingCheckBox = uicheckbox(app.GridLayout3);
            app.openfileaftersavingCheckBox.Text = 'open file after saving';
            app.openfileaftersavingCheckBox.Value = true;
            app.BottomPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.BottomPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.GridLayout4 = uigridlayout(app.GraphTab);
            app.InteractionModeButtonGroup = uibuttongroup(app.GridLayout4);
            app.InteractionModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InteractionModeButtonGroupSelectionChanged, true);
            app.InteractionModeButtonGroup.Title = 'Interaction Mode';
            app.normalButton = uiradiobutton(app.InteractionModeButtonGroup);
            app.normalButton.Text = 'normal';
            app.normalButton.Value = true;
            app.MarkAreaButton = uiradiobutton(app.InteractionModeButtonGroup);
            app.MarkAreaButton.Text = 'Mark Area';
            app.MoveGraphButton = uiradiobutton(app.InteractionModeButtonGroup);
            app.MoveGraphButton.Text = 'Move Graph';
            app.Button13 = uibutton(app.GridLayout4, 'push');
            app.Button13.Text = 'Button13';
            app.UndoallshiftsButton = uibutton(app.GridLayout4, 'push');
            app.UndoallshiftsButton.ButtonPushedFcn = createCallbackFcn(app, @UndoallshiftsButtonPushed, true);
            app.UndoallshiftsButton.Text = 'Undo all shifts';
            app.plottingPanelContainer = uipanel(app.GridLayout4);
            app.plottingPanel = uipanel(app.plottingPanelContainer);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout5 = uigridlayout(app.ResultsTab);
            app.parameterResultsUITable = uitable(app.GridLayout5);
            app.parameterResultsUITable.ColumnName = {'Channel name'; 'Parameter'; 'Source'; 'Median'; 'Mean'; 'STD'; 'Min'; 'Max'};
            app.parameterResultsUITable.RowName = {};
            app.parameterResultsUITable.ColumnSortable = [true true true true true true true true];
            app.parameterResultsUITable.CellSelectionCallback = createCallbackFcn(app, @parameterResultsUITableCellSelection, true);
            app.parameterResultsUITable.Multiselect = 'off';
            app.availableChannelsTable = uitable(app.GridLayout2);
            app.availableChannelsTable.ColumnName = {'Channel name'; 'source'};
            app.availableChannelsTable.RowName = {};
            app.availableChannelsTable.ColumnSortable = [false false];
            app.availableChannelsTable.SelectionType = 'row';
            app.availableChannelsTable.ColumnEditable = [false true];
            app.availableChannelsTable.CellEditCallback = createCallbackFcn(app, @adjustDataSourceOnTableEdit, true);
            app.availableChannelsTable.SelectionChangedFcn = createCallbackFcn(app, @changeDisplayedChannels, true);
            app.SelectallButton = uibutton(app.GridLayout2, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.GridLayout2, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.DeleteselectedsignalsButton = uibutton(app.GridLayout2, 'push');
            app.DeleteselectedsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedsignalsButtonPushed, true);
            app.DeleteselectedsignalsButton.Text = 'Delete selected signals';
            app.showedgraphswithoutscrollingDropDownLabel = uilabel(app.GridLayout2);
            app.showedgraphswithoutscrollingDropDownLabel.Text = 'showed graphs without scrolling';
            app.showedgraphswithoutscrollingDropDown = uidropdown(app.GridLayout2);
            app.showedgraphswithoutscrollingDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.showedgraphswithoutscrollingDropDown.ValueChangedFcn = createCallbackFcn(app, @changeNumberOfGraphsWithoutScrolling, true);
            app.showedgraphswithoutscrollingDropDown.Value = '4';
            app.SelectedAreaLabel = uilabel(app.GridLayout2);
            app.SelectedAreaLabel.Text = 'Selected Area:';
            app.SelectionstartLabel = uilabel(app.GridLayout2);
            app.SelectionstartLabel.Text = 'Selection start:';
            app.SelectedAreaStartEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SelectedAreaStartEditField.ValueDisplayFormat = '%11.6g s';
            app.SelectedAreaStartEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedAreaStartEditFieldValueChanged, true);
            app.SelectedAreaStartEditField.Editable = 'off';
            app.SelectedAreaStartEditField.Value = -Inf;
            app.SelectionendLabel = uilabel(app.GridLayout2);
            app.SelectionendLabel.Text = 'Selection end:';
            app.SelectedAreaEndEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SelectedAreaEndEditField.ValueDisplayFormat = '%11.6g s';
            app.SelectedAreaEndEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedAreaEndEditFieldValueChanged, true);
            app.SelectedAreaEndEditField.Editable = 'off';
            app.SelectedAreaEndEditField.Value = Inf;
            app.DurationEditFieldLabel = uilabel(app.GridLayout2);
            app.DurationEditFieldLabel.Text = 'Duration:';
            app.SelectedAreaDurationEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SelectedAreaDurationEditField.Limits = [0 Inf];
            app.SelectedAreaDurationEditField.ValueDisplayFormat = '%11.6g s';
            app.SelectedAreaDurationEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedAreaDurationEditFieldValueChanged, true);
            app.SelectedAreaDurationEditField.Editable = 'off';
            app.HidecyclesButton = uibutton(app.GridLayout2, 'state');
            app.HidecyclesButton.ValueChangedFcn = createCallbackFcn(app, @HidecyclesButtonValueChanged, true);
            app.HidecyclesButton.Text = 'Hide cycles';
            app.ParameterResultsTableContextMenu = uicontextmenu(app.UIFigure);
            app.CopyleftclickedCellMenu = uimenu(app.ParameterResultsTableContextMenu);
            app.CopyleftclickedCellMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyleftclickedCellMenuSelected, true);
            app.CopyleftclickedCellMenu.Accelerator = 'C';
            app.CopyleftclickedCellMenu.Text = 'Copy left-clicked Cell';
            % Assign app.ParameterResultsTableContextMenu
            app.parameterResultsUITable.ContextMenu = app.ParameterResultsTableContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSAreplacement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end