classdef Vocaliscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileimportappendexportMenu      matlab.ui.container.Menu
        ImportappendchanneldatafromfilesMenu  matlab.ui.container.Menu
        ExportchanneldatatowavandmatMenu  matlab.ui.container.Menu
        allavailablechannelsMenu        matlab.ui.container.Menu
        to1multichannelMenu             matlab.ui.container.Menu
        tomultiplesinglechannelMenu     matlab.ui.container.Menu
        selectedchannelsonlyMenu        matlab.ui.container.Menu
        to1multichannelMenu_2           matlab.ui.container.Menu
        tomultiplesinglechannelMenu_2   matlab.ui.container.Menu
        SavechannelnamessourcetypesandselectedareatoFileMenu  matlab.ui.container.Menu
        LoadchannelnamessourcetypesandselectedareafromFileMenu  matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        IncreasepressMenu               matlab.ui.container.Menu
        DecreasepressMenu               matlab.ui.container.Menu
        SelectionevaluationsMenu        matlab.ui.container.Menu
        ShowSelectionEvaluationsMenu    matlab.ui.container.Menu
        HideSelectionEvaluationsMenu    matlab.ui.container.Menu
        ShowMeanMenu                    matlab.ui.container.Menu
        ShowMedianMenu                  matlab.ui.container.Menu
        ShowMinMenu                     matlab.ui.container.Menu
        ShowMaxMenu                     matlab.ui.container.Menu
        ShowRangeMenu                   matlab.ui.container.Menu
        ShowStandardDeviationMenu       matlab.ui.container.Menu
        ShowRootMeanSquareMenu          matlab.ui.container.Menu
        ExtrasMenu                      matlab.ui.container.Menu
        PianoMenu                       matlab.ui.container.Menu
        LagEstimatorofselectionMenu     matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        savechanneldatatoxlsxCheckBox   matlab.ui.control.CheckBox
        DeletenonselectedsignalsButton  matlab.ui.control.Button
        savewavandchannelnamestypesCheckBox  matlab.ui.control.CheckBox
        DeleteselectedsignalsButton     matlab.ui.control.Button
        SelectedAreaDurationEditField   matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        SelectedAreaEndEditField        matlab.ui.control.NumericEditField
        SelectionendLabel               matlab.ui.control.Label
        SelectedAreaStartEditField      matlab.ui.control.NumericEditField
        SelectionstartLabel             matlab.ui.control.Label
        SelectedAreaLabel               matlab.ui.control.Label
        showngraphswithoutscrollingDropDown  matlab.ui.control.DropDown
        showngraphswithoutscrollingDropDownLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GraphTab                        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        plottingPanelContainer          matlab.ui.container.Panel
        plottingPanel                   matlab.ui.container.Panel
        UndoallshiftsButton             matlab.ui.control.Button
        InteractionModeButtonGroup      matlab.ui.container.ButtonGroup
        MoveGraphButton                 matlab.ui.control.RadioButton
        MarkAreaButton                  matlab.ui.control.RadioButton
        normalButton                    matlab.ui.control.RadioButton
        ResultsTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        SourcetypeCheckBox              matlab.ui.control.CheckBox
        ParameternameCheckBox           matlab.ui.control.CheckBox
        ChannelnameCheckBox             matlab.ui.control.CheckBox
        SearchinthesecolumnsLabel       matlab.ui.control.Label
        CasesensitiveCheckBox           matlab.ui.control.CheckBox
        FilterResultsTableEditField     matlab.ui.control.EditField
        parameterResultsUITable         matlab.ui.control.Table
        ClQareasarehiddenButton         matlab.ui.control.StateButton
        HideOQareasButton               matlab.ui.control.StateButton
        HidecyclesbordersButton         matlab.ui.control.StateButton
        InvertselectionButton           matlab.ui.control.Button
        DeselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        availableChannelsTable          matlab.ui.control.Table
        SoundVolumeSlider               matlab.ui.control.Slider
        StopSoundButton                 matlab.ui.control.Button
        weightchannelsevenlyCheckBox    matlab.ui.control.CheckBox
        PlaySoundButton                 matlab.ui.control.Button
        openfileaftersavingCheckBox     matlab.ui.control.CheckBox
        SaveResultsButton               matlab.ui.control.Button
        CalculateParametersButton       matlab.ui.control.Button
        uOupenShiftToolButton           matlab.ui.control.Button
        ParameterResultsTableContextMenu  matlab.ui.container.ContextMenu
        CopyleftclickedCellMenu         matlab.ui.container.Menu
        RowWindowdataMenu               matlab.ui.container.Menu
        copyMenu                        matlab.ui.container.Menu
        plotMenu                        matlab.ui.container.Menu
        copycompleterowMenu             matlab.ui.container.Menu
        withoutcolumnheaderMenu         matlab.ui.container.Menu
        withcolumnheaderMenu            matlab.ui.container.Menu
        channelTableContextMenu         matlab.ui.container.ContextMenu
        SelectallMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        tlTime; % Tiled Layout for time plots
        channelYValues = {}; % "y-values" of each channel
        channelXValues = {};
        channelYValuesHistory = {}; % versions of each channel and the original
        channelXValuesHistory = {}; % time data of versions of each channel and the original
        channelNames = {};
        channelSampleRates = [];
        channelSource = {}; % Description
        channelSourcePossibilities =      {'Audio','Flow','Pressure','GAW','EGG','Larynx Height','Accelerometer','Raw'}; % Description
        channelSourcePossibilitiesColor = [  255     0.0      0.0     245   128       128            220          0.0;...
                                             225     130      130     130   0.0       0.0            190          0.0;...
                                              25     200      200      48   0.0       0.0            255          0.0]'/255;
        channelAxes = gobjects(0); % Description
        xlimListener = []; % Description
        DraggingData = struct('Dragging', false, 'StartX', [], 'EndX', [],...
                'CurrentRectangles', [], 'PreviousRectangles', []);
        timePlotHandles = gobjects(0); % Description
        selectionEvaluationTexts = gobjects(0);
        channelLag = []; % Description
        executeXLimListener=1; % Description
        audioPlayerObj % Description
        audioPlayerData % Description
        calculationSettings = [];% Description
        calculationCalledAsPreview = [];
        calculationResults = [];
        cycleBorderHandles=gobjects(0);
        keepVocaliscopeSettingsOverSession = 1; % Description
        xlimBeforeDeletion =[]; % Description
        parameterResultsUITableLastValue = ''; % Description
        cycleOQHandle = gobjects(0); % marking of the OQ areas
        cycleClQHandle = gobjects(0); % marking of the ClQ areas
        currentResultsTableChannelSources % Description
        refreshInteractionComingFromZoomTool=0; % Description
        titleTimer; % Description
        playbackPositionXLines % Description
    end
    
    methods (Access = private)
        
        function [data,meta] = importPhotronWVD(~,path,numberOutputStyle)

            if nargin>1 && ~isempty(numberOutputStyle) && (isstring(numberOutputStyle)|| ischar(numberOutputStyle)) && strcmpi(numberOutputStyle,'native')
                native=1;
            else
                native=0;
            end

            id=fopen(path,'r');

            if native==0
                meta.headerSize=fread(id,1,"long=>double");
                meta.WVDversion=fread(id,1,"long=>double");

                meta.vendorName=fread(id,[1 64],"char*1=>char");
                meta.vendorName=strrep(meta.vendorName,char(0),'');

                meta.deviceName=fread(id,[1 64],"char*1=>char");
                meta.deviceName=strrep(meta.deviceName,char(0),'');

                meta.deviceID=fread(id,1,"long=>double");
                meta.sampleRate=fread(id,1,"float=>double");
                meta.numberOfData=fread(id,1,"ulong=>double");
                meta.numberOfChannels=fread(id,1,"ulong=>double");

                meta.channelNumber=nan(1,meta.numberOfChannels);
                meta.channelName=cell(1,meta.numberOfChannels);
                for nr=1:meta.numberOfChannels
                    meta.channelNumber(nr)=fread(id,1,"ulong=>double");
                end

                for nr=1:meta.numberOfChannels
                    meta.channelName{nr}=fread(id,[1 64],"char*1=>char");
                end
                meta.channelName=strtrim(meta.channelName);

                meta.startFrame=fread(id,1,"long=>double");

                data=fread(id,[meta.numberOfChannels meta.numberOfData],"float=>double");


            else
                meta.headerSize=fread(id,1,"long=>long");
                meta.WVDversion=fread(id,1,"long=>long");

                meta.vendorName=fread(id,[1 64],"char*1=>char*1");

                meta.deviceName=fread(id,[1 64],"char*1=>char*1");

                meta.deviceID=fread(id,1,"long=>long");
                meta.sampleRate=fread(id,1,"float=>float");
                meta.numberOfData=fread(id,1,"ulong=>ulong");
                meta.numberOfChannels=fread(id,1,"ulong=>ulong");

                meta.channelNumber=nan(1,meta.numberOfChannels);
                meta.channelName=cell(1,meta.numberOfChannels);
                for nr=1:meta.numberOfChannels
                    meta.channelNumber(nr)=fread(id,1,"ulong=>ulong");
                end

                for nr=1:meta.numberOfChannels
                    meta.channelName{nr}=fread(id,[1 64],"char*1=>char*1");
                end

                meta.startFrame=fread(id,1,"long=>long");

                data=fread(id,[meta.numberOfChannels meta.numberOfData],"float=>float");

            end

            fclose(id);

        end

        function [header,allData]=readPhotronGraphExportData(~,filepath)
            disp('reading photron csv header...')
        
            header=readcell(filepath,'Range','A1:K23');
            header(end+1,:)=header(end,:);
            header(end-1,:)={[]};
        
            header(cellfun(@(in) all(ismissing(in)),header))={[]};
        
            disp('reading photron csv measurement data...')
            allData=readmatrix(filepath,'Range','A24');
        end


        function rescaleTimePlotTilesVerticallyPanel(app,refreshTicks)
            numberOfMaxPlotsToView=str2double(app.showngraphswithoutscrollingDropDown.Value);

            % this can also be 0 when nothing is selected in the table
            numberOfSelectedChannels=numel(app.availableChannelsTable.Selection);
            bestMax=min(numberOfMaxPlotsToView,numberOfSelectedChannels);

            if bestMax>0
                if numberOfSelectedChannels<=numberOfMaxPlotsToView
                    correctionFactor=1.1;
                else
                    correctionFactor=1.05;
                end


                app.plottingPanel.OuterPosition(4)=app.plottingPanelContainer.InnerPosition(4)*numberOfSelectedChannels/bestMax*correctionFactor;
                if ~refreshTicks
                    return;
                end
                for dispChannelNr=1:numel(app.channelAxes)
                    
%                     if dispChannelNr~=numel(app.channelAxes) && mod(dispChannelNr,str2double(app.showngraphswithoutscrollingDropDown.Value))~=0
%                         xticklabels(app.channelAxes(dispChannelNr),{''});
%                     else
                        xticklabels(app.channelAxes(dispChannelNr),'auto');
                        
%                     end

                    if dispChannelNr==numel(app.channelAxes)
                        xlabel(app.channelAxes(dispChannelNr),'time (s)');
                    end
                    xticks(app.channelAxes(dispChannelNr),'auto');
                    %ylim(app.channelAxes(dispChannelNr),'padded');
                end
            else

            end

            
        end
        
        function refreshTimePlotData(app)
            %profile on;

             wb = uiprogressdlg(app.UIFigure,'Title','Plotting Data...',...
        'Indeterminate','on');

            if ~isempty(app.channelAxes)
                oldXLim=app.channelAxes(1).XLim;
            elseif ~isempty(app.xlimBeforeDeletion)
                oldXLim=app.xlimBeforeDeletion;
            else
                oldXLim='tight';
            end

            nChannels=size(app.availableChannelsTable.Data,1);

            app.executeXLimListener=0;

            delete(app.channelAxes);
            app.timePlotHandles=gobjects(1,nChannels);
            app.cycleBorderHandles=gobjects(1,nChannels);
            app.cycleOQHandle=gobjects(1,nChannels);
            app.cycleClQHandle=gobjects(1,nChannels);
            
            app.channelAxes=gobjects(1,nChannels);
            if isempty(app.availableChannelsTable.Selection)
                return;
            end

            app.rescaleTimePlotTilesVerticallyPanel(0);
            %drawnow;


            %app.tlTime=tiledlayout(app.plottingPanel,numel(app.availableChannelsTable.Selection),1,'TileSpacing','tight','Padding','tight','Visible','off');
            %app.tlTime=tiledlayout(app.plottingPanel,'vertical','TileSpacing','tight','Padding','tight','Visible','off');

            %channelNr=1;

            
            for channelNr=1:nChannels

                %% create plot line and set labels
                currAx=nexttile(app.tlTime);


                app.channelAxes(channelNr)=currAx;

                xlim(currAx,oldXLim);                

                currYData=app.channelYValues{channelNr};
                %currFs=app.channelSampleRates(channelNr);
                %currTData=(0:numel(currYData)-1)/currFs;

                currTData=app.channelXValues{channelNr};

                hold(currAx,'on'); % otherwise the xlims are set to tickalgigned

                currentLineColor=app.channelSourcePossibilitiesColor(ismember(app.channelSourcePossibilities,app.channelSource{channelNr}),:);
                %app.timePlotHandles(channelNr)=plot(currAx,currTData+app.channelLag(channelNr),currYData,'-','LineWidth',1,'PickableParts','none','Color',currentLineColor);
                app.timePlotHandles(channelNr)=plot(currAx,currTData,currYData,'-','LineWidth',1,'PickableParts','none','Color',currentLineColor);

                hold(currAx,'off');


                currAx.UserData.channelNr=channelNr;

%                 if channelNr~=app.availableChannelsTable.Selection(end) && mod(plotNr,str2double(app.showngraphswithoutscrollingDropDown.Value))~=0
%                     xticklabels(currAx,{''});
%                 end
                xticks(currAx,'auto');

                title(currAx,app.splitCharArray(app.channelNames{channelNr},110),'Interpreter','none','Interactions',editInteraction);
                xlabel(currAx,'time (s)');


                ylabel(currAx,app.channelSource{channelNr},'Interpreter','none');


                %ylim(currAx,'padded');


                %% create context menus for each axis

                app.addContextMenuToAxis(currAx,channelNr);


            end


            %% set basic layout of axes and labels
            % use a basic grid
            grid(app.channelAxes,"on");


            enableLegacyExplorationModes(app.UIFigure);
            % do not show the axtoolbars
            %arrayfun(@(in) axtoolbar(in,'Visible','off'),app.channelAxes);
            % arrayfun(@(in) axtoolbar(in,["zoomin" "zoomout"],'SelectionChangedFcn',@app.setToNormalModeWhenManualToolSelected),app.channelAxes);
            axtoolbar(app.tlTime,["zoomin" "zoomout"],'SelectionChangedFcn',@app.setToNormalModeWhenManualToolSelected);


            %arrayfun(@(in) set(zoom(in),'Motion','horizontal'),app.channelAxes);

            z=zoom(app.UIFigure);
            set(z,'Motion','horizontal');

            % using linkaxes updates only when stopping dragging
            %linkaxes(app.channelAxes,'x');
            %linkprop(app.channelAxes,'XTicks');


            % i want to have the full y scale visible also after zooming
            delete(app.xlimListener);
            app.xlimListener=listener(app.channelAxes, 'XLim', 'PostSet', @(~, evt) debounceMinimizeYLims(app,evt));


%             app.rescaleTimePlotTilesVerticallyPanel(0);
            %scroll(app.plottingPanelContainer,"top");

            app.refreshInteractionMode();


            %% add rectangles
            if ~isempty(app.DraggingData.StartX)
                rectangles = [];
            end

            if ~isempty(app.DraggingData.StartX)

                % when a selection is outside a newly loaded channel, we
                % need to delete the rectangles
                app.DraggingData.StartX=max(cellfun(@(in) in(1),app.channelXValues));
                app.DraggingData.EndX=min(cellfun(@(in) in(end),app.channelXValues));

                if app.DraggingData.StartX==app.DraggingData.EndX
                    app.DraggingData.StartX=[];
                    app.DraggingData.EndX=[];
                    delete(app.DraggingData.CurrentRectangles);
                    app.DraggingData.CurrentRectangles=[];
                else
                    for channelNr=1:numel(app.channelAxes)
                        % draw rectangle if data already existed


                        if isnumeric(oldXLim)
                            currExcerpt=app.channelXValues{channelNr}>=oldXLim(1) & app.channelXValues{channelNr}<=oldXLim(2);
                            %else % if it is tight
                            %  oldXLim=[-inf inf];
                        end

                        if any(currExcerpt)
                            yMin = min(app.channelYValues{channelNr}(currExcerpt));
                            yMax = max(app.channelYValues{channelNr}(currExcerpt));
                        else
                            yMin=min(app.channelYValues{channelNr});
                            yMax=max(app.channelYValues{channelNr});
                        end
                        padval=0.1*range(yMax-yMin);
                        yMin=yMin-padval;
                        yMax=yMax+padval;
    
                        %yMin=app.channelAxes(axisNr).YLim(1);
                        %yMax=app.channelAxes(axisNr).YLim(2);
                        rect = rectangle(app.channelAxes(channelNr), ...
                            'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                            'FaceColor', [0.8, 0.8, 1, 0.5], ...
                            'FaceAlpha',0.5,...
                            'EdgeColor', 'none', ...
                            'PickableParts', 'none');
                        rectangles = [rectangles rect];
    
                        %app.channelAxes(axisNr).YLim=[yMin yMax];


                    end

                end


            end

            % save rectangles after creation
            if ~isempty(app.DraggingData.StartX)
                arrayfun(@(in)uistack(in,"bottom"),rectangles);
                app.DraggingData.CurrentRectangles = rectangles;
            end

            app.refreshSelectionEvaluationText();


            %% set selection border edit values
            app.updateSelectionAreaEditFieldsValues()

            %% add cycle Borders if availble
            %channelNr=1;
            for channelNr=app.availableChannelsTable.Selection
                if ~isempty(app.calculationResults) && isfield(app.calculationResults,'channelResults') && numel(app.calculationResults.channelResults)>=channelNr
                    if ~isempty(app.calculationResults.channelResults(channelNr).cycleBorderVals)
                        if app.HidecyclesbordersButton.Value==1
                            vis='off';
                        else
                            vis='on';
                        end
                        app.cycleBorderHandles(channelNr)=app.addManyXlinesAsSingleObj(channelNr,app.calculationResults.channelResults(channelNr).cycleBorderVals,vis);
                        uistack(app.cycleBorderHandles(channelNr),'top');
                        uistack(app.timePlotHandles(channelNr),'top');
                    end
                end
                %channelNr=channelNr+1;
            end

            %% cycle OQ and ClQ areas if available

            for channelNr=app.availableChannelsTable.Selection
                if ~isempty(app.calculationResults) && isfield(app.calculationResults,'channelResults') && numel(app.calculationResults.channelResults)>=channelNr
                    if isfield(app.calculationResults.channelResults(channelNr),'allOQBorders') && ~isempty(app.calculationResults.channelResults(channelNr).allOQBorders)
                        %if ismember(channelNr,app.availableChannelsTable.Selection)
  
                            if app.HideOQareasButton.Value==1
                                oqvis='off';
                            else
                                oqvis='on';
                            end
                            if app.ClQareasarehiddenButton.Value==1
                                clqvis='off';
                            else
                                clqvis='on';
                            end
    
                            %channelNr=find(app.availableChannelsTable.Selection==channelNr,1);
                            app.cycleOQHandle(channelNr)=app.addManyPatchesAsSinglePatch(channelNr,app.calculationResults.channelResults(channelNr).allOQBorders,[0.2 0 0],[],oqvis);
                            app.cycleClQHandle(channelNr)=app.addManyPatchesAsSinglePatch(channelNr,app.calculationResults.channelResults(channelNr).allClQBorders,[0 0.2 0],[],clqvis);
    
                            uistack(app.cycleOQHandle(channelNr),'bottom');
                        %end


                    end
                end
            end


            %% load new data for audio player
            app.createAudioPlayerData();

            %% set old xlims and activate listener again
%             if ~isempty(oldXLim)
%                 %drawnow;
%                 %xlim(app.channelAxes,oldXLim);
%                 set(app.channelAxes,'XLim',oldXLim);
%             end

            %% make tiledlayout visible again
            
            set(app.tlTime,'Units','normalized','OuterPosition',[0 0 1 1]);
            %drawnow limitrate;% pause(0.1)

            app.tlTime.Visible='on';
            %drawnow limitrate;


            %drawnow;
            app.executeXLimListener=1;

            close(wb);


            drawnow;

            %profile viewer;
        end

        function refreshTimePlotDataVisibility(app)
            axesToShow=ismember(1:size(app.availableChannelsTable.Data,1),app.availableChannelsTable.Selection);

            set(app.tlTime,'Visible','off');
            drawnow;
            set(app.channelAxes(axesToShow),'Parent',app.tlTime);
            set(app.channelAxes(~axesToShow),'Parent',[]);
            drawnow;
            app.rescaleTimePlotTilesVerticallyPanel(0);

            app.createAudioPlayerData();

            drawnow;

            set(app.tlTime,'Visible','on');
        end
        
        function minimizeYLims(app,evt)
            %drawnow;

            if ~app.executeXLimListener
                return;
            end
            
               
            % with pause it seems to work, but seems not to be smooth
            %pause(0.01);


            if ishandle(evt) % if i give an axes as event
                newXLim=evt.XLim;
                originatingAxis=evt;
            else
                newXLim=evt.AffectedObject.XLim;
                originatingAxis=evt.AffectedObject;
            end

            % update only the axes of the calling xlim axis, since each
            % axis has its own listener and linked, each axis should be
            % called anyways
            % ok now the listener is only attached to the first axes, so we
            % do have less listener callbacks, but all axes are updated
%                 channelIdx=find(app.channelAxes==evt.AffectedObject,1);
% 
%                 currDataExcerpt=app.channelYValues{channelIdx}(app.channelXValues{channelIdx}>=newXLim(1)-app.channelLag(channelIdx) & app.channelXValues{channelIdx}<=newXLim(2)-app.channelLag(channelIdx));
%                 
%                 minVal=min(currDataExcerpt);
%                 maxVal=max(currDataExcerpt);
%                 ylim(evt.AffectedObject,[minVal maxVal]+0.1*[-1 1]*(maxVal-minVal));
%                 yticks(evt.AffectedObject,'auto');

            % listener is only attached to first axis, but since axes are
            % linked with linkaxes, it should execute every time when it is
            % zoomed

            for channelNr=1:size(app.availableChannelsTable.Data,1)

                selectionPart=app.channelXValues{channelNr}>=newXLim(1) & app.channelXValues{channelNr}<=newXLim(2);
                
                % no need to add/remove lag here since the XData cell was
                % updated while dragging
                %selectionPart=app.channelXValues{channelIdx}>=newXLim(1)-app.channelLag(channelIdx) & app.channelXValues{channelIdx}<=newXLim(2)-app.channelLag(channelIdx);


                currDataExcerpt=app.channelYValues{channelNr}(selectionPart);


                minVal=min(currDataExcerpt);
                maxVal=max(currDataExcerpt);
                newYLimits=[minVal maxVal]+0.1*[-1 1]*(maxVal-minVal);

                if isequal(originatingAxis,app.channelAxes(channelNr)) % only the axes in which is zoomed shows a problem
                    mytimer=timer("StartDelay",1,"ExecutionMode","singleShot","TimerFcn", {@app.tmpFcn, channelNr, newYLimits});
                    mytimer.start();
                end

                ylim(app.channelAxes(channelNr),newYLimits);

                
                yticks(app.channelAxes(channelNr),'auto');


                if numel(app.cycleBorderHandles)>= channelNr && app.isvalidhandle(app.cycleBorderHandles(channelNr))
                    app.cycleBorderHandles(channelNr).YData(1:3:end)=newYLimits(1);
                    app.cycleBorderHandles(channelNr).YData(2:3:end)=newYLimits(2);
                end

                if numel(app.cycleOQHandle)>= channelNr && app.isvalidhandle(app.cycleOQHandle(channelNr)) && ishandle(app.cycleOQHandle(channelNr))
                    app.cycleOQHandle(channelNr).Vertices([1:4:end 2:4:end],2)=newYLimits(1);
                    app.cycleOQHandle(channelNr).Vertices([3:4:end 4:4:end],2)=newYLimits(2);

                    app.cycleClQHandle(channelNr).Vertices([1:4:end 2:4:end],2)=newYLimits(1);
                    app.cycleClQHandle(channelNr).Vertices([3:4:end 4:4:end],2)=newYLimits(2);
                end

                if ~isempty(app.DraggingData.CurrentRectangles) && numel(app.DraggingData.CurrentRectangles)>= channelNr && isvalid(app.DraggingData.CurrentRectangles(channelNr))
                    app.DraggingData.CurrentRectangles(channelNr).Position([2 4])=[newYLimits(1) newYLimits(2)-newYLimits(1)];
                end
            end


            % manual linked x axes

            %ylim([app.channelAxes],'padded')
            %newXLim=evt.AffectedObject.XLim;

            app.executeXLimListener=0;
            xlim(app.channelAxes,newXLim);
            
 
%             for chNr=1:numel(app.channelAxes)
%                 if evt.AffectedObject~=app.channelAxes(chNr) && ~isequal(app.channelAxes(chNr).XLim,newXLim)
%                     xlim(app.channelAxes(chNr),newXLim);
%                 end
% 
%             end

            drawnow;
            app.executeXLimListener=1;
            
        end
        
        function setToDataMarkMode(app)
                app.executeXLimListener=0;
                set(app.timePlotHandles,'HitTest','off');
                

                % Disable default interactions and set up dragging mode
                for i = 1:numel(app.channelAxes)
                    app.channelAxes(i).Interactions = []; % Disable interactivity
                end

                % Set up callbacks for drag behavior
                app.UIFigure.WindowButtonDownFcn = @(src, ~) startDrag(app,src);
                app.UIFigure.WindowButtonMotionFcn = @(src,~) duringDrag(app,src);
                app.UIFigure.WindowButtonUpFcn = @(src,~) endDrag(app,src);

                % Add listeners for YLim changes
                for i = 1:numel(app.channelAxes)
                    if isfield(app.channelAxes(i).UserData,'Listener')
                        delete(app.channelAxes(i).UserData.Listener);
                    end
                end
                disp('Data mark mode enabled');


            % Dragging Callbacks
            function startDrag(app,src)
                if ~strcmp(src.SelectionType, 'normal'),return;end
                disp('Start data mark');


                ax = app.UIFigure.CurrentObject;
                if isempty(ax) || ~isa(ax, 'matlab.graphics.axis.Axes'), return; end

                if ~isempty(app.audioPlayerObj)
                    stop(app.audioPlayerObj);
                    delete(app.playbackPositionXLines);
                end
                delete(app.playbackPositionXLines);
                set(app.PlaySoundButton,'Text','Play Sound');
                delete(app.playbackPositionXLines);


                % Store drag state
                data = app.DraggingData;
                data.Dragging = true;
                data.StartX = ax.CurrentPoint(1, 1);
                data.PreviousRectangles = data.CurrentRectangles;
                app.DraggingData = data;

                % Create new rectangles
                rectangles = [];
                for axNr = 1:numel(app.channelAxes)
                    yMin = app.channelAxes(axNr).YLim(1);
                    yMax = app.channelAxes(axNr).YLim(2);
                    rect = rectangle(app.channelAxes(axNr), ...
                        'Position', [data.StartX, yMin, 0, yMax - yMin], ...
                        'FaceColor', [0.8, 0.8, 1, 0.5], ...
                        'FaceAlpha',0.5,...
                        'EdgeColor', 'none', ...
                        'PickableParts', 'none','HandleVisibility','off');
                    rectangles = [rectangles, rect];
                end

                arrayfun(@(in)uistack(in,"bottom"),rectangles);
                
                data.CurrentRectangles = rectangles;
                app.DraggingData = data;
            end

            function duringDrag(app,src)
                if ~strcmp(src.SelectionType, 'normal'),return;end
                data = app.DraggingData;
                if ~data.Dragging, return; end

                %disp('During data mark');
                ax = app.UIFigure.CurrentObject;
                if isempty(ax) || ~isa(ax, 'matlab.graphics.axis.Axes'), return; end

                currX = ax.CurrentPoint(1, 1);
                xPos = min(data.StartX, currX);
                width = abs(currX - data.StartX);
                app.DraggingData.EndX=currX;

                % Update rectangles
                for axNr = 1:numel(data.CurrentRectangles)
                    yMin = app.channelAxes(axNr).YLim(1);
                    yMax = app.channelAxes(axNr).YLim(2);
                    data.CurrentRectangles(axNr).Position = [xPos, yMin, width, yMax - yMin];

                    % also refresh calucations in the selected area
                    app.refreshSelectionEvaluationText(axNr,[xPos xPos+width]);
                end


                app.updateSelectionAreaEditFieldsValues();

                drawnow limitrate;
            end

            function endDrag(app,src)
                if ~strcmp(src.SelectionType, 'normal')
                    return;
                end
                data = app.DraggingData;

                ax=app.UIFigure.CurrentObject;
                if isempty(ax) || ~isa(ax, 'matlab.graphics.axis.Axes')
                    return
                end

                maxMinX=max(cellfun(@(in) in(1),app.channelXValues));
                minMaxX=min(cellfun(@(in) in(end),app.channelXValues));

                if data.Dragging && (isempty(data.CurrentRectangles) || data.CurrentRectangles(1).Position(3)==0)
                    data.Dragging = false;
                    delete(app.DraggingData.PreviousRectangles);
                    delete(app.DraggingData.CurrentRectangles);
                    app.DraggingData.PreviousRectangles=[];
                    app.DraggingData.CurrentRectangles=[];
                    p=ax.CurrentPoint(1,1);
                    app.DraggingData.StartX=[];
                    app.DraggingData.EndX=[];
                    app.createAudioPlayerData();

                    app.DraggingData.Dragging=false;

                    app.updateSelectionAreaEditFieldsValues();
                    app.refreshSelectionEvaluationText();
                    return;
                end

                if data.Dragging
                    disp('End data mark');
                    data.Dragging = false;


                    data.StartX=min(app.DraggingData.StartX,app.DraggingData.EndX);
                    data.EndX=max(app.DraggingData.StartX,app.DraggingData.EndX);


                    if sum(data.CurrentRectangles(1).Position([1 3]))<maxMinX || data.CurrentRectangles(1).Position(1)>minMaxX
                        % when the marked region is completly below the
                        % highest minimum or above the smallest maximum
                        % then do not paint
                        delete(data.CurrentRectangles);
                        data.currentRectangles=[];
                        data.StartX=[];
                        data.EndX=[];
                    else
                        if maxMinX>data.CurrentRectangles(1).Position(1)
                            oldEnd=sum(data.CurrentRectangles(1).Position([1 3]));
                            for rectNr=1:numel(data.CurrentRectangles)
                                data.CurrentRectangles(rectNr).Position(1)=maxMinX;
                                data.CurrentRectangles(rectNr).Position(3)=oldEnd-maxMinX;
                            end
                            data.StartX=maxMinX;
    
                        end
    
                        if minMaxX<sum(data.CurrentRectangles(1).Position([1 3]))
                            for rectNr=1:numel(data.CurrentRectangles)
                                data.CurrentRectangles(rectNr).Position(3)=minMaxX-data.CurrentRectangles(rectNr).Position(1);
                            end
                            data.EndX=minMaxX;
    
                        end
                    end


                    % Delete previous rectangles
                    if ~isempty(data.PreviousRectangles)
                        delete(data.PreviousRectangles);
                        data.PreviousRectangles = [];
                    end


                    app.DraggingData = data;

                    app.createAudioPlayerData();


                    % set selection edit field values
                    app.updateSelectionAreaEditFieldsValues();

                    drawnow;


                end

                app.refreshSelectionEvaluationText();
            end


        end


        function refreshInteractionMode(app)
            if isempty(app.channelAxes)
                return;
            end

            % for channelNr=1:numel(app.channelAxes)
            %     if ~isempty(app.channelAxes(channelNr).Parent) && ~app.refreshInteractionComingFromZoomTool
            %         zoom(app.channelAxes(channelNr),'off');
            %     end
            % end

            
            if ~app.refreshInteractionComingFromZoomTool
                zoom(app.UIFigure,'off');
            end
            

            app.refreshInteractionComingFromZoomTool=0;

            %drawnow;
            
            
            if contains(app.InteractionModeButtonGroup.SelectedObject.Text,'normal','IgnoreCase',true)
                % normal mode
                %ylim(app.channelAxes,'auto');
                app.setToNormalInteractionMode();
            elseif contains(app.InteractionModeButtonGroup.SelectedObject.Text,'mark','IgnoreCase',true)
                % selection mode
                %ylim(app.channelAxes,'auto');
                app.setToDataMarkMode();
            else
                % move graph mode
                app.setToDataDragMode();
            end
            %pause(0.1);
            %drawnow;
            
        end

        function setToDataDragMode(app)
            set(app.timePlotHandles,'HitTest','off');
            app.executeXLimListener=0;
            if isempty(app.availableChannelsTable.Data) || isempty(app.availableChannelsTable.Selection), return,end
            xlim(app.channelAxes, 'manual');
            ylim(app.channelAxes,'manual');

            disp('Data shift mode enabled');
            
            
            % Initialize state variables
            appState.StartPoint = [];
            appState.CurrentGraph = []; % Index of the currently active graph
            
            % Set callbacks for mouse actions
            app.UIFigure.WindowButtonDownFcn = @(~, ~) onMouseDown(app);
            app.UIFigure.WindowButtonMotionFcn = @(~, ~) onMouseDrag(app);
            app.UIFigure.WindowButtonUpFcn = @(~, ~) onMouseUp(app);
            
            % Nested function: onMouseDown
                function onMouseDown(app)
                    clickedObject = app.UIFigure.CurrentObject;
                    if isa(clickedObject, 'matlab.graphics.axis.Axes')
                        % Find the corresponding graph index
                        axesHandles = app.channelAxes;
                        appState.CurrentGraph = find(axesHandles == clickedObject); % Direct indexing for arrays
                        appState.StartPoint = clickedObject.CurrentPoint;
                    else
                        appState.CurrentGraph = [];
                        appState.StartPoint = [];
                    end
                end

    % Nested function: onMouseDrag
            function onMouseDrag(app)
                if ~isempty(appState.StartPoint) && ~isempty(appState.CurrentGraph)
                    % Access the current graph's data
                    idx = appState.CurrentGraph;
                    axesHandles = app.channelAxes;
                    plotHandles = app.timePlotHandles;
                    xData = app.channelXValues;
                    %cumulativeShifts = app.channelLag(app.availableChannelsTable.Selection);
                    cumulativeShifts = app.channelLag;

                    % Calculate the shift
                    currentPoint = axesHandles(idx).CurrentPoint; % Use array indexing
                    shiftAmount = currentPoint(1, 1) - appState.StartPoint(1, 1);

                    % Update xData and cumulative shift
                    newXData = xData{idx} + shiftAmount; % Keep xData access as a cell array
                    xData{idx} = newXData; % Save updated xData
                    cumulativeShifts(idx) = cumulativeShifts(idx) + shiftAmount;

                    % Update the plot
                    plotHandles(idx).XData = newXData; % Use array indexing for plot handles

                    if numel(app.cycleBorderHandles)>=idx && app.isvalidhandle(app.cycleBorderHandles(idx))
                        app.cycleBorderHandles(idx).XData([1:3:end 2:3:end])=app.cycleBorderHandles(idx).XData([1:3:end 2:3:end])+shiftAmount;
                    end

                    if numel(app.cycleOQHandle)>=idx && app.isvalidhandle(app.cycleOQHandle(idx))
                        app.cycleOQHandle(idx).Vertices(:,1)=app.cycleOQHandle(idx).Vertices(:,1)+shiftAmount;
                        app.cycleClQHandle(idx).Vertices(:,1)=app.cycleClQHandle(idx).Vertices(:,1)+shiftAmount;
                    end


                    % Update the axis title with the total shift
                    %title(axesHandles(idx), sprintf('X Shift: %+.2f', cumulativeShifts(idx)));

                    % Save updated data back to UserData
                    app.channelXValues = xData;
                   % app.channelLag(app.availableChannelsTable.Selection)= cumulativeShifts;

                    app.channelLag=cumulativeShifts;


                    % Update the start point for smooth dragging
                    appState.StartPoint = currentPoint;

                    app.refreshSelectionEvaluationText(idx);
                end
            end

            % Nested function: onMouseUp
            function onMouseUp(app)
                % Clear state variables
                appState.StartPoint = [];
                appState.CurrentGraph = [];

                app.updateSelectionAreaEditFieldsLimits();


            end
        end


        function lineObj=addManyXlinesAsSingleObj(app,channelNr,xvals,vis)
            ax=app.channelAxes(channelNr);
            xlims=xlim(ax);
            currydata=app.timePlotHandles(channelNr).YData(app.timePlotHandles(channelNr).XData>=xlims(1) & app.timePlotHandles(channelNr).XData<=xlims(2));
            ylims=[min(currydata) max(currydata)]+[-1 1]*0.1*range(currydata);
            %ylims=ylim(ax);
            plotxvals=repelem(xvals,3); xvals(3:3:end)=NaN;
            yvals=repmat([ylims NaN],[1 numel(xvals)]);

            lineObj=line(ax,plotxvals(:),yvals(:),'PickableParts','none','Color',[0.2 0 0],'LineStyle','-','Visible',vis);

            if isempty(lineObj)
                lineObj=gobjects(1);
            end
        end

        function setToNormalInteractionMode(app)
            set(app.timePlotHandles,'HitTest','on');
            app.executeXLimListener=1;
            for i=1:numel(app.channelAxes)
                app.channelAxes(i).ButtonDownFcn = [];
            end

            app.UIFigure.WindowButtonDownFcn = [];
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
            
            %set(app.channelAxes,'Interactions',[panInteraction("Dimensions",'x') zoomInteraction("Dimensions",'x') dataTipInteraction]);
            set(app.channelAxes,'Interactions',[panInteraction("Dimensions",'x') zoomInteraction("Dimensions",'x')]);

            disp('Normal mode enabled (zoom, pan)');
        end
        
        function setChannelShiftToZero(app,channelNumbers)
            for nr=channelNumbers
                app.channelXValues{nr}=(0:numel(app.channelYValues{nr})-1)/app.channelSampleRates(nr);
            end

            %nr=1;
            for nr=channelNumbers%app.availableChannelsTable.Selection
                xlim(app.channelAxes(nr),'manual');
                app.timePlotHandles(nr).XData=app.channelXValues{nr};
                if numel(app.cycleBorderHandles)>=nr && app.isvalidhandle(app.cycleBorderHandles(nr))
                    app.cycleBorderHandles(nr).XData([1:3:end 2:3:end])=app.cycleBorderHandles(nr).XData([1:3:end 2:3:end])-app.channelLag(nr);
                end
                
                %nr=nr+1;
            end

            app.channelLag(channelNumbers)=0;

            app.createAudioPlayerData();

            drawnow;

            
        end
        
        function createAudioPlayerData(app)

            if isempty(app.availableChannelsTable.Selection)
                return;
            end

            % if there is a marked area available
            if ~isempty(app.DraggingData) && ~isempty(app.DraggingData.StartX) && app.DraggingData.StartX~=app.DraggingData.EndX%(app.DraggingData.EndX-app.DraggingData.StartX)>0.1
                startTime = app.DraggingData.StartX;
                endTime = app.DraggingData.EndX;
            else
                % if nothing was marked yet, play the most common points in
                % time
                startTime=max(cellfun(@(in) in(1),app.channelXValues));
                endTime=min(cellfun(@(in) in(end),app.channelXValues));
            end

            % Determine the common sample rate (e.g., maximum of given sample rates)
            commonRate = max(app.channelSampleRates(app.availableChannelsTable.Selection));

            % Resample all traces to the common sample rate and extract the required segment
            
            resampledData = [];
            for channelNr = app.availableChannelsTable.Selection
                trace = app.channelYValues{channelNr};
                time=app.channelXValues{channelNr};

                trace=trace(time>=startTime & time<=endTime);

                originalRate = app.channelSampleRates(channelNr);

                % Calculate the resampling ratio
                [P, Q] = rat(commonRate / originalRate);

                % Resample the trace
                resampledTrace = resample(trace, P, Q);

                % we only play AC parst of the signals
                resampledTrace=resampledTrace-mean(resampledTrace);

                % we can weight the channels evenyl by normalizing each
                % channel by the number of channels selected
                if app.weightchannelsevenlyCheckBox.Value==1
                    resampledTrace=resampledTrace/max(abs(resampledTrace))/numel(app.availableChannelsTable.Selection);
                end

                % Adjust the length of the current trace to match resampledData, if necessary
                if isempty(resampledData)
                    resampledData = resampledTrace; % First trace defines the length
                else
                    if length(resampledTrace) < length(resampledData)
                        % Pad with zeros if shorter
                        resampledTrace = [resampledTrace; zeros(length(resampledData) - length(resampledTrace), 1)];
                    elseif length(resampledTrace) > length(resampledData)
                        % Truncate if longer
                        resampledTrace = resampledTrace(1:length(resampledData));
                    end
                    % Combine the traces
                    resampledData = resampledData + resampledTrace;
                end
            end

            % normalize to keep it to maxmimum abs amplitude of 1

            % Normalize the combined data to avoid clipping
            resampledData = resampledData / max(abs(resampledData));

            app.audioPlayerData=resampledData;


        end


        function uiMenuChangeColorCallback(app,src,channelNr)
            % Uncheck all menu items first
            for nr = 1:numel(src.Parent.Children)
                src.Parent.Children(nr).Checked = 'off';
            end

            
            % Check the selected menu item
            src.Checked = 'on';
            app.timePlotHandles(channelNr).Color=app.channelSourcePossibilitiesColor(ismember(app.channelSourcePossibilities,src.Label),:);
            ylabel(app.channelAxes(channelNr),src.Label);

            app.availableChannelsTable.Data{channelNr,2}=src.Label;
            app.channelSource{channelNr}=src.Label;

            
            % Display selected entry
            fprintf('Selected Source: %s\n', src.Label);
        end
        
        function updateSelectionAreaEditFieldsValues(app)
            
            if ~isempty(app.DraggingData.StartX)
                %if app.DraggingData.StartX ~= app.DraggingData.EndX
                    app.SelectedAreaStartEditField.Value=min([app.DraggingData.StartX,app.DraggingData.EndX]);
                    app.SelectedAreaEndEditField.Value=max([app.DraggingData.StartX,app.DraggingData.EndX]);
                    app.SelectedAreaDurationEditField.Value=abs(app.DraggingData.EndX-app.DraggingData.StartX); % also called during dragging, so there could be negative values
                else % during mark mode it was not dragged, but only clicked

                    % we could use this to limit the borders to the latest
                    % start end earliest data ending.
%                     app.SelectedAreaStartEditField.Value=max(cellfun(@(in) in(1),app.channelXValues));
%                     app.SelectedAreaEndEditField.Value=min(cellfun(@(in) in(end),app.channelXValues));


                    app.SelectedAreaStartEditField.Value=max(cellfun(@(in) in(1),app.channelXValues));
                    app.SelectedAreaEndEditField.Value=min(cellfun(@(in) in(end),app.channelXValues));
                    app.SelectedAreaDurationEditField.Value=app.SelectedAreaEndEditField.Value-app.SelectedAreaStartEditField.Value;

                %end
            end

            
        end
        
        function updateSelectionAreaEditFieldsLimits(app)

            if isempty(app.DraggingData.StartX) % e.g. when deleting all channels and setting those fields to inf, this function is called also
                return;
            end

            channelEndTimes=(cellfun(@numel,app.channelYValues)-1)./app.channelSampleRates+app.channelLag;

            if isinf(app.SelectedAreaStartEditField.Value)
                drawRects=0;
            else
                drawRects=1;
            end

            app.SelectedAreaEndEditField.Value=min([channelEndTimes,app.SelectedAreaEndEditField.Value]);
            app.SelectedAreaStartEditField.Value=max([app.channelLag,app.SelectedAreaStartEditField.Value]);

            app.SelectedAreaEndEditField.Value=max([app.channelLag,app.SelectedAreaEndEditField.Value]);
            app.SelectedAreaStartEditField.Value=min([channelEndTimes,app.SelectedAreaStartEditField.Value]);

            app.SelectedAreaDurationEditField.Value=app.SelectedAreaEndEditField.Value-app.SelectedAreaStartEditField.Value;

            % set rect dragging data appropriatly
            %if ~isempty(app.DraggingData.StartX)
                app.DraggingData.StartX=app.SelectedAreaStartEditField.Value;
                app.DraggingData.EndX=app.SelectedAreaEndEditField.Value;

                % also change displayed rectangles, if displayed
                if ~isempty(app.DraggingData.CurrentRectangles)
                    for rectNr=1:numel(app.DraggingData.CurrentRectangles)
                        app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[app.SelectedAreaStartEditField.Value,app.SelectedAreaDurationEditField.Value];
                    end
                elseif drawRects
                    rects=gobjects(0);
                    for rectNr=1:numel(app.channelAxes)
                        %ylimits=ylim(app.channelAxes(rectNr));
                        xlimits=xlim(app.channelAxes(rectNr));
                        excerpt=app.channelYValues{rectNr}(app.channelXValues{rectNr}>=xlimits(1) & app.channelXValues{rectNr}<=xlimits(2));
                        ylimits=[min(excerpt) max(excerpt)];
                        ylimits=ylimits+[-1 1]*0.1*range(ylimits);
                        yMin=ylimits(1);
                        yMax=ylimits(2);
                        rects(rectNr)=rectangle(app.channelAxes(rectNr), ...
                            'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                            'FaceColor', [0.8, 0.8, 1, 0.5], ...
                            'FaceAlpha',0.5,...
                            'EdgeColor', 'none', ...
                            'PickableParts', 'none');
                        app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[app.SelectedAreaStartEditField.Value,app.SelectedAreaDurationEditField.Value];
                    end
                    app.DraggingData.CurrentRectangles=rects;
                    arrayfun(@(in) uistack(in,'bottom'),rects);
                end

            %end

            app.refreshSelectionEvaluationText();
            
        end
        
        function uiMenuFilterDataCallback(app,channelNr)
            %channelNr=axisHandle.UserData.channelNr;
            yDataIn=app.channelYValues{channelNr};
            fsIn=round(app.channelSampleRates(channelNr));

            filterToolboxApp=interactiveFiltering();
            if strcmp(app.channelSource{channelNr},'GAW')
                filterToolboxApp.Preserve0valuesandclipbelowCheckBox.Value=1;
            end
            %filterToolboxApp.UIFigure.WindowStyle='modal';

            filterToolboxApp.UIFigure.Theme='light';
            filterToolboxApp.originalData=yDataIn;
            filterToolboxApp.originalFs=fsIn;
            filterToolboxApp.setFreqMaxValues();
            filterToolboxApp.refreshFilterPlot();
            filterToolboxApp.refreshDataPlot();
            
            waitfor(filterToolboxApp,'finalOutputData');
            if isvalid(filterToolboxApp) % if filter tools were closed by apply button

                outputData=filterToolboxApp.finalOutputData;
                app.channelYValues{channelNr}=outputData;
                app.timePlotHandles(channelNr).YData=outputData;
                app.createAudioPlayerData();

                app.addValueSetToValueHistory(outputData,app.timePlotHandles(channelNr).XData,channelNr)

                ylim(app.channelAxes(channelNr),[min(outputData) max(outputData)]+[-1 1]*0.1*range(outputData));

                
                delete(filterToolboxApp);


            else % if just the window was closed

                % when doing that, the settings are deleted if calculated
                % before
%                 app.calculationSettings=[];
%                 app.calculationCalledAsPreview=[];
                return;
            end
        end
        
        function performBasicChannelEditing(app,OriginatingChannelNr,oneSomeOrAllChannels,wholeOrMarked,actionToPerform)
            

            if strcmpi(oneSomeOrAllChannels,'displayed')
                channelsToEdit=app.availableChannelsTable.Selection;
            elseif strcmpi(oneSomeOrAllChannels,'all')
                channelsToEdit=1:size(app.availableChannelsTable.Data,1);
            else % 'this'
                channelsToEdit=OriginatingChannelNr;
            end

            if ~isempty(app.DraggingData.StartX)

                selectionStart=app.DraggingData.StartX;
                selectionEnd=app.DraggingData.EndX;
            else
                selectionStart=app.SelectedAreaStartEditField.Value;
                selectionEnd=app.SelectedAreaEndEditField.Value;
            end
            

            for channelNr=channelsToEdit
                currXData=app.channelXValues{channelNr};                
                currYData=app.channelYValues{channelNr};

                
                if ~strcmp(actionToPerform,'leadingZerosToLag')
                

                    if strcmpi(wholeOrMarked,'whole') % affect whole channel
                        destinationExcerpt=true(size(app.channelYValues{channelNr}));
                    else %% affect only selected region
                        destinationExcerpt=currXData>=selectionStart & currXData<=selectionEnd;
                    end
    
                    if contains(actionToPerform,'Selection')
                        sourceExcerpt=currXData>=selectionStart & currXData<=selectionEnd;
                    else
                        sourceExcerpt=true(size(app.channelYValues{channelNr}));
                    end
    
                    if strcmpi(wholeOrMarked,'whole') && contains(actionToPerform,'selectionDifferential')
                        sourceExcerpt=currXData>=selectionStart & currXData<=selectionEnd;
                        destinationExcerpt=currXData>=selectionStart;
                    end

                    if strcmpi(wholeOrMarked,'whole') && contains(actionToPerform,'deleteInverse')
                        sourceExcerpt=currXData<selectionStart | currXData>selectionEnd;
                        destinationExcerpt=sourceExcerpt;
                    end
    
                    currSourceYData=app.channelYValues{channelNr}(sourceExcerpt);

                end


                switch actionToPerform
                    case 'delete'
                        currYData(destinationExcerpt)=[];

                        if destinationExcerpt(1)==0 % of not the first sample is selected, delete values and concatenate
                            currXData=(0:numel(currYData)-1)/app.channelSampleRates(channelNr)+app.channelLag(channelNr);
                        else % if the first value is selected, we do not want to move the rest of the signal to make it start from channelLag, but keep the time values and set the lag appropriately
                            currXData(destinationExcerpt)=[];
                            app.channelLag(channelNr)=currXData(1);
                        end
                        if ~isempty(app.DraggingData.StartX)
                            app.updateSelectionAreaEditFieldsLimits();
                        end
                    case 'deleteInverse'
                        currYData(destinationExcerpt)=[];
                        currXData(destinationExcerpt)=[];
                        app.channelLag(channelNr)=currXData(1);
                        if ~isempty(app.DraggingData.StartX)
                            app.updateSelectionAreaEditFieldsLimits();
                        end

                    case 'linearRamp'
                        currYData(destinationExcerpt)=linspace(currSourceYData(1),currSourceYData(end),sum(destinationExcerpt));

                    %% changes in differential
                    case 'selectionDifferentialMinToZero'
                        %initVal=currYData(sourceExcerpt);
                        %initVal=initVal(1);

                        differential=diff(currYData(sourceExcerpt));
                        [value,minIdx]=min(differential);
                        %differential(minIdx)=0;

                        %currYData(destinationExcerpt)=cumsum([0; differential(:)]+initVal);
                        currYData(find(sourceExcerpt==1,1,"first")+minIdx:end)=currYData(find(sourceExcerpt==1,1,"first")+minIdx:end)-value;
                    case 'selectionDifferentialMaxToZero'
                        % initVal=currYData(sourceExcerpt);
                        % initVal=initVal(1);

                        differential=diff(currYData(sourceExcerpt));
                        [value,maxIdx]=max(differential);
                        % differential(maxIdx)=0;

                        % currYData(destinationExcerpt)=cumsum([initVal; differential(:)]);
                        currYData(find(sourceExcerpt==1,1,"first")+maxIdx:end)=currYData(find(sourceExcerpt==1,1,"first")+maxIdx:end)-value;
                    case 'selectionDifferentialAbsMaxToZero'
                        %initVal=currYData(sourceExcerpt);
                        %initVal=initVal(1);

                        differential=diff(currYData(sourceExcerpt));
                        [value,maxIdx]=max(abs(differential));
                        value=sign(differential(maxIdx))*value;

                        %differential(maxIdx)=0;

                        %currYData(destinationExcerpt)=cumsum([0; differential(:)]+initVal);

                        currYData(find(sourceExcerpt==1,1,"first")+maxIdx:end)=currYData(find(sourceExcerpt==1,1,"first")+maxIdx:end)-value;

                    %% set to value...
                    case 'setToZero' 
                        currYData(destinationExcerpt)=0;
                    case 'setToChannelMin'
                        currYData(destinationExcerpt)=min(currSourceYData);
                    case 'setToSelectionMin'
                        currYData(destinationExcerpt)=min(currSourceYData);
                    case 'setToChannelMax'
                        currYData(destinationExcerpt)=max(currSourceYData);
                    case 'setToSelectionMax'
                        currYData(destinationExcerpt)=max(currSourceYData);
                    case 'setToChannelMean' 
                        currYData(destinationExcerpt)=mean(currSourceYData);
                    case 'setToSelectionMean'
                        currYData(destinationExcerpt)=mean(currSourceYData);
                    case 'setToChannelMedian' 
                        currYData(destinationExcerpt)=median(currSourceYData);
                    case 'setToSelectionMedian'
                        currYData(destinationExcerpt)=median(currSourceYData);

                    %% add value...
                    case 'addChannelMin'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)+min(currYData(sourceExcerpt));
                    case 'addSelectionMin'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)+min(currYData(sourceExcerpt));
                    case 'addChannelMax'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)+max(currYData(sourceExcerpt));
                    case 'addSelectionMax'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)+max(currYData(sourceExcerpt));
                    case 'addChannelMean' 
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)+mean(currYData(sourceExcerpt));
                    case 'addSelectionMean'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)+mean(currYData(sourceExcerpt));
                    case 'addChannelMedian' 
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)+median(currYData(sourceExcerpt));
                    case 'addSelectionMedian'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)+median(currYData(sourceExcerpt));

                    %% subtract value...
                    case 'subtractChannelMin'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)-min(currYData(sourceExcerpt));
                    case 'subtractSelectionMin'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)-min(currYData(sourceExcerpt));
                    case 'subtractChannelMax'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)-max(currYData(sourceExcerpt));
                    case 'subtractSelectionMax'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)-max(currYData(sourceExcerpt));
                    case 'subtractChannelMean' 
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)-mean(currYData(sourceExcerpt));
                    case 'subtractSelectionMean'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)-mean(currYData(sourceExcerpt));
                    case 'subtractChannelMedian' 
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)-median(currYData(sourceExcerpt));
                    case 'subtractSelectionMedian'
                        currYData(destinationExcerpt)=currYData(destinationExcerpt)-median(currYData(sourceExcerpt));

                    %% convert leading zeros to lag
                    case 'leadingZerosToLag'
                        firstNonZero=find(currYData~=0,1,"first");
                        if firstNonZero==1 % abort then
                            continue;
                        end
                        app.channelLag(channelNr)=currXData(firstNonZero);
                        currYData(1:firstNonZero-1)=[];
                        currXData(1:firstNonZero-1)=[];

                        if ~isempty(app.DraggingData.StartX) && app.DraggingData.StartX<currXData(1)
                            newStartX=currXData(1);
                            newWidth=app.DraggingData.EndX-newStartX;

                            if newWidth>0
                                for rectNr=1:numel(app.DraggingData.CurrentRectangles)
                                    app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[newStartX newWidth];
                                end
                                app.DraggingData.StartX=newStartX;
                                app.DraggingData.EndX=newStartX+newWidth;
                            else
                                delete(app.DraggingData.CurrentRectangles);
                                app.DraggingData.CurrentRectangles=[];
                                app.DraggingData.StartX=[];
                                app.DraggingData.EndX=[];
                            end

                            app.updateSelectionAreaEditFieldsValues();

                        end
                        
                end


                app.channelYValues{channelNr}=currYData;
                app.channelXValues{channelNr}=currXData;

                app.addValueSetToValueHistory(currYData,currXData,channelNr);

                
                set(app.timePlotHandles(channelNr),'XData',currXData,'YData',currYData);

                currXLim=xlim(app.channelAxes(channelNr));
                displayMask=app.channelXValues{channelNr}>=currXLim(1) & app.channelXValues{channelNr}<=currXLim(2);
                newylims=[min(currYData(displayMask)) max(currYData(displayMask))]+[-1 1]*0.1*range(currYData(displayMask));
                ylim(app.channelAxes(channelNr),newylims);

                if ~isempty(app.DraggingData.CurrentRectangles) && isvalid(app.DraggingData.CurrentRectangles(channelNr))
                    app.DraggingData.CurrentRectangles(channelNr).Position([2 4])=[newylims(1) newylims(2)-newylims(1)];
                end


            end

            app.refreshInteractionMode();

            app.refreshSelectionEvaluationText();


            %ylim(app.channelAxes,'padded');
            %drawnow;
            %ylim(app.channelAxes,'manual');
        end
        
        function populateResultsTable(app)
            nWindows=app.calculationResults.numberOfWindows;
            nChannels=numel(app.calculationResults.channelResults);

            % organize by parameter type

            parameterNames=fieldnames(app.calculationResults.channelResults);

            parameterNames(contains(parameterNames,{'curr'},"IgnoreCase",true))=[];
            parameterNames(ismember(parameterNames,{'cycleBorderVals' 'cycleDurations' 'allOQBorders' 'allClQBorders'}))=[];


            app.parameterResultsUITable.Data=[];
            TableHeader={'Channel name','Parameter','Source','Median','Mean','STD','Min','Max'};

            windowHeaders=arrayfun(@(x) ['window ' num2str(x)], 1:nWindows, 'UniformOutput', false);
            TableHeader=[TableHeader windowHeaders {''}];
            app.parameterResultsUITable.ColumnName=TableHeader;
            app.parameterResultsUITable.ColumnWidth=[repelem({'fit'},numel(TableHeader)-1) {'1x'}];
            app.parameterResultsUITable.ColumnFormat=[repelem({''}, 3) repelem({'shortG'}, numel(TableHeader)-3)];

            channelShortNames=app.shortenNames(app.channelNames);

            tableData={};
            app.currentResultsTableChannelSources=[];
            for parameterNr=1:numel(parameterNames)
                windowValues={app.calculationResults.channelResults.(parameterNames{parameterNr})}';

                channelsInWhichTheParameterExists=~cellfun(@isempty,windowValues);

                % EGG the quotients are only calucated for EGG and GAW
                windowValues=windowValues(channelsInWhichTheParameterExists);
                
                medians=cellfun(@(in) median(in,"omitnan"),windowValues,'UniformOutput',false);
                means=cellfun(@(in) mean(in,'omitnan'),windowValues,'UniformOutput',false);
                stds=cellfun(@(in) std(in,'omitnan'),windowValues,'UniformOutput',false);
                mins=cellfun(@(in) min(in,[],'omitnan'),windowValues,'UniformOutput',false);
                maxs=cellfun(@(in) max(in,[],'omitnan'),windowValues,'UniformOutput',false);


                currnames=channelShortNames(channelsInWhichTheParameterExists);
                currparams=repelem(parameterNames(parameterNr),sum(channelsInWhichTheParameterExists),1);
                currsources=app.channelSource(channelsInWhichTheParameterExists)';

                currnames=currnames(:);
                currparams=currparams(:);
                currsources=currsources(:);

                tableData=[tableData;...
                   currnames currparams currsources medians means stds mins maxs num2cell(cell2mat(windowValues)) repelem({''},sum(channelsInWhichTheParameterExists),1)];

                app.currentResultsTableChannelSources=[app.currentResultsTableChannelSources; find(channelsInWhichTheParameterExists)];
            end

            removeStyle(app.parameterResultsUITable);

            % center text
            centerStyle=uistyle('HorizontalAlignment','center');
            addStyle(app.parameterResultsUITable,centerStyle,"column",2:3)


            [app.parameterResultsUITable.Data,idx]=sortrows(tableData,2,'ascend');
            app.currentResultsTableChannelSources=app.currentResultsTableChannelSources(idx);

            % save the full results table as USerData to the Filter Line to
            % be able to remove non fitting rows
            app.FilterResultsTableEditField.UserData.ResultsTable=app.parameterResultsUITable.Data;


            app.ResultsTab.Parent=app.TabGroup;
            
            app.parameterResultsUITable.Enable='on';
            app.SaveResultsButton.Enable='on';
            
        end

        function sampEn = calculateSampleEntropy(~,signal, templateLength, stdPercentage)
            % maybe we should use https://github.com/BYO-UPM/AVCA-ByO/blob/master/src/Complexity/CalculateRegularity.m

%             sampEn=physionet_sampen(signal,templateLength,stdPercentage);
%             sampEn=sampEn(end);
%             return;

            %try
                if isempty(signal) || stdPercentage==0
                    sampEn=NaN;
                else
                    sampEn=fastSampEn(signal,templateLength,stdPercentage);
                end

            % catch ME
            %     disp('error calculating sample entropy. Replacing with NaN')
            %     disp(ME.message);
            %     sampEn=NaN;
            % 
            % end
             
            return;
            
            r=stdPercentage*std(signal);
            N = length(signal);

            
            % Construct embedding matrix for m-length segments:
            X_m = zeros(N-templateLength+1, templateLength);
            for i = 1:(N-templateLength+1)
                X_m(i,:) = signal(i:i+templateLength-1);
            end
            
            % Compute the Chebyshev (maximum) norm between all template pairs:
            dists_m = pdist(X_m, @(xi,xj)  max(abs(xi - xj), [], 2));
            D_m = squareform(dists_m);
            
            % Count the number of matches (exclude the self-match on the diagonal)
            % Note that D_m is symmetric, so we count the entries below (or above) the diagonal.
            B = sum(sum(tril(D_m < r, -1)));
            
            % Now construct the embedding matrix for m+1-length segments:
            X_m1 = zeros(N-templateLength, templateLength+1);
            for i = 1:(N-templateLength)
                X_m1(i,:) = signal(i:i+templateLength);
            end
            
            % Compute distances for (m+1)-length segments
            dists_m1 = pdist(X_m1, @(xi,xj) max(abs(xi - xj), [], 2));
            D_m1 = squareform(dists_m1);
            
            A = sum(sum(tril(D_m1 < r, -1)));
            
            % Avoid division by zero. If no matches are found, return Inf.


            if B == 0 || A == 0
                sampEn = 0;
            else
                sampEn = -log(A / B);
            end
        end


        function result = splitCharArray(~,inputArray, maxLength)
            % Initialize output cell array
            result = {};
            while ~isempty(inputArray)
                % Check if remaining part is longer than maxLength
                if length(inputArray) > maxLength
                    % Find last space within maxLength to split neatly
                    splitPoint = find(inputArray(1:maxLength) == ' ', 1, 'last');
                    if isempty(splitPoint)
                        % No space found, split at maxLength
                        splitPoint = maxLength;
                    end
                else
                    % Use remaining length if shorter than maxLength
                    splitPoint = length(inputArray);
                end
                % Extract substring and add to result
                result{end+1} = inputArray(1:splitPoint);
                % Remove the processed part from inputArray
                inputArray = strtrim(inputArray(splitPoint+1:end));
            end
        end

        
        function uiMenuUndoDataChangeCallback(app,channelNr)


%            

            nonEmptyCells=~cellfun(@isempty,app.channelYValuesHistory(:,channelNr));

            if sum(nonEmptyCells)==1 % if there is still the original data or if only one filtering process was done
                return;
            else
                lastNonEmpty=find(nonEmptyCells,1,'last');

                % Y Values
                app.channelYValues(channelNr)=app.channelYValuesHistory(lastNonEmpty-1,channelNr);
                app.channelYValuesHistory{lastNonEmpty,channelNr}=[];

                % X Values
                app.channelXValues(channelNr)=app.channelXValuesHistory(lastNonEmpty-1,channelNr);
                app.channelXValuesHistory{lastNonEmpty,channelNr}=[];

                app.channelLag(channelNr)=app.channelXValues{channelNr}(1);

                % we also need to restore fs when undoing e.g. resampling
                app.channelSampleRates(channelNr)=round(1/min(diff(app.channelXValues{channelNr})));
            end

            app.timePlotHandles(channelNr).YData=app.channelYValues{channelNr};
            app.timePlotHandles(channelNr).XData=app.channelXValues{channelNr};
            app.createAudioPlayerData();

            try
            app.calculationResults.channelResults(channelNr).cycleBorderVals=NaN;
            delete(app.cycleBorderHandles(channelNr));
            end

            drawnow;

            %ylim(app.channelAxes(channelNr),'padded');
            %app.minimizeYLims(app.channelAxes(channelNr));

            currXLim=xlim(app.channelAxes(channelNr));
            displayMask=app.channelXValues{channelNr}>=currXLim(1) & app.channelXValues{channelNr}<=currXLim(2);
            newYLimits=[min(app.channelYValues{channelNr}(displayMask)) max(app.channelYValues{channelNr}(displayMask))]+[-1 1]*0.1*range(app.channelYValues{channelNr}(displayMask));
            ylim(app.channelAxes(channelNr),newYLimits);

            if ~isempty(app.DraggingData.CurrentRectangles) && isvalid(app.DraggingData.CurrentRectangles(channelNr))
                app.DraggingData.CurrentRectangles(channelNr).Position([2 4])=[newYLimits(1) newYLimits(2)-newYLimits(1)];
            end

            app.refreshInteractionMode();

            app.refreshSelectionEvaluationText(channelNr);
            
        end
        
        function [CPPlin,CPPparabolic,CPPexp]=calculateCPP(~,s,t,doPlot,windowType,lowerQuefrencyFitLimit,upperQuefrencyFitLimit,maxInsideFitRegion,fitTypesToUse,robustMethodsToUse)
            [CPPlin,CPPparabolic,CPPexp]=deal([]);
            
            if nargin<4 || isempty(doPlot)
                doPlot=0;
            end
            
            if nargin<5 || isempty(windowType)
                windowType='hann';
            end
        
            if nargin<6 || isempty(lowerQuefrencyFitLimit)
                lowerQuefrencyFitLimit=0.001;
            end
        
            if nargin<7 || isempty(upperQuefrencyFitLimit)
                upperQuefrencyFitLimit=inf;
            end
        
            if nargin<8 || isempty(maxInsideFitRegion)
                maxInsideFitRegion=1;
            end

            if nargin<9 || isempty(fitTypesToUse)
                fitTypes={'poly1'};
            elseif strcmpi(fitTypesToUse,'all')
                fitTypes={'poly1','poly2','exp1'};
            else
                fitTypes={fitTypesToUse};
            end

            if nargin<10 || isempty(robustMethodsToUse)
                methods={'bisquare'};
            elseif strcmpi(robustMethodsToUse,'all')
                methods={'off','LAR','bisquare'};
            else
                methods={robustMethodsToUse};
            end
        
            if isscalar(t) % if t was given as sampling frequency
                t=(0:numel(s)-1)/(1/t);
            end
        
            t=t(:); s=s(:);
        
            switch windowType
                case 'hann'
                    window=hann(numel(s));
                case 'hamming'
                    window=hamming(numel(s));
                otherwise % no windowing
                    window=ones(size(s));
            end
        
            s=s.*window;
            
            CPS=20*log10(abs(fft(20*log10(abs(fft(s))))));

            CPS(isinf(CPS))=0;

            if all(s==0)
                CPS(:)=0;
            end
            
            % the cepstrum is symmetric, we need only half of it
            t=t(1:ceil(end/2));
            CPS=CPS(1:ceil(end/2));
            %% fit of the quefrencies values
            
            
            for fitTypeNr=1:numel(fitTypes)

                %disp(['CPP: fitting method ' fitTypes{fitTypeNr}]);
            
                if doPlot
                    figure;
                    ax=gca;
                    plot(ax,t,CPS,'k','DisplayName','dB Cepstrum');
                end
                
                fitX=t(t>lowerQuefrencyFitLimit & t<upperQuefrencyFitLimit);


                CPP=nan(size(methods));
                gofCell=cell(size(methods));
                for methodNr=1:numel(methods)

                    try
                        %disp(['CPP: robust method ' methods{methodNr}]);

                        warning('off','curvefit:fit:iterationLimitReached');
                        [fitObj,gof]=fit(fitX,CPS((t>lowerQuefrencyFitLimit & t<upperQuefrencyFitLimit)),fitTypes{fitTypeNr},'Robust',methods{methodNr});
                        warning('on','curvefit:fit:iterationLimitReached');

                        fitY=fitObj(fitX);
            
                        if doPlot
                            hold on;
                            plot(ax,fitX,fitY,'DisplayName',sprintf('robust method %s, R^2: %.3f',methods{methodNr},gof.rsquare));
                        end
                        % get max
                        if maxInsideFitRegion
                            [maxVal,maxIdx]=max(CPS(t>lowerQuefrencyFitLimit & t<upperQuefrencyFitLimit));
                            CPP(methodNr)=maxVal-fitY(maxIdx);
                        else
                            [maxVal,maxIdx]=max(CPS);
                            fitY=fitObj(t(maxIdx));
                            CPP(methodNr)=maxVal-fitY;
                        end
                        gofCell{methodNr}=gof;

                    catch ME
                        disp(ME.getReport());
                        CPP(methodNr)=NaN;
                        gofCell{methodNr}=NaN;
                        continue;
                    end
                        
                    
                end
                
                if doPlot
        
                    if maxInsideFitRegion
                        xline(ax,fitX(maxIdx),'--k','DisplayName','peak position');
                    else
                        xline(ax,t(maxIdx),'--k','DisplayName','peak position');
                    end
                    
                    title(ax,sprintf('CPP with %s fit is %.2f, %.2f or %.2f dB',fitTypes{fitTypeNr},CPP));
                    legend(ax,'Location','northeast');
        
                    ylabel(ax,'Cesptrum (dB)')
                    xlabel(ax,'Quefrency (s)')
                end
        
                if strcmpi(fitTypes{fitTypeNr},'poly1')
                    CPPlin.value=CPP;
                    CPPlin.method=methods;
                    CPPlin.goodnessOfFit=gofCell;
                elseif strcmpi(fitTypes{fitTypeNr},'poly2')
                    CPPparabolic.value=CPP;
                    CPPparabolic.method=methods;
                    CPPparabolic.goodnessOfFit=gofCell;
                else
                    CPPexp.value=CPP;
                    CPPexp.method=methods;
                    CPPexp.goodnessOfFit=gofCell;
                end
            
            end
        end


function shortNames = shortenNames(~, nameList)
    if isempty(nameList)
        shortNames = {};
        return;
    elseif isscalar(nameList)
        shortNames = nameList;
        return;
    end

    % Step 1: Identify common word-based prefixes across meaningful subsets
    prefixCounts = containers.Map;
    for i = 1:length(nameList)
        for j = i+1:length(nameList)
            commonPrefix = getCommonWordPrefix(nameList{i}, nameList{j});
            if ~isempty(commonPrefix)
                if isKey(prefixCounts, commonPrefix)
                    prefixCounts(commonPrefix) = prefixCounts(commonPrefix) + 1;
                else
                    prefixCounts(commonPrefix) = 2; % At least two occurrences
                end
            end
        end
    end

    % Filter out prefixes that appear in too few entries
    minGroupSize = 2; % Require at least two occurrences to consider removing
    validPrefixes = keys(prefixCounts);
    validPrefixes = validPrefixes(cellfun(@(x) prefixCounts(x) >= minGroupSize, validPrefixes));

    % Sort prefixes by word count (longest first)
    [~, idx] = sort(cellfun(@(x) numel(strsplit(convertCharsToStrings(x), ' ')), validPrefixes), 'descend');
    validPrefixes = validPrefixes(idx);

    % Step 2: Remove meaningful word prefixes within subsets while ensuring non-empty output
    shortNames = nameList;
    for i = 1:length(nameList)
        for p = validPrefixes
            if startsWith(nameList{i}, p{1})
                tempName = erase(nameList{i}, p{1});
                tempName = strtrim(tempName); % Trim leading/trailing spaces
                if ~isempty(tempName) % Prevent empty strings
                    shortNames{i} = tempName;
                end
                break; % Remove only one prefix per name
            end
        end
    end


    % Nested function to find common word-based prefix
    function wp = getCommonWordPrefix(str1, str2)
        % Ensure both inputs are strings or cell arrays properly converted
        str1 = convertCharsToStrings(str1);
        str2 = convertCharsToStrings(str2);

        words1 = strsplit(str1, ' ');
        words2 = strsplit(str2, ' ');
        minLen = min(length(words1), length(words2));

        wp = {}; % Use a cell array to collect words safely
        for k = 1:minLen
            if strcmp(words1{k}, words2{k})
                wp{end+1} = words1{k}; %#ok<AGROW> Collect words as cell array
            else
                break;
            end
        end

        wp = strjoin(wp, ' '); % Convert cell array to a single string safely
    end
end


        function patchObj=addManyPatchesAsSinglePatch(app,channelNr,x_pairs,facecolor,facealpha,vis)
            if nargin<4 || isempty(facecolor)
                facecolor='blue';
            end

            if nargin<5 || isempty(facealpha)
                facealpha=0.3;
            end

            ax=app.channelAxes(channelNr);
            
            num_patches = size(x_pairs,1);

            %ylims=ylim(ax);

            xlims=xlim(ax);
            currydata=app.timePlotHandles(channelNr).YData(app.timePlotHandles(channelNr).XData>=xlims(1) & app.timePlotHandles(channelNr).XData<=xlims(2));
            ylims=[min(currydata) max(currydata)]+[-1 1]*0.1*range(currydata);
            
            % Preallocate arrays for vertex coordinates
            vertices = zeros(num_patches * 4, 2); % Each patch has 4 corners
            faces = zeros(num_patches, 4);        % Each face links four vertices
            
            for i = 1:num_patches
                idx = (i-1)*4 + (1:4); % Index range for vertices
                
                % Define the four corners of the rectangle
                vertices(idx, :) = [ ...
                    x_pairs(i,1), ylims(1); % Bottom-left
                    x_pairs(i,2), ylims(1); % Bottom-right
                    x_pairs(i,2), ylims(2); % Top-right
                    x_pairs(i,1), ylims(2)  % Top-left
                ];
                
                % Define the face linking the four vertices
                faces(i, :) = idx;
            end
            
            % Create a single patch object using FaceVertexData
            patchObj=patch(ax,'Faces', faces, 'Vertices', vertices, 'FaceColor', facecolor, ...
                  'FaceAlpha', facealpha, 'EdgeColor', 'none','HitTest','off','Visible',vis);
            
        end


        function addValueSetToValueHistory(app,newDataYArray,newDataXArray,channelNr)

        % add data to history cell
            nonEmptyCells=~cellfun(@isempty,app.channelYValuesHistory(:,channelNr));
            if all(nonEmptyCells)
                app.channelYValuesHistory(end+1,channelNr)={newDataYArray};
                app.channelXValuesHistory(end+1,channelNr)={newDataXArray};
            else
                app.channelYValuesHistory(find(~nonEmptyCells,1,"first"),channelNr)={newDataYArray};
                app.channelXValuesHistory(find(~nonEmptyCells,1,"first"),channelNr)={newDataXArray};
            end
            
        end
        
        function accelerator=getLowerLeftAccelerator(~)
            accelerator='Y';
            if ispc
                % Add the .NET assembly for Windows Forms (if not already loaded)
                NET.addAssembly('System.Windows.Forms');
                
                % Get the current input language object
                inputLang = System.Windows.Forms.InputLanguage.CurrentInputLanguage;
                
                % Retrieve the layout name and culture information
                layoutName  = char(inputLang.LayoutName);
                cultureName = char(inputLang.Culture.EnglishName);
                
                % Decide on the accelerator key based on common layout conventions:
                % For a German keyboard (QWERTZ), the lower left key is usually 'Y',
                % while for a US/UK keyboard (QWERTY), it is usually 'Z'.
                    if contains(layoutName, 'German', 'IgnoreCase', true) || ...
                       contains(cultureName, 'Germany', 'IgnoreCase', true)
                        accelerator = 'Y';
                    elseif contains(layoutName, 'French', 'IgnoreCase', true) || ...
                           contains(cultureName, 'France', 'IgnoreCase', true)
                        accelerator = 'W';
                    elseif contains(layoutName, 'Belgian', 'IgnoreCase', true)
                        accelerator = 'W';
                    elseif contains(layoutName, 'Spanish', 'IgnoreCase', true) || ...
                           contains(cultureName, 'Spain', 'IgnoreCase', true)
                        accelerator = 'Z';
                    elseif contains(layoutName, 'Italian', 'IgnoreCase', true) || ...
                           contains(cultureName, 'Italy', 'IgnoreCase', true)
                        accelerator = 'Z';
                    else
                        % Fallback: assume QWERTY.
                        accelerator = 'Z';
                    end
            else
                disp('Getting lower left keyboard accelerator key only supported for windows.');
            end
        end

        
        function updateChannelName(app)

            if isempty(app.channelAxes) || any(~ishandle(app.channelAxes))
                return;
            end

            try
                currentTitlesOnAxes=get([app.channelAxes.Title],'String');
    
                for titleNr=1:numel(currentTitlesOnAxes)
                    if iscell(currentTitlesOnAxes{titleNr})
                        currentTitlesOnAxes{titleNr}=strjoin(currentTitlesOnAxes{titleNr},'');
                    end
                end

                if size(currentTitlesOnAxes,1)>size(currentTitlesOnAxes,2)
                    currentTitlesOnAxes=currentTitlesOnAxes';
                end

                % welaso have to refresh the actual channel names!
                app.channelNames=currentTitlesOnAxes;
    
                shortNames=app.shortenNames(currentTitlesOnAxes);
    
                app.availableChannelsTable.Data(:,1)=shortNames;

            catch ME
                disp(ME.getReport());
            end


            % newChannelName=app.channelAxes(channelNr).Title.String;
            % 
            % if iscell(newChannelName) && isscalar(newChannelName) % one liner
            %     newChannelName=newChannelName{1};
            % elseif ~ischar(newChannelName)
            %     newChannelName=strjoin(newChannelName);
            % end
            % app.channelNames{channelNr}=newChannelName;
            % 
            % if ~isempty(app.availableChannelsTable) % when deleting all channels, this coud interfer by setting the data property to the channels names, effectively not deleting the available channels table
            %     %app.availableChannelsTable.Data{channelNr,1}=newChannelName;
            %     app.availableChannelsTable.Data(:,1)=app.shortenNames(app.channelNames);
            % end
            % %app.populateResultsTable();
        end
        
        function results = isvalidhandle(~,in)
            results=ishandle(in) & isvalid(in);
        end
        
        function path=exportChannelsToWAV(app,toMultiChannel,selectedChannelsOnly,completepath)


            if selectedChannelsOnly && isempty(app.availableChannelsTable.Selection)
                return; % if no channels are selected but only selected files shall be exported, abort
            end

            if nargin<4 || isempty(completepath)
                fakefig=figure('Visible','off');
    
                persistent lastPath;
    
                [file,path,index]=uiputfile({'*.wav','32-bit float';'*.wav','64-bit float'},'Where to save wav files',[lastPath 'exportedData']);
    
                close(fakefig);
                figure(app.UIFigure);
                if isequal(path,0)
                    return;
                end
    
                lastPath=path;
            else
                [path,file,ext]=fileparts(completepath);
                path=[path filesep];
                file=[file ext];
                index=1;
            end

            % if there is a marked area available
            % if ~isempty(app.DraggingData) && ~isempty(app.DraggingData.StartX) && app.DraggingData.StartX~=app.DraggingData.EndX%(app.DraggingData.EndX-app.DraggingData.StartX)>0.1
            %     startTime = app.DraggingData.StartX;
            %     endTime = app.DraggingData.EndX;
            % else
                % if nothing was marked yet, play the most common points in
                % time
                startTime=min(cellfun(@(in) in(1),app.channelXValues));
                endTime=max(cellfun(@(in) in(end),app.channelXValues));
            % end

            if selectedChannelsOnly
                channelsToSave=app.availableChannelsTable.Selection;
            else
                channelsToSave=1:numel(app.channelAxes);
            end

            if index == 2
                BITS = 64;
            else
                BITS = 32;
            end

            sampleRatesForExport=app.channelSampleRates(channelsToSave);
            yValuesForExport=app.channelYValues(channelsToSave);
            xValuesForExport=app.channelXValues(channelsToSave);
            sourceTypesForExport=app.channelSource(channelsToSave);
            namesForExport=app.channelNames(channelsToSave);


            [xSetValuesOut, ySetValuesOut] = app.prepadPostpadToCommonRange(xValuesForExport, yValuesForExport, sampleRatesForExport,startTime,endTime);
            
            [uniqueSampleRates,~,groupNumber]=unique(sampleRatesForExport,'stable');

            if toMultiChannel
                listOfChannelTypes={};
                listOfChannelNames={};
                listOfYHistory={};
                listOfXHistory={};
                for i = 1:numel(uniqueSampleRates)
                    idxGroup = groupNumber == i;
                    signalsGroup = ySetValuesOut(idxGroup);

                    listOfChannelTypes=[listOfChannelTypes sourceTypesForExport(idxGroup)];

                    curnames=namesForExport(idxGroup);
                    if size(curnames,1)>size(curnames,2)
                        curnames=curnames';
                    end

                    listOfChannelNames=[listOfChannelNames curnames];
                    listOfYHistory=[listOfYHistory app.channelYValuesHistory(:,idxGroup)];
                    listOfXHistory=[listOfXHistory app.channelXValuesHistory(:,idxGroup)];
                    
                    minSize=min(cellfun(@numel,signalsGroup));
                    signalsGroup=cellfun(@(in) in(1:minSize),signalsGroup,'UniformOutput',false);

                    % Directly form multi-channel matrix assuming equal length signals
                    multiChannelData = horzcat(signalsGroup{:});
                    
                    % Compose filename for this sample rate group
                    [filepath, name, ext] = fileparts(file);

                    if numel(uniqueSampleRates)>1
                        saveFileName = fullfile(path, [name sprintf('_%03i_fs-%dHz', i, uniqueSampleRates(i)) ext]);
                    else
                        saveFileName = fullfile(path, [name ext]);
                    end
                    
                    % Save multi-channel WAV
                    audiowrite(saveFileName, multiChannelData, uniqueSampleRates(i), 'BitsPerSample', BITS);
                end

                sources=listOfChannelTypes;
                names=listOfChannelNames;
                yhistory=listOfYHistory;
                xhistory=listOfXHistory;
                selectionStart=app.DraggingData.StartX;
                selectionEnd=app.DraggingData.EndX;
                
                save(fullfile(path, [strrep(name,'_channelData','') '_channelNameTypeHistoryAndSelectedArea.mat']),'sources','names','yhistory',"xhistory","selectionEnd","selectionStart");

                
            else
                for channelNr = 1:numel(ySetValuesOut)
                    ySingle = ySetValuesOut{channelNr};
                    fs = sampleRatesForExport(channelNr);
                    [filepath, name, ext] = fileparts(file);
                    saveFileName = fullfile(path, [name sprintf('-channel%02d', app.availableChannelsTable.Selection(channelNr)) ext]);
                    audiowrite(saveFileName, ySingle, fs, 'BitsPerSample', BITS);
                end
            end

            
        end
        
        function calibrationByStartEnd(app,channelNr)
            if ~isempty(app.DraggingData.StartX)
                selectionStart=app.DraggingData.StartX;
                selectionEnd=app.DraggingData.EndX;
            else
                selectionStart=app.SelectedAreaStartEditField.Value;
                selectionEnd=app.SelectedAreaEndEditField.Value;
            end
            
            
            targetValue=0;

            %% create figure
            fig=uifigure('Units','normalized','Position',[0 0 0.5 0.08],'Visible','off','Theme','light');
            movegui(fig,'center');
            
            drawnow;
            fig.Visible='on';
            
            gl=uigridlayout(fig,[2 2],"ColumnWidth",{'1x','fit'},'RowSpacing',1);
            
            label=uilabel(gl,"Text",'End minus start equals which value?','WordWrap','on');
            label.Layout.Column=1;
            
            persistent editFieldValue

            if isempty(editFieldValue)
                editFieldValue=-20;
            end

            editField=uieditfield(gl,'numeric','Limits',[-inf inf],'Tooltip','Use 0 or close window if you want to cancel','Value',editFieldValue);
            editField.Layout.Column=2;

            applyButton=uibutton(gl,'Text','apply','ButtonPushedFcn',@saveAndClose);
            applyButton.Layout.Column=2;
            applyButton.Layout.Row=2;

            function saveAndClose(~,~)
                targetValue=editField.Value;
                editFieldValue=editField.Value;
                delete(fig);
            end

            uiwait(fig);
            
            %% now do something with the result
            if targetValue==0
                return;
            end

            currYData=app.channelYValues{channelNr};
            currXData=app.channelXValues{channelNr};
            currYExcerpt=currYData(currXData>=selectionStart & currXData<=selectionEnd);

            calibrationFactor=targetValue/(currYExcerpt(end)-currYExcerpt(1));

            newYData=currYData*calibrationFactor;

            % add this as version
            app.addValueSetToValueHistory(newYData,app.channelXValues{channelNr},channelNr);

            %% save new calibrated values
            app.channelYValues{channelNr}=newYData;

            app.timePlotHandles(channelNr).YData=newYData;

            drawnow;

            currXLim=xlim(app.channelAxes(channelNr));
            displayMask=currXData>=currXLim(1) & currXData<=currXLim(2);
            newYLimits=[min(newYData(displayMask)) max(newYData(displayMask))]+[-1 1]*0.1*range(newYData(displayMask));
            ylim(app.channelAxes(channelNr),newYLimits);

            if ~isempty(app.DraggingData.CurrentRectangles) && isvalid(app.DraggingData.CurrentRectangles(channelNr))
                app.DraggingData.CurrentRectangles(channelNr).Position([2 4])=[newYLimits(1) newYLimits(2)-newYLimits(1)];
            end

        end
        

        function calibrationBySummedArea(app,channelNr)
            if ~isempty(app.DraggingData.StartX)
                selectionStart=app.DraggingData.StartX;
                selectionEnd=app.DraggingData.EndX;
            else
                selectionStart=app.SelectedAreaStartEditField.Value;
                selectionEnd=app.SelectedAreaEndEditField.Value;
            end
            
            
            targetValue=0;

            %% create figure
            fig=uifigure('Units','normalized','Position',[0 0 0.5 0.08],'Visible','off','Theme','light');
            movegui(fig,'center');
            
            drawnow;
            fig.Visible='on';
            
            gl=uigridlayout(fig,[2 2],"ColumnWidth",{'1x','fit'},'RowSpacing',1);
            
            label=uilabel(gl,"Text",'summed amplitudes equals which value?','WordWrap','on');
            label.Layout.Column=1;

            persistent editFieldValue

            if isempty(editFieldValue)
                editFieldValue=140;
            end
            
            editField=uieditfield(gl,'numeric','Limits',[-inf inf],'Tooltip','Use 0 or close window if you want to cancel','Value',editFieldValue);
            editField.Layout.Column=2;

            applyButton=uibutton(gl,'Text','apply','ButtonPushedFcn',@saveAndClose);
            applyButton.Layout.Column=2;
            applyButton.Layout.Row=2;

            function saveAndClose(~,~)
                targetValue=editField.Value;
                editFieldValue=editField.Value;
                delete(fig);
            end

            uiwait(fig);
            
            %% now do something with the result
            if targetValue==0
                return;
            end

            currYData=app.channelYValues{channelNr};
            currXData=app.channelXValues{channelNr};
            currYExcerpt=currYData(currXData>=selectionStart & currXData<=selectionEnd);

            calibrationFactor=targetValue/sum(currYExcerpt);

            newYData=currYData*calibrationFactor;

            % add this as version
            app.addValueSetToValueHistory(newYData,app.channelXValues{channelNr},channelNr);

            %% save new calibrated values
            app.channelYValues{channelNr}=newYData;

            app.timePlotHandles(channelNr).YData=newYData;

            drawnow;

            currXLim=xlim(app.channelAxes(channelNr));
            displayMask=currXData>=currXLim(1) & currXData<=currXLim(2);
            newYLimits=[min(newYData(displayMask)) max(newYData(displayMask))]+[-1 1]*0.1*range(newYData(displayMask));
            ylim(app.channelAxes(channelNr),newYLimits);

            if ~isempty(app.DraggingData.CurrentRectangles) && isvalid(app.DraggingData.CurrentRectangles(channelNr))
                app.DraggingData.CurrentRectangles(channelNr).Position([2 4])=[newYLimits(1) newYLimits(2)-newYLimits(1)];
            end
            
        end
        
        function calibrationByAreaSPL(app,channelNr)
            if ~isempty(app.DraggingData.StartX)
                selectionStart=app.DraggingData.StartX;
                selectionEnd=app.DraggingData.EndX;
            else
                selectionStart=app.SelectedAreaStartEditField.Value;
                selectionEnd=app.SelectedAreaEndEditField.Value;
            end
            
            
            targetValue=0;

            persistent splWeighting splSpeed

            if isempty(splWeighting)
                splWeighting='A-weighting';
                splSpeed='Fast';

            end
                        

            %% create figure
            fig=uifigure('Units','pixels','Position',[0 0 350 300],'Visible','off','Theme','light');
            movegui(fig,'center');
            
            drawnow;
            fig.Visible='on';
            
            gl=uigridlayout(fig,[4 2],"ColumnWidth",{'3x','1x'},'RowSpacing',1);
            
            spllabel=uilabel(gl,"Text",'which SPL value in marked area?','WordWrap','on');
            spllabel.Layout.Column=1;
            spllabel.Layout.Row=1;

            persistent lastSPLvalue;

            if isempty(lastSPLvalue)
                lastSPLvalue=0;
            end
            
            editField=uieditfield(gl,'numeric','Limits',[-inf inf],'Tooltip','Use 0 or close window if you want to cancel','Value',lastSPLvalue);
            editField.Layout.Column=2;
            editField.Layout.Row=1;

            spltypelabel=uilabel(gl,"Text",'SPL weighting','WordWrap','on');
            spltypelabel.Layout.Column=1;
            spltypelabel.Layout.Row=2;

            dBtypeDropdown=uidropdown(gl,"Items",{'dB(A)','dB(C)','dB(Z)','none'},'ItemsData',{'A-weighting','C-weighting','Z-weighting','none'},'Value',splWeighting);
            dBtypeDropdown.Layout.Column=2;
            dBtypeDropdown.Layout.Row=2;

            splSpeedlabel=uilabel(gl,"Text",'SPL time constant','WordWrap','on');
            splSpeedlabel.Layout.Column=1;
            splSpeedlabel.Layout.Row=3;

            dBSpeedDropdown=uidropdown(gl,"Items",{'Fast','Slow'},'Value',splSpeed);
            dBSpeedDropdown.Layout.Column=2;
            dBSpeedDropdown.Layout.Row=3;


            applyButton=uibutton(gl,'Text','apply','ButtonPushedFcn',@saveAndClose);
            applyButton.Layout.Column=2;
            applyButton.Layout.Row=4;


            function saveAndClose(~,~)
                targetValue=editField.Value;
                lastSPLvalue=targetValue;
                splWeighting=dBtypeDropdown.Value;
                splSpeed=dBSpeedDropdown.Value;
                delete(fig);
            end

            uiwait(fig);
            
            %% now do something with the result
            if targetValue==0
                return;
            end

            currYData=app.channelYValues{channelNr};
            currXData=app.channelXValues{channelNr};
            currYExcerpt=currYData(currXData>=selectionStart & currXData<=selectionEnd);

            if strcmp(splWeighting,'none')
                splDiff=targetValue-20*log10(rms(currYExcerpt)/(20e-6));
            else
                splmeterObj=splMeter('SampleRate',app.channelSampleRates(channelNr),'TimeWeighting',splSpeed,'FrequencyWeighting',splWeighting,"PressureReference",20e-6);
                splDiff=targetValue-mean(splmeterObj(currYExcerpt));
            end
            calibrationFactor=10^(splDiff/20);
            

            newYData=currYData*calibrationFactor;

            % add this as version
            app.addValueSetToValueHistory(newYData,app.channelXValues{channelNr},channelNr);

            %% save new calibrated values
            app.channelYValues{channelNr}=newYData;

            app.timePlotHandles(channelNr).YData=newYData;

            drawnow;

            currXLim=xlim(app.channelAxes(channelNr));
            displayMask=currXData>=currXLim(1) & currXData<=currXLim(2);
            newYLimits=[min(newYData(displayMask)) max(newYData(displayMask))]+[-1 1]*0.1*range(newYData(displayMask));
            ylim(app.channelAxes(channelNr),newYLimits);

            if ~isempty(app.DraggingData.CurrentRectangles) && isvalid(app.DraggingData.CurrentRectangles(channelNr))
                app.DraggingData.CurrentRectangles(channelNr).Position([2 4])=[newYLimits(1) newYLimits(2)-newYLimits(1)];
            end

        end
        
        function copyCompleteRowToClipboard(app,copyHeader)


            completeRow=num2str(cell2mat(app.parameterResultsUITable.Data(app.parameterResultsUITable.Selection(1),4:end-1)),'%12e\t');
            completeRow=[strjoin(app.parameterResultsUITable.Data(app.parameterResultsUITable.Selection(1),1:3),'\t') sprintf('\t') completeRow];

            if ~copyHeader
                clipboard("copy",completeRow);
            else
                clipboard("copy",[strjoin(app.parameterResultsUITable.ColumnName(1:end-1)','\t') newline completeRow]);
            end


            h=msgbox('Copied successfully...','Success','help');
            pause(1);
            if isvalid(h)
                close(h);
            end
        end
        
        function tmpFcn(app,~,~, channelNr, newYLimits)
            ylim(app.channelAxes(channelNr), newYLimits);
        end
        
        function [channelTypeStrings,abortValue] = createChooseWAVchannelTypesFigure(app,filepath,numOfChannels)
            chooseChannelFig=uifigure('Name','choose channels'' source type','Units','normalized','Position',[0.25 0.05 0.5 0.9],'CloseRequestFcn','','WindowStyle','modal','Theme','light');
            
            thegrid=uigridlayout(chooseChannelFig,[numOfChannels+3,2]);
            lbl=uilabel(thegrid,'Text',filepath,'WordWrap','on');
            lbl.Layout.Column=[1 2]; lbl.Layout.Row=1;

            lbl=uilabel(thegrid,'Text','set all channels'' type','WordWrap','on','HorizontalAlignment','right','FontWeight','bold');
            lbl.Layout.Column=1; lbl.Layout.Row=2;


            allDropdowns=gobjects(numOfChannels,1);
            for channelNr=1:numOfChannels
                allDropdowns(channelNr)=uidropdown(thegrid,"Items",app.channelSourcePossibilities,'Value','Raw','ValueChangedFcn',@setsetAllDropdown);
                allDropdowns(channelNr).Layout.Column=2;
                allDropdowns(channelNr).Layout.Row=2+channelNr;

                lbl=uilabel(thegrid,'Text',['channel ' num2str(channelNr,'%i')],'WordWrap','on','HorizontalAlignment','right');
                lbl.Layout.Column=1; lbl.Layout.Row=2+channelNr;
            end

            setAllDropdown=uidropdown(thegrid,"Items",[app.channelSourcePossibilities {'mixed'}],'Value','Raw',"ValueChangedFcn",@setAllChannelTypes);
            setAllDropdown.Layout.Column=2; setAllDropdown.Layout.Row=2;

            loadConfigButton=uibutton(thegrid,'Text','load channel configuration from file','WordWrap','on','ButtonPushedFcn',@loadWavConfig);
            loadConfigButton.Layout.Column=1; loadConfigButton.Layout.Row=numOfChannels+3;

            saveConfigButton=uibutton(thegrid,'Text','save channel configuration to file','WordWrap','on','ButtonPushedFcn',@saveWavConfig);
            saveConfigButton.Layout.Column=2; saveConfigButton.Layout.Row=numOfChannels+3;


            abortValue=0;

            cancelButton=uibutton(thegrid,'Text','cancel import','FontWeight','bold','ButtonPushedFcn',@(~,~) closeThatFig(1));
            cancelButton.Layout.Column=1; cancelButton.Layout.Row=numOfChannels+4;

            applyButton=uibutton(thegrid,'Text','apply channel types','FontWeight','bold','ButtonPushedFcn',@(~,~) closeThatFig(0));
            applyButton.Layout.Column=2; cancelButton.Layout.Row=numOfChannels+4;

            uiwait(chooseChannelFig);
            function closeThatFig(doAbort)
                abortValue=doAbort;
                channelTypeStrings={allDropdowns.Value};
                delete(chooseChannelFig);
            end

            function saveWavConfig(~,~)
                persistent lastPath;
                
                defaultfilename=['loadWavChannelConfigurationFile-' strjoin({allDropdowns.Value},'-') '.txt'];

                if isempty(lastPath)
                    [foldername,~,~]=fileparts(filepath);
                    [file,path]=uiputfile('*.txt','save to wav config text file',fullfile(foldername,defaultfilename));
                else
                    [file,path]=uiputfile('*.txt','save to wav config text file',fullfile(lastPath,defaultfilename));
                end
                if isequal(file,0)
                    return;
                end

                lastPath=path;
                
                writecell({allDropdowns.Value},fullfile(path,file),"WriteMode","overwrite","FileType","text",'Delimiter',';');

            end

            function loadWavConfig(~,~)
                persistent lastPath;
                

                if isempty(lastPath)
                    [foldername,~,~]=fileparts(filepath);
                    [file,path]=uigetfile('*.txt','load from wav config text file',foldername);
                else
                    [file,path]=uigetfile('*.txt','load from wav config text file',lastPath);
                end
                if isequal(file,0)
                    return;
                end

                lastPath=path;

                listOfChannelTypes=readcell(fullfile(path,file),'TextType','char','Delimiter',';','FileType','text');
                
                disp(listOfChannelTypes')

                for dropdownNr=1:min(numel(listOfChannelTypes),numel(allDropdowns))
                    allDropdowns(dropdownNr).Value=listOfChannelTypes{dropdownNr};
                end

                if isscalar(unique({allDropdowns.Value}))
                    setAllDropdown.Value=listOfChannelTypes{dropdownNr};
                else
                    setAllDropdown.Value='mixed';
                end

            end

            function setsetAllDropdown(~,~)
                currentdropdownvalues=unique({allDropdowns.Value});

                if numel(currentdropdownvalues)>1
                    setAllDropdown.Value='mixed';
                else
                    setAllDropdown.Value=currentdropdownvalues{1};
                end

            end


            function setAllChannelTypes(~,~)
                if strcmp(setAllDropdown.Value,'mixed')
                    return;
                else
                    set(allDropdowns,'Value',setAllDropdown.Value);
                end
            end
            
        end
        
        function debounceMinimizeYLims(app,evt)

            persistent debounceTimer;

            if ~isempty(debounceTimer) && isvalid(debounceTimer)
                stop(debounceTimer);
            end

            if app.executeXLimListener
                debounceTimer=timer("ExecutionMode","singleShot","StartDelay",1,"TimerFcn",@(~,~) minimizeYLims(app,evt));
                start(debounceTimer);
            end
            
        end
        
        function [sourceChannelNr,targetChannelNr] = append_channel_assignment(~,nNewChannels, nExist)
        
            if nargin==0
                nNewChannels = 4;
                nExist  = 8;
            end
        
            sourceChannelNr = []; 
            targetChannelNr = [];
        
            figHeight = (nNewChannels + 4) * 40;
            fig = uifigure('Position', [100 100 650 figHeight], ...
                'Name', 'Choose source and target channel fro appending', ...
                'Visible', 'off', ...
                'CloseRequestFcn', @onClose,'Theme','light');
            movegui(fig, 'center');
        
            kanalliste = arrayfun(@(k) sprintf('existing channel number %d', k), 1:nExist, 'UniformOutput', false);
            kanalIDs   = 1:nExist;
        
            yStart = figHeight - 20;
            dd = gobjects(nNewChannels,1);
        
            itemsText = ['–', kanalliste];
            itemsData = [0, kanalIDs];
        
            % Labels und Dropdowns erzeugen
            for i = 1:nNewChannels
                uilabel(fig, 'Position', [0, yStart - 40*i, 300, 22], ...
                    'Text', sprintf('New channel %d shall be appended to', i), ...
                    'HorizontalAlignment', 'right');
                dd(i) = uidropdown(fig, ...
                    'Position', [310, yStart - 40*i, 300, 22], ...
                    'Items', itemsText, ...
                    'ItemsData', itemsData, ...
                    'Value', 0);
            end
        
            set(dd,'ValueChangedFcn', @(src,~) updateDropdowns(dd, itemsData, itemsText));
        
            % Button auto apply
            btnAuto = uibutton(fig, 'push', ...
                'Text', 'auto apply: 1→1, 2→2, ...', ...
                'Position', [220, figHeight - 25, 210, 25], ...
                'ButtonPushedFcn', @(~,~) autoAssign(dd, kanalIDs));
        
            % Button "Apply"
            btnApply = uibutton(fig, 'push', ...
                'Text', 'Apply', ...
                'Position', [135, 15, 150, 30], ...
                'ButtonPushedFcn', @onApply);
        
            % Button "Abort"
            btnAbort = uibutton(fig, 'push', ...
                'Text', 'cancel', ...
                'Position', [330, 15, 150, 30], ...
                'ButtonPushedFcn', @onAbort);
        
            updateDropdowns(dd, itemsData, itemsText);
        
            drawnow;
            fig.Visible = 'on';
        
            uiwait(fig);
        
            if isvalid(fig) && ~isempty(getappdata(fig,'AssignedValues'))
                vals = getappdata(fig,'AssignedValues');
                idxValid = find(vals ~= 0);
                % output as n x 2 Matrix
                sourceChannelNr = idxValid(:);
                targetChannelNr = vals(idxValid);
                delete(fig);
            else
                sourceChannelNr = [];
                if isvalid(fig)
                    delete(fig);
                end
            end
        
        
            function updateDropdowns(dropdowns, allData, allText)
                persistent isUpdating
                if isempty(isUpdating), isUpdating = false; end
                if isUpdating, return; end
                isUpdating = true;
        
                selectedData = arrayfun(@(d) d.Value, dropdowns);
        
                for k = 1:numel(dropdowns)
                    prevVal = dropdowns(k).Value;
                    alreadyTaken = setdiff(selectedData, [0, prevVal]); % außer eigener
                    freeData = setdiff(allData(allData > 0), alreadyTaken);
                    itemsXData = [0, freeData];
                    itemsXText = allText(ismember(allData, itemsXData));
                    dropdowns(k).Items = itemsXText;
                    dropdowns(k).ItemsData = itemsXData;
        
                    if ~ismember(prevVal, itemsXData)
                        dropdowns(k).Value = 0;
                    else
                        dropdowns(k).Value = prevVal;
                    end
                end
        
                isUpdating = false;
            end
        
            function autoAssign(dropdowns, kanalIDs)
                set(dropdowns, 'Items', itemsText, 'ItemsData', itemsData, 'Value', 0);
                drawnow;
                nDD = numel(dropdowns);
                maxAssign = min(numel(kanalIDs), nDD);
                assigned = zeros(1, nDD);
                assigned(1:maxAssign) = kanalIDs(1:maxAssign);
                for i = 1:nDD
                    dropdowns(i).Value = assigned(i);
                end
                updateDropdowns(dd, itemsData, itemsText);
            end
        
            function onApply(~, ~)
                vals = arrayfun(@(d) d.Value, dd);
                setappdata(fig, 'AssignedValues', vals);
                uiresume(fig);
            end
        
            function onAbort(~, ~)
                if isappdata(fig, 'AssignedValues')
                    rmappdata(fig, 'AssignedValues');
                end
                uiresume(fig);
            end
        
            function onClose(~, ~)
                if isappdata(fig, 'AssignedValues')
                    rmappdata(fig, 'AssignedValues');
                end
                uiresume(fig);
                delete(fig);
            end
        end


        function setToNormalModeWhenManualToolSelected(app,src,evt)

            if strcmp(evt.Selection.Value,'on') && app.normalButton.Value~=1
                app.refreshInteractionComingFromZoomTool=1;
                app.normalButton.Value=1;
                drawnow;
                app.refreshInteractionMode();
                %evt.Selection.Value='on';
            else
                app.refreshInteractionComingFromZoomTool=1;
                app.refreshInteractionMode();
            end
            
        end

        function resetView(app,specificAxisNr)

            oldState=app.executeXLimListener;
            app.executeXLimListener=1;
            if ~isempty(specificAxisNr)
                newXLims=app.timePlotHandles(specificAxisNr).XData([1 end]);
                xlim(app.channelAxes,newXLims);
                app.minimizeYLims(app.channelAxes(specificAxisNr));
            else
                xlim(app.channelAxes,[min(cellfun(@(in) in(1),app.channelXValues)) max(cellfun(@(in) in(end),app.channelXValues))]);
                app.minimizeYLims(app.channelAxes(1));
            end

            drawnow;

            app.executeXLimListener=oldState;
        end
        
        function outCell = struct2cell2col(app)
            %STRUCT2CELL2COL  Wandelt einen skalaren Struct in eine 2-spaltige Zelle um.
            %   outCell = STRUCT2CELL2COL(settings)
            %   Spalte 1: vollständiger Feldname (Punktnotation bei Nested Structs)
            %   Spalte 2: Wert als char
            %
            %   Beide Spalten enthalten Zellarrays von char-Vektoren.

            settings=app.calculationSettings;

            outCell = recurseStruct(settings, '');

            function cellRows = recurseStruct(S, prefix)
                cellRows = {};
                fields = fieldnames(S);
                for i = 1:numel(fields)
                    fname = fields{i};
                    fullName = fname;
                    if ~isempty(prefix)
                        fullName = [prefix '.' fname];
                    end
                    value = S.(fname);
                    if isstruct(value) && isscalar(value)
                        % Rekursiv weitergehen bei Nested Struct
                        cellRows = [cellRows; recurseStruct(value, fullName)];
                    else
                        % Wert → Text umwandeln
                        if isnumeric(value)
                            %valStr = num2str(value);
                            valStr = value; % if numeric, we want to keep it as it is
                        elseif ischar(value)
                            valStr = value;
                        elseif isstring(value)
                            valStr = char(value);
                        elseif islogical(value)
                            valStr = mat2str(value);
                        else
                            valStr = ['<' class(value) '>'];
                        end
                        cellRows = [cellRows; {fullName, valStr}];
                    end
                end
            end
        end


        function openSampEnApp(app,channelNr)
            SEApp=SampEnApp(app.channelYValues{channelNr},app.channelSampleRates(channelNr));

            %SEApp.UIFigure.WindowStyle="modal";
            
            waitfor(SEApp,'dataApplied',1);

            if ~isvalid(SEApp)
                disp('sample entropy calculation aborted')
                return;
            else
                disp('exporting sample entropy calculation...')
            end

            sampEn=SEApp.finalSampEn(:);
            sampEnTime=SEApp.finalSampEnTime(:);

            if sampEnTime(1)~=0 % this way the signal will have the same delay as the input EGG signal
                sampEnTime=[0;sampEnTime];
                sampEn=[0;sampEn];
            end

            if ~isempty(SEApp.finalCycleFreq)
                cycleFreq=SEApp.finalCycleFreq(:);
                cycleFreqTime=SEApp.finalCycleFreqTime(:);
                cycleFreqAvailable=1;

                if cycleFreqTime(1)~=0 % this way the signal will have the same delay as the input EGG signal
                    cycleFreqTime=[0;cycleFreqTime];
                    cycleFreq=[cycleFreq(1);cycleFreq];
                end
            else
                cycleFreqAvailable=0;
            end


            if isvalid(SEApp)
                delete(SEApp);
            end

            %% save SampEn values

            newFs=1/(min(diff(sampEnTime)));
            [sampEnResampled,sampEnTimeResampled]=resample(sampEn,sampEnTime,newFs);

            % if the original signal was shifted, we also have to shift the
            % new signal
            sampEnTimeResampled=sampEnTimeResampled+app.channelLag(channelNr);

            app.addSingleChannelData(sampEnResampled,sampEnTimeResampled,[app.channelNames{channelNr} ' SampEn']);

            % app.channelLag(end+1)=app.channelLag(channelNr);
            % app.channelYValues{end+1}=sampEnResampled;
            % app.channelXValues{end+1}=sampEnTimeResampled;
            % app.channelSampleRates(end+1)=newFs;
            % 
            % app.channelYValuesHistory{1,end+1} = sampEnResampled;
            % app.channelXValuesHistory{1,end+1} = sampEnTimeResampled;
            % 
            % app.channelSource{end+1}='Raw';
            % 
            % app.channelNames{end+1}=['SampEn of ' app.channelNames{channelNr}];

            %% save cycleFreq if available

            if cycleFreqAvailable
                newFs=1/(min(diff(cycleFreqTime)));
                [cycleFreqResampled,cycleFreqTimeResampled]=resample(cycleFreq,cycleFreqTime,newFs);
    
                % if the original signal was shifted, we also have to shift the
                % new signal
                cycleFreqTimeResampled=cycleFreqTimeResampled+app.channelLag(channelNr);

                app.addSingleChannelData(cycleFreqResampled,cycleFreqTimeResampled,[app.channelNames{channelNr} ' Cycle Frequency according hilbert']);
    
                % app.channelLag(end+1)=app.channelLag(channelNr);
                % app.channelYValues{end+1}=cycleFreqResampled;
                % app.channelXValues{end+1}=cycleFreqTimeResampled;
                % app.channelSampleRates(end+1)=newFs;
                % 
                % app.channelYValuesHistory{1,end+1} = cycleFreqResampled;
                % app.channelXValuesHistory{1,end+1} = cycleFreqTimeResampled;
                % 
                % app.channelSource{end+1}='Raw';
                % 
                % app.channelNames{end+1}=['Cycle Frequency according hilbert of ' app.channelNames{channelNr}];

            end


            %% store to table and plot

            % app.availableChannelsTable.Data=[app.channelNames(:) app.channelSource(:)];
            % 
            % oldSelection=app.availableChannelsTable.Selection;
            % 
            % app.availableChannelsTable.Selection=1:size(app.availableChannelsTable.Data,1);
            % 
            % app.refreshTimePlotData();
            % 
            % if ~cycleFreqAvailable
            %     app.availableChannelsTable.Selection=[oldSelection size(app.availableChannelsTable.Data,1)];
            % else
            %     app.availableChannelsTable.Selection=[oldSelection size(app.availableChannelsTable.Data,1)+[-1 0]];
            % end
            % drawnow;
            % app.refreshTimePlotDataVisibility();
        end
        
        function saveChannelNamesTypesAndSelectedArea(app,completepath,showconfirm)
            if numel(app.channelAxes)==0
                return;
            end

            if nargin<2 || isempty(completepath)
                fakefig=figure('Visible','off');

                persistent lastPath;
    
                [file,path]=uiputfile({'*.mat','mat file'},'Where to save channel name and type',[lastPath 'channelNamesTypesAndSelectedArea']);
    
                close(fakefig);
                figure(app.UIFigure);
                if isequal(path,0)
                    return;
                end
                lastPath=path;
                completepath=[path file];                
            end
            sources=app.channelSource;
            names=app.channelNames;
            %yhistory=app.channelYValuesHistory;
            %xhistory=app.channelXValuesHistory;
            selectionStart=app.DraggingData.StartX;
            selectionEnd=app.DraggingData.EndX;
            save(completepath,'sources','names','selectionEnd',"selectionStart");

            if nargin<3 || showconfirm==1

                uialert(app.UIFigure,'Saving successfull','Success','Icon','success');

            end
            
        end
        
        function loadChannelNamesTypesAndSelectedArea(app,completepath)
            if numel(app.channelAxes)==0
                return;
            end
            if nargin<2 || isempty(completepath)
                fakefig=figure('Visible','off');

                persistent lastPath;
    
                [file,path]=uigetfile({'*.mat','mat file from Vocaliscope'},'Select mat file with channel name and type',[lastPath 'channelNamesAndTypes']);
    
                close(fakefig);
                figure(app.UIFigure);
                if isequal(path,0)
                    return;
                end
                lastPath=path;
                completepath=[path file];                
            end
            
            load(completepath,'sources','names');
           

            for sourceNr=1:min(numel(sources),numel(app.channelAxes))
                if ismember(sources{sourceNr},app.channelSourcePossibilities)
                    currChannelName=names{sourceNr};
                    currSourceType=sources{sourceNr};


                    app.channelSource{sourceNr}=currSourceType;
                    app.channelNames{sourceNr}=currChannelName;
                    app.channelAxes(sourceNr).Title.String=currChannelName;
                    app.availableChannelsTable.Data{sourceNr,1}=currChannelName;
                    app.availableChannelsTable.Data{sourceNr,2}=currSourceType;


                    app.channelAxes(sourceNr).YLabel.String=currSourceType;
                    app.timePlotHandles(sourceNr).Color=app.channelSourcePossibilitiesColor(ismember(app.channelSourcePossibilities,currSourceType),:);
                end
            end

            % before 2.6 the history cells were not sorted according the
            % sample rates of the channel, like the WAV files or channel
            % names. So restoring yhistory is disabled using this function
            %load(completepath, 'yhistory','xhistory');
            %app.channelYValuesHistory=yhistory(:,1:min(numel(sources),numel(app.channelAxes)));
            %app.channelXValuesHistory=xhistory(:,1:min(numel(sources),numel(app.channelAxes)));


            try % added in later version (since 2.5), so we try it
                load(completepath,'selectionEnd',"selectionStart");
                app.DraggingData.StartX=selectionStart;
                app.DraggingData.EndX=selectionEnd;

                delete(app.DraggingData.CurrentRectangles);
                app.DraggingData.CurrentRectangles=gobjects(1,numel(app.channelAxes));

                for channelNr=1:numel(app.channelAxes)
                    yMin=min(app.channelYValues{channelNr})-0.1*range(app.channelYValues{channelNr});
                    yMax=max(app.channelYValues{channelNr})+0.1*range(app.channelYValues{channelNr});
                    app.DraggingData.CurrentRectangles(channelNr) = rectangle(app.channelAxes(channelNr), ...
                            'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                            'FaceColor', [0.8, 0.8, 1, 0.5], ...
                            'FaceAlpha',0.5,...
                            'EdgeColor', 'none', ...
                            'PickableParts', 'none');
                    uistack(app.DraggingData.CurrentRectangles(channelNr),'bottom');
                end

                app.SelectedAreaStartEditField.Value=selectionStart;
                app.SelectedAreaEndEditField.Value=selectionEnd;
                app.SelectedAreaDurationEditField.Value=selectionEnd-selectionStart;
                

            end

            uialert(app.UIFigure,'Loading successfull','Success','Icon','success');


        end
        
        function createNewNasalanceChannel(app)

            answers = inputdlg({'Nose Flow Channel Nr','Oral Flow Channel Nr','Low Pass passband freq applied to Flow Signals','Low Pass stopband freq applied to Flow Signals','Low Pass stopband attenuation (dB) applied to Flow Signals'},'Select Flow channels',1,{'' '' '20' '50' '60'});

            if any(cellfun(@isempty,answers))
                return;
            end

            try
                answers=cellfun(@str2double,answers);
            catch
                uialert(app.UIFigure,'Error when converting flow channel answer strings to numbers. Aborting...',"Error");
                return;
            end


            currSampleRates=app.channelSampleRates(answers(1:2));

            if numel(unique(currSampleRates))>1 % if not same sample rate

                uialert(app.UIFigure,"The flow channel do not have the same sample rate. Aborting...","Error");
                return;

                
            end

            timeSelectionStart=max([app.channelXValues{answers(1)}(1) app.channelXValues{answers(2)}(1)]);
            timeSelectionEnd=min([app.channelXValues{answers(1)}(end) app.channelXValues{answers(2)}(end)]);

            noseExcerpt=app.channelXValues{answers(1)}>=timeSelectionStart & app.channelXValues{answers(1)}<=timeSelectionEnd;
            oralExcerpt=app.channelXValues{answers(2)}>=timeSelectionStart & app.channelXValues{answers(2)}<=timeSelectionEnd;


            noseFlow=app.channelYValues{answers(1)}(noseExcerpt);
            oralFlow=app.channelYValues{answers(2)}(oralExcerpt);

            %noseFlowFiltered=lowpass(noseFlow,answers(3),app.channelSampleRates(answers(1)),"StopbandAttenuation",answers(4));
            %oralFlowFiltered=lowpass(oralFlow,answers(3),app.channelSampleRates(answers(2)),"StopbandAttenuation",answers(4));

            d = designfilt('lowpassiir', ...        % Response type
                   'PassbandFrequency',answers(3), ...     % Frequency constraints
                   'StopbandFrequency',answers(4), ...
                   'PassbandRipple',1, ...          % Magnitude constraints
                   'StopbandAttenuation',answers(5), ...
                   'DesignMethod','butter', ...      % Design method
                   'MatchExactly','passband', ...   % Design method options
                   'SampleRate',app.channelSampleRates(answers(1)));

            noseFlowFiltered=filtfilt(d,noseFlow);
            oralFlowFiltered=filtfilt(d,oralFlow);

            Nasalance=noseFlowFiltered./(noseFlowFiltered+oralFlowFiltered);
            Nasalance(Nasalance>1)=1;
            Nasalance(Nasalance<0)=0;
            NasalanceTime=(app.channelXValues{answers(1)}(noseExcerpt)+app.channelXValues{answers(2)}(oralExcerpt))/2;

            app.addSingleChannelData(Nasalance,NasalanceTime,'Nasalance');


        end
        
        function addSingleChannelData(app,newY,newX,nameOfNewChannel,typeOfNewChannel)

            if nargin<5 || isempty(typeOfNewChannel)
                typeOfNewChannel='Raw';
            end

            app.titleTimer.stop();

            newChannelNr=numel(app.channelAxes)+1;

            app.channelYValues(newChannelNr)={newY(:)};
            app.channelXValues(newChannelNr)={newX(:)};
            app.channelNames{newChannelNr}=nameOfNewChannel;
            app.channelSource{newChannelNr}=typeOfNewChannel;
            app.channelYValuesHistory(1,newChannelNr)={newY(:)};
            app.channelXValuesHistory(1,newChannelNr)={newX(:)};
            app.channelSampleRates(newChannelNr)=round(1/min(diff(newX)));
            app.availableChannelsTable.Data=[app.channelNames(:) app.channelSource(:)];
            app.availableChannelsTable.Selection=[app.availableChannelsTable.Selection newChannelNr];
            app.channelLag(newChannelNr)=newX(1);

            app.cycleBorderHandles(newChannelNr)=gobjects(1);
            app.cycleOQHandle(newChannelNr)=gobjects(1);
            app.cycleClQHandle(newChannelNr)=gobjects(1);
            app.timePlotHandles(newChannelNr)=gobjects(1);


            % tile numbers are not refreshed when an axes, e.g. the 3rd of
            % 5 is deleted, so the tile number has to be the max number +1
            nextTileNumber=max(tilenum(app.channelAxes))+1;


            app.channelAxes(newChannelNr)=nexttile(app.tlTime,nextTileNumber);
            

            channelColor=app.channelSourcePossibilitiesColor(contains(app.channelSourcePossibilities,typeOfNewChannel),:);
            app.timePlotHandles(newChannelNr)=plot(app.channelAxes(newChannelNr),newX,newY,'Color',channelColor,'LineWidth',1,'PickableParts','none');

            xlabel(app.channelAxes(newChannelNr),'time (s)');
            ylabel(app.channelAxes(newChannelNr),'Raw');
            title(app.channelAxes(newChannelNr),app.splitCharArray(nameOfNewChannel,110),'Interpreter','none','Interactions',editInteraction);

         
            app.refreshSelectionEvaluationText(newChannelNr);


            grid(app.channelAxes(newChannelNr),'on')


            app.channelAxes(newChannelNr).UserData.channelNr=newChannelNr;
            app.addContextMenuToAxis(app.channelAxes(newChannelNr),newChannelNr);


            drawnow;
            
            prae=app.executeXLimListener;
            app.executeXLimListener=1;
            app.minimizeYLims(app.channelAxes(1));

            app.executeXLimListener=prae;

            delete(app.xlimListener);
            app.xlimListener=listener(app.channelAxes, 'XLim', 'PostSet', @(~, evt) debounceMinimizeYLims(app,evt));

            app.refreshInteractionMode();

            app.createAudioPlayerData();


            drawnow;
            app.rescaleTimePlotTilesVerticallyPanel(0);

            if ~isempty(app.DraggingData.StartX) % we need to add the rectangle to the newly added channel
                xlimits=xlim(app.channelAxes(newChannelNr));
                excerpt=app.channelYValues{newChannelNr}(app.channelXValues{newChannelNr}>=xlimits(1) & app.channelXValues{newChannelNr}<=xlimits(2));
                ylimits=[min(excerpt) max(excerpt)];
                ylimits=ylimits+[-1 1]*0.1*range(ylimits);
                yMin=ylimits(1);
                yMax=ylimits(2);
                app.DraggingData.CurrentRectangles(newChannelNr)=rectangle(app.channelAxes(newChannelNr), ...
                    'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                    'FaceColor', [0.8, 0.8, 1, 0.5], ...
                    'FaceAlpha',0.5,...
                    'EdgeColor', 'none', ...
                    'PickableParts', 'none');
                uistack(app.DraggingData.CurrentRectangles(newChannelNr),'bottom');
            end

            drawnow;

            scroll(app.plottingPanelContainer,'bottom');

            app.titleTimer.start();
            
        end
        
        function addContextMenuToAxis(app,currAx,channelNr)

                cmenu = uicontextmenu(app.UIFigure);

                % change source node
                changeSourceNode = uimenu(cmenu, 'Text', 'Change Source');
                subNodesText = app.channelSourcePossibilities;
                subHandles = gobjects(size(subNodesText));
                for dispNr = 1:numel(subNodesText)
                   subHandles(dispNr) = uimenu(changeSourceNode, 'Text', subNodesText{dispNr}, ...
                       'Callback',@(src,~)app.uiMenuChangeColorCallback(src,currAx.UserData.channelNr));
                end

                subHandles(ismember(app.channelSourcePossibilities,app.channelSource{channelNr})).Checked='on';

                % filter channel node
                %filteringNode = uimenu(cmenu, 'Text', 'Filtering...');
                filterToolboxNode = uimenu(cmenu, 'Text', 'Process with Filter Toolbox...','Callback',@(~,~) app.uiMenuFilterDataCallback(currAx.UserData.channelNr),'Separator','on');


                % edit selected area
                basicEditingNode = uimenu(cmenu,'Text','Basic Editing');
                basicEditingThisChannelOnlySubnode = uimenu(basicEditingNode,'Text','Edit this channel only');
                basicEditingAllDisplayedChannelsSubnode = uimenu(basicEditingNode,'Text','Edit all displayed channels');
                basicEditingAllChannelsSubnode = uimenu(basicEditingNode,'Text','Edit all available channels');

                basicEditingTypes={'this','displayed','all'};
                nodes=[basicEditingThisChannelOnlySubnode basicEditingAllDisplayedChannelsSubnode basicEditingAllChannelsSubnode];
                for affectedChannelsTypeNr=1:numel(basicEditingTypes)
                    editWholeChannelDataNode = uimenu(nodes(affectedChannelsTypeNr),'Text','affect whole channel data');
                    markedAreaOnlyNode = uimenu(nodes(affectedChannelsTypeNr),'Text','affect selected area only');

                    affectedArea={'whole' 'marked'};
                    affectedAreaNodes=[editWholeChannelDataNode markedAreaOnlyNode];
                    for affectedAreaTypeNr=1:numel(affectedArea)
                        
                        if affectedAreaTypeNr>1 % only available for affecting the marked area
                            uimenu(affectedAreaNodes(affectedAreaTypeNr),'Text','Delete and concatenate','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'delete'));
                            
                            uimenu(affectedAreaNodes(affectedAreaTypeNr),'Text','Overwrite with linear ramp','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'linearRamp'));
                            
                            %% set to
                            setToNode=uimenu(affectedAreaNodes(affectedAreaTypeNr),'Text','Set to ...');
                            uimenu(setToNode,'Text','Zero','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToZero'));
    
                            uimenu(setToNode,'Text','Channel min','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToChannelMin'));
                            uimenu(setToNode,'Text','Channel marked area min','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToSelectionMin'));
    
                            uimenu(setToNode,'Text','Channel max','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToChannelMax'));
                            uimenu(setToNode,'Text','Channel marked area max','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToSelectionMax'));
    
                            uimenu(setToNode,'Text','Channel mean','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToChannelMean'));
                            uimenu(setToNode,'Text','Channel marked area mean','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToSelectionMean'));
    
                            uimenu(setToNode,'Text','Channel median','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToChannelMedian'));
                            uimenu(setToNode,'Text','Channel marked area median','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'setToSelectionMedian'));
                        end

                        %% changes in differential
                        if affectedChannelsTypeNr==1
                            differentialNode=uimenu(affectedAreaNodes(affectedAreaTypeNr),'Text','Differential...');
                            uimenu(differentialNode,'Text','Set selection maximum to 0','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'selectionDifferentialMaxToZero'));
                            uimenu(differentialNode,'Text','Set selection minimum to 0','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'selectionDifferentialMinToZero'));
                            uimenu(differentialNode,'Text','Set selection absolute maximum to 0','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'selectionDifferentialAbsMaxToZero'));

                            uimenu(affectedAreaNodes(affectedAreaTypeNr),'Text','Delete inverse selection','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'deleteInverse'));
                        

                        end

                        %% add ...
                        addValueNode=uimenu(affectedAreaNodes(affectedAreaTypeNr),'Text','Add value ...');

                        uimenu(addValueNode,'Text','Channel min','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'addChannelMin'));
                        uimenu(addValueNode,'Text','Channel marked area min','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'addSelectionMin'));

                        uimenu(addValueNode,'Text','Channel max','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'addChannelMax'));
                        uimenu(addValueNode,'Text','Channel marked area max','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'addSelectionMax'));

                        uimenu(addValueNode,'Text','Channel mean','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'addChannelMean'));
                        uimenu(addValueNode,'Text','Channel marked area mean','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'addSelectionMean'));

                        uimenu(addValueNode,'Text','Channel median','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'addChannelMedian'));
                        uimenu(addValueNode,'Text','Channel marked area median','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'addSelectionMedian'));

                        %% subtract ...
                        subtractValueNode=uimenu(affectedAreaNodes(affectedAreaTypeNr),'Text','Subtract value ...');

                        uimenu(subtractValueNode,'Text','Channel min','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'subtractChannelMin'));
                        uimenu(subtractValueNode,'Text','Channel marked area min','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'subtractSelectionMin'));

                        uimenu(subtractValueNode,'Text','Channel max','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'subtractChannelMax'));
                        uimenu(subtractValueNode,'Text','Channel marked area max','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'subtractSelectionMax'));

                        uimenu(subtractValueNode,'Text','Channel mean','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'subtractChannelMean'));
                        uimenu(subtractValueNode,'Text','Channel marked area mean','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'subtractSelectionMean'));

                        uimenu(subtractValueNode,'Text','Channel median','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'subtractChannelMedian'));
                        uimenu(subtractValueNode,'Text','Channel marked area median','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'subtractSelectionMedian'));


                        %% leading zeros to lag
                        if affectedAreaTypeNr==1
                            leadingZerosToLagNode = uimenu(affectedAreaNodes(affectedAreaTypeNr),'Text','Convert leading zeros to lag','Callback',@(src,evt) app.performBasicChannelEditing(currAx.UserData.channelNr,basicEditingTypes{affectedChannelsTypeNr},affectedArea{affectedAreaTypeNr},'leadingZerosToLag'));
                        end
                        
                    end

                end

                %% calibration point
                calibrationNode = uimenu(cmenu, 'Text', 'Calibrate using...');

                calibrateByStartAndEndNode = uimenu(calibrationNode, 'Text','Abs. Difference of start and end of marked region','Callback',@(src,evt) app.calibrationByStartEnd(currAx.UserData.channelNr));
                calibrateBySummedAreaNode  = uimenu(calibrationNode, 'Text','summed values inside marked region','Callback',@(src,evt) app.calibrationBySummedArea(currAx.UserData.channelNr));
                calibrateBySPL             = uimenu(calibrationNode, 'Text','SPL inside marked region','Callback',@(src,evt) app.calibrationByAreaSPL(currAx.UserData.channelNr));


                %% generate data from this channel
                createNewChannelsNode = uimenu(cmenu,'Text','Calculate from this channel...');
                sampleEntropyNode = uimenu(createNewChannelsNode, 'Text', 'Sample Entropy based on Fourier descriptors...','Callback',@(~,~) app.openSampEnApp(currAx.UserData.channelNr));
                NasalanceNode = uimenu(createNewChannelsNode,'Text', 'Nasalance','Callback',@(~,~) app.createNewNasalanceChannel());
                MaskCreatorNode = uimenu(createNewChannelsNode,'Text', 'Mask depending on signal valleys and peaks','Callback',@(~,~) app.openMaskCreatorApp(currAx.UserData.channelNr));
                SPLCreaterNode = uimenu(createNewChannelsNode,'Text', 'SPL','Callback',@(~,~) app.openSPLcalculationWindow(currAx.UserData.channelNr));
                foCreaterNode = uimenu(createNewChannelsNode,'Text', 'fundamental frequency','Callback',@(~,~) app.openFOcalculationWindow(currAx.UserData.channelNr));
                voiceActivityCreaterNode = uimenu(createNewChannelsNode,'Text', 'voice activity probability','Callback',@(~,~) app.openVADWindow(currAx.UserData.channelNr));
                cwtCreatorNode = uimenu(createNewChannelsNode,'Text', 'Continuous Wavelet transform (like spectrogram)','Callback',@(~,~) app.openCWTWindow(currAx.UserData.channelNr));
                

                changeTemporalResolutionNode = uimenu(cmenu,'Text','Resample...','Callback',@(~,~) app.openResamplingApp(currAx.UserData.channelNr));
                

                % conversion, e.g. Pa to cm H2O
                conversionChannelNode = uimenu(cmenu, 'Text', 'Unit conversion...','Separator','on');
                cmH2OToPaChannelNode = uimenu(conversionChannelNode, 'Text', 'cm H2O to Pa','Callback',@(~,~) app.convertCmH2OtoPa(currAx.UserData.channelNr));
                PaToCmH2OChannelNode = uimenu(conversionChannelNode, 'Text', 'Pa cm H2O','Callback',@(~,~) app.convertPaToCmH2O(currAx.UserData.channelNr));


                 %% duplicate channel
                duplicateChannelNode = uimenu(cmenu, 'Text', 'Duplicate channel','Callback',@(~,~) app.uiMenuDuplicateChannelCallback(currAx.UserData.channelNr),'Separator','on');


                %% undo context point
                lowerLeftAccelerator='';%;app.getLowerLeftAccelerator();
                changeUndoNode = uimenu(cmenu, 'Text', 'Go back to previous version','Callback',@(~,~) app.uiMenuUndoDataChangeCallback(currAx.UserData.channelNr),'Accelerator',lowerLeftAccelerator,'Separator','on');
                
                changeUndoNode.UserData.channelNr=channelNr;


                %% restore view context point

                resetViewNode = uimenu(cmenu, 'Text', 'Reset view in...','Separator','on');

                resetViewSingleNode = uimenu(resetViewNode, 'Text', 'this channel','Callback',@(~,~) app.resetView(currAx.UserData.channelNr));
                resetViewAllNode = uimenu(resetViewNode, 'Text', 'all channels','Callback',@(~,~) app.resetView([]));


                %% apply context menu

                app.channelAxes(channelNr).ContextMenu=cmenu;


        end
        
        function deleteSignals(app,selection)
             if ~isempty(app.availableChannelsTable.Data)
                app.channelYValues(selection)=[];
                app.channelXValues(selection)=[];
                app.channelYValuesHistory(:,selection)=[];
                app.channelXValuesHistory(:,selection)=[];
                app.channelLag(selection)=[];
                %app.channelNames(selection)=[]; moved to the end since
                %this is refreshed by the title refresh timer
                app.channelSampleRates(selection)=[];
                app.channelSource(selection)=[];
                app.selectionEvaluationTexts(selection)=[];

                % this is needed because the
                % updateSelectionAreaEditFieldsLimits throws an error of
                % invalid objects exist.
                if ~isempty(app.DraggingData.CurrentRectangles)
                    delete(app.DraggingData.CurrentRectangles);
                    app.DraggingData.CurrentRectangles=[];
                end

                % if every channel was deleted
                if isempty(app.channelLag)
                    delete(app.DraggingData.CurrentRectangles);
                    delete(app.DraggingData.PreviousRectangles);
                    app.DraggingData=struct('Dragging', false, 'StartX', [], 'EndX', [],...
                'CurrentRectangles', [], 'PreviousRectangles', []);
                    app.SelectedAreaStartEditField.Value=-inf;
                    app.SelectedAreaEndEditField.Value=inf;
                    app.SelectedAreaDurationEditField.Value=0;
                    app.SaveResultsButton.Enable="off";
                end


                if ~isempty(app.calculationResults) && ~isempty(app.calculationResults.channelResults)
                    % remove calcultions stored from that channel
                    app.calculationResults.channelResults(selection(selection<=numel(app.calculationResults.channelResults)))=[];
                end

                if ~isempty(app.channelAxes)
                    app.xlimBeforeDeletion=app.channelAxes(1).XLim;
                end
                
                delete(app.channelAxes(selection));
                app.channelAxes(selection)=[];

                % remove from channel table and set no selection
                
                app.availableChannelsTable.Data(selection,:)=[];


                %app.channelAxes=gobjects(0);

                % update the channel number stored in the UserData
                for channelNr=1:numel(app.channelAxes)
                    app.channelAxes(channelNr).UserData.channelNr=channelNr;
                end

                app.timePlotHandles(selection)=[];
                app.cycleBorderHandles(selection)=[];
                app.cycleOQHandle(selection)=[];
                app.cycleClQHandle(selection)=[];

                
                if isempty(app.availableChannelsTable.Data)
                    app.ResultsTab.Parent=[];

                end


                app.channelNames(selection)=[];
                app.updateSelectionAreaEditFieldsLimits();


                app.availableChannelsTable.Selection=1:numel(app.channelAxes);
                app.refreshTimePlotDataVisibility();
            end
            
        end
        
        function [xSetValuesOut, ySetValuesOut] = prepadPostpadToCommonRange(~,xSetValues, ySetValues, sampleRates,minStart,maxEnd)
            nSignals = numel(xSetValues);
            
            xSetValuesOut = cell(size(xSetValues));
            ySetValuesOut = cell(size(ySetValues));
            
            for i = 1:nSignals
                x = xSetValues{i}(:);
                y = ySetValues{i}(:);
                fs = sampleRates(i);
                dx = 1/fs;
                
                % Prepad calculations
                shiftStart = x(1) - minStart;
                nPrePad = round(shiftStart * fs);
                
                % Postpad calculations
                shiftEnd = maxEnd - x(end);
                nPostPad = round(shiftEnd * fs);
        
                % Generate prepend and append time vectors and zero values
                xPrePad = minStart + ((0:(nPrePad-1))*dx)';
                yPrePad = zeros(nPrePad,1);
                xPostPad = (x(end) + dx) + ((0:(nPostPad-1))*dx)';
                yPostPad = zeros(nPostPad,1);


                % Concatenate padded vectors
                xSetValuesOut{i} = [xPrePad; x; xPostPad];
                ySetValuesOut{i} = [yPrePad; y; yPostPad];
            end
        end

        
        function uiMenuDuplicateChannelCallback(app,channelNr)

            newChannelName=[app.channelNames{channelNr} ' duplicate'];

            app.addSingleChannelData(app.channelYValues{channelNr},app.channelXValues{channelNr},newChannelName,app.channelSource{channelNr});
            
        end
        
        function openMaskCreatorApp(app,channelNr)

            maskApp=maskCreator(app.channelYValues{channelNr},app.channelXValues{channelNr});

            %uiwait(app.UIFigure);

            waitfor(maskApp,'finalMask');

            returnedMask=maskApp.finalMask;
            delete(maskApp);


            %uiresume(app.UIFigure);

            if isnan(returnedMask)
                return;
            end

            app.addSingleChannelData(returnedMask,app.channelXValues{channelNr},'Mask');
            
        end
        
        function openShiftTool(app)
            if isempty(app.availableChannelsTable.Data)
                return;
            end
            
            shifttoolFig=uifigure('WindowStyle','normal','Name','Shifttool','Units','normalized','Position',[0.25 0.25 0.5 0.5],'Theme','light','Visible','off');

            uigrid=uigridlayout(shifttoolFig,[5,2],"ColumnWidth",{200 '1x'},"RowHeight",{'fit','fit','1x','fit'});

            persistent lastShiftNumberUnit lastShiftAmountValue

            if isempty(lastShiftNumberUnit)
                lastShiftNumberUnit='ms';
                lastShiftAmountValue=0;
            end


            ShiftTypeDropdown=uidropdown(uigrid,"Items",{'Time in ms','Time in s','By Frames'},'ItemsData',{'ms' 's' 'frames'},'Value',lastShiftNumberUnit);
            ShiftTypeDropdown.Layout.Row=1;
            ShiftTypeDropdown.Layout.Column=2;

            ShiftTypeDropdownLabel=uilabel(uigrid,"Text",'Unit of shift','WordWrap','on');
            ShiftTypeDropdownLabel.Layout.Row=1;
            ShiftTypeDropdownLabel.Layout.Column=1;

            ShiftAmountEdit=uieditfield(uigrid,"numeric","Value",lastShiftAmountValue,"Tooltip",'Positive values add lag','ValueDisplayFormat',['%+11g ' ShiftTypeDropdown.Value]);
            ShiftAmountEdit.Layout.Row=2;
            ShiftAmountEdit.Layout.Column=2;

            ShiftTypeDropdown.ValueChangedFcn=@(~,~) set(ShiftAmountEdit,'ValueDisplayFormat',['%+11g ' ShiftTypeDropdown.Value],'RoundFractionalValues',contains(ShiftTypeDropdown.Value,'frame'));

            HowMuchEditLabel=uilabel(uigrid,"Text",'How much? (ms)','Tooltip',ShiftAmountEdit.Tooltip,'WordWrap','on');
            HowMuchEditLabel.Layout.Row=2;
            HowMuchEditLabel.Layout.Column=1;

            availableSignalsTable = uitable(uigrid,'Data',[app.shortenNames(app.availableChannelsTable.Data(:,1)) num2cell(app.channelLag(:))],'ColumnWidth',{'1x' '1x'},...
                'ColumnEditable',[false false],'SelectionType','row','ColumnName',{'channel name' 'current shift (s)'},...
                'ColumnSortable',[false false],'Multiselect','on','RowName',[],'Selection',1:numel(app.channelLag),'RowStriping','off');
            availableSignalsTable.Layout.Row=3;
            availableSignalsTable.Layout.Column=2;

            availableSignalsTableLabel= uilabel(uigrid,"Text",'Select signals to shift','WordWrap','on','VerticalAlignment','top');
            availableSignalsTableLabel.Layout.Row=3;
            availableSignalsTableLabel.Layout.Column=1;

            openShiftEstimatorButton = uibutton(uigrid,'Text','Open Lag Estimator Tool','WordWrap','on','ButtonPushedFcn',@(~,evt) app.LagEstimatorofselectionMenuSelected(evt));
            openShiftEstimatorButton.Layout.Row=4;
            openShiftEstimatorButton.Layout.Column = [1 2];

            RemoveShiftButton=uibutton(uigrid,'push','Text','Remove Shift','ButtonPushedFcn',@(~,~)removeChannelShift(app),'WordWrap','on');
            RemoveShiftButton.Layout.Row=5;
            RemoveShiftButton.Layout.Column=1;

            function removeChannelShift(app)
                app.setChannelShiftToZero(availableSignalsTable.Selection);
                availableSignalsTable.Data(availableSignalsTable.Selection,2)={0};

                drawnow;
            end

            ApplyShiftButton=uibutton(uigrid,'push','Text','Apply Shift');
            ApplyShiftButton.Layout.Row=5;
            ApplyShiftButton.Layout.Column=2;

            ApplyShiftButton.ButtonPushedFcn=@(~,~) adjustSignalShift(app);

            shifttoolFig.CloseRequestFcn=@(~,~) addLatestVersionsToHistory(app);

            drawnow;

            shifttoolFig.Visible='on';
            drawnow;


            function adjustSignalShift(app)
                selected=availableSignalsTable.Selection;
                oldLags=app.channelLag(selected);

                % convert to seconds
                if strcmp(ShiftTypeDropdown.Value,'ms')
                    toBeAdded=repelem(ShiftAmountEdit.Value/1000,numel(selected));
                elseif strcmp(ShiftTypeDropdown.Value,'s')
                    toBeAdded=repelem(ShiftAmountEdit.Value,numel(selected));
                else % frames
                    toBeAdded=ShiftAmountEdit.Value./app.channelSampleRates(selected);
                end
                newTotalLags=oldLags+toBeAdded;
                app.channelLag(selected)=newTotalLags;

                availableSignalsTable.Data(selected,2)=num2cell(newTotalLags);

                % this is slow and not that handy, try to adjust the
                % plotted handles directly
                %app.refreshTimePlotData();

                xlim(app.channelAxes,'manual');
                for nr=1:numel(selected)
                    newTimevalues=app.channelXValues{selected(nr)}+toBeAdded(nr);
                    app.channelXValues{selected(nr)}=newTimevalues;

                    if ~isempty(find(app.availableChannelsTable.Selection==selected(nr),1))
                        %app.timePlotHandles(find(app.availableChannelsTable.Selection==selected(nr),1)).XData=newTimevalues;

                        app.timePlotHandles(selected(nr)).XData=newTimevalues;
                    end

                    if numel(app.cycleBorderHandles)>=nr && app.isvalidhandle(app.cycleBorderHandles(selected(nr)))
                        app.cycleBorderHandles(selected(nr)).XData([1:3:end 2:3:end])=app.cycleBorderHandles(selected(nr)).XData([1:3:end 2:3:end])+toBeAdded(nr);
                    end
                    

                end

                drawnow;

                lastShiftNumberUnit=ShiftTypeDropdown.Value;
                lastShiftAmountValue=ShiftAmountEdit.Value;


                % if we move the signals outside the marked area, we need
                % reduce the marked area
                if ~isempty(app.DraggingData.StartX)
                    app.DraggingData.StartX=max([app.DraggingData.StartX cellfun(@(in) in(1),app.channelXValues)]);
                    app.DraggingData.EndX=min([app.DraggingData.EndX cellfun(@(in) in(end),app.channelXValues)]);
                    
                    if app.DraggingData.StartX>=app.DraggingData.EndX
                        app.DraggingData.StartX=[];
                        app.DraggingData.EndX=[];
                        delete(app.DraggingData.CurrentRectangles);
                        app.DraggingData.CurrentRectangles=[];

                        app.SelectedAreaStartEditField.Value=max(cellfun(@(in) in(1),app.channelXValues));
                        app.SelectedAreaEndEditField.Value=min(cellfun(@(in) in(end),app.channelXValues));
                        app.SelectedAreaDurationEditField=app.SelectedAreaEndEditField.Value-app.SelectedAreaStartEditField.Value;
                    else
                        for rectNr=1:numel(app.DraggingData.CurrentRectangles)
                            app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[app.DraggingData.StartX app.DraggingData.EndX-app.DraggingData.StartX];
                        end
                    end

                end


                app.updateSelectionAreaEditFieldsValues();

                %if ~isempty(app.DraggingData.StartX)
                    app.updateSelectionAreaEditFieldsLimits();
                %end

                app.refreshSelectionEvaluationText();

                app.createAudioPlayerData();
            end


            function addLatestVersionsToHistory(app)

                for channelNr=1:numel(app.channelAxes)
                    currentChannelYHistory=app.channelYValuesHistory(:,channelNr);
                    currentChannelXHistory=app.channelXValuesHistory(:,channelNr);

                    mostRecentYValues=app.channelYValues{channelNr};
                    mostRecentXValues=app.channelXValues{channelNr};
                   
                    lastNonEmptyEntry=find(~cellfun(@isempty,currentChannelYHistory),1,"last");

                    if ~isequal(mostRecentYValues,currentChannelYHistory{lastNonEmptyEntry}) || ~isequal(mostRecentXValues,currentChannelXHistory{lastNonEmptyEntry})
                        app.addValueSetToValueHistory(mostRecentYValues,mostRecentXValues,channelNr);
                    end
                end

                delete(shifttoolFig);
            end

            
        end

        function openSPLcalculationWindow(app,channelNr)
            targetValue=0;
            persistent splWeighting splSpeed splOutputMetric reportInterval
            if isempty(splWeighting)
                splWeighting='A-weighting';
                splSpeed='Fast';
                splOutputMetric=1;
                reportInterval=1;
            end

            %% create figure
            fig=uifigure('Units','pixels','Position',[0 0 900 300],'Visible','off','Theme','light'); % doubled width
            movegui(fig,'center');

            drawnow;
            fig.Visible='on';

            % Updated grid layout: 12 rows, 3 columns, to fit new axes
            gl=uigridlayout(fig,[6 3],"ColumnWidth",{'1x','1x','2x'},'RowSpacing',1);

            spllabel=uilabel(gl,"Text",'Reference Pressure (Pa)','WordWrap','on');
            spllabel.Layout.Column=1;
            spllabel.Layout.Row=3;
            persistent lastSPLreferenceValue;
            if isempty(lastSPLreferenceValue)
                lastSPLreferenceValue=20e-6;
            end

            editField=uieditfield(gl,'numeric','Limits',[0 inf],'Tooltip','Use 0 or close window if you want to cancel','Value',lastSPLreferenceValue,'ValueDisplayFormat','%g Pa');
            editField.Layout.Column=2;
            editField.Layout.Row=3;
            spltypelabel=uilabel(gl,"Text",'SPL weighting','WordWrap','on');
            spltypelabel.Layout.Column=1;
            spltypelabel.Layout.Row=1;
            dBtypeDropdown=uidropdown(gl,"Items",{'dB(A)','dB(C)','dB(Z)'},'ItemsData',{'A-weighting','C-weighting','Z-weighting'},'Value',splWeighting);
            dBtypeDropdown.Layout.Column=2;
            dBtypeDropdown.Layout.Row=1;
            splSpeedlabel=uilabel(gl,"Text",'SPL time constant','WordWrap','on');
            splSpeedlabel.Layout.Column=1;
            splSpeedlabel.Layout.Row=2;
            dBSpeedDropdown=uidropdown(gl,'Items',{'Fast (125ms)','Slow (1s)'},"ItemsData",{'Fast','Slow'},'Value',splSpeed);
            dBSpeedDropdown.Layout.Column=2;
            dBSpeedDropdown.Layout.Row=2;
            sploutputlabel=uilabel(gl,"Text",'Output Metric','WordWrap','on');
            sploutputlabel.Layout.Column=1;
            sploutputlabel.Layout.Row=4;
            metricList={'Time-weighted sound level','Equivalent-continuous sound level','Peak sound level','Maximum time-weighted sound level'};
            dBoutputmetricDropdown=uidropdown(gl,"Items",metricList,'ItemsData',{1 2 3 4},'Value',splOutputMetric);
            dBoutputmetricDropdown.Layout.Column=2;
            dBoutputmetricDropdown.Layout.Row=4;

            vis=splOutputMetric~=1;
            sploutputintervallabel=uilabel(gl,"Text",'Report interval (s)','WordWrap','on','Visible',vis);
            sploutputintervallabel.Layout.Column=1;
            sploutputintervallabel.Layout.Row=5;
            dBoutputmetricIntervalEditField=uieditfield(gl,'numeric','Visible',vis,'Limits',[0 range(app.channelXValues{channelNr})],'LowerLimitInclusive','off','ValueDisplayFormat','%g s','Value',reportInterval);
            dBoutputmetricIntervalEditField.Layout.Column=2;
            dBoutputmetricIntervalEditField.Layout.Row=5;


            applyButton=uibutton(gl,'Text','apply','ButtonPushedFcn',@saveAndClose);
            applyButton.Layout.Column=2;
            applyButton.Layout.Row=6;

            pnl=uipanel(gl,"Title",'','BorderType','none','Visible','off');
            pnl.Layout.Column=3;
            pnl.Layout.Row=[1 6];

            tl=tiledlayout(pnl,2,1,'Padding','compact','TileSpacing','compact');
            % tl.Layout.Row=[1 6];
            % tl.Layout.Column=3;

            % Add two new axes to third column: upper 3 rows for original data, lower 6 rows for SPL curve
            % axOriginal = uiaxes(gl);
            % axOriginal.Layout.Row = [1 3];
            % axOriginal.Layout.Column = 3;
            axOriginal = nexttile(tl,1);
            plot(axOriginal, app.channelXValues{channelNr}, app.channelYValues{channelNr});
            title(axOriginal, 'Original Data');
            xlabel(axOriginal,'time (s)');

            % axSPL = uiaxes(gl);
            % axSPL.Layout.Row = [4 6];
            % axSPL.Layout.Column = 3;
            axSPL = nexttile(tl,2);
            % Initial empty plot for SPL
            axSPL.Title.String = 'SPL Curve';


            linkaxes([axSPL axOriginal],'x');

            % Callback function to update SPL curve
            function updateSPLCurve(~,~)
                currYData = app.channelYValues{channelNr};
                currXData = app.channelXValues{channelNr};
                splmeterObj = splMeter('SampleRate', app.channelSampleRates(channelNr), 'TimeWeighting', dBSpeedDropdown.Value, ...
                    'FrequencyWeighting', dBtypeDropdown.Value, 'PressureReference', editField.Value, 'TimeInterval', dBoutputmetricIntervalEditField.Value);
                [A,B,C,D] = splmeterObj(currYData);
                switch dBoutputmetricDropdown.Value
                    case 1
                        newYData = A;
                    case 2
                        newYData = B;
                    case 3
                        newYData = C;
                    case 4
                        newYData = D;
                end
                % Refresh SPL curve plot
                plot(axSPL, currXData, newYData);
                title(axSPL, 'SPL Curve');
            end

            function outputMetricCallback(src,~)
                set([sploutputintervallabel dBoutputmetricIntervalEditField],'Visible',src.Value~=1);
                updateSPLCurve();
            end

            dBoutputmetricDropdown.ValueChangedFcn=@outputMetricCallback;

            % Assign callbacks to update SPL curve on value changes
            editField.ValueChangedFcn = @updateSPLCurve;
            dBtypeDropdown.ValueChangedFcn = @updateSPLCurve;
            dBSpeedDropdown.ValueChangedFcn = @updateSPLCurve;
            updateSPLCurve();  % also update SPL curve plot when metric changes
            dBoutputmetricIntervalEditField.ValueChangedFcn = @updateSPLCurve;

            % Initial plot update
            updateSPLCurve();

            xlabel(axSPL,'time (s)');
            ylabel(axSPL,'SPL')

            drawnow; pnl.Visible='on';

            function saveAndClose(~,~)
                targetValue=editField.Value;
                lastSPLreferenceValue=targetValue;
                splWeighting=dBtypeDropdown.Value;
                splSpeed=dBSpeedDropdown.Value;
                splOutputMetric=dBoutputmetricDropdown.Value;
                reportInterval=dBoutputmetricIntervalEditField.Value;
                delete(fig);
            end

            uiwait(fig);

            %% now do something with the result
            if targetValue==0
                return;
            end
            currYData=app.channelYValues{channelNr};
            currXData=app.channelXValues{channelNr};
            splmeterObj=splMeter('SampleRate',app.channelSampleRates(channelNr),'TimeWeighting',splSpeed,'FrequencyWeighting',splWeighting,"PressureReference",targetValue,'TimeInterval',reportInterval);
            [A,B,C,D]=splmeterObj(currYData);
            switch splOutputMetric
                case 1
                    newYData=A;
                case 2
                    newYData=B;
                case 3
                    newYData=C;
                case 4
                    newYData=D;
            end

            if splOutputMetric==1
                newTitle=[app.channelNames{channelNr} ' ' metricList{splOutputMetric} ' ' splWeighting ' ' splSpeed ' ref ' num2str(targetValue,'%g')];
            else
                newTitle=[app.channelNames{channelNr} ' ' metricList{splOutputMetric} ' ' splWeighting ' ' splSpeed ' ref ' num2str(targetValue,'%g') ' report interval ' num2str(reportInterval,'%g s')];
            end
            app.addSingleChannelData(newYData,currXData,newTitle,'Raw');
        end

        function openFOcalculationWindow(app, channelNr)

            windowClosedByApply=0;

            % Persistent settings
            persistent pitchMethod foMin foMax windowLength overlap
            if isempty(pitchMethod)
                pitchMethod = 'NCF';
                foMin = 80;
                foMax = 500;
                windowLength = 0.03;
                overlap = 0.5;
            end

            fs = app.channelSampleRates(channelNr);
            y = app.channelYValues{channelNr};
            t = app.channelXValues{channelNr};

            % --- Figure & GridLayout ---
            fig = uifigure('Units','pixels','Position',[0 0 900 350],'Visible','off','Name','Pitch (fo) Calculation');
            movegui(fig, 'center');
            drawnow;
            fig.Visible = 'on';
            gl = uigridlayout(fig, [7 3], 'ColumnWidth', {'1x','1x','2x'}, 'RowSpacing', 3, 'Padding', 10);

            % --- Controls ---
            methodLabel = uilabel(gl, 'Text', 'Pitch Method', 'FontWeight', 'bold');
            methodLabel.Layout.Row = 1; methodLabel.Layout.Column = 1;
            mDrop = uidropdown(gl, 'Items',{      'Normalized Correlation Function','Pitch Estimation Filter','Cepstrum Pitch Determination','Log-Harmonic Summation','Summation of Residual Harmonics'},...
                'ItemsData',  {'NCF',                            'PEF',                    'CEP',                         'LHS',                   'SRH'}, 'Value', pitchMethod);
            mDrop.Layout.Row = 1; mDrop.Layout.Column = 2;

            minLabel = uilabel(gl, 'Text', 'Min f_o (Hz)', 'FontWeight', 'bold');
            minLabel.Layout.Row = 2; minLabel.Layout.Column = 1;
            minEdit = uieditfield(gl,'numeric','Value',foMin,'ValueDisplayFormat','%.1f');
            minEdit.Layout.Row = 2; minEdit.Layout.Column = 2;

            maxLabel = uilabel(gl, 'Text', 'Max f_o (Hz)', 'FontWeight', 'bold');
            maxLabel.Layout.Row = 3; maxLabel.Layout.Column = 1;
            maxEdit = uieditfield(gl,'numeric','Value',foMax,'ValueDisplayFormat','%.1f');
            maxEdit.Layout.Row = 3; maxEdit.Layout.Column = 2;

            winLabel = uilabel(gl, 'Text', 'Window Length (s)', 'FontWeight', 'bold');
            winLabel.Layout.Row = 4; winLabel.Layout.Column = 1;
            winEdit = uieditfield(gl,'numeric','Value',windowLength,'Limits',[0.005 0.1],'ValueDisplayFormat','%.3f');
            winEdit.Layout.Row = 4; winEdit.Layout.Column = 2;

            ovLabel = uilabel(gl, 'Text', 'Overlap (% * 100)', 'FontWeight', 'bold');
            ovLabel.Layout.Row = 5; ovLabel.Layout.Column = 1;
            ovEdit = uieditfield(gl,'numeric','Value',overlap,'Limits',[0 0.95],'ValueDisplayFormat','%.2f');
            ovEdit.Layout.Row = 5; ovEdit.Layout.Column = 2;

            applyBtn = uibutton(gl, 'Text', 'Apply', 'FontWeight', 'bold');
            applyBtn.Layout.Row = 6; applyBtn.Layout.Column = 2;

            % --- Plots ---
            pnl = uipanel(gl, 'BorderType', 'none','Visible','off');
            pnl.Layout.Column = 3; pnl.Layout.Row = [1 7];
            tl = tiledlayout(pnl, 2, 1, 'Padding', 'compact', 'TileSpacing', 'compact');
            axOriginal = nexttile(tl, 1);
            plot(axOriginal,t,y); title(axOriginal,'Original Data'); xlabel(axOriginal,'Time (s)');
            axPitch = nexttile(tl, 2);
            title(axPitch,'Pitch (fo) Contour'); xlabel(axPitch,'Time (s)'); ylabel(axPitch,'Frequency (Hz)');
            linkaxes([axOriginal axPitch],'x');

            xlabel(axOriginal,'time (s)');

            % --- Helper for valid pitch ranges ---
            function [vMin,vMax] = getValidRange(method,fs,winLen)
                if winLen <= 0, winLen = 0.01; end
                switch method
                    case 'NCF'
                        vMin = fs/winLen;   vMax = fs/2;
                    case 'PEF'
                        vMin = 10;          vMax = min(4000,fs/2);
                    case 'CEP'
                        nPow = 2^nextpow2(2*round(winLen*fs)-1);
                        if nPow <= 0, nPow = 128; end
                        vMin = fs/nPow;     vMax = fs/2;
                    case {'LHS','SRH'}
                        vMin = 1;           vMax = fs/5-1;
                    otherwise
                        vMin = 10;          vMax = fs/2;
                end
                % Ensure valid numbers and order
                if ~isfinite(vMin) || ~isfinite(vMax) || isnan(vMin) || isnan(vMax) || vMin >= vMax
                    vMin = 10;
                    vMax = fs/2;
                end
            end

            function updateValidRangeAndInputs(~,~)
                method = mDrop.Value;
                winLen = winEdit.Value;
                [validMin, validMax] = getValidRange(method, fs, winLen);
                minEdit.Limits = [validMin, validMax];
                maxEdit.Limits = [validMin, validMax];
                if minEdit.Value < validMin, minEdit.Value = validMin; end
                if minEdit.Value > validMax, minEdit.Value = validMax; end
                if maxEdit.Value > validMax, maxEdit.Value = validMax; end
                if maxEdit.Value < validMin, maxEdit.Value = validMin; end
                if minEdit.Value >= maxEdit.Value
                    maxEdit.Value = minEdit.Value + 1;
                    if maxEdit.Value > validMax, maxEdit.Value = validMax; end
                end
                updatePitch();
            end

            function updatePitch(~,~)
                method = mDrop.Value;
                minFq = minEdit.Value; maxFq = maxEdit.Value;
                winLen = winEdit.Value; ovrlp = ovEdit.Value;
                winSamples = round(winLen * fs);
                overlapSamples = round(ovrlp * winSamples);
                try
                    [f0,loc] = pitch(y,fs,'Method',method,'Range',[minFq, maxFq],'WindowLength',winSamples,'OverlapLength',overlapSamples);
                    tPitch = loc / fs;
                    cla(axPitch);
                    plot(axPitch, tPitch, f0, '-o');
                    ylim(axPitch, [minFq maxFq]*1.2);
                    title(axPitch, ['Pitch Contour (' method ')']);
                catch ME
                    cla(axPitch);
                    text(0.5,0.5,['Error: ' ME.message], 'Parent',axPitch, 'HorizontalAlignment','center', 'FontSize',12);
                end
            end

            mDrop.ValueChangedFcn      = @updateValidRangeAndInputs;
            winEdit.ValueChangedFcn    = @updateValidRangeAndInputs;
            minEdit.ValueChangedFcn    = @updateValidRangeAndInputs;
            maxEdit.ValueChangedFcn    = @updateValidRangeAndInputs;
            ovEdit.ValueChangedFcn     = @updatePitch;
            applyBtn.ButtonPushedFcn   = @(src, event) saveAndClose();

            updateValidRangeAndInputs();

            ylabel(axPitch,'fo (Hz)','Interpreter','none');
            xlabel(axPitch,'time (s)');

            drawnow; pnl.Visible='on';

            function saveAndClose()
                pitchMethod   = mDrop.Value;
                foMin         = minEdit.Value;
                foMax         = maxEdit.Value;
                windowLength  = winEdit.Value;
                overlap       = ovEdit.Value;

                windowClosedByApply=1;
                delete(fig);
            end

            uiwait(fig);

            if ~windowClosedByApply || foMin==0 || foMax==0, return; end
            winSamples     = round(windowLength * fs);
            overlapSamples = round(overlap * winSamples);

            [f0,loc] = pitch(y,fs,'Method',pitchMethod,'Range',[foMin,foMax],'WindowLength',winSamples,'OverlapLength',overlapSamples);
            tPitch = loc / fs;
            newTitle = [app.channelNames{channelNr} ' fo (' pitchMethod ') ' ...
                num2str(foMin,'%.1f') ' to ' num2str(foMax,'%.1f Hz') ', Win:' num2str(windowLength,'%.3f s') ', Ovl:' num2str(overlap*windowLength,'%.2f s')];
            app.addSingleChannelData(f0, tPitch, newTitle, 'Raw');
        end


        function convertPaToCmH2O(app,channelNr)
            % Temperaturauswahl
            temps = {'0°C','4°C','10°C','15°C','20°C','25°C','30°C','35°C','37°C','40°C','50°C','60°C','70°C','80°C','90°C','100°C'};
            densities = [999.84, 1000.00, 999.70, 999.10, 998.23, 997.05, 995.65, 994.03, 993.33, 992.22, 988.05, 983.20, 977.76, 971.80, 965.33, 958.36];

        
            [idx, tf] = listdlg('PromptString','Wähle die Wassertemperatur:', ...
                                'SelectionMode','single', ...
                                'ListString',temps,'InitialValue',2);
        
            if ~tf
                return;
            end
        
            rho = densities(idx); % density at chosen temperature
            g = 9.80665;          
        
            newYData = (app.channelYValues{channelNr} / (rho * g)) * 100;

            app.addValueSetToValueHistory(newYData,app.channelXValues{channelNr},channelNr);

            %% save new calibrated values
            app.channelYValues{channelNr}=newYData;
            currXData=app.channelXValues{channelNr};

            app.timePlotHandles(channelNr).YData=newYData;

            drawnow;

            currXLim=xlim(app.channelAxes(channelNr));
            displayMask=currXData>=currXLim(1) & currXData<=currXLim(2);
            newYLimits=[min(newYData(displayMask)) max(newYData(displayMask))]+[-1 1]*0.1*range(newYData(displayMask));
            ylim(app.channelAxes(channelNr),newYLimits);

            if ~isempty(app.DraggingData.CurrentRectangles) && isvalid(app.DraggingData.CurrentRectangles(channelNr))
                app.DraggingData.CurrentRectangles(channelNr).Position([2 4])=[newYLimits(1) newYLimits(2)-newYLimits(1)];
            end
        end
        
        function convertCmH2OtoPa(app,channelNr)
            % Temperature
            temps = {'0°C','4°C','10°C','15°C','20°C','25°C','30°C','35°C','37°C','40°C','50°C','60°C','70°C','80°C','90°C','100°C'};
            densities = [999.84, 1000.00, 999.70, 999.10, 998.23, 997.05, 995.65, 994.03, 993.33, 992.22, 988.05, 983.20, 977.76, 971.80, 965.33, 958.36];

        
            [idx, tf] = listdlg('PromptString','Wähle die Wassertemperatur:', ...
                                'SelectionMode','single', ...
                                'ListString',temps,'InitialValue',2);
        
            if ~tf
                return;
            end
        
            rho = densities(idx); % density at chosen temperature
            g = 9.80665;           
        
            newYData = app.channelYValues{channelNr} * (rho / 100) * g;

            app.addValueSetToValueHistory(newYData,app.channelXValues{channelNr},channelNr);

            %% save new calibrated values
            app.channelYValues{channelNr}=newYData;
            currXData=app.channelXValues{channelNr};

            app.timePlotHandles(channelNr).YData=newYData;

            drawnow;

            currXLim=xlim(app.channelAxes(channelNr));
            displayMask=currXData>=currXLim(1) & currXData<=currXLim(2);
            newYLimits=[min(newYData(displayMask)) max(newYData(displayMask))]+[-1 1]*0.1*range(newYData(displayMask));
            ylim(app.channelAxes(channelNr),newYLimits);

            if ~isempty(app.DraggingData.CurrentRectangles) && isvalid(app.DraggingData.CurrentRectangles(channelNr))
                app.DraggingData.CurrentRectangles(channelNr).Position([2 4])=[newYLimits(1) newYLimits(2)-newYLimits(1)];
            end
        end
        
        function refreshSelectionEvaluationText(app,givenChannelNrs,manualBorders)

            if nargin<2 || isempty(givenChannelNrs)
                givenChannelNrs=1:numel(app.channelAxes);
            end

            for channelNr=givenChannelNrs

                if numel(app.selectionEvaluationTexts)>=channelNr
                    delete(app.selectionEvaluationTexts(channelNr));
                end
    
                if ~isempty(app.DraggingData.StartX)

                    % this is necessary for data calculation during marking
                    if nargin<3 || isempty(manualBorders) 
                        startX=min(app.DraggingData.StartX,app.DraggingData.EndX);
                        endX=max(app.DraggingData.StartX,app.DraggingData.EndX);
                    else
                        startX=manualBorders(1);
                        endX=manualBorders(2);
                    end


                    yDataExcerpt=app.channelYValues{channelNr}(app.channelXValues{channelNr}>=startX & app.channelXValues{channelNr}<=endX);
                else % e.g. when during mark mode it was only clicked, StartX might be empty
                    yDataExcerpt=app.channelYValues{channelNr};
                end

                if isempty(yDataExcerpt)
                    continue;
                end


                evaluations=[mean(yDataExcerpt) median(yDataExcerpt) min(yDataExcerpt) max(yDataExcerpt) range(yDataExcerpt) std(yDataExcerpt) rms(yDataExcerpt)];

                evalToShow = get([app.ShowMeanMenu app.ShowMedianMenu app.ShowMinMenu app.ShowMaxMenu app.ShowRangeMenu app.ShowStandardDeviationMenu app.ShowRootMeanSquareMenu], 'Checked');

                % Corresponding names and values
                evalNames = {'mean', 'median', 'min', 'max', 'range', 'std', 'rms'};
                
                % Build dynamic text string
                textParts = {};
                for i = 1:length(evalToShow)
                    if strcmp(evalToShow{i}, 'on')
                        textParts{end+1} = sprintf('%s: %g', evalNames{i}, evaluations(i));
                    end
                end
                finalText = strjoin(textParts, ', ');
    
                app.selectionEvaluationTexts(channelNr)=text(app.channelAxes(channelNr),1,1,finalText,'HorizontalAlignment','right','VerticalAlignment','top','Units','normalized','Clipping','off','PickableParts','none');

   
            end
            
        end
        
        function [version]=getexeversion(~)
            if isdeployed % Executable in stand-alone mode.
                [~, result] = system('path');
                exedir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                args = System.Environment.GetCommandLineArgs;
                relpath = char(args(1));
                [~,exename,ext] = fileparts(relpath);
                filename = fullfile(exedir,[exename,ext]);

                cmdstr=['powershell -command (Get-Command """',filename,'""").Version'];
                [status,cmdout]=system(cmdstr);
                if status, version=[]; return, end                    % command failed

                version=cell2struct(extract(cmdout,digitsPattern),extract(cmdout,lettersPattern)); % return as fields of struct instead

            end
        end
        
        function HNR_dB = computeHNRbiasedXcorr(~,x)
            % x : input signal (single window)
            
            % Step 1: Remove DC offset
            x = x - mean(x);
            
            % Step 2: Autocorrelation
            r = xcorr(x, 'biased');
            mid = ceil(length(r)/2);
            rxx = r(mid:end); % keep non-negative lags
            
            % Step 3: Find fundamental period (lag of max peak beyond zero)
            [~, T0] = max(rxx(2:end));
            
            % Step 4: Harmonics-to-Noise Ratio
            R0  = rxx(1);        % autocorrelation at lag 0
            RT0 = rxx(T0+1);     % autocorrelation at lag T0
            HNR_dB = 10*log10(RT0 / (R0 - RT0));
        end
        
        function openVADWindow(app, channelNr)
            % Interactive window for voiceActivityDetector probability, time-domain input only

            % Persistent vars for user settings
            persistent SpeechToSilenceProbability SilenceToSpeechProbability Window FrameLengthMs Overlap
            if isempty(SpeechToSilenceProbability)
                SpeechToSilenceProbability = 0.1;
                SilenceToSpeechProbability = 0.2;
                Window = 'hann';
                FrameLengthMs = 30;   % default 30 ms frame length visually
                Overlap = 0.5;
            end

            fs = app.channelSampleRates(channelNr);
            y = app.channelYValues{channelNr};
            t = app.channelXValues{channelNr};

            % Create UI figure and layout
            fig = uifigure('Position',[0 0 900 600],'Name','Voice Activity Probability','Visible','off');
            movegui(fig,'center'); drawnow; fig.Visible = 'on';
            gl = uigridlayout(fig,[6 3],'ColumnWidth',{'1x','1x','2x'},'RowSpacing',3,'Padding',10);

            % Window (nontunable) with descriptive names and internal value mapping
            labelWindow = uilabel(gl,'Text','Window:'); labelWindow.Layout.Row=1; labelWindow.Layout.Column=1;
            windowDisplayNames = {'Flat Top', 'Hamming', 'Hann', 'Kaiser', 'Chebyshev', 'Rectangular'};
            windowInternalNames = {'flat top', 'hamming', 'hann', 'kaiser', 'chebyshev', 'rectangular'};
            dropdownWindow = uidropdown(gl,"Items",windowDisplayNames,"ItemsData",windowInternalNames,'Value',Window);
            dropdownWindow.Layout.Row=1; dropdownWindow.Layout.Column=2;

            % Frame Length in ms (nontunable)
            labelFrameLen = uilabel(gl,'Text','Frame Length [ms]:'); labelFrameLen.Layout.Row=2; labelFrameLen.Layout.Column=1;
            frameLenEdit = uieditfield(gl,'numeric','Value',FrameLengthMs,'Limits',[1 1000*0.5*(t(end)-t(1))],'RoundFractionalValues','off');
            frameLenEdit.Layout.Row=2; frameLenEdit.Layout.Column=2;

            % Overlap slider (0 to 0.95)
            labelOverlap = uilabel(gl,'Text','Overlap (0-0.95):'); labelOverlap.Layout.Row=3; labelOverlap.Layout.Column=1;
            overlapSlider = uislider(gl,'Limits',[0 0.95],'Value',Overlap);
            overlapSlider.Layout.Row=3; overlapSlider.Layout.Column=2;

            % SpeechToSilenceProbability (tunable)
            labelSTSP = uilabel(gl,'Text','SpeechToSilenceProb:'); labelSTSP.Layout.Row=4; labelSTSP.Layout.Column=1;
            stspEdit = uieditfield(gl,'numeric','Value',SpeechToSilenceProbability,'Limits',[0 1],'ValueDisplayFormat','%.2f');
            stspEdit.Layout.Row=4; stspEdit.Layout.Column=2;

            % SilenceToSpeechProbability (tunable)
            labelSTSP2 = uilabel(gl,'Text','SilenceToSpeechProb:'); labelSTSP2.Layout.Row=5; labelSTSP2.Layout.Column=1;
            sptsEdit = uieditfield(gl,'numeric','Value',SilenceToSpeechProbability,'Limits',[0 1],'ValueDisplayFormat','%.2f');
            sptsEdit.Layout.Row=5; sptsEdit.Layout.Column=2;

            % Plot panel and tiled layout
            pnl = uipanel(gl,'BorderType','none'); pnl.Layout.Column=3; pnl.Layout.Row=[1 6];
            tl = tiledlayout(pnl,2,1,'Padding','compact','TileSpacing','compact');
            axOriginal = nexttile(tl,1);
            plot(axOriginal,t,y); title(axOriginal,'Original Data'); xlabel(axOriginal,'Time (s)');
            grid(axOriginal,'on');
            axVAD = nexttile(tl,2);
            title(axVAD,'Voice Activity Probability'); xlabel(axVAD,'Time (s)');
            linkaxes([axOriginal axVAD],'x');


            % Create voiceActivityDetector object
            function VAD = createVAD()
                windowVal = dropdownWindow.Value;
                VAD = voiceActivityDetector(...
                    'Window', windowVal, ...
                    'SpeechToSilenceProbability', stspEdit.Value, ...
                    'SilenceToSpeechProbability', sptsEdit.Value);
            end

            vadObj = createVAD();

            probLine = gobjects(1);

            % Refresh VAD probability plot
            function updateVADPlot(~,~)
                release(vadObj);
                frameLenSamples = round(frameLenEdit.Value * fs / 1000); % ms to samples
                overlapVal = overlapSlider.Value;
                voiceProb = [];
                idx = 1;
                step = max(1, round(frameLenSamples * (1 - overlapVal)));
                while idx + frameLenSamples - 1 <= length(y)
                    segment = y(idx:(idx+frameLenSamples-1));
                    p = vadObj(segment);
                    voiceProb = [voiceProb; p(:)];
                    idx = idx + step;
                end
                tVAD = ((0:length(voiceProb)-1)+0.5)*step/fs;
                cla(axVAD);
                if numel(voiceProb)>1
                    probLine(1) = plot(axVAD,tVAD,voiceProb,'-');
                else
                    probLine(1) = plot(axVAD,tVAD,voiceProb,'-o');
                end
                ylim(axVAD,[-0.1 1.1]);
                xlim(axVAD,[t(1) t(end)]+0.1*[-1 1]*(t(end)-t(1)));
                title(axVAD,'Voice Activity Probability'); xlabel(axVAD,'Time (s)');
                grid(axVAD,'on');
            end

            % Nontunable property changes: recreate object
            function changeNontunable(~,~)
                release(vadObj);
                vadObj = createVAD();
                updateVADPlot();
            end

            % Tunable property changes: update properties only
            function changeTunable(~,~)
                if isprop(vadObj,'SpeechToSilenceProbability'), vadObj.SpeechToSilenceProbability = stspEdit.Value; end
                if isprop(vadObj,'SilenceToSpeechProbability'), vadObj.SilenceToSpeechProbability = sptsEdit.Value; end
                updateVADPlot();
            end

            % Assign callbacks
            dropdownWindow.ValueChangedFcn = @changeNontunable;
            frameLenEdit.ValueChangedFcn = @updateVADPlot;
            overlapSlider.ValueChangedFcn = @updateVADPlot;
            stspEdit.ValueChangedFcn = @changeTunable;
            sptsEdit.ValueChangedFcn = @changeTunable;

            updateVADPlot();

            set([axVAD axOriginal],'Interactions',[zoomInteraction panInteraction]);

            % Apply and close button
            applyBtn = uibutton(gl,'Text','Apply','FontWeight','bold');
            applyBtn.Layout.Row = 6; applyBtn.Layout.Column = 2;

            closedByApply = false;
            outX = [];
            outY = [];

            function saveAndClose()
                SpeechToSilenceProbability = stspEdit.Value;
                SilenceToSpeechProbability = sptsEdit.Value;
                Window = dropdownWindow.Value;
                FrameLengthMs = frameLenEdit.Value;
                Overlap = overlapSlider.Value;
                closedByApply = true;

                outX = probLine.XData;
                outY = probLine.YData;
                delete(fig);
            end

            applyBtn.ButtonPushedFcn = @(~,~) saveAndClose();
            uiwait(fig);

            if ~closedByApply
                return;
            end

            newTitle = sprintf('%s Voice Activity Probability speech-to-silence prob %.2f, silence-to-speech prob %.2f, window type %s, frame length %.1f ms, overlap %.1f%%',...
                app.channelNames{channelNr}, SpeechToSilenceProbability, SilenceToSpeechProbability, Window, FrameLengthMs, Overlap*100);

            app.addSingleChannelData(outY, outX, newTitle, 'Raw');
        end


        function updateAudioPlayerIndicator(app,src,evt)

            delete(app.playbackPositionXLines);

            app.playbackPositionXLines=gobjects(numel(app.channelAxes),1);

            if ~isempty(app.DraggingData.StartX)
                time=src.CurrentSample/src.SampleRate+app.DraggingData.StartX;
            else
                time=src.CurrentSample/src.SampleRate;
            end
            for channelNr=1:numel(app.channelAxes)
                app.playbackPositionXLines(channelNr)=xline(app.channelAxes(channelNr),time,'-k');
            end
            
            drawnow limitrate;
            
        end

        function openCWTWindow(app,channelNr)

            % if ~isdeployed
            %     addpath('fCWT\MATLAB');
            % end

            data=single(app.channelYValues{channelNr});

            data=data(:).';

            time=app.channelXValues{channelNr};

            if ~isempty(app.DraggingData.StartX)
                excerpt=time>= app.DraggingData.StartX & time<=app.DraggingData.EndX;
                time=time(excerpt);
                data=data(excerpt);
            end

            
            fs=app.channelSampleRates(channelNr);
            
            fig=uifigure('Units','normalized','Position',[0.2 0.2 0.6 0.6],'Name','CWT window');

            gl=uigridlayout(fig,[6 4],'Padding',[0 0 0 0],'RowSpacing',5,'ColumnSpacing',5);

            panel=uipanel(gl,"BorderType","none");
            panel.Layout.Row=[1 4];
            panel.Layout.Column=[1 4];

            persistent initalStartFreq initialEndFreq initialSmoothingOption initialZScale

            if isempty(initialEndFreq) % default for first loading
                initalStartFreq=100;
                initialEndFreq=2000;
                initialSmoothingOption=1;
                initialZScale=0;
            end

            startFreqLabel=uilabel(gl,"Text",'Start frequency (Hz)');
            startFreqLabel.Layout.Row=5;
            startFreqLabel.Layout.Column=1;

            startFreqEditField=uieditfield(gl,'numeric','Limits',[0.1 fs/2],'Value',initalStartFreq);
            startFreqEditField.Layout.Row=5;
            startFreqEditField.Layout.Column=2;


            endFreqLabel=uilabel(gl,"Text",'Start frequency (Hz)');
            endFreqLabel.Layout.Row=5;
            endFreqLabel.Layout.Column=3;

            endFreqEditField=uieditfield(gl,'numeric','Limits',[0.1 fs/2],'Value',initialEndFreq);
            endFreqEditField.Layout.Row=5;
            endFreqEditField.Layout.Column=4;

            normalizeAlongFreqCheckbox=uicheckbox(gl,'Text','Normalize along frequency axis','WordWrap','on','Value',initialSmoothingOption);
            normalizeAlongFreqCheckbox.Layout.Row=6;
            normalizeAlongFreqCheckbox.Layout.Column=1;

            useLogarithmicZScaleCheckbox=uicheckbox(gl,'Text','use logarithmic z-scale','WordWrap','on','Value',initialZScale);
            useLogarithmicZScaleCheckbox.Layout.Row=6;
            useLogarithmicZScaleCheckbox.Layout.Column=2;


            set([startFreqEditField endFreqEditField],'ValueChangedFcn',@(~,~)refreshCWT());

            set([normalizeAlongFreqCheckbox useLogarithmicZScaleCheckbox],'ValueChangedFcn',@(~,~)toggleZScaleAndNormalization());


            tl=tiledlayout(panel,2,1,"Padding","tight","TileSpacing","compact");

            ax1=nexttile(tl,1);
            plot(ax1,time,data,'Color',app.timePlotHandles(channelNr).Color);
            title(ax1,app.channelNames{channelNr},'Interpreter','none');
            xlabel(ax1,'time (s)');
            grid(ax1,'on');

            ax2=nexttile(tl,2);


            linkaxes([ax1 ax2],'x');

            cwtData=[];
            imageObj=[];


            %fCWT_create_plan(length(data),1,'measure');

            refreshCWT();

            axis(ax2,'tight');

            function refreshCWT()

                startFreq=min([startFreqEditField.Value endFreqEditField.Value]);
                endFreq=max([startFreqEditField.Value endFreqEditField.Value]);

                %octaves=log2(endFreq/startFreq);
                voicesPerOctave=48;

                %evalPoints=octaves*voicesPerOctave;

                % [CWTvals,fcwtf] = fCWT(data,5,fs,startFreq,endFreq,evalPoints,1);
                % 
                % clear mex;

                [CWTvals,fcwtf] = cwt(data,fs,'VoicesPerOctave',voicesPerOctave,'FrequencyLimits',[startFreq endFreq],'TimeBandwidth',120);

                absCWT=abs(CWTvals);

                cwtData=absCWT;

                if normalizeAlongFreqCheckbox.Value==1
                    absMaxVals=max(absCWT);
                    absMaxValsSmooth=movmedian(absMaxVals,round(0.01*fs));

                    absCWT=absCWT./absMaxValsSmooth;
                end

                if useLogarithmicZScaleCheckbox.Value==1
                    absCWT=20*log10(absCWT);
                end

                imageObj=imagesc(ax2,time,fcwtf,absCWT);
                set(ax2,'YDir','normal');
                yscale(ax2,'log');

                xlabel(ax2,'time (s)');
                ylabel(ax2,'frequency (Hz)');

                initalStartFreq=startFreq;
                initialEndFreq=endFreq;
                initialSmoothingOption=normalizeAlongFreqCheckbox.Value;
                initialZScale=useLogarithmicZScaleCheckbox.Value;


            end

            function toggleZScaleAndNormalization()
                absCWT=cwtData;
                if normalizeAlongFreqCheckbox.Value==1
                    absMaxVals=max(absCWT);
                    absMaxValsSmooth=movmedian(absMaxVals,round(0.01*fs));

                    absCWT=absCWT./absMaxValsSmooth;
                end

                if useLogarithmicZScaleCheckbox.Value==1
                    absCWT=20*log10(absCWT);
                end


                imageObj.CData=absCWT;


            end
        end


        function openResamplingApp(app,channelNr)

            y=app.channelYValues{channelNr};
            x=app.channelXValues{channelNr};
            originalFs=app.channelSampleRates(channelNr);

            fig=uifigure('Units','normalized','Position',[0.25 0.05 0.5 0.85]);

            gl=uigridlayout(fig,[7 2]);

            panel=uipanel(gl,'BorderType','none');
            panel.Layout.Row=[1 4];
            panel.Layout.Column=[1 2];

            tl=tiledlayout(panel,2,1,'TileSpacing','compact','Padding','tight');
            ax1=nexttile(tl,1);
            line1=plot(ax1,x,y,'Color',app.timePlotHandles(channelNr).Color);
            title(ax1,[app.channelNames{channelNr};{['Sample Rate ' num2str(originalFs,'%i') ' Hz']}],'Interpreter','none');
            xlabel(ax1,'time (s)');

            ax2=nexttile(tl,2);
            line2=plot(ax2,x,y,'Color',app.timePlotHandles(channelNr).Color);
            title(ax2,[app.channelNames{channelNr};{['resampled at ' num2str(originalFs,'%i') ' Hz']}],'Interpreter','none');
            xlabel(ax1,'time (s)');

            linkaxes([ax1 ax2]);

            newSampleRateLabel=uilabel(gl,"Text",'New Sample Rate');
            newSampleRateLabel.Layout.Row=5;
            newSampleRateLabel.Layout.Column=1;

            newSampleRateEditField=uieditfield(gl,'numeric','Value',originalFs,'Limits',[1 inf],'RoundFractionalValues','on','ValueDisplayFormat','%i');
            newSampleRateEditField.Layout.Row=5;
            newSampleRateEditField.Layout.Column=2;

            doNoSamplingCheckbox=uicheckbox(gl,"Value",0,'Text','Just change time vector without proper resampling','Tooltip','Useful e.g. when the GAW was saved with the wrong sampling rate, e.g. 100kHz instead of 20kHz but the data are in reality in 20kHz. WARNING: this can introduce aliasing when used inproperly!');
            doNoSamplingCheckbox.Layout.Row=6;
            doNoSamplingCheckbox.Layout.Column=[1 2];

            useNewDataButton=uibutton(gl,'Text','Apply',"BackgroundColor",[245, 182, 66]./255,'Enable','on','ButtonPushedFcn',@(~,~) applyAndClose());
            useNewDataButton.Layout.Row=7;
            useNewDataButton.Layout.Column=2;

            set([doNoSamplingCheckbox newSampleRateEditField],'ValueChangedFcn',@(~,~) refreshResampledData());


            %fig.CloseRequestFcn=@(~,~) uiresume(app.UIFigure);
            %uiwait(app.UIFigure);

            %waitfor(fig);


            function refreshResampledData()
                newFs=newSampleRateEditField.Value;
                title(ax2,[app.channelNames{channelNr};{['resampled at ' num2str(newFs,'%i') ' Hz']}]);
                % if newFs==originalFs
                %     useNewDataButton.Enable='off';
                % else
                %     useNewDataButton.Enable='on';
                % end

                if doNoSamplingCheckbox.Value==0
                    [newY,newX]=resample(y,x,newFs);
                else
                    newY=y;
                    newX=((0:numel(y)-1)./newFs)+x(1);
                end
                set(line2,'XData',newX(:),'YData',newY(:));

                drawnow;


            end

            function applyAndClose()
                newY=line2.YData;
                newX=line2.XData;

                if isequal(app.channelYValues{channelNr},newY) && isequal(app.channelXValues{channelNr},newX)
                    close(fig);
                    return;
                end

                app.channelYValues{channelNr}=newY;

                app.channelXValues{channelNr}=newX;
                app.channelSampleRates(channelNr)=newSampleRateEditField.Value;

                set(app.timePlotHandles(channelNr),'XData',newX,'YData',newY);

                app.addValueSetToValueHistory(newY,newX,channelNr);

                
                close(fig);

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.tlTime=tiledlayout(app.plottingPanel,'vertical','TileSpacing','tight','Padding','tight','Visible','on');

            app.availableChannelsTable.Data={};
            app.availableChannelsTable.ColumnFormat= {[], app.channelSourcePossibilities};

            app.ResultsTab.Parent=[];

            %drawnow;
            %pause(0.5);
           
            set([app.plottingPanelContainer app.plottingPanel],'Units','normalized');
            app.plottingPanel.OuterPosition=[0 0 0.97 1];

            drawnow;
            set(app.tlTime,'Units','normalized','OuterPosition',[0 0 1 1]);

            set(app.UIFigure,'Units','Normalized','Position',[0 0.05 1 0.9]);
            fontsize(app.UIFigure,'Scale',1.2);

            app.titleTimer=timer('ExecutionMode','fixedSpacing','Period',1,'TimerFcn',@(~,~) app.updateChannelName()); %
            app.titleTimer.start();

            set(0, 'DefaultLineLineWidth', 1);
        end

        % Menu selected function: ImportappendchanneldatafromfilesMenu
        function importDataFromFile(app, event)
            persistent lastPath;

            listOfFormatsForAudioread=';*.aifc;*.aiff;*.aif;*.au;*.flac;*.ogg;*.opus;*.wav;*.mp3;*.m4a;*.mp4';
            
            fakefig=uifigure('Visible','off');
            [files,path,idx] = uigetfile( ...
                {['*.gaw;*.csv;*.wvd;*.mat;*.wav' listOfFormatsForAudioread],'gaw or photron csv (type A)/wvd import, Vocaliscope .mat files, audio files, audio from mp4'; ...
                '*.*','any other file'}, ...
                'Select one or more files',lastPath,'MultiSelect','on');
            close(fakefig);

            if isequal(files,0)
                disp('file import aborted by user');
                return;
            end
            lastPath=path;

            % if only one file was selected, convert from char array to cell string
            if ischar(files)
                files={files};
            end

            app.titleTimer.stop();

            if idx==1 % if mixed audio, photron csv type A, or photron wvd
                for fileNr=1:numel(files)
                    currFile=[path files{fileNr}];
                    [~,~,currExt]=fileparts(currFile);

                    %% import audio files
                    if ismember(currExt,strsplit(listOfFormatsForAudioread,';*'))

                        info=audioinfo(currFile);

                        [channelTypeNames,doAbort]=app.createChooseWAVchannelTypesFigure(currFile,info.NumChannels);
                        
                        if doAbort%~answerValid
                            disp('User aborted answer. Skipping file');
                            continue;
                        end

                        [newdata,fs]=audioread(currFile,[1 inf],"double");
                        numberOfNewChannelsInThisFile=size(newdata,2);
                        newdataChannelSources=channelTypeNames;%repelem(app.channelSourcePossibilities(answerIdx),numberOfNewChannelsInThisFile);
                        newdataSampleRates=repelem(fs,numberOfNewChannelsInThisFile);

                        if info.NumChannels>1
                            newdataChannelNames=append(files(fileNr),' channel ',strsplit(num2str(1:numberOfNewChannelsInThisFile,'%02i ')));
                        else
                            newdataChannelNames=files(fileNr);
                        end


                    %% import photron wvd
                    elseif strcmpi(currExt,'.wvd')
                        [newdata,meta]=app.importPhotronWVD(currFile,'double');
                        newdata=newdata'; % originally the data is given rowwise, so convert to columnwise
                        meta.channelName=regexprep(meta.channelName,char(0),'');
                        fs=meta.sampleRate;

                        numberOfNewChannelsInThisFile=size(newdata,2);
                        newdataSampleRates=repelem(fs,numberOfNewChannelsInThisFile);
                        
                        
                        % check if found channel names are part of the
                        % predefined channel name list
                        % each row is one channel, each column one of the
                        % possibilities in whereToFind
                        whereToFind=false(numberOfNewChannelsInThisFile,numel(app.channelSourcePossibilities));
                        for channelPossNr=1:numel(app.channelSourcePossibilities)
                            whereToFind(:,channelPossNr)=contains(meta.channelName,app.channelSourcePossibilities{channelPossNr});
                        end
                        whereToFind(~any(whereToFind,2),contains(app.channelSourcePossibilities,'Raw','IgnoreCase',true))=true;

                        % maybe better with user input?
                        [row,~]=find(whereToFind'); 
                        newdataChannelSources=app.channelSourcePossibilities(row);

                        % do not use channel x, but the types here
                        newdataChannelNames=append(files(fileNr),' channel ',meta.channelName);

                    %% import photron type A csv
                    elseif strcmpi(currExt,'.csv')
                        [meta,data]=app.readPhotronGraphExportData(currFile);

                        newdata=data(:,4:end);

                        meta(cellfun(@(in) isempty(in) || ~ischar(in),meta(:,1)),1)={''};
                        fs=meta{contains(meta(:,1),'SamplingRate'),3};
                        newdataChannelNames=append(files(fileNr),' channel ',meta(end,4:end));

                        numberOfNewChannelsInThisFile=numel(newdataChannelNames);
                        whereToFind=false(numberOfNewChannelsInThisFile,numel(app.channelSourcePossibilities));
                        for channelPossNr=1:numel(app.channelSourcePossibilities)
                            whereToFind(:,channelPossNr)=contains(newdataChannelNames,app.channelSourcePossibilities{channelPossNr});
                        end
                        whereToFind(~any(whereToFind,2),contains(app.channelSourcePossibilities,'Raw','IgnoreCase',true))=true;

                        % maybe better with user input?
                        [row,~]=find(whereToFind'); 
                        newdataChannelSources=app.channelSourcePossibilities(row);
                        newdataSampleRates=repelem(fs,numberOfNewChannelsInThisFile);

                    %% import vocaliscope mat file
                    elseif strcmpi(currExt,'.mat')

                        availableVars=who(matfile(currFile));
                        if ~all(ismember({'names','yhistory'},availableVars))
                            continue; % skip mat file if it does not contain the e.g. the history variable and the names variable
                        end
                    
                        
                        load(currFile,'sources','names');
                        newdataChannelSources=sources;
                        newdataChannelNames=names;

                        numberOfNewChannelsInThisFile=numel(sources);

                        answerLoadHistory = questdlg({'Use this only for post Version 2.5 Vocaliscope mat files.' 'Before 2.6 there can occur errors regarding association of channel data and channel name/type when there were channels with more than 1 unique sample rate.' 'Version 2.6 was released on 29.10.2025 if you want to check .mat creation date.' 'Either way you can still use this file to restore channel names and types after loading from e.g. from wav files.' 'Continue?'}, ...
	                        'Ask for version of mat file', ...
	                        'Skip file','Continue','Continue');

                        if isempty(answerLoadHistory) || strcmpi(answerLoadHistory,'Skip file')
                            continue;
                        end

                        load(currFile,'yhistory');

                        nonemptyEntries=~cellfun(@isempty,yhistory);

                        yhistory(~any(nonemptyEntries,2),:)=[];
                        nonemptyEntries(~any(nonemptyEntries,2),:)=[];

                        lastNonEmpty=arrayfun(@(c) find(nonemptyEntries(:,c), 1, 'last'), 1:size(nonemptyEntries,2));
                        newdata= yhistory(sub2ind(size(yhistory), lastNonEmpty , 1:size(yhistory,2)));


                    %% import GAW
                    elseif strcmpi(currExt,'.gaw')
                        out=readmatrix(currFile,'FileType','text','Range','B:C');
                        out(1,:)=[]; % header lines
                        newdataChannelSources=app.channelSourcePossibilities(contains(app.channelSourcePossibilities,'GAW','IgnoreCase',true));
                        newdataChannelNames=files(fileNr);
                        newdataSampleRates=round(1/mean(diff(out(:,1))));
                        numberOfNewChannelsInThisFile=1;
                        newdata=out(:,2);

                    end

                    if numel(app.channelLag)>0
                        answer=uiconfirm(app.UIFigure,{'Here you can choose to import data as standalone channels or append them to existing channels'...
                            'File Path:' currFile},...
                        'Standalone or append',"Options",["Standalone","Append","Cancel import"], ...
                        "DefaultOption",1,"CancelOption",3,'Icon','Question');
                    else
                        answer='Standalone';
                    end

                    switch answer
                        case 'Cancel import'
                            return;
                        case 'Append'
                            [sourceChannelNr,targetChannelNr] = app.append_channel_assignment(numberOfNewChannelsInThisFile, size(app.availableChannelsTable.Data,1));

                            for appendNr=1:numel(sourceChannelNr)

                                if ~strcmpi(currExt,'.mat')
                                    appendedT=(1:size(newdata,1))/app.channelSampleRates(targetChannelNr(appendNr));
                                    appendedT=appendedT+app.channelXValues{targetChannelNr(appendNr)}(end);
    
                                    app.channelXValues{targetChannelNr(appendNr)}=[app.channelXValues{targetChannelNr(appendNr)}(:);...
                                                                                    appendedT(:)];
                                    app.channelYValues{targetChannelNr(appendNr)}=[app.channelYValues{targetChannelNr(appendNr)}(:);...
                                                                                    newdata(:,sourceChannelNr(appendNr))];
                                else
                                    % this line is different
                                    appendedT=(1:numel(newdata{sourceChannelNr(appendNr)}))/app.channelSampleRates(targetChannelNr(appendNr));
                                    appendedT=appendedT+app.channelXValues{targetChannelNr(appendNr)}(end);
                                    app.channelXValues{targetChannelNr(appendNr)}=[app.channelXValues{targetChannelNr(appendNr)}(:);...
                                                                                    appendedT(:)];
                                    % and this line is different
                                    app.channelYValues{targetChannelNr(appendNr)}=[app.channelYValues{targetChannelNr(appendNr)}(:);...
                                                                                    newdata{sourceChannelNr(appendNr)}(:)];
                                end

                                set(app.timePlotHandles(targetChannelNr(appendNr)),'XData',app.channelXValues{targetChannelNr(appendNr)},...
                                                                                   'YData',app.channelYValues{targetChannelNr(appendNr)});


                                app.addValueSetToValueHistory(app.channelYValues{targetChannelNr(appendNr)},app.channelXValues{targetChannelNr(appendNr)},targetChannelNr(appendNr));


                            end
                            xlim(app.channelAxes,[min(cellfun(@(in) in(1),app.channelXValues)) max(cellfun(@(in) in(end),app.channelXValues))]);


                            return;

                        otherwise % normal import
                           

                            if ~strcmpi(currExt,'.mat')
                                % replace NaN values

                                nanEntries=isnan(newdata);
    
                                if any(nanEntries(:))
    
                                    fillMethodShort={'nearest','linear','spline','makima','pchip'};
                                    fillMethodLong={'nearest non-NaN','linear interpoation','spline interpolation','Modified Akima cubic Hermite interpolation','piecewise cubic interpolation'};
                                    [answeridx,validanswer]=listdlg("PromptString",{['There are NaN values in import channels ' strjoin(strsplit(num2str(find(any(nanEntries,1)))),', ')  '.'] 'Choose replacement method:'},'Name','NaN found','SelectionMode','single','ListString',fillMethodLong,'CancelString','Stop Data Import','OKString','Apply','ListSize',[320 150]);
    
    
                                    if ~validanswer
                                        return;
                                    end
    
                                    newdata=fillmissing(newdata,fillMethodShort{answeridx},1);
    
                                    message='There were NaN values, those were replaced with nearest non-NaN value.';
                                    disp(message)
    
    
                                end

                            else

                            end


                            %% add new data to property

                            if ~strcmpi(currExt,'.mat')
                                newYValues=mat2cell(newdata, size(newdata, 1), ones(1, numberOfNewChannelsInThisFile));
                                app.channelYValues=[app.channelYValues newYValues];
                                app.channelXValues=[app.channelXValues mat2cell(repmat((0:size(newdata,1)-1)'/newdataSampleRates(1),[1 numberOfNewChannelsInThisFile]), size(newdata, 1), ones(1, numberOfNewChannelsInThisFile))];
                                app.channelNames=[app.channelNames newdataChannelNames];
                                app.channelSource=[app.channelSource newdataChannelSources];
                                app.channelSampleRates=[app.channelSampleRates newdataSampleRates];
                                app.channelLag=[app.channelLag zeros(1,numberOfNewChannelsInThisFile)];
            
                                app.channelYValuesHistory(1,end+1:end+numberOfNewChannelsInThisFile) = newYValues;
                                app.channelXValuesHistory(1,end+1:end+numberOfNewChannelsInThisFile) = mat2cell(repmat((0:size(newdata,1)-1)'/newdataSampleRates(1),[1 numberOfNewChannelsInThisFile]), size(newdata, 1), ones(1, numberOfNewChannelsInThisFile));

                            else
                                newYValues=newdata;
                                app.channelYValues=[app.channelYValues newYValues];

                                load(currFile,'xhistory');
                                nonemptyEntries=~cellfun(@isempty,xhistory);
                                xhistory(~any(nonemptyEntries,2),:)=[];
                                nonemptyEntries(~any(nonemptyEntries,2),:)=[];
                                lastNonEmpty=arrayfun(@(c) find(nonemptyEntries(:,c), 1, 'last'), 1:size(nonemptyEntries,2));


                                newxdata= xhistory(sub2ind(size(xhistory), lastNonEmpty , 1:size(xhistory,2)));

                                app.channelXValues=[app.channelXValues newxdata];
                                app.channelNames=[app.channelNames newdataChannelNames];
                                app.channelSource=[app.channelSource newdataChannelSources];
                                newdataSampleRates=cellfun(@(in) round(1/min(diff(in))),newxdata);
                                app.channelSampleRates=[app.channelSampleRates newdataSampleRates];
                                app.channelLag=[app.channelLag cellfun(@(in) in(1),newxdata)];

                                if numel(app.channelYValuesHistory)>0
                                    if size(yhistory,1)>size(app.channelYValuesHistory,1)
                                        app.channelXValuesHistory(end+1:(end+size(yhistory,1)-size(app.channelYValuesHistory,1)),:)=cell(1);
                                        app.channelYValuesHistory(end+1:(end+size(yhistory,1)-size(app.channelYValuesHistory,1)),:)=cell(1);
                                    elseif size(yhistory,1)<size(app.channelYValuesHistory,2)
                                        xhistory(end+1:(end+size(app.channelYValuesHistory,1)-size(yhistory,1)),:)=cell(1);
                                        yhistory(end+1:(end+size(app.channelYValuesHistory,1)-size(yhistory,1)),:)=cell(1);
                                    end
                                    app.channelYValuesHistory=[app.channelYValuesHistory yhistory];
                                    app.channelXValuesHistory=[app.channelXValuesHistory xhistory];
                                else
                                    app.channelYValuesHistory=yhistory;
                                    app.channelXValuesHistory=xhistory;
                                end

                            end
                    end

                    
                end
                

            elseif idx==2 % not audio, not gaw, not wvd, not photron csv type A
                disp('generic files not yet supported')
                return;


            end

            figure(app.UIFigure);


            app.availableChannelsTable.Data=[app.channelNames(:) app.channelSource(:)];
            app.availableChannelsTable.Selection=1:numel(app.channelNames);

            if isempty(app.availableChannelsTable.Data)
                app.titleTimer.start();
                return;
            end

            app.refreshTimePlotData();

            %drawnow;
            %xlim(app.channelAxes,'tight');

           % drawnow;
            xlim(app.channelAxes,[min(cellfun(@(in) in(1),app.channelXValues)) max(cellfun(@(in) in(end),app.channelXValues))]);

            %% set selection edit field values
            %app.updateSelectionAreaEditFieldsValues(); % already included
            %in refreshTimePlotData

            if ~isempty(app.DraggingData.StartX)
                app.updateSelectionAreaEditFieldsLimits();
            end

            set([app.SelectedAreaDurationEditField app.SelectedAreaEndEditField app.SelectedAreaStartEditField],'Editable','on');
            

            if strcmpi(currExt,'.mat')
                try % added in later version (since 2.5), so we try it
                    load(currFile,'selectionEnd',"selectionStart");
                    app.DraggingData.StartX=selectionStart;
                    app.DraggingData.EndX=selectionEnd;
    
                    delete(app.DraggingData.CurrentRectangles);
                    app.DraggingData.CurrentRectangles=gobjects(1,numel(app.channelAxes));
    
                    for channelNr=1:numel(app.channelAxes)
                        yMin=min(app.channelYValues{channelNr})-0.1*range(app.channelYValues{channelNr});
                        yMax=max(app.channelYValues{channelNr})+0.1*range(app.channelYValues{channelNr});
                        app.DraggingData.CurrentRectangles(channelNr) = rectangle(app.channelAxes(channelNr), ...
                                'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                                'FaceColor', [0.8, 0.8, 1, 0.5], ...
                                'FaceAlpha',0.5,...
                                'EdgeColor', 'none', ...
                                'PickableParts', 'none');
                        uistack(app.DraggingData.CurrentRectangles(channelNr),'bottom');
                    end
    
                    app.SelectedAreaStartEditField.Value=selectionStart;
                    app.SelectedAreaEndEditField.Value=selectionEnd;
                    app.SelectedAreaDurationEditField.Value=selectionEnd-selectionStart;
                    
                    app.createAudioPlayerData();
                    
                end


            end
            app.titleTimer.start();
        end

        % Button pushed function: CalculateParametersButton
        function CalculateParametersButtonPushed(app, event)
            %% open settings and let the user chosse settings
            if isempty(app.channelYValues)
                return;
            end
            settingsApp=VocaliscopeCalculationSettings();
            settingsApp.loadAppState();

            settingsApp.channelsSuitableAsMask=find(cellfun(@(in) isequal(unique(in(:)),[0; 1]),app.channelYValues));
            settingsApp.checkIfAnyChannelCanBeUsedAsMask();

            settingsApp.UIFigure.Theme='light';

            %% get start and end times of selection if possible
            if isempty(app.DraggingData.StartX) || app.DraggingData.StartX==app.DraggingData.EndX
                selectionStart=max(cellfun(@(in) in(1),app.channelXValues));
                selectionEnd=min(cellfun(@(in) in(end),app.channelXValues));
            else
                selectionStart=app.DraggingData.StartX;
                selectionEnd=app.DraggingData.EndX;
            end

            % the maxmimum window size equal to the selected area duration
            settingsApp.setMaximumWindowLength(selectionEnd-selectionStart);
            %settingsApp.UIFigure.WindowStyle='modal';
            drawnow;
            waitfor(settingsApp,'outputSettings');
            if isvalid(settingsApp) % if settings were closed by preview or calculation button
                app.calculationSettings=settingsApp.outputSettings;
                app.calculationCalledAsPreview=settingsApp.calledAsPreview;

                app.keepVocaliscopeSettingsOverSession=settingsApp.keepVocaliscopeSettingsOverSession;
                settingsApp.saveCurrentAppState();
                delete(settingsApp);

                % also change back to normal mode after parameter
                % calculation
                app.normalButton.Value=1;
                app.setToNormalInteractionMode();

            else % if just the window was closed

                % when doing that, the settings are deleted if calculated
                % before
%                 app.calculationSettings=[];
%                 app.calculationCalledAsPreview=[];
                return;
            end

            
                %profile on;
            

            %% delete old cycle borders
            delete(app.cycleBorderHandles);
            app.cycleBorderHandles=gobjects(1,numel(app.channelAxes));

            %% delete old OQ and CLQ areas
            delete(app.cycleOQHandle(isvalid(app.cycleOQHandle)));
            delete(app.cycleClQHandle(isvalid(app.cycleClQHandle)));

            app.cycleOQHandle =gobjects(1,numel(app.channelAxes));
            app.cycleClQHandle=gobjects(1,numel(app.channelAxes));


            %% delete old calculation results
            app.calculationResults=struct();

            nChannels=numel(app.channelYValues);


            %% create porgress bar that can be canceled
            %progDialog = waitbar(0,['0 of ' num2str(nChannels) ' channels complete'],'Name','Calculating parameters...',...
    %'CreateCancelBtn','setappdata(gcbf,''canceling'',1)','WindowStyle','normal','Theme','light');
            % set this to modal later

            %setappdata(progDialog,'canceling',0);

            progDialog=uiprogressdlg(app.UIFigure,'Title','Calculating parameters...',...
        'Message',['0 of ' num2str(nChannels) ' channels complete'],'Cancelable','on');

            %% save applied lag
            app.calculationResults.appliedChannelLag=app.channelLag;


            %% save selected interval
            app.calculationResults.selectionStart=selectionStart;
            app.calculationResults.selectionEnd=selectionEnd;
            app.calculationResults.channelNames=app.channelNames;
            app.calculationResults.channelSource=app.channelSource;

            %% see if we calculate on base of windows, or use only one window
            if app.calculationSettings.useWindows==1
                startTimes=selectionStart:app.calculationSettings.windowHopLength:selectionEnd-app.calculationSettings.windowLength;
                endTimes=selectionStart+app.calculationSettings.windowLength:app.calculationSettings.windowHopLength:selectionEnd;

                % if we would use the following, the last window could
                % be not full length; maybe add option if this is
                % wanted
%                     if endTimes(end)~=selectionEnd
%                         endTimes=[endTimes selectionEnd];
%                     end

            elseif app.calculationSettings.useMask==1
                maskChannelNumber=app.calculationSettings.maskChannelNumber;

                maskTime=app.channelXValues{maskChannelNumber};
                maskValues=app.channelYValues{maskChannelNumber};

                excerpt=maskTime>=selectionStart & maskTime<=selectionEnd;
                maskValues=maskValues(excerpt);
                maskTime=maskTime(excerpt);

                maskPadded=[0 maskValues(:)' 0];


                starts=strfind(maskPadded,[0 1]);
                ends=strfind(maskPadded,[1 0])-1;

                startTimes=maskTime(starts);
                endTimes=maskTime(ends);

            else
                startTimes=selectionStart;
                endTimes=selectionEnd;
            end

            app.calculationResults.numberOfWindows=numel(startTimes);
            app.calculationResults.windowStartTimes=startTimes;
            app.calculationResults.windowEndTimes=endTimes;


            % separate 1/cycleDuration plot 
            if app.calculationSettings.cycleDuration.showSeparate1overCycleDurationPlot==1
                foFig=figure('Units','normalized','OuterPosition',[0.25 0.1 0.5 0.8],'Visible','off','Theme','light');
                %tl=tiledlayout(foFig,nChannels+1,1,'Padding','tight','TileSpacing','compact');
                tl=tiledlayout(foFig,'vertical','Padding','tight','TileSpacing','compact');
                foAxs=gobjects(0);
            end

           % channelNr=1;
            for channelNr=1:nChannels
                % if ~isvalid(progDialog) || getappdata(progDialog,'canceling')
                %     break;
                % end

                if progDialog.CancelRequested || ~isvalid(progDialog)
                    break;
                end


                %% cycle detection
                excerpt=app.channelXValues{channelNr}>=selectionStart & app.channelXValues{channelNr}<=selectionEnd;
                currYData=app.channelYValues{channelNr}(excerpt);
                currXData=app.channelXValues{channelNr}(excerpt);
                currFs=app.channelSampleRates(channelNr);

                % % calculate sampEn based on FD for the whole signal (GAW and EGG)
                % if app.calculationCalledAsPreview~=1 && ismember(app.channelSource{channelNr},{'GAW','EGG'})
                %     minfo=app.calculationSettings.fo.Min;
                %     maxfo=app.calculationSettings.fo.Max;
                %     sampenSex=app.calculationSettings.SampleEntropyFDfixedNumberOfCycles.sex;
                %     try
                %         [sampen_y,sampen_x]=FromSelamtzisCyclebasedSampEn.sampEnFDaLaSelamtzis(currYData,currFs,[minfo maxfo],sampenSex,app.calculationSettings.SampleEntropyFDfixedNumberOfCycles.showInternalCycleDetection,app.calculationSettings.SampleEntropyFDfixedNumberOfCycles.doNormalizeSampEn);
                %         sampen_x=sampen_x+startTimes(1); % the algorith always thinks starting at t=0;
                %     catch ME
                %         sampen_y=nan(1,numel(startTimes));
                %         sampen_x=startTimes;
                %         disp('Error: calculating fixed cycle size SampEn failed');
                % 
                %         disp(ME.getReport());
                %     end
                % end

                if app.calculationCalledAsPreview~=1 && ismember(app.channelSource{channelNr},{'GAW','EGG','Audio'})
                
                    pitchMethod={'NCF','PEF','CEP','LHS','SRH'};
                    pitchesY=cell(size(pitchMethod));
                    pitchesX=cell(size(pitchMethod));
                    for pitchMethodNr=1:numel(pitchMethod)
                        % no pitch needed since pitch function apllies a
                        % window by itself
                        % SRH uses periodic hann, NCF needs no windowing
                        % and all other use periodic hamming window
                        try
                            [pitchesY{pitchMethodNr},loc]=pitch(currYData(:),currFs,'Method',pitchMethod{pitchMethodNr},"Range",[app.calculationSettings.fo.Min app.calculationSettings.fo.Max],'WindowLength',round(app.calculationSettings.pitch.timeWindow*currFs),'OverlapLength',round(app.calculationSettings.pitch.timeWindowOverlap*currFs));
                            pitchesX{pitchMethodNr}=currXData(loc);
                        catch ME
                            disp(['Error during pitch calculation using ' pitchMethod{pitchMethodNr} ' method:']);
                            disp(ME.getReport());
                            pitchesY{pitchMethodNr}=[];
                            pitchesX{pitchMethodNr}=[];
                        end
                    end

                end

                if app.calculationSettings.fo.useAbsoluteCyclePeakProminence==1
                    prominence=app.calculationSettings.fo.absoluteCyclePeakProminence;
                else
                    prominence=app.calculationSettings.fo.relativeCyclePeakProminence*range(currYData);
                end

                where=islocalmax(currYData,'MinSeparation',round(app.calculationSettings.cycleDuration.Min*currFs),'MinProminence',prominence);
                cycleBorderVals=currXData(where);
                cycleDurations=diff(cycleBorderVals);

                if app.calculationSettings.useMask
                    notInsideMaskRegions=~any(cycleBorderVals(:)>=startTimes(:)' & cycleBorderVals(:)<=endTimes(:)',2);
                end

                app.calculationResults.channelResults(channelNr).cycleBorderVals=cycleBorderVals;
                app.calculationResults.channelResults(channelNr).cycleDurations=cycleDurations;
                if 1% ismember(channelNr,app.availableChannelsTable.Selection)
                    if app.HidecyclesbordersButton.Value==1
                        vis='off';
                    else
                        vis='on';
                    end
                    if ~isempty(cycleBorderVals) && ~app.calculationSettings.useMask || app.calculationSettings.useMask && ~isempty(cycleBorderVals(~notInsideMaskRegions))
                        if app.calculationSettings.useMask
                            app.cycleBorderHandles(channelNr)=app.addManyXlinesAsSingleObj(channelNr,cycleBorderVals(~notInsideMaskRegions),vis);
                        else
                            app.cycleBorderHandles(channelNr)=app.addManyXlinesAsSingleObj(channelNr,cycleBorderVals,vis);
                        end
                        if ishandle(app.cycleBorderHandles(channelNr))
                            uistack(app.cycleBorderHandles(channelNr),'top');
                        end
                        uistack(app.timePlotHandles(channelNr),'top');
                    end
                    %channelNr=channelNr+1;
                end

                % separate 1/cycleDuration plot 
                if app.calculationSettings.cycleDuration.showSeparate1overCycleDurationPlot==1 && ismember(app.channelSource(channelNr),{'GAW' 'EGG' 'Audio'})
                    %foAxs(channelNr)=nexttile(tl,channelNr);
                    foAxs(end+1)=nexttile(tl);

                    cycleBorderValsWithNaN=cycleBorderVals;
                    if app.calculationSettings.useMask
                        cycleBorderValsWithNaN(notInsideMaskRegions)=NaN;
                    end
                    
                    plot(foAxs(end),movmean(cycleBorderValsWithNaN,2,'Endpoints','discard'),1./cycleDurations);
                    title(foAxs(end),app.channelNames{channelNr},'Interpreter','none');
                    
                    lastAx=nexttile(tl,sum(ismember(app.channelSource,{'GAW' 'EGG' 'Audio'}))+1);
                    plot(lastAx,movmean(cycleBorderValsWithNaN,2,'Endpoints','discard'),1./cycleDurations);
                    hold(lastAx,'on');
                end


                %% generate a normalized data copy for sample entropy calculation if requested

                % if not 'global' is chosen, this is just a copy of the
                % regular Y values
                currYDataForSampleEntropyTime=currYData;
                if      strcmp(app.calculationSettings.SampleEntropyTime.normalizationHorizont,'global/selection based')...
                        && ~strcmp(app.calculationSettings.SampleEntropyTime.normalizationMethod,'none')

                    normMethod=app.calculationSettings.SampleEntropyTime.normalizationMethod;
                    currYDataForSampleEntropyTime=normalize(currYDataForSampleEntropyTime,normMethod);
                end


                nTotalCycles = numel(cycleBorderVals) - 1;
                [allCyclesYDataRange, allCyclesYDataEnergy,allCyclesOQs,allCyclesClQs,allCyclesOpQs,allCyclesGGI,allCyclesPlateauQuotient] = deal(nan(1, nTotalCycles));

                % for multiple values per cycle
                FDnumberOfHarmonics=app.calculationSettings.SampleEntropyFD.numberOfHarmonics;
                [fourierDescriptors] = deal(nan(FDnumberOfHarmonics, nTotalCycles));

                allOQBorders=[];
                allClQBorders=[];

                %% precompute values for all cycles
                for cycleNr = 1:nTotalCycles
                    currCycleYData = currYData(currXData >= cycleBorderVals(cycleNr) & ...
                                              currXData <= cycleBorderVals(cycleNr + 1));
                    currCycleXData = currXData(currXData >= cycleBorderVals(cycleNr) & ...
                                              currXData <= cycleBorderVals(cycleNr + 1));

                    %% required for some many parameters later
                    [cycleMax,cycleMaxIdx]=max(currCycleYData);
                    [cycleMin,cycleMinIdx]=min(currCycleYData);
                    cycleRange=cycleMax-cycleMin;
                    allCyclesYDataRange(cycleNr) = cycleRange;
                    allCyclesYDataEnergy(cycleNr) = sum(currCycleYData .^ 2);
                    allCyclesGGI(cycleNr)=cycleMin/cycleMax;
                    allCyclesPlateauQuotient(cycleNr)=sum(currCycleYData>=cycleMin+app.calculationSettings.plateauQuotientThreshold*cycleRange);

                    %% here calculate the basic open, closed, closing and opening quotients
                    if ismember(app.channelSource{channelNr},{'GAW','EGG'})
                        total=numel(currCycleYData);

                        % !!!remember!!! the cycles are (at the moment) max based because min
                        % based values are difficult due to noise
                        if strcmpi(app.channelSource{channelNr},'GAW')
                            zeroThreshold=app.calculationSettings.GAWopenThresholdPercentage*cycleMax;
    
                            closedDuration=sum(currCycleYData<=zeroThreshold);
                            openDuration=total-closedDuration;
                            openingDuration=sum(currCycleYData(cycleMinIdx:end)>zeroThreshold);
                            closingDuration=sum(currCycleYData(1:cycleMinIdx)>zeroThreshold);

                            closedIdx=find(currCycleYData<=zeroThreshold);
                            if numel(closedIdx)>=2
                                allOQBorders=[allOQBorders  currCycleXData(closedIdx(1)) NaN currCycleXData(closedIdx(end))  ];
                                allClQBorders=[allClQBorders currCycleXData(1) currCycleXData(closedIdx(1)) NaN];
                            end
    
                        elseif strcmpi(app.channelSource{channelNr},'EGG')
                            % howard for the moment % closed according DEGG,
                            % open according 3/7
                            cycleGradient=gradient(currCycleYData);
                            [~,maxDEGGidx]=max(cycleGradient);
                            closingDuration=maxDEGGidx-cycleMinIdx;
                            
                            openingDuration=find(cycleMin+3/7*(currCycleYData(1)-cycleMin)>=currCycleYData(1:cycleMinIdx),1,"first");
    
                            openDuration=closingDuration+(cycleMinIdx-openingDuration);
                            closedDuration=total-openDuration;

                            allOQBorders=[allOQBorders currCycleXData(openingDuration) currCycleXData(maxDEGGidx) NaN];
                            allClQBorders=[allClQBorders currCycleXData(cycleMinIdx) currCycleXData(maxDEGGidx) NaN];

                        end
                        allCyclesOQs(cycleNr)=openDuration/total;
                        allCyclesClQs(cycleNr)=closingDuration/total;
                        allCyclesOpQs(cycleNr)=openingDuration/total;
                    end

                    %% calculate FD based SampEn for fixed number of cycles
                    
                    if ismember(app.channelSource{channelNr},{'GAW','EGG'}) % only for gaw and egg, the cycle fft is so far only needed for FD based SampEn
                        cycleFFT=fft(currCycleYData,numel(currCycleYData));
    
                        % each column is one cycle
                        fourierDescriptors(:,cycleNr)=cycleFFT(2:2+FDnumberOfHarmonics-1); % magnitudes


                    end
                end

                if ismember(app.channelSource{channelNr},{'GAW','EGG'}) % only for gaw and egg, the cycle fft is so far only needed for FD based SampEn

                    fourierDescriptorsMagnitude=abs(fourierDescriptors);
                    fourierDescriptorsPhase=angle(fourierDescriptors);

                    if app.calculationSettings.SampleEntropyFD.useRelativeFD
                        fourierDescriptorsMagnitude=20*log10(fourierDescriptorsMagnitude(2:end,:)./fourierDescriptorsMagnitude(1,:));
                        fourierDescriptorsPhase=fourierDescriptorsPhase(2:end,:)-fourierDescriptorsPhase(1,:);
                        %currentFDs=currentFDs(2:end,:)-currentFDs(1,:); % now we have one less FD
                    else
                        fourierDescriptorsMagnitude=20*log10(fourierDescriptorsMagnitude);
                    end
                end

                if strcmpi(app.channelSource{channelNr},'GAW')
                    firstNaN=find(isnan(allOQBorders),1,'first');
                    if firstNaN<=2
                        allOQBorders(1:firstNaN)=[]; % should only be relevant for max based GAW, since there the open phase is cut by cycle borders
                    end

                    lastNaN=find(isnan(allOQBorders),1,'last');
                    if lastNaN>=numel(allOQBorders)-1
                        allOQBorders(lastNaN+1:end)=[]; % should only be relevant for max based GAW, since there the open phase is cut by cycle borders
                    end
                end

                if ismember(app.channelSource{channelNr},{'GAW','EGG'})
                    allOQBorders=reshape(allOQBorders,3,[])';
                    allOQBorders(:,3)=[]; % remove NaN values
    
                    allClQBorders=reshape(allClQBorders,3,[])';
                    allClQBorders(:,3)=[]; % remove NaN values

                    app.calculationResults.channelResults(channelNr).allOQBorders=allOQBorders;
                    app.calculationResults.channelResults(channelNr).allClQBorders=allClQBorders;

                    %if ismember(channelNr,app.availableChannelsTable.Selection)

                        if app.HideOQareasButton.Value==1
                            oqvis='off';
                        else
                            oqvis='on';
                        end
                        if app.ClQareasarehiddenButton.Value==1
                            clqvis='off';
                        else
                            clqvis='on';
                        end

                        %channelNr=find(app.availableChannelsTable.Selection==channelNr,1);
                        app.cycleOQHandle(channelNr)=app.addManyPatchesAsSinglePatch(channelNr,allOQBorders,[0.2 0 0],[],oqvis);
                        app.cycleClQHandle(channelNr)=app.addManyPatchesAsSinglePatch(channelNr,allClQBorders,[0 0.2 0],[],clqvis);

                        uistack(app.cycleOQHandle(channelNr),'bottom');
                    %end
                end

                if app.calculationCalledAsPreview==1
                    progDialog.Value=channelNr/nChannels;
                    progDialog.Message=sprintf('%i of %i channels complete',channelNr,nChannels);

                    continue;
                end

                %% starting from here, those calculation are not part of the preview cycles button calculations

                %% FD based Sample Entropy
                if ismember(app.channelSource{channelNr},{'GAW','EGG'})

                    if strcmpi(app.calculationSettings.SampleEntropyFD.sex,'male')
                        cyclesPerSet=10;
                    else
                        cyclesPerSet=20;
                    end

                    SampEnFDofEachSet=nan(size(fourierDescriptorsMagnitude,2)-cyclesPerSet+1,1);
                    SampEnFDofEachSetTime=SampEnFDofEachSet;


                    for cycleNr=1:size(fourierDescriptors,2)-cyclesPerSet+1
    
                        currentFDsMagnitude=fourierDescriptorsMagnitude(:,cycleNr:cycleNr+cyclesPerSet-1);
                        currentFDsPhase=fourierDescriptorsPhase(:,cycleNr:cycleNr+cyclesPerSet-1);


                        if contains(app.calculationSettings.SampleEntropyFD.templateLengthMethod,'log10')
                            templateLength=round(log10(size(currentFDsMagnitude,2)));
                        else
                            templateLength=app.calculationSettings.SampleEntropyFD.templateLengthFixed;
                        end
        
                        if templateLength>size(currentFDsMagnitude,2)-1
                            templateLength=size(currentFDsMagnitude,2)-1;
                        end
                        if templateLength<1
                            templateLength=1;
                        end


                        FSsampEn=nan(size(currentFDsMagnitude,1),1); % either nHarmonics or nHarmonics-1, depending on the relativeFD setting
                        
                        for FDnr=1:size(currentFDsMagnitude,1)
        
        
                            %angleSampEnSinus    =SampEn_fast_omidvarnia(sin(currentFDsPhase(FDnr,:)),templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdPhase);
                            %angleSampEnCosinus  =SampEn_fast_omidvarnia(cos(currentFDsPhase(FDnr,:)),templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdPhase);
                           % magnitudeSampEn=SampEn_fast_omidvarnia(currentFDsMagnitude(FDnr,:),templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdMagnitude);
        
                            angleSampEnSinus    =app.calculateSampleEntropy(sin(currentFDsPhase(FDnr,:)),templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdPhase);
                            angleSampEnCosinus  =app.calculateSampleEntropy(cos(currentFDsPhase(FDnr,:)),templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdPhase);
        
                            magnitudeSampEn     =app.calculateSampleEntropy(currentFDsMagnitude(FDnr,:) ,templateLength,app.calculationSettings.SampleEntropyFD.distanceSTDthresholdMagnitude);
        
                            switch app.calculationSettings.SampleEntropyFD.combinationMethod
                                case 'add'
                                    FSsampEn(FDnr)=magnitudeSampEn+angleSampEnSinus+angleSampEnCosinus;
                                case 'min'
                                    FSsampEn(FDnr)=min(magnitudeSampEn,[angleSampEnSinus;angleSampEnCosinus]);
                                case 'max'
                                    FSsampEn(FDnr)=max(magnitudeSampEn,[angleSampEnSinus;angleSampEnCosinus]);
                            end
                            
                            
                        end
        
                        switch app.calculationSettings.SampleEntropyFD.combinationMethod
                            case 'add'
                                SampEnFDofEachSet(cycleNr) = sum(FSsampEn);
                            case 'min'
                                SampEnFDofEachSet(cycleNr) = min(FSsampEn);
                            case 'max'
                                SampEnFDofEachSet(cycleNr) = max(FSsampEn);
                        end
    
                        SampEnFDofEachSetTime(cycleNr)=cycleBorderVals(cycleNr+1);
    
                    end

                end

                %% also calculate dB for each of the channels


                if ~strcmpi(app.channelSource{channelNr},'Raw')
                    splSpeedMethod={'Slow','Fast'};
                    splWeightingMethod={'A-weighting','C-weighting','Z-weighting'};
                    splReferencePressure={20e-6 1};
    
                    splY=cell(cellfun(@numel,{splSpeedMethod splWeightingMethod splReferencePressure}));
                    for splSpeedNr=1:numel(splSpeedMethod)
                        for splWeightingNr=1:numel(splWeightingMethod)
                            for splReferenceNr=1:numel(splReferencePressure)
                                splObj=splMeter("SampleRate",app.channelSampleRates(channelNr),"PressureReference",splReferencePressure{splReferenceNr},'TimeWeighting',splSpeedMethod{splSpeedNr},'FrequencyWeighting',splWeightingMethod{splWeightingNr});
                                try
                                    [splY{splSpeedNr,splWeightingNr,splReferenceNr}]=splObj(currYData(:));
                                catch ME
                                    disp('Error during SPL calculation');
                                    disp(ME.getReport());
                                    splY{splSpeedNr,splWeightingNr,splReferenceNr}=[];
                                end
                            end
                        end
                    end

                end

                %% window based calculations

                for windowNr=1:numel(startTimes)
                    currCyclesBorders=cycleBorderVals(cycleBorderVals>=startTimes(windowNr) & cycleBorderVals<=endTimes(windowNr));
                    currCyclesDurations=diff(currCyclesBorders);
    
                    app.calculationResults.channelResults(channelNr).currWindowStartTime(windowNr)=startTimes(windowNr);
                    app.calculationResults.channelResults(channelNr).currWindowEndTime(windowNr)=endTimes(windowNr);

                    app.calculationResults.channelResults(channelNr).currWindowCycleBorders{windowNr}=currCyclesBorders;
                    app.calculationResults.channelResults(channelNr).currWindowCycleDurations{windowNr}=currCyclesDurations;


                    currWindowYData=currYData(currXData >= startTimes(windowNr) & ...
                                                  currXData <= endTimes(windowNr));
                    currWindowXData=currXData(currXData >= startTimes(windowNr) & ...
                                                  currXData <= endTimes(windowNr));
                    currWindowYDataWindowed=hann(numel(currWindowYData),"periodic").*currWindowYData;


                    %% get current cycle values

                    whichCycles=cycleBorderVals(1:end-1)>=startTimes(windowNr) & cycleBorderVals(2:end)<=endTimes(windowNr);
                    
                    currCycleYDataRange = allCyclesYDataRange(whichCycles);
                    currCycleYDataEnergy = allCyclesYDataEnergy(whichCycles);

                    if ismember(app.channelSource{channelNr},{'GAW','EGG'})
                        currCyclesOQValues = allCyclesOQs(whichCycles);
                        currCyclesClQValues = allCyclesClQs(whichCycles);
                        currCyclesOpQValues = allCyclesOpQs(whichCycles);
                    end

                    currCyclesGGI = allCyclesGGI(whichCycles);
                    currCyclesPlateauQuotient = allCyclesPlateauQuotient(whichCycles);
                    
                    
                    % Precompute mean values to avoid redundant calculations
                    meanCurrCyclesYDataRange     = mean(currCycleYDataRange);
                    meanCurrCyclesDurations      = mean(cycleDurations);
                    meanCurrCyclesYDataEnergy    = mean(currCycleYDataEnergy);

                    % assign window means of already precomputed cycle
                    % parameters

                    app.calculationResults.channelResults(channelNr).meanCycleYDataRange(windowNr)=meanCurrCyclesYDataRange;
                    app.calculationResults.channelResults(channelNr).meanCycleDurations(windowNr)=meanCurrCyclesDurations;
                    app.calculationResults.channelResults(channelNr).meanCycleYDataEnergy(windowNr)=meanCurrCyclesYDataEnergy;

                    % quotient values
                    if ismember(app.channelSource{channelNr},{'GAW','EGG'})
                        app.calculationResults.channelResults(channelNr).meanOQ(windowNr)=mean(currCyclesOQValues);
                        app.calculationResults.channelResults(channelNr).medianOQ(windowNr)=median(currCyclesOQValues);

                        app.calculationResults.channelResults(channelNr).meanClQ(windowNr)=mean(currCyclesClQValues);
                        app.calculationResults.channelResults(channelNr).medianClQ(windowNr)=median(currCyclesClQValues);

                        app.calculationResults.channelResults(channelNr).meanOpQ(windowNr)=mean(currCyclesOpQValues);
                        app.calculationResults.channelResults(channelNr).medianOpQ(windowNr)=median(currCyclesOpQValues);

                        app.calculationResults.channelResults(channelNr).meanSpeedQuotient(windowNr)=mean(currCyclesOpQValues./currCyclesClQValues);
                        app.calculationResults.channelResults(channelNr).medianSpeedQuotient(windowNr)=median(currCyclesOpQValues./currCyclesClQValues);

                        app.calculationResults.channelResults(channelNr).meanSpeedIndex(windowNr)=mean((currCyclesOpQValues-currCyclesClQValues)./(currCyclesOpQValues+currCyclesClQValues));
                        app.calculationResults.channelResults(channelNr).medianSpeedIndex(windowNr)=median((currCyclesOpQValues-currCyclesClQValues)./(currCyclesOpQValues+currCyclesClQValues));

                        app.calculationResults.channelResults(channelNr).meanRateQuotient(windowNr)=mean((1-currCyclesOQValues+currCyclesOpQValues)./currCyclesClQValues);
                        app.calculationResults.channelResults(channelNr).medianRateQuotient(windowNr)=median((1-currCyclesOQValues+currCyclesOpQValues)./currCyclesClQValues);
                        
                        app.calculationResults.channelResults(channelNr).meanAsymmetryQuotient(windowNr)=mean(currCyclesOpQValues./(currCyclesClQValues+currCyclesOpQValues));
                        app.calculationResults.channelResults(channelNr).medianAsymmetryQuotient(windowNr)=median(currCyclesOpQValues./(currCyclesClQValues+currCyclesOpQValues));

                        app.calculationResults.channelResults(channelNr).meanGGI(windowNr)=mean(currCyclesGGI);
                        app.calculationResults.channelResults(channelNr).medianGGI(windowNr)=median(currCyclesGGI);

                        app.calculationResults.channelResults(channelNr).meanPlateauQuotient(windowNr)=mean(currCyclesPlateauQuotient);
                        app.calculationResults.channelResults(channelNr).medianPlateauQuotient(windowNr)=median(currCyclesPlateauQuotient);
        
                    end
                    %% perturbation measurements
                    
                    % Generic functions for PF, PQ, and VI calculations
                    calculatePerturbationFactor  = @(data) mean(abs(diff(data) ./ data(2:end))) * 100;
                    calculatePerturbationQuotient = @(data, k) mean(abs(1 - ...
                        (k * data((k-1)/2+1:end-(k-1)/2) ./ movmean(data, k, 'Endpoints', 'discard')))) * 100;
                    calculateVariabilityIndex     = @(data, meanData) log10(1000 * var(data) / (meanData ^ 2));
                    calculateRAPv1 = @(data) sum(abs(movmean(data, 3, 'Endpoints', 'discard')-data(2:end-1)))/sum(data);
                    calculateRAPv2 = @(data) mean(abs(movmean(data, 3, 'Endpoints', 'discard')-data(2:end-1)))/mean(data);
                    
                    % Amplitude Perturbation
                    app.calculationResults.channelResults(channelNr).meanShimmerDB(windowNr)    = 20 * mean(abs(log10(currCycleYDataRange(1:end-1) ./ currCycleYDataRange(2:end))));
                    app.calculationResults.channelResults(channelNr).shimmerInPercent(windowNr) = mean(abs(diff(currCycleYDataRange))) / meanCurrCyclesYDataRange * 100;
                    for k = 3:2:11
                        app.calculationResults.channelResults(channelNr).(['APQ' num2str(k)])(windowNr) = calculatePerturbationQuotient(currCycleYDataRange, k);
                    end
                    app.calculationResults.channelResults(channelNr).APF(windowNr) = calculatePerturbationFactor(currCycleYDataRange);
                    app.calculationResults.channelResults(channelNr).AVI(windowNr) = calculateVariabilityIndex(currCycleYDataRange, meanCurrCyclesYDataRange);
                    
                    % Period Perturbation
                    app.calculationResults.channelResults(channelNr).meanJitter(windowNr)      = mean(abs(currCyclesDurations));
                    app.calculationResults.channelResults(channelNr).jitterInPercent(windowNr) = app.calculationResults.channelResults(channelNr).meanJitter(windowNr) / mean(diff(currCyclesBorders)) * 100;
                    app.calculationResults.channelResults(channelNr).jitterFactor(windowNr)    = app.calculationResults.channelResults(channelNr).jitterInPercent(windowNr) * 10;
                    for k = 3:2:11
                        app.calculationResults.channelResults(channelNr).(['PPQ' num2str(k)])(windowNr) = calculatePerturbationQuotient(currCyclesDurations, k);
                    end
                    app.calculationResults.channelResults(channelNr).PPF(windowNr) = calculatePerturbationFactor(currCyclesDurations);

                    app.calculationResults.channelResults(channelNr).RAPv1(windowNr) = calculateRAPv1(currCyclesDurations);
                    app.calculationResults.channelResults(channelNr).RAPv2(windowNr) = calculateRAPv2(currCyclesDurations);
                    app.calculationResults.channelResults(channelNr).PVI(windowNr) = calculateVariabilityIndex(currCyclesDurations, meanCurrCyclesDurations);


                    % Energy Perturbation
                    for k = 3:2:11
                        app.calculationResults.channelResults(channelNr).(['EPQ' num2str(k)])(windowNr) = calculatePerturbationQuotient(currCycleYDataEnergy, k);
                    end
                    app.calculationResults.channelResults(channelNr).EPF(windowNr) = calculatePerturbationFactor(currCycleYDataEnergy);
                    app.calculationResults.channelResults(channelNr).EVI(windowNr) = calculateVariabilityIndex(currCycleYDataEnergy,meanCurrCyclesYDataEnergy);
                
                    %% noise measurements

                    % harmonic intensity - window based

                    % HNR - cycle based

                    % NNE based - window based

                    % spectral flatness - window based
                    % !!!!! different to MSA implemetation!!!!
                    % this is based on Johnston, J. D. "Transform Coding of Audio Signals Using Perceptual Noise Criteria." IEEE Journal on Selected Areas in Communications. Vol. 6, Number 2, 1988, pp. 314–323.
                    % MSA is based on J. Lessing. “Entwicklung einer Klassiﬁkationsmethode zur akustischen Analyse fortlaufender Sprache unterschiedlicher Stimmgüte mittels Neuronaler Netze und deren Anwendung”. PhD thesis. Georg-August-Universität Göttingen, Mathematisch-Naturwissenschaftlich Fakultät, 2007.

                    try
                        app.calculationResults.channelResults(channelNr).spectralFlatness(windowNr) = spectralFlatness(currWindowYData(:),currFs,"Window",rectwin(numel(currWindowYData)),"OverlapLength",0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralFlatness(windowNr)=NaN;
                        disp('error while calculating spectral flatness, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end
                    % GNE - window based

                    % WMC - window based

                    % MWMC - window based

                    % SNR v1- window based % !! matlab snr is calucated elsewhere

                    % SNR v2 - window based % !! matlab snr is calucated elsewhere

                    % CPP v1 - window based
                    plotCPP=0;
                    [CPPlin,CPPparabolic,CPPexp]=app.calculateCPP(currWindowYData,currWindowXData-min(currWindowXData),...
                        plotCPP,...
                        app.calculationSettings.CPPv1.windowType,...
                        app.calculationSettings.CPPv1.lowerQuefrencyLimit,...
                        app.calculationSettings.CPPv1.upperQuefrencyLimit,...
                        app.calculationSettings.CPPv1.maxInFitRegion,...
                        app.calculationSettings.CPPv1.fitMethod,...
                        app.calculationSettings.CPPv1.robustMethod);

                    if ~isempty(CPPlin)
                        for robustNr=1:numel(CPPlin.value)
                            app.calculationResults.channelResults(channelNr).(['CPPlinRobust' CPPlin.method{robustNr}])(windowNr)=CPPlin.value(robustNr);
                        end
                    end

                    if ~isempty(CPPparabolic)
                        for robustNr=1:numel(CPPparabolic.value)
                            app.calculationResults.channelResults(channelNr).(['CPPparabolicRobust' CPPparabolic.method{robustNr}])(windowNr)=CPPparabolic.value(robustNr);
                        end
                    end

                    if ~isempty(CPPexp)
                        for robustNr=1:numel(CPPexp.value)
                            app.calculationResults.channelResults(channelNr).(['CPPexponentialRobust' CPPexp.method{robustNr}])(windowNr)=CPPexp.value(robustNr);
                        end
                    end
                    

                    % CPP v2 - window based

                    %% mechanical

                    % stiffness - cycle based

                    % peak closing velocity - cycle based

                    % peak acceleration - cycle based

                    % amplitude to length ratio  - cycle based

                    
                    %% calculate sample entropy in time domain for this window
                    currWindowYDataForSampleEntropyTime=currYDataForSampleEntropyTime(currXData >= startTimes(windowNr) & ...
                                                  currXData <= endTimes(windowNr));
                    
                    if          ~strcmp(app.calculationSettings.SampleEntropyTime.normalizationHorizont,'global/selection based')...
                             && ~strcmp(app.calculationSettings.SampleEntropyTime.normalizationMethod,'none')

                        normMethod=app.calculationSettings.SampleEntropyTime.normalizationMethod;
                        if ~isscalar(unique(currWindowYDataForSampleEntropyTime))
                            currWindowYDataForSampleEntropyTime=normalize(currWindowYDataForSampleEntropyTime,normMethod);
                        end
                    end

                    if contains(app.calculationSettings.SampleEntropyTime.templateLengthMethod,'log10')
                        templateLength=round(log10(numel(currWindowYDataForSampleEntropyTime)));
                    else
                        templateLength=app.calculationSettings.SampleEntropyTime.templateLengthFixed;
                    end
                    if templateLength>numel(currWindowYDataForSampleEntropyTime)-2
                        templateLength=numel(currWindowYDataForSampleEntropyTime)-2;
                    end
                    if templateLength<1
                        templateLength=1;
                    end

                    stdThreshold=app.calculationSettings.SampleEntropyTime.distanceSTDthreshold;


                    app.calculationResults.channelResults(channelNr).sampleEntropyTimeDomain(windowNr)=app.calculateSampleEntropy(currWindowYDataForSampleEntropyTime,templateLength,stdThreshold);
                    
                    %% calculate FS based SampEn using fixed number of cycles

                    if ismember(app.channelSource{channelNr},{'GAW','EGG'})

                        % % from selamtzis output
                        % currWindowSampEn=sampen_y(sampen_x >= startTimes(windowNr) & sampen_x < endTimes(windowNr));
                        % if isempty(currWindowSampEn)
                        %     currWindowSampEn=NaN;
                        % else
                        %     currWindowSampEn=mean(currWindowSampEn);
                        % end
                        % app.calculationResults.channelResults(channelNr).sampleEntropyFourierDomainFixedNumberOfCycles(windowNr)=currWindowSampEn;


                        % from my own output
                        currWindowSampEn=SampEnFDofEachSet(SampEnFDofEachSetTime >= startTimes(windowNr) & SampEnFDofEachSetTime < endTimes(windowNr));
                        if isempty(currWindowSampEn)
                            currWindowSampEn=NaN;
                        else
                            currWindowSampEn=mean(currWindowSampEn);
                        end
                        app.calculationResults.channelResults(channelNr).sampleEntropyFourierDomain(windowNr)=currWindowSampEn;


                    end

                    %% list of additional parameters
                    if ismember(app.channelSource{channelNr},{'GAW','EGG','Audio'})
                    
                        pitchMethod={'NCF','PEF','CEP','LHS','SRH'};
                        for pitchMethodNr=1:numel(pitchMethod)
                            % no pitch needed since pitch function apllies a
                            % window by itself
                            % SRH uses periodic hann, NCF needs no windowing
                            % and all other use periodic hamming window
                            try
                                app.calculationResults.channelResults(channelNr).(['fo' pitchMethod{pitchMethodNr}])(windowNr)=median(pitchesY{pitchMethodNr}(pitchesX{pitchMethodNr} >= startTimes(windowNr) & pitchesX{pitchMethodNr} <= endTimes(windowNr)));
                            catch ME
                                disp(['Error getting median pitch in window during ' pitchMethod{pitchMethodNr} ' method:']);
                                disp(ME.getReport());
                                app.calculationResults.channelResults(channelNr).(['fo' pitchMethod{pitchMethodNr}])(windowNr)=NaN;
                            end
                        end

                    end

                    if ~strcmpi(app.channelSource{channelNr},'Raw')

                        splSpeedMethod={'Slow','Fast'};
                        splWeightingMethod={'A-weighting','C-weighting','Z-weighting'};
                        splReferencePressure={'20mu' '1'};
    
                        for splSpeedNr=1:numel(splSpeedMethod)
                            for splWeightingNr=1:numel(splWeightingMethod)
                                for splReferenceNr=1:numel(splReferencePressure)
                                    try
                                        app.calculationResults.channelResults(channelNr).(['SPL' strrep(splWeightingMethod{splWeightingNr},'-','') splSpeedMethod{splSpeedNr} 'Ref' splReferencePressure{splReferenceNr}])(windowNr)=mean(splY{splSpeedNr,splWeightingNr,splReferenceNr}(currXData >= startTimes(windowNr) & currXData <= endTimes(windowNr)));
                                    catch ME
                                        disp('Error gettin mean SPL in window. Replacing with NaN');
                                        disp(ME.getReport());
                                        app.calculationResults.channelResults(channelNr).(['SPL' strrep(splWeightingMethod{splWeightingNr},'-','') splSpeedMethod{splSpeedNr} 'Ref' splReferencePressure{splReferenceNr}])(windowNr)=NaN;
                                    end
                                end
                            end
                        end

                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalMean(windowNr) = mean(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalMean(windowNr) = NaN;
                        disp('error while calculating signalMean, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalHarmonicMean(windowNr) = harmmean(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalHarmonicMean(windowNr) = NaN;
                        disp('error while calculating signalHarmonicMean, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        geometricMeanFcn = @(in) prod(abs(in))^(1/numel(in));
                        app.calculationResults.channelResults(channelNr).signalGeometricMean(windowNr) = geometricMeanFcn(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalGeometricMean(windowNr) = NaN;
                        disp('error while calculating signalGeometricMean, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalStd(windowNr) = std(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalStd(windowNr) = NaN;
                        disp('error while calculating signalStd, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalMedian(windowNr) = median(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalMedian(windowNr) = NaN;
                        disp('error while calculating signalMedian, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalMode(windowNr) = mode(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalMode(windowNr) = NaN;
                        disp('error while calculating signalMode, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalMin(windowNr) = min(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalMin(windowNr) = NaN;
                        disp('error while calculating signalMin, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalMax(windowNr) = max(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalMax(windowNr) = NaN;
                        disp('error while calculating signalMax, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalIQR(windowNr) = iqr(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalIQR(windowNr) = NaN;
                        disp('error while calculating signalIQR, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalKurtosis(windowNr) = kurtosis(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalKurtosis(windowNr) = NaN;
                        disp('error while calculating signalKurtosis, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalSkewness(windowNr) = skewness(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalSkewness(windowNr) = NaN;
                        disp('error while calculating signalSkewness, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalSummedSquares(windowNr) = sum(currWindowYData.^2);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalSummedSquares(windowNr) = NaN;
                        disp('error while calculating signalSummedSquares, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).signalRMS(windowNr) = rms(currWindowYData);
                    catch ME
                        app.calculationResults.channelResults(channelNr).signalRMS(windowNr) = NaN;
                        disp('error while calculating signalRMS, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        quantQuery = 0.05:0.05:0.95;
                        quantValues = quantile(currWindowYData, quantQuery);
                        for qNr = 1:numel(quantQuery)
                            app.calculationResults.channelResults(channelNr).(['signalQuantile' num2str(quantQuery(qNr)*100) 'percent'])(windowNr) = quantValues(qNr);
                        end
                    catch ME
                        % Set all quantiles to NaN if error occurs
                        quantQuery = 0.05:0.05:0.95;
                        for qNr = 1:numel(quantQuery)
                            app.calculationResults.channelResults(channelNr).(['signalQuantile' num2str(quantQuery(qNr)*100) 'percent'])(windowNr) = NaN;
                        end
                        disp('error while calculating signal quantiles, replacing all with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    % Spectral functions in try-catch blocks

                    try
                        app.calculationResults.channelResults(channelNr).spectralCrest(windowNr) = spectralCrest(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralCrest(windowNr) = NaN;
                        disp('error while calculating spectralCrest, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralFlux(windowNr) = spectralFlux(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralFlux(windowNr) = NaN;
                        disp('error while calculating spectralFlux, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralSpread(windowNr) = spectralSpread(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralSpread(windowNr) = NaN;
                        disp('error while calculating spectralSpread, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralSkewness(windowNr) = spectralSkewness(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralSkewness(windowNr) = NaN;
                        disp('error while calculating spectralSkewness, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralKurtosis(windowNr) = spectralKurtosis(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralKurtosis(windowNr) = NaN;
                        disp('error while calculating spectralKurtosis, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralCentroid(windowNr) = spectralCentroid(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralCentroid(windowNr) = NaN;
                        disp('error while calculating spectralCentroid, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralEntropy(windowNr) = spectralEntropy(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralEntropy(windowNr) = NaN;
                        disp('error while calculating spectralEntropy, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralSlope(windowNr) = spectralSlope(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralSlope(windowNr) = NaN;
                        disp('error while calculating spectralSlope, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralDecrease(windowNr) = spectralDecrease(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralDecrease(windowNr) = NaN;
                        disp('error while calculating spectralDecrease, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).spectralRolloffPoint(windowNr) = spectralRolloffPoint(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).spectralRolloffPoint(windowNr) = NaN;
                        disp('error while calculating spectralRolloffPoint, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).harmonicRatio(windowNr) = harmonicRatio(currWindowYData(:), currFs, "Window", rectwin(numel(currWindowYData)), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).harmonicRatio(windowNr) = NaN;
                        disp('error while calculating harmonicRatio, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        app.calculationResults.channelResults(channelNr).zeroCrossingRate(windowNr) = zerocrossrate(currWindowYData(:), "WindowLength", numel(currWindowYData), 'OverlapLength', 0);
                    catch ME
                        app.calculationResults.channelResults(channelNr).zeroCrossingRate(windowNr) = NaN;
                        disp('error while calculating zeroCrossingRate, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        % This is different compared to the MSA implementation!
                        app.calculationResults.channelResults(channelNr).SNRindBcarrier(windowNr) = snr(currWindowYData(:), currFs, 6);
                    catch ME
                        app.calculationResults.channelResults(channelNr).SNRindBcarrier(windowNr) = NaN;
                        disp('error while calculating SNRindBcarrier, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                    try
                        % Different compared to MSA: this is based on xcorr, not on spectral
                        app.calculationResults.channelResults(channelNr).HNRxcorrBiased(windowNr) = computeHNRbiasedXcorr(app, currWindowYData(:));
                    catch ME
                        app.calculationResults.channelResults(channelNr).HNRxcorrBiased(windowNr) = NaN;
                        disp('error while calculating HNRxcorrBiased, replacing with NaN. Error message:');
                        disp(ME.getReport());
                        disp('-----------------');
                    end

                end


                %waitbar(channelNr/nChannels,progDialog,sprintf('%i of %i channels complete',channelNr,nChannels))

                progDialog.Value=channelNr/nChannels;
                progDialog.Message=sprintf('%i of %i channels complete',channelNr,nChannels);

                drawnow limitrate;

            end

            delete(progDialog);


            % if progressbar aborted or closed
            if channelNr~=nChannels
                app.calculationResults=struct();
                if app.calculationSettings.cycleDuration.showSeparate1overCycleDurationPlot==1
                    delete(foFig);
                end
                return;
            end


            % separate 1/cycleDuration plot 
            if app.calculationSettings.cycleDuration.showSeparate1overCycleDurationPlot==1
                title(tl,'1/(cycle length)');
                xlabel(tl,'time (s)');

                if numel(foAxs)>0 % e.g. if only raw channels are available
                    linkaxes([foAxs lastAx]);
        
                    lg=legend(lastAx,'Orientation','horizontal');
                    lg.Layout.Tile='south';

                    showXLims=[selectionStart selectionEnd];
                    xlim([foAxs lastAx],showXLims+[-1 +1]*0.1*range(showXLims));
                    
    
                    foFig.Visible='on';
                else
                    delete(foFig);
                end
            end

            if app.calculationCalledAsPreview==0
                app.populateResultsTable();
                app.TabGroup.SelectedTab=app.ResultsTab;
                uialert(app.UIFigure,'Parameter Calculation complete','Complete','Icon','success');

            end

            
                %profile viewer;
            
        end

        % Value changed function: showngraphswithoutscrollingDropDown
        function changeNumberOfGraphsWithoutScrolling(app, event)
            app.rescaleTimePlotTilesVerticallyPanel(1);
        end

        % Selection changed function: availableChannelsTable
        function changeDisplayedChannels(app, event)

            % % if there are less channels selected than before, but all of
            % % them very already plotted before
            % if numel(event.PreviousSelection)>numel(event.Selection) && all(ismember(event.Selection,event.PreviousSelection))
            %     delete(app.channelAxes(event.PreviousSelection(~ismember(event.PreviousSelection,event.Selection))));
            %     app.rescaleTimePlotTilesVerticallyPanel(0);
            %     return;
            % end

            app.refreshTimePlotDataVisibility();


            % return;
            % 
            % 
            % persistent SelectionDebounceTimer;
            % if ~isempty(SelectionDebounceTimer) && isvalid(SelectionDebounceTimer)
            %     stop(SelectionDebounceTimer);
            % end
            % % Timer neu starten (z.B. mit 0.4 Sek. Verzögerung)
            % SelectionDebounceTimer = timer( ...
            %     'StartDelay', 0.5, ...
            %     'ExecutionMode', 'singleShot', ...
            %     'TimerFcn', @(~,~) app.refreshTimePlotData());
            % start(SelectionDebounceTimer);


            %app.refreshTimePlotData();
        end

        % Cell edit callback: availableChannelsTable
        function adjustDataSourceOnTableEdit(app, event)
            % when the source gets changed, also save that info in the
            % internal data source
            
            indices = event.Indices;
            newData = event.NewData;

            app.channelSource{indices(1)}=newData;
            ylabel(app.channelAxes(indices(1)),newData);

            app.timePlotHandles(indices(1)).Color=app.channelSourcePossibilitiesColor(ismember(app.channelSourcePossibilities,newData),:);

            
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            if ~isempty(app.availableChannelsTable.Data)
                app.availableChannelsTable.Selection=1:size(app.availableChannelsTable.Data,1);
                app.refreshTimePlotDataVisibility();
            end
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            if ~isempty(app.availableChannelsTable.Data)
                app.xlimBeforeDeletion=app.channelAxes(1).XLim;
                app.availableChannelsTable.Selection=[];
                app.refreshTimePlotDataVisibility();
            end
        end

        % Button pushed function: DeleteselectedsignalsButton
        function DeleteselectedsignalsButtonPushed(app, event)
            selection=app.availableChannelsTable.Selection;
            app.deleteSignals(selection);
        end

        % Selection changed function: InteractionModeButtonGroup
        function InteractionModeButtonGroupSelectionChanged(app, event)
            app.refreshInteractionMode();
        end

        % Button pushed function: uOupenShiftToolButton
        function uOupenShiftToolButtonPushed(app, event)
            app.openShiftTool();
        end

        % Button pushed function: UndoallshiftsButton
        function UndoallshiftsButtonPushed(app, event)
            app.setChannelShiftToZero(1:numel(app.channelAxes));
        end

        % Button pushed function: StopSoundButton
        function StopSoundButtonPushed(app, event)
            if ~isempty(app.audioPlayerObj) %&& app.audioPlayerObj.isplaying
                stop(app.audioPlayerObj);
            end
            set(app.PlaySoundButton,'Text','Play Sound');
            delete(app.playbackPositionXLines);
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            if isempty(app.availableChannelsTable.Selection)
                return;
            end

            
            if ~isempty(app.audioPlayerObj) && isplaying(app.audioPlayerObj)
                pause(app.audioPlayerObj);
                set(app.PlaySoundButton,'Text','Resume...')
                return;
            end


            if isempty(app.audioPlayerObj) || app.audioPlayerObj.CurrentSample==1
                app.audioPlayerObj=audioplayer(app.SoundVolumeSlider.Value*app.audioPlayerData,max(app.channelSampleRates(app.availableChannelsTable.Selection)));
                
                app.audioPlayerObj.TimerFcn=@app.updateAudioPlayerIndicator;
                app.audioPlayerObj.StartFcn=@(~,~) delete(app.playbackPositionXLines);
                app.audioPlayerObj.StopFcn=@(~,~) set(app.PlaySoundButton,'Text','Play Sound');
    
                play(app.audioPlayerObj);
            else
                resume(app.audioPlayerObj);
            end

            app.PlaySoundButton.Text='Click to pause...';
        end

        % Value changed function: weightchannelsevenlyCheckBox
        function weightchannelsevenlyCheckBoxValueChanged(app, event)
            app.createAudioPlayerData();
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.keepVocaliscopeSettingsOverSession~=1 && isfile("lastVocaliscopesettings.mat")
                delete('lastVocaliscopesettings.mat');
            end
            
            delete(app)
            
        end

        % Value changed function: SelectedAreaStartEditField
        function SelectedAreaStartEditFieldValueChanged(app, event)
            if isempty(app.availableChannelsTable.Data)
                return;
            end
            
            if app.SelectedAreaStartEditField.Value>=app.SelectedAreaEndEditField.Value
                app.SelectedAreaEndEditField.Value=app.SelectedAreaStartEditField.Value;
            end


            % also change displayed rectangles, if displayed
            if ~isempty(app.DraggingData.CurrentRectangles)
                delete(app.DraggingData.CurrentRectangles);
                app.DraggingData.CurrentRectangles=[];
            end
            
            app.DraggingData.StartX=app.SelectedAreaStartEditField.Value;
            app.DraggingData.EndX=app.SelectedAreaEndEditField.Value;
            rects=gobjects(0);
            for rectNr=1:numel(app.channelAxes)
                %ylimits=ylim(app.channelAxes(rectNr));
                xlimits=xlim(app.channelAxes(rectNr));
                excerpt=app.channelYValues{rectNr}(app.channelXValues{rectNr}>=xlimits(1) & app.channelXValues{rectNr}<=xlimits(2));
                ylimits=[min(excerpt) max(excerpt)];
                ylimits=ylimits+[-1 1]*0.1*range(ylimits);
                yMin=ylimits(1);
                yMax=ylimits(2);
                rects(rectNr)=rectangle(app.channelAxes(rectNr), ...
                    'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                    'FaceColor', [0.8, 0.8, 1, 0.5], ...
                    'FaceAlpha',0.5,...
                    'EdgeColor', 'none', ...
                    'PickableParts', 'none');
                app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[app.SelectedAreaStartEditField.Value,app.SelectedAreaDurationEditField.Value];
            end
            app.DraggingData.CurrentRectangles=rects;
            arrayfun(@(in) uistack(in,'bottom'),rects);
            
            
            app.updateSelectionAreaEditFieldsLimits();


        end

        % Value changed function: SelectedAreaEndEditField
        function SelectedAreaEndEditFieldValueChanged(app, event)
            if isempty(app.availableChannelsTable.Data)
                return;
            end
            
            if app.SelectedAreaEndEditField.Value<=app.SelectedAreaStartEditField.Value
                app.SelectedAreaStartEditField.Value=app.SelectedAreaEndEditField.Value;
            end


             % also change displayed rectangles, if displayed
            if ~isempty(app.DraggingData.CurrentRectangles)
                delete(app.DraggingData.CurrentRectangles);
                app.DraggingData.CurrentRectangles=[];
            end
            
            app.DraggingData.StartX=app.SelectedAreaStartEditField.Value;
            app.DraggingData.EndX=app.SelectedAreaEndEditField.Value;
            rects=gobjects(0);
            for rectNr=1:numel(app.channelAxes)
                %ylimits=ylim(app.channelAxes(rectNr));
                xlimits=xlim(app.channelAxes(rectNr));
                excerpt=app.channelYValues{rectNr}(app.channelXValues{rectNr}>=xlimits(1) & app.channelXValues{rectNr}<=xlimits(2));
                ylimits=[min(excerpt) max(excerpt)];
                ylimits=ylimits+[-1 1]*0.1*range(ylimits);
                yMin=ylimits(1);
                yMax=ylimits(2);
                rects(rectNr)=rectangle(app.channelAxes(rectNr), ...
                    'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                    'FaceColor', [0.8, 0.8, 1, 0.5], ...
                    'FaceAlpha',0.5,...
                    'EdgeColor', 'none', ...
                    'PickableParts', 'none');
                app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[app.SelectedAreaStartEditField.Value,app.SelectedAreaDurationEditField.Value];
            end
            app.DraggingData.CurrentRectangles=rects;
            arrayfun(@(in) uistack(in,'bottom'),rects);


            app.updateSelectionAreaEditFieldsLimits();


        end

        % Value changed function: SelectedAreaDurationEditField
        function SelectedAreaDurationEditFieldValueChanged(app, event)
            if isempty(app.availableChannelsTable.Data)
                return;
            end

            app.SelectedAreaEndEditField.Value=app.SelectedAreaStartEditField.Value+app.SelectedAreaDurationEditField.Value;

             % also change displayed rectangles, if displayed
            if ~isempty(app.DraggingData.CurrentRectangles)
                delete(app.DraggingData.CurrentRectangles);
                app.DraggingData.CurrentRectangles=[];
            end
            
            app.DraggingData.StartX=app.SelectedAreaStartEditField.Value;
            app.DraggingData.EndX=app.SelectedAreaEndEditField.Value;
            rects=gobjects(0);
            for rectNr=1:numel(app.channelAxes)
                %ylimits=ylim(app.channelAxes(rectNr));
                xlimits=xlim(app.channelAxes(rectNr));
                excerpt=app.channelYValues{rectNr}(app.channelXValues{rectNr}>=xlimits(1) & app.channelXValues{rectNr}<=xlimits(2));
                ylimits=[min(excerpt) max(excerpt)];
                ylimits=ylimits+[-1 1]*0.1*range(ylimits);
                yMin=ylimits(1);
                yMax=ylimits(2);
                rects(rectNr)=rectangle(app.channelAxes(rectNr), ...
                    'Position', [app.DraggingData.StartX, yMin, app.DraggingData.EndX-app.DraggingData.StartX, yMax - yMin], ...
                    'FaceColor', [0.8, 0.8, 1, 0.5], ...
                    'FaceAlpha',0.5,...
                    'EdgeColor', 'none', ...
                    'PickableParts', 'none');
                app.DraggingData.CurrentRectangles(rectNr).Position([1 3])=[app.SelectedAreaStartEditField.Value,app.SelectedAreaDurationEditField.Value];
            end
            app.DraggingData.CurrentRectangles=rects;
            arrayfun(@(in) uistack(in,'bottom'),rects);

            app.updateSelectionAreaEditFieldsLimits();
        end

        % Value changed function: HidecyclesbordersButton
        function HidecyclesbordersButtonValueChanged(app, event)
            value = app.HidecyclesbordersButton.Value;

            if ~isempty(app.cycleBorderHandles)
                if value==1
                    set(app.cycleBorderHandles(app.isvalidhandle(app.cycleBorderHandles)),'Visible','off');
                else
                    set(app.cycleBorderHandles(app.isvalidhandle(app.cycleBorderHandles)),'Visible','on');
                end


            end
            
        end

        % Cell selection callback: parameterResultsUITable
        function parameterResultsUITableCellSelection(app, event)
            if isempty(event.Indices)
                return;
            end

            % first style is the centered style of columns 2 and 3. keep
            % that one
            numOfStyles=size(app.parameterResultsUITable.StyleConfigurations,1);
            if numOfStyles>1
                removeStyle(app.parameterResultsUITable,2:numOfStyles);
            end
        
            % Get the selected row and first column value
            selectedRow = event.Indices(1);
            selectedValue = app.parameterResultsUITable.Data{selectedRow, 1};
        
            matchRows = cellfun(@(x) isequal(x, selectedValue), app.parameterResultsUITable.Data(:, 1)); % Matches in first column
            %nonMatchRows = ~matchRows; % Complement of matching rows
        
            % Define styles
            highlightedStyle = uistyle('BackgroundColor', 'yellow');
            %defaultStyle = uistyle('BackgroundColor', 'white');
        
            % Apply styles in two calls
            addStyle(app.parameterResultsUITable, highlightedStyle, 'row', find(matchRows)); % Apply to matching rows


            % to remove that weird color
            %app.parameterResultsUITable.Selection=[];
        end

        % Menu selected function: CopyleftclickedCellMenu
        function CopyleftclickedCellMenuSelected(app, event)
            if isempty(app.ResultsTab.Parent) || isempty(app.parameterResultsUITable.Selection)
                return;
            end
            clipboard("copy",app.parameterResultsUITable.Data{app.parameterResultsUITable.Selection(1),app.parameterResultsUITable.Selection(2)});

            h=msgbox('Copied to Clipboard successfully...','Success','help');
            pause(1);
            if isvalid(h)
                close(h);
            end
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            persistent lastPath;

            fakefig=uifigure('Visible','off');

            [file,path]=uiputfile([lastPath 'VocaliscopeResults.xlsx'],'Where to save the values');

            close(fakefig);
            if isequal(file,0)
                return;
            else
                lastPath=path;
            end

            excelFilePath=fullfile(path,file);

            progDlg=uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Writing results to xlsx...','Title','Saving in progress');

            %% write to excel
            nWindows=app.calculationResults.numberOfWindows;
            nChannels=numel(app.calculationResults.channelResults);
            windowStartTimes=app.calculationResults.windowStartTimes;

            %% write general info sheet
            progDlg.Message='Writing general info...';

            % i want that the channel name is clickable and refers to the
            % corresponding sheet

            channelNamesAsLink=cell(size(app.calculationResults.channelNames));

            for nr=1:numel(channelNamesAsLink)
                channelNamesAsLink{nr}=sprintf('=HYPERLINK("#%i!A1"; "%s")',nr,app.calculationResults.channelNames{nr});
            end

            % we write the excel file to a local temp file and copy to the
            % destination laster, since writing repeatedly to e.g. a one drive
            % folder may cause problems connected to file access permission.
            localTempFile=[tempname '.xlsx'];

            generalInfo = [{'Channel Name'}            app.calculationResults.channelNames(:)';...
                           {'Channel short Name'}      app.shortenNames(app.calculationResults.channelNames(:)');...
                           {'Channel Source'}          app.calculationResults.channelSource;...
                           {'Applied lag (s)'}         num2cell(app.calculationResults.appliedChannelLag);...
                           {'Selection start (s)'}     {app.calculationResults.selectionStart} repelem({''},nChannels-1);...
                           {'Selection end (s)'}       {app.calculationResults.selectionEnd} repelem({''},nChannels-1);...
                           {'Cycle border values (s)'} repelem({''},nChannels)];


            if isfile(excelFilePath)
                delete(excelFilePath);
            end
            % here, the file is replaced!
            writecell(generalInfo',localTempFile,"Sheet",'General','WriteMode','replacefile','Range','A1','AutoFitWidth',true);

            if isdeployed
                version=app.getexeversion();
                writecell({sprintf('Vocaliscope Version %i.%i',version.Major,version.Minor)},localTempFile,"Sheet",'General','WriteMode','inplace','Range',['A'  num2str(nChannels+2,'%i')],'AutoFitWidth',true);
            else
                version=getVersionFromMlapp('Vocaliscope.mlapp');
                writecell({sprintf('Vocaliscope Version %s',version)},                       localTempFile,"Sheet",'General','WriteMode','inplace','Range',['A'  num2str(nChannels+2,'%i')],'AutoFitWidth',true);
            end


            for channelNr=1:nChannels
                cycleBorders=num2cell(app.calculationResults.channelResults(channelNr).cycleBorderVals);
                if size(cycleBorders,1)==1
                    cycleBorders=cycleBorders';
                end
                writecell(cycleBorders',localTempFile,"Sheet",'General','WriteMode','inplace','Range',['G' num2str(1+channelNr)],'AutoFitWidth',true);
            end


            %% organize by channel nr

            progDlg.Message='Writing calculated parameters';

            parameterNames=fieldnames(app.calculationResults.channelResults);

            parameterNames(contains(parameterNames,{'curr'},"IgnoreCase",true))=[];
            parameterNames(ismember(parameterNames,{'cycleBorderVals' 'cycleDurations' 'allOQBorders' 'allClQBorders'}))=[];
            
            TableHeader={'Parameter','Median','Mean','STD','Min','Max'};
            windowHeaders=arrayfun(@(x) ['window ' num2str(x)], 1:nWindows, 'UniformOutput', false);
            TableHeader=[TableHeader windowHeaders];

            for channelNr=1:nChannels

                tableData=[[{'channel name',                                'channel source',                               '','','','Window start time'} num2cell(app.calculationResults.channelResults(channelNr).currWindowStartTime)];...
                           [{app.calculationResults.channelNames{channelNr},app.calculationResults.channelSource{channelNr},'','','','Window end time'} num2cell(app.calculationResults.channelResults(channelNr).currWindowEndTime)];...
                           [{'',                                            '',                                             '','Total number of cycles in selection',numel(app.calculationResults.channelResults(channelNr).cycleDurations),'number of cycles in this window'} cellfun(@numel,app.calculationResults.channelResults(channelNr).currWindowCycleDurations,'UniformOutput',false)];...
                          TableHeader];
                for parameterNr=1:numel(parameterNames)
                    
                    windowValues={app.calculationResults.channelResults(channelNr).(parameterNames{parameterNr})}';

                    if isempty(windowValues{1})
                        sprintf('the parametter %s in channel %i is empty. skipping...',parameterNames{parameterNr},channelNr);
                        continue;
                    end
                    
                    medians=cellfun(@(in) median(in,"omitnan"),windowValues,'UniformOutput',false);
                    means=cellfun(@(in) mean(in,'omitnan'),windowValues,'UniformOutput',false);
                    stds=cellfun(@(in) std(in,'omitnan'),windowValues,'UniformOutput',false);
                    mins=cellfun(@(in) min(in,[],'omitnan'),windowValues,'UniformOutput',false);
                    maxs=cellfun(@(in) max(in,[],'omitnan'),windowValues,'UniformOutput',false);
    
                    tableData=[tableData;...
                       parameterNames(parameterNr) medians means stds mins maxs num2cell(cell2mat(windowValues))];
    
                end

                % do some number replacements, e.g. infinity values and NaN
                whereIsInf=cellfun(@(in) isequal(in,inf),tableData);
                tableData(whereIsInf)={'∞'};
                whereIsMinusInf=cellfun(@(in) isequal(in,-inf),tableData);
                tableData(whereIsMinusInf)={'-∞'};
                whereIsNaN=cellfun(@(in) isequal(in,NaN),tableData);
                tableData(whereIsNaN)={'NaN'};
                
                writecell(tableData,localTempFile,"Sheet",num2str(channelNr),'WriteMode','overwritesheet','Range','A1');

            end

            try % if excel is installed, we want to create hyperlinks here
                writecell(channelNamesAsLink(:),localTempFile,"Sheet",'General','WriteMode','inplace','Range','A2','AutoFitWidth',true,'UseExcel',true);

                linkback={sprintf('=HYPERLINK("#%s!A1"; "%s")','General','(click here to go back to ''General'')')};
                for channelNr=1:nChannels
                    writecell(linkback,localTempFile,"Sheet",num2str(channelNr),'WriteMode','inplace','Range','A3','UseExcel',true,'AutoFitWidth',true);
                end
            catch ME
                disp(ME.getReport);
            end

            %% freeze panels, only possible with excel instaled

            try
                progDlg.Message='Freezing Panels in Parameter Sheets';
                
                % Freeze four rows and one column
                % Define the full file path to your Excel file
                
                % Create an Excel COM server instance
                excelApp = actxserver('Excel.Application');
                %excelApp.Visible = true;  % Set to true so you can see what's happening
                
                % Open the workbook
                workbook = excelApp.Workbooks.Open(localTempFile);
                
                % Get the collection of sheets
                sheets = workbook.Sheets;
                numSheets = sheets.Count;
                
                % Loop through each sheet
                for idx = 1:numSheets
                    sheet = sheets.Item(idx);
                    
                    % Skip the sheet named 'General'
                    if strcmp(sheet.Name, 'General')
                        continue;
                    end
                    
                    % Activate the sheet
                    sheet.Activate;
                    
                    % To freeze the first 4 rows and the first column,
                    % select cell B5 (since freeze panes locks rows above and columns to the left)
                    sheet.Range('B5').Select;
                    
                    % Freeze the panes by setting FreezePanes to true on the active window
                    excelApp.ActiveWindow.FreezePanes = true;
                end
    
                % Activate the "General" sheet so it's the active sheet when opening the file
                generalSheet = sheets.Item('General');
                generalSheet.Activate;
    
                % Save the changes if needed
                workbook.Save;
                
                % Optionally, close the workbook and quit Excel
                workbook.Close;
    
                excelApp.Quit;
                delete(excelApp);

            catch ME
                disp('Error Freezing panel: probably no excel is installed');
                disp(ME.getReport());

            end

            %% add settings in additional tab

            progDlg.Message='Writing calculation settings...';

            settingsAsTwoColumns=app.struct2cell2col;

            writecell(settingsAsTwoColumns,localTempFile,"Sheet",'lastSettings','WriteMode','overwritesheet','Range','A1');

            %% add written channel data in additional tab

            if app.savechanneldatatoxlsxCheckBox.Value==1
                progDlg.Message='Writing external and interal channel data to xlsx...';
    
                sheetName='channelData';
                if unique(cellfun(@numel,app.channelYValues))>1 % if channels have different number of values, write time and y values for each channel
                    headerCell=cell(2,nChannels*2);
                    headerCell(1,1:2:end)={'time (s)'};
                    headerCell(1,2:2:end)=app.channelNames;
                    headerCell(2,2:2:end)=app.shortenNames(app.channelNames);


                    newcell=cell(0,0);
                    for channelNr=1:numel(app.channelYValues)
                        toBeAdded=num2cell([app.channelXValues{channelNr}(:) app.channelYValues{channelNr}(:)]);
                        newcell(1:size(toBeAdded,1),((channelNr-1)*2+1)+[0 1])=toBeAdded;
                    end
    
                    writecell([headerCell;newcell],localTempFile,"Sheet",sheetName,'Range','A1','AutoFitWidth',false);


                else
                    headerCell=cell(2,nChannels+1);
                    headerCell(1,1)={'time (s)'};
                    headerCell(1,2:end)=app.channelNames;
                    headerCell(2,2:end)=app.shortenNames(app.channelNames);
    
    
                    newcell=num2cell(app.channelXValues{1}(:));
                    for channelNr=1:numel(app.channelYValues)
                        toBeAdded=num2cell(app.channelYValues{channelNr}(:));
                        newcell(1:size(toBeAdded,1),channelNr+1)=toBeAdded;
                    end
    
    
                end
                writecell([headerCell;newcell],localTempFile,"Sheet",sheetName,'Range','A1','AutoFitWidth',false);
            end

            %% writing wav file
            if app.savewavandchannelnamestypesCheckBox.Value==1
                progDlg.Message='Saving multi channel wav file...';
                app.exportChannelsToWAV(1,0,strrep(excelFilePath,'.xlsx','_channelData.wav'));
            end


            progDlg.Message='Copying last calculation settings file...';
            if isfile('lastVocaliscopesettings.mat')
                copyfile('lastVocaliscopesettings.mat',strrep(excelFilePath,'.xlsx','_lastVocaliscopesettings.mat'),'f');
            end

            % here the file is copied from the temp direction to the
            % destination

            try
                copyfile(localTempFile,excelFilePath,'f');
            catch
                
                uialert(app.UIFigure,'Excel file probably opened already, please close to proceed saving excel file','Please close excel file','Icon','warning','CloseFcn',@(~,~) uiresume(app.UIFigure));
                uiwait(app.UIFigure);

                try
                    copyfile(localTempFile,excelFilePath,'f');
                catch ME
                    uialert(app.UIFigure,['Could not move excel file from temp directory to target directory. You can find the file in ' localTempFile],'Error moving excel file to target direction','Icon','error');
                    disp(ME.getReport());
                    if isvalid(progDlg)
                        close(progDlg);
                    end
                    return;
                end
            end
            delete(localTempFile);

            if isvalid(progDlg)
                close(progDlg);
            end

            uialert(app.UIFigure,'Save successfull','Success','Icon','success');

            if app.openfileaftersavingCheckBox.Value==1
                winopen(excelFilePath);
            end

        end

        % Value changed function: HideOQareasButton
        function HideOQareasButtonValueChanged(app, event)
            value = app.HideOQareasButton.Value;
            
            if ~isempty(app.cycleOQHandle)
                if value==1
                    set(app.cycleOQHandle(ishandle(app.cycleOQHandle)),'Visible','off');
                else
                    set(app.cycleOQHandle(ishandle(app.cycleOQHandle)),'Visible','on');
                end
            end

            if value==1
                app.HideOQareasButton.Text='OQ areas are hidden';
            else
                app.HideOQareasButton.Text='Hide OQ areas';
            end
        end

        % Value changed function: ClQareasarehiddenButton
        function ClQareasarehiddenButtonValueChanged(app, event)
            value = app.ClQareasarehiddenButton.Value;
            
            if ~isempty(app.cycleOQHandle)
                if value==1
                    set(app.cycleClQHandle(ishandle(app.cycleClQHandle)),'Visible','off');
                else
                    set(app.cycleClQHandle(ishandle(app.cycleClQHandle)),'Visible','on');
                end
            end

            if value==1
                app.ClQareasarehiddenButton.Text='ClQ areas are hidden';
            else
                app.ClQareasarehiddenButton.Text='Hide ClQ areas';
            end
        end

        % Menu selected function: SelectallMenu
        function SelectallMenuSelected(app, event)
            if ~isempty(app.availableChannelsTable.Data) && ~isequal(app.availableChannelsTable.Selection,1:size(app.availableChannelsTable.Data,1))
                app.availableChannelsTable.Selection=1:size(app.availableChannelsTable.Data,1);
                app.refreshTimePlotDataVisibility();
            end
        end

        % Menu selected function: to1multichannelMenu
        function to1multichannelMenuSelected(app, event)
            if size(app.availableChannelsTable.Data,1)==0
                return;
            end

            toMultiChannel=1;
            selectedChannelsOnly=0;
            path=app.exportChannelsToWAV(toMultiChannel,selectedChannelsOnly);

            if ~isequal(path,0)
                uialert(app.UIFigure,'WAV(s) saved successfully.','Success','Icon','success');
            end
        end

        % Menu selected function: tomultiplesinglechannelMenu
        function tomultiplesinglechannelMenuSelected(app, event)
            if size(app.availableChannelsTable.Data,1)==0
                return;
            end

            toMultiChannel=0;
            selectedChannelsOnly=0;
            app.exportChannelsToWAV(toMultiChannel,selectedChannelsOnly);
            uialert(app.UIFigure,'WAV(s) saved successfully.','Success','Icon','success');

        end

        % Menu selected function: to1multichannelMenu_2
        function to1multichannelMenu_2Selected(app, event)
            if size(app.availableChannelsTable.Data,1)==0
                return;
            end

            toMultiChannel=1;
            selectedChannelsOnly=1;
            app.exportChannelsToWAV(toMultiChannel,selectedChannelsOnly);
             uialert(app.UIFigure,'WAV(s) saved successfully.','Success','Icon','success');

        end

        % Menu selected function: tomultiplesinglechannelMenu_2
        function tomultiplesinglechannelMenu_2Selected(app, event)
            if size(app.availableChannelsTable.Data,1)==0
                return;
            end

            toMultiChannel=0;
            selectedChannelsOnly=1;
            app.exportChannelsToWAV(toMultiChannel,selectedChannelsOnly);
            uialert(app.UIFigure,'WAV(s) saved successfully.','Success','Icon','success');

        end

        % Menu selected function: copyMenu
        function CopywindowdatafromrowofleftclickedcellMenuSelected(app, event)

            app.RowWindowdataMenu.Enable="on";
            app.copycompleterowMenu.Enable="on";
            if ~isempty(event.InteractionInformation.Row)% && ~isempty(event.InteractionInformation.Column)
                rowNumber=event.InteractionInformation.Row;
            elseif ~isempty(app.parameterResultsUITable.Selection(1))
                rowNumber=app.parameterResultsUITable.Selection(1);
            else
                app.RowWindowdataMenu.Enable="off";
                app.copycompleterowMenu.Enable="off";

            end


            completeRow=cell2mat(app.parameterResultsUITable.Data(app.parameterResultsUITable.Selection(1),9:end-1));

            clipboard("copy",completeRow);

             h=msgbox('Copied successfully...','Success','help');
             pause(1);
             if isvalid(h)
                 close(h);
             end
        end

        % Menu selected function: plotMenu
        function PlotwindowdatafromrowofleftclickedcellMenuSelected(app, event)
            completeRow=cell2mat(app.parameterResultsUITable.Data(app.parameterResultsUITable.Selection(1),9:end-1));

            figure('Theme','light'); tl=tiledlayout(2,1);
            ax1=nexttile(tl,1);
            middleOfWindows=(app.calculationResults.windowStartTimes+app.calculationResults.windowEndTimes)/2;
            %plot(ax1,linspace(app.calculationResults.selectionStart,app.calculationResults.selectionEnd,numel(completeRow)),completeRow);

            if isscalar(completeRow)
                marker='o';
            else
                marker='none';
            end

            plot(ax1,middleOfWindows,completeRow,'Marker',marker);
            title(ax1,app.parameterResultsUITable.Data{app.parameterResultsUITable.Selection(1),2});
            
            
            ax2=nexttile(tl,2);
            sourceNr=app.currentResultsTableChannelSources(app.parameterResultsUITable.Selection(1));
            sourceYData=app.channelYValues{sourceNr};
            sourceXData=app.channelXValues{sourceNr};

            %excerpt=sourceXData>=app.calculationResults.selectionStart & sourceXData<=app.calculationResults.selectionEnd;
            excerpt=sourceXData>=app.calculationResults.windowStartTimes(1) & sourceXData<=app.calculationResults.windowEndTimes(end);
            sourceYData=sourceYData(excerpt);
            sourceXData=sourceXData(excerpt);
            plot(ax2,sourceXData,sourceYData);
            title(ax2,{'source data',app.parameterResultsUITable.Data{app.parameterResultsUITable.Selection(1),1}},'Interpreter','none');

            linkaxes([ax1 ax2],'x');

            
            xlabel(tl,'time (s)')

            
            ylim([ax1 ax2],'padded');

            %nexttile(tl,2); plot()
        end

        % Menu selected function: withoutcolumnheaderMenu
        function withoutcolumnheaderMenuSelected(app, event)
            copyHeader=0;
            app.copyCompleteRowToClipboard(copyHeader);
        end

        % Menu selected function: withcolumnheaderMenu
        function withcolumnheaderMenuSelected(app, event)
            copyHeader=1;
            app.copyCompleteRowToClipboard(copyHeader);
        end

        % Context menu opening function: ParameterResultsTableContextMenu
        function ParameterResultsTableContextMenuOpening(app, event)
            app.RowWindowdataMenu.Enable="on";
            app.copycompleterowMenu.Enable="on";
            if ~isempty(event.InteractionInformation.DisplayRow) && ~isempty(event.InteractionInformation.DisplayColumn)
                app.parameterResultsUITable.Selection=[event.InteractionInformation.DisplayRow event.InteractionInformation.DisplayColumn];
            elseif ~isempty(app.parameterResultsUITable.Selection) && ~isempty(event.InteractionInformation.DisplayColumn)
                app.parameterResultsUITable.Selection(2)=event.InteractionInformation.DisplayColumn;
            elseif ~isempty(app.parameterResultsUITable.Selection) && ~isempty(event.InteractionInformation.DisplayRow)
                app.parameterResultsUITable.Selection(1)=event.InteractionInformation.DisplayRow;
            else
                app.RowWindowdataMenu.Enable="off";
                app.copycompleterowMenu.Enable="off";
            end

            % app.parameterResultsUITable.Enable='inactive';
            % drawnow;
            % app.parameterResultsUITable.Enable='on';
            % drawnow;
            % scroll(app.parameterResultsUITable,'cell',app.parameterResultsUITable.Selection);
            % 
            % disp('scroll position'); disp(app.parameterResultsUITable.Selection);
            %% select also all rows of the same source
            numOfStyles=size(app.parameterResultsUITable.StyleConfigurations,1);
            if numOfStyles>1
                removeStyle(app.parameterResultsUITable,2:numOfStyles);
            end

            % Get the selected row and first column value
            selectedRow = app.parameterResultsUITable.Selection(1);
            selectedValue = app.parameterResultsUITable.DisplayData{selectedRow, 1};
        
            matchRows = cellfun(@(x) isequal(x, selectedValue), app.parameterResultsUITable.Data(:, 1)); % Matches in first column
        
            % Define styles
            highlightedStyle = uistyle('BackgroundColor', 'yellow');
        
            % Apply styles in two calls
            addStyle(app.parameterResultsUITable, highlightedStyle, 'row', find(matchRows)); % Apply to matching rows
            

            %% mark the selected cell


            if size(app.parameterResultsUITable.StyleConfigurations,1)>2
                removeStyle(app.parameterResultsUITable,3);
            end
            addStyle(app.parameterResultsUITable,uistyle("BackgroundColor", [0 0.7 1]),'cell',app.parameterResultsUITable.Selection);
            drawnow;
        end

        % Button pushed function: InvertselectionButton
        function InvertselectionButtonPushed(app, event)
            if size(app.availableChannelsTable.Data,1)==0
                return;
            end

            app.availableChannelsTable.Selection=find(~ismember(1:size(app.availableChannelsTable.Data,1),app.availableChannelsTable.Selection));
            app.refreshTimePlotDataVisibility();
        end

        % Size changed function: plottingPanelContainer
        function plottingPanelContainerSizeChanged(app, event)
            persistent debounceTimer;

            if ~isempty(debounceTimer) && isvalid(debounceTimer)
                stop(debounceTimer);
            else
                debounceTimer=timer("ExecutionMode","singleShot",'StartDelay',0.5,'TimerFcn',@(~,~) set(app.tlTime,'OuterPosition',[0 0 1 1]));
            end

            
            start(debounceTimer)


        end

        % Menu selected function: PianoMenu
        function PianoMenuSelected(app, event)
            persistent pianoFig

            if isempty(pianoFig) || ~isvalid(pianoFig)
                pianoFig=pianoGUI();
            else
                figure(pianoFig);
            end
        end

        % Menu selected function: 
        % SavechannelnamessourcetypesandselectedareatoFileMenu
        function SavechannelnamessourcetypesandselectedareatoFileMenuSelected(app, event)
            app.saveChannelNamesTypesAndSelectedArea();
        end

        % Menu selected function: 
        % LoadchannelnamessourcetypesandselectedareafromFileMenu
        function LoadchannelnamessourcetypesandselectedareafromFileMenuSelected(app, event)
            app.loadChannelNamesTypesAndSelectedArea();
        end

        % Menu selected function: IncreasepressMenu
        function IncreasepressMenuSelected(app, event)
            fontsize(app.UIFigure,'increase');
        end

        % Menu selected function: DecreasepressMenu
        function DecreasepressMenuSelected(app, event)
            fontsize(app.UIFigure,'decrease');
        end

        % Button pushed function: DeletenonselectedsignalsButton
        function DeletenonselectedsignalsButtonPushed(app, event)
            selection=1:numel(app.channelAxes);
            selection(app.availableChannelsTable.Selection)=[];
            app.deleteSignals(selection);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            if strcmp(event.Character,'+')
                fontsize(app.UIFigure,'increase');
            elseif strcmp(event.Character,'-')
                fontsize(app.UIFigure,'decrease');
            elseif strcmpi(event.Character,'o')
                app.openShiftTool();
            end
            
            
        end

        % Menu selected function: ShowSelectionEvaluationsMenu
        function ShowSelectionEvaluationsMenuSelected(app, event)
            app.ShowSelectionEvaluationsMenu.Checked='on';
            app.HideSelectionEvaluationsMenu.Checked="off";
            set(app.selectionEvaluationTexts,'Visible','on');
            
        end

        % Menu selected function: HideSelectionEvaluationsMenu
        function HideSelectionEvaluationsMenuSelected(app, event)
            app.HideSelectionEvaluationsMenu.Checked='on';
            app.ShowSelectionEvaluationsMenu.Checked="off";
            set(app.selectionEvaluationTexts,'Visible','off');
            
        end

        % Menu selected function: ShowMaxMenu, ShowMeanMenu, 
        % ...and 4 other components
        function SelectionCalculationsSelectedParametersChanged(app, event)
            if strcmp(event.Source.Checked,'on')
                event.Source.Checked='off';
            else
                event.Source.Checked='on';
            end

            app.refreshSelectionEvaluationText();
        end

        % Menu selected function: LagEstimatorofselectionMenu
        function LagEstimatorofselectionMenuSelected(app, event)
            if numel(app.channelAxes)<2
                return;
            end

            lagEstimationFig=uifigure("NumberTitle","off",'Name','Lag Estimator','Position',[100 100 900 700]);

            gl=uigridlayout(lagEstimationFig,[7 4],'Padding',[0 0 0 0],'RowSpacing',5,'ColumnSpacing',5,'RowHeight',{'1x' '1x' '1x' '3x' '3x' '3x' '1x'});

            signal1DropdownLabel=uilabel(gl,"Text",'1. signal');
            signal1DropdownLabel.Layout.Row=1;
            signal1DropdownLabel.Layout.Column=1;

            signal1Dropdown=uidropdown(gl,"Items",app.shortenNames(app.channelNames),'ItemsData',1:numel(app.channelAxes));
            signal1Dropdown.Layout.Row=2;
            signal1Dropdown.Layout.Column=1;

            signal2DropdownLabel=uilabel(gl,"Text",'2. signal');
            signal2DropdownLabel.Layout.Row=1;
            signal2DropdownLabel.Layout.Column=2;

            signal2Dropdown=uidropdown(gl,"Items",app.shortenNames(app.channelNames),'ItemsData',1:numel(app.channelAxes),'Value',2);
            signal2Dropdown.Layout.Row=2;
            signal2Dropdown.Layout.Column=2;

            alignMethodDropdownLabel=uilabel(gl,"Text",'Estimation Method');
            alignMethodDropdownLabel.Layout.Row=1;
            alignMethodDropdownLabel.Layout.Column=3;

            persistent lastMethod lastLowerBound lastUpperBound;

            alignMethodDropdown=uidropdown(gl,"Items",{'Cross Correlation' 'Generalized Cross Correlation'},'ItemsData',{'xcorr' 'gcc'});
            alignMethodDropdown.Layout.Row=2;
            alignMethodDropdown.Layout.Column=3;

            if ~isempty(lastMethod)
                alignMethodDropdown.Value=lastMethod;
            end

            minimumLagEditFieldLabel=uilabel(gl,'Text','Minimum absolute lag for seach (s)','WordWrap','on');
            minimumLagEditFieldLabel.Layout.Row=3;
            minimumLagEditFieldLabel.Layout.Column=1;

            minimumLagEditField=uieditfield(gl,'numeric','Limits',[0 inf],'Value',0.01,'ValueDisplayFormat','%g s');
            minimumLagEditField.Layout.Row=3;
            minimumLagEditField.Layout.Column=2;

            if ~isempty(lastLowerBound)
                minimumLagEditField.Value=lastLowerBound;
            end

            maximumLagEditFieldLabel=uilabel(gl,'Text','Maximum absolute lag for seach (s)','WordWrap','on');
            maximumLagEditFieldLabel.Layout.Row=3;
            maximumLagEditFieldLabel.Layout.Column=3;

            maximumLagEditField=uieditfield(gl,'numeric','Limits',[0 inf],'Value',1,'ValueDisplayFormat','%g s');
            maximumLagEditField.Layout.Row=3;
            maximumLagEditField.Layout.Column=4;

            if ~isempty(lastUpperBound)
                maximumLagEditField.Value=lastUpperBound;
            end


            plotsPanel=uipanel(gl,"Title",'','BorderType','none');
            plotsPanel.Layout.Row=[4 6];
            plotsPanel.Layout.Column=[1 4];

            copyAsMsButton=uibutton(gl,'Text','Copy shift as ms');
            copyAsMsButton.Layout.Row=7;
            copyAsMsButton.Layout.Column=1;
            copyAsMsButton.UserData.value='0';
            copyAsMsButton.ButtonPushedFcn=@copyAndSuccessFcn;

            copyAsSButton=uibutton(gl,'Text','Copy shift as ms');
            copyAsSButton.Layout.Row=7;
            copyAsSButton.Layout.Column=2;
            copyAsSButton.UserData.value='0';
            copyAsSButton.ButtonPushedFcn=@copyAndSuccessFcn;

            copyAsSamplesButton=uibutton(gl,'Text','Copy shift as ms');
            copyAsSamplesButton.Layout.Row=7;
            copyAsSamplesButton.Layout.Column=3;
            copyAsSamplesButton.UserData.value='0';
            copyAsSamplesButton.ButtonPushedFcn=@copyAndSuccessFcn;

            copyStateLabel=uilabel(gl,'Text','','WordWrap','on','FontColor','red');
            copyStateLabel.Layout.Row=7;
            copyStateLabel.Layout.Column=4;
            

            tl=tiledlayout(plotsPanel,3,1,"Padding","tight","TileSpacing","compact");
            axOriginal=nexttile(tl,1);
            axLags=nexttile(tl,2);
            axAligned=nexttile(tl,3);

            set([signal1Dropdown signal2Dropdown alignMethodDropdown minimumLagEditField maximumLagEditField],'ValueChangedFcn',@(~,~) refreshLagPlot(app));

            refreshLagPlot(app);

            function refreshLagPlot(app)
                signal1=app.channelYValues{signal1Dropdown.Value}(:);
                signal2=app.channelYValues{signal2Dropdown.Value}(:);

                fs1=app.channelSampleRates(signal1Dropdown.Value);
                fs2=app.channelSampleRates(signal2Dropdown.Value);


                % if there is a part selected, we analyze only the
                % selection
                if ~isempty(app.DraggingData.StartX)
                    signal1=signal1(app.channelXValues{signal1Dropdown.Value}>=app.DraggingData.StartX & app.channelXValues{signal1Dropdown.Value}<=app.DraggingData.EndX);
                    signal2=signal2(app.channelXValues{signal2Dropdown.Value}>=app.DraggingData.StartX & app.channelXValues{signal2Dropdown.Value}<=app.DraggingData.EndX);
                end

                % remove mean for correlation analysis
                signal1=signal1-mean(signal1);
                signal2=signal2-mean(signal2);

                % check if equal sampling frequency, if not, upsample
                % signal with lower sampling frequency
                if fs1~=fs2
                    [maxFs,idx]=max([fs1 fs2]);

                    if idx==1
                        signal2=resample(signal2,maxFs,fs2);
                    else
                        signal1=resample(signal1,maxFs,fs1);
                    end
                else
                    maxFs=fs1;
                end

                % pad shorter signal if signals do not have equal length
                if numel(signal1)>numel(signal2)
                    signal2=[signal2;zeros(abs(numel(signal1)-numel(signal2)),1)];
                elseif numel(signal1)<numel(signal2)
                    signal1=[signal1;zeros(abs(numel(signal1)-numel(signal2)),1)];

                end

                plot(axOriginal,(1:numel(signal1))./maxFs,rescale(signal1,-1, 1),'DisplayName','signal 1');
                hold(axOriginal,'on');
                plot(axOriginal,(1:numel(signal2))./maxFs,rescale(signal2,-1, 1),'DisplayName','signal 2');
                hold(axOriginal,'off');

                legend(axOriginal);

                % calculate correlation either with normal xcorr or GCC
                if strcmpi(alignMethodDropdown.Value,'xcorr')
                   [corrVal,lag]=xcorr(signal1,signal2);
                else
                    [~, corrVal, lag] = gccphat(signal1,signal2,1);
                    % Output: bestLag (seconds), corrVal (amplitude), lagMS (seconds)

                    corrVal=abs(corrVal);

                end


                plot(axLags,lag/maxFs,corrVal,'k');
                title(axLags,'Lag of signal 1 in reference to signal 2');
                xlabel(axLags,'lag (s)');

                limits=[-1 1]*maximumLagEditField.Value;

                xls=xline(axLags,[-maximumLagEditField.Value maximumLagEditField.Value minimumLagEditField.Value -minimumLagEditField.Value],'LineStyle','--','Color','r');
                arrayfun(@(in) uistack(in,'bottom'),xls);

                xlim(axLags,limits+[-1 1]*0.1*range(limits));

                corrVal((abs(lag)/maxFs)<minimumLagEditField.Value | (abs(lag)/maxFs)>maximumLagEditField.Value)=0;

                [~,bestLagIdx]=max(abs(corrVal));

                bestLag=lag(bestLagIdx);

                % delay 'earlier' signal
                if bestLag>0
                    signal2=[zeros(abs(bestLag),1);signal2];
                else
                    signal1=[zeros(abs(bestLag),1);signal1];
                end

                plot(axAligned,(1:numel(signal1))./maxFs,rescale(signal1,-1, 1),'DisplayName','signal 1');
                hold(axAligned,'on');
                plot(axAligned,(1:numel(signal2))./maxFs,rescale(signal2,-1, 1),'DisplayName','signal 2');
                hold(axAligned,'off');
                legend(axAligned);

                if bestLag>0
                    title(axAligned,sprintf('Added lag to signal 2: %i samples or %f s',abs(bestLag),abs(bestLag)/maxFs));
                else
                    title(axAligned,sprintf('Added lag to signal 1: %i samples or %f s',abs(bestLag),abs(bestLag)/maxFs));
                end

                copyAsMsButton.UserData.value=num2str(abs(bestLag)/maxFs*1000,'%.2f');
                copyAsSButton.UserData.value=num2str(abs(bestLag)/maxFs,'%.2f');
                copyAsSamplesButton.UserData.value=num2str(abs(bestLag),'%i');
                
                lastMethod=alignMethodDropdown.Value;
                lastLowerBound=minimumLagEditField.Value;
                lastUpperBound=maximumLagEditField.Value;
            end

            function copyAndSuccessFcn(src,~)
                clipboard("copy",src.UserData.value);
                copyStateLabel.Text='Copied successfully to clipboard!';
                pause(1);
                copyStateLabel.Text='';

            end

        end

        % Callback function: CasesensitiveCheckBox, ChannelnameCheckBox, 
        % ...and 3 other components
        function FilterResultsTableEditFieldValueChanging(app, event)

            if event.Source==app.FilterResultsTableEditField
                filterText = event.Value;
            else
                filterText = app.FilterResultsTableEditField.Value;
            end

            originalTable=app.FilterResultsTableEditField.UserData.ResultsTable;

            if app.CasesensitiveCheckBox.Value==1
                doIgnoreCase=false;
            else
                doIgnoreCase=true;
            end

            if ~app.ChannelnameCheckBox.Value && ~app.ParameternameCheckBox.Value && ~app.SourcetypeCheckBox.Value
                app.ChannelnameCheckBox.Value=1;
                columnsToSearchFor=1;
            else
                columnsToSearchFor=find([app.ChannelnameCheckBox.Value app.ParameternameCheckBox.Value app.SourcetypeCheckBox.Value]==1);
            end

            if ~isempty(filterText)
                suitingRows=any(contains(originalTable(:,columnsToSearchFor),filterText,'IgnoreCase',doIgnoreCase),2);
                app.parameterResultsUITable.Data=originalTable(suitingRows,:);
            else
                app.parameterResultsUITable.Data=originalTable;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileimportappendexportMenu = uimenu(app.UIFigure);
            app.FileimportappendexportMenu.Text = '&File import/append/export';
            app.ImportappendchanneldatafromfilesMenu = uimenu(app.FileimportappendexportMenu);
            app.ImportappendchanneldatafromfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @importDataFromFile, true);
            app.ImportappendchanneldatafromfilesMenu.Text = '&Import/append channel data from files';
            app.ExportchanneldatatowavandmatMenu = uimenu(app.FileimportappendexportMenu);
            app.ExportchanneldatatowavandmatMenu.Text = '&Export channel data to wav and mat...';
            app.allavailablechannelsMenu = uimenu(app.ExportchanneldatatowavandmatMenu);
            app.allavailablechannelsMenu.Text = 'all available channels';
            app.to1multichannelMenu = uimenu(app.allavailablechannelsMenu);
            app.to1multichannelMenu.MenuSelectedFcn = createCallbackFcn(app, @to1multichannelMenuSelected, true);
            app.to1multichannelMenu.Text = 'to 1 multi channel';
            app.tomultiplesinglechannelMenu = uimenu(app.allavailablechannelsMenu);
            app.tomultiplesinglechannelMenu.MenuSelectedFcn = createCallbackFcn(app, @tomultiplesinglechannelMenuSelected, true);
            app.tomultiplesinglechannelMenu.Text = 'to multiple single channel';
            app.selectedchannelsonlyMenu = uimenu(app.ExportchanneldatatowavandmatMenu);
            app.selectedchannelsonlyMenu.Text = 'selected channels only';
            app.to1multichannelMenu_2 = uimenu(app.selectedchannelsonlyMenu);
            app.to1multichannelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @to1multichannelMenu_2Selected, true);
            app.to1multichannelMenu_2.Text = 'to 1 multi channel';
            app.tomultiplesinglechannelMenu_2 = uimenu(app.selectedchannelsonlyMenu);
            app.tomultiplesinglechannelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @tomultiplesinglechannelMenu_2Selected, true);
            app.tomultiplesinglechannelMenu_2.Text = 'to multiple single channel';
            app.SavechannelnamessourcetypesandselectedareatoFileMenu = uimenu(app.FileimportappendexportMenu);
            app.SavechannelnamessourcetypesandselectedareatoFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavechannelnamessourcetypesandselectedareatoFileMenuSelected, true);
            app.SavechannelnamessourcetypesandselectedareatoFileMenu.Visible = 'off';
            app.SavechannelnamessourcetypesandselectedareatoFileMenu.Text = '&Save channel names, source types and selected area to File';
            app.LoadchannelnamessourcetypesandselectedareafromFileMenu = uimenu(app.FileimportappendexportMenu);
            app.LoadchannelnamessourcetypesandselectedareafromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadchannelnamessourcetypesandselectedareafromFileMenuSelected, true);
            app.LoadchannelnamessourcetypesandselectedareafromFileMenu.Visible = 'off';
            app.LoadchannelnamessourcetypesandselectedareafromFileMenu.Text = '&Load channel names, source types and selected area from File';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = '&View';
            app.FontSizeMenu = uimenu(app.ViewMenu);
            app.FontSizeMenu.Text = 'Font Size...';
            app.IncreasepressMenu = uimenu(app.FontSizeMenu);
            app.IncreasepressMenu.MenuSelectedFcn = createCallbackFcn(app, @IncreasepressMenuSelected, true);
            app.IncreasepressMenu.Tooltip = {'Font Size can also be controlled by pressing + or -'};
            app.IncreasepressMenu.Text = 'Increase (press +)';
            app.DecreasepressMenu = uimenu(app.FontSizeMenu);
            app.DecreasepressMenu.MenuSelectedFcn = createCallbackFcn(app, @DecreasepressMenuSelected, true);
            app.DecreasepressMenu.Tooltip = {'Font Size can also be controlled by pressing + or -'};
            app.DecreasepressMenu.Text = 'Decrease (press -)';
            app.SelectionevaluationsMenu = uimenu(app.ViewMenu);
            app.SelectionevaluationsMenu.Text = 'Selection evaluations...';
            app.ShowSelectionEvaluationsMenu = uimenu(app.SelectionevaluationsMenu);
            app.ShowSelectionEvaluationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowSelectionEvaluationsMenuSelected, true);
            app.ShowSelectionEvaluationsMenu.Checked = 'on';
            app.ShowSelectionEvaluationsMenu.Text = 'Show';
            app.HideSelectionEvaluationsMenu = uimenu(app.SelectionevaluationsMenu);
            app.HideSelectionEvaluationsMenu.MenuSelectedFcn = createCallbackFcn(app, @HideSelectionEvaluationsMenuSelected, true);
            app.HideSelectionEvaluationsMenu.Text = 'Hide';
            app.ShowMeanMenu = uimenu(app.SelectionevaluationsMenu);
            app.ShowMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionCalculationsSelectedParametersChanged, true);
            app.ShowMeanMenu.Separator = 'on';
            app.ShowMeanMenu.Checked = 'on';
            app.ShowMeanMenu.Text = 'Show Mean';
            app.ShowMedianMenu = uimenu(app.SelectionevaluationsMenu);
            app.ShowMedianMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionCalculationsSelectedParametersChanged, true);
            app.ShowMedianMenu.Checked = 'on';
            app.ShowMedianMenu.Text = 'Show Median';
            app.ShowMinMenu = uimenu(app.SelectionevaluationsMenu);
            app.ShowMinMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionCalculationsSelectedParametersChanged, true);
            app.ShowMinMenu.Checked = 'on';
            app.ShowMinMenu.Text = 'Show Min';
            app.ShowMaxMenu = uimenu(app.SelectionevaluationsMenu);
            app.ShowMaxMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionCalculationsSelectedParametersChanged, true);
            app.ShowMaxMenu.Checked = 'on';
            app.ShowMaxMenu.Text = 'Show Max';
            app.ShowRangeMenu = uimenu(app.SelectionevaluationsMenu);
            app.ShowRangeMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionCalculationsSelectedParametersChanged, true);
            app.ShowRangeMenu.Checked = 'on';
            app.ShowRangeMenu.Text = 'Show Range';
            app.ShowStandardDeviationMenu = uimenu(app.SelectionevaluationsMenu);
            app.ShowStandardDeviationMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionCalculationsSelectedParametersChanged, true);
            app.ShowStandardDeviationMenu.Checked = 'on';
            app.ShowStandardDeviationMenu.Text = 'Show Standard Deviation';
            app.ShowRootMeanSquareMenu = uimenu(app.SelectionevaluationsMenu);
            app.ShowRootMeanSquareMenu.Checked = 'on';
            app.ShowRootMeanSquareMenu.Text = 'Show Root-Mean-Square';
            app.ExtrasMenu = uimenu(app.UIFigure);
            app.ExtrasMenu.Text = '&Extras';
            app.PianoMenu = uimenu(app.ExtrasMenu);
            app.PianoMenu.MenuSelectedFcn = createCallbackFcn(app, @PianoMenuSelected, true);
            app.PianoMenu.Text = 'Piano';
            app.LagEstimatorofselectionMenu = uimenu(app.ExtrasMenu);
            app.LagEstimatorofselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @LagEstimatorofselectionMenuSelected, true);
            app.LagEstimatorofselectionMenu.Text = 'Lag Estimator (of selection)';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.uOupenShiftToolButton = uibutton(app.GridLayout, 'push');
            app.uOupenShiftToolButton.ButtonPushedFcn = createCallbackFcn(app, @uOupenShiftToolButtonPushed, true);
            app.uOupenShiftToolButton.Interpreter = 'html';
            app.uOupenShiftToolButton.Text = '<u>O</u>pen Shift Tool';
            app.CalculateParametersButton = uibutton(app.GridLayout, 'push');
            app.CalculateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateParametersButtonPushed, true);
            app.CalculateParametersButton.Text = 'Calculate Parameters';
            app.SaveResultsButton = uibutton(app.GridLayout, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.openfileaftersavingCheckBox = uicheckbox(app.GridLayout);
            app.openfileaftersavingCheckBox.Text = 'open file after saving';
            app.PlaySoundButton = uibutton(app.GridLayout, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Tooltip = {'Play channels as audio'; 'The channels'' MEAN is subtracted'};
            app.PlaySoundButton.Text = 'Play Sound';
            app.weightchannelsevenlyCheckBox = uicheckbox(app.GridLayout);
            app.weightchannelsevenlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @weightchannelsevenlyCheckBoxValueChanged, true);
            app.weightchannelsevenlyCheckBox.Text = {'weight'; 'channels'; 'evenly'};
            app.weightchannelsevenlyCheckBox.Value = true;
            app.StopSoundButton = uibutton(app.GridLayout, 'push');
            app.StopSoundButton.ButtonPushedFcn = createCallbackFcn(app, @StopSoundButtonPushed, true);
            app.StopSoundButton.Text = 'Stop Sound';
            app.SoundVolumeSlider = uislider(app.GridLayout);
            app.SoundVolumeSlider.Limits = [0 1];
            app.SoundVolumeSlider.MajorTicks = [0 0.5 1];
            app.SoundVolumeSlider.Tooltip = {'Sound Volume'; 'No effect while playing'};
            app.SoundVolumeSlider.Value = 0.5;
            app.availableChannelsTable = uitable(app.GridLayout);
            app.availableChannelsTable.ColumnName = {'Channel name'; 'source'};
            app.availableChannelsTable.ColumnSortable = [false false];
            app.availableChannelsTable.SelectionType = 'row';
            app.availableChannelsTable.ColumnEditable = [false true];
            app.availableChannelsTable.CellEditCallback = createCallbackFcn(app, @adjustDataSourceOnTableEdit, true);
            app.availableChannelsTable.SelectionChangedFcn = createCallbackFcn(app, @changeDisplayedChannels, true);
            app.SelectallButton = uibutton(app.GridLayout, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.GridLayout, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.InvertselectionButton = uibutton(app.GridLayout, 'push');
            app.InvertselectionButton.ButtonPushedFcn = createCallbackFcn(app, @InvertselectionButtonPushed, true);
            app.InvertselectionButton.Text = 'Invert selection';
            app.HidecyclesbordersButton = uibutton(app.GridLayout, 'state');
            app.HidecyclesbordersButton.ValueChangedFcn = createCallbackFcn(app, @HidecyclesbordersButtonValueChanged, true);
            app.HidecyclesbordersButton.Text = 'Hide cycles borders';
            app.HideOQareasButton = uibutton(app.GridLayout, 'state');
            app.HideOQareasButton.ValueChangedFcn = createCallbackFcn(app, @HideOQareasButtonValueChanged, true);
            app.HideOQareasButton.Tooltip = {'If activated, the OQ and ClQ areas are hidden. OQ and ClQ areas are calculated only for GAW and EGG signals.'};
            app.HideOQareasButton.Text = 'Hide OQ areas';
            app.ClQareasarehiddenButton = uibutton(app.GridLayout, 'state');
            app.ClQareasarehiddenButton.ValueChangedFcn = createCallbackFcn(app, @ClQareasarehiddenButtonValueChanged, true);
            app.ClQareasarehiddenButton.Tooltip = {'If activated, the OQ and ClQ areas are hidden. OQ and ClQ areas are calculated only for GAW and EGG signals.'};
            app.ClQareasarehiddenButton.Text = 'ClQ areas are hidden';
            app.ClQareasarehiddenButton.Value = true;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.GridLayout4 = uigridlayout(app.GraphTab);
            app.InteractionModeButtonGroup = uibuttongroup(app.GridLayout4);
            app.InteractionModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InteractionModeButtonGroupSelectionChanged, true);
            app.InteractionModeButtonGroup.Title = 'Interaction Mode';
            app.normalButton = uiradiobutton(app.InteractionModeButtonGroup);
            app.normalButton.Text = 'normal';
            app.normalButton.Value = true;
            app.MarkAreaButton = uiradiobutton(app.InteractionModeButtonGroup);
            app.MarkAreaButton.Text = 'Mark Area';
            app.MoveGraphButton = uiradiobutton(app.InteractionModeButtonGroup);
            app.MoveGraphButton.Text = 'Move Graph';
            app.UndoallshiftsButton = uibutton(app.GridLayout4, 'push');
            app.UndoallshiftsButton.ButtonPushedFcn = createCallbackFcn(app, @UndoallshiftsButtonPushed, true);
            app.UndoallshiftsButton.Text = 'Undo all shifts';
            app.plottingPanelContainer = uipanel(app.GridLayout4);
            app.plottingPanelContainer.SizeChangedFcn = createCallbackFcn(app, @plottingPanelContainerSizeChanged, true);
            app.plottingPanel = uipanel(app.plottingPanelContainer);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout5 = uigridlayout(app.ResultsTab);
            app.parameterResultsUITable = uitable(app.GridLayout5);
            app.parameterResultsUITable.ColumnName = {'Channel name'; 'Parameter'; 'Source'; 'Median'; 'Mean'; 'STD'; 'Min'; 'Max'};
            app.parameterResultsUITable.RowName = {};
            app.parameterResultsUITable.ColumnSortable = [true true true true true true true true];
            app.parameterResultsUITable.CellSelectionCallback = createCallbackFcn(app, @parameterResultsUITableCellSelection, true);
            app.parameterResultsUITable.Multiselect = 'off';
            app.FilterResultsTableEditField = uieditfield(app.GridLayout5, 'text');
            app.FilterResultsTableEditField.ValueChangingFcn = createCallbackFcn(app, @FilterResultsTableEditFieldValueChanging, true);
            app.FilterResultsTableEditField.Placeholder = 'Type here to filter results table';
            app.CasesensitiveCheckBox = uicheckbox(app.GridLayout5);
            app.CasesensitiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterResultsTableEditFieldValueChanging, true);
            app.CasesensitiveCheckBox.Text = 'Case sensitive';
            app.SearchinthesecolumnsLabel = uilabel(app.GridLayout5);
            app.SearchinthesecolumnsLabel.Text = 'Search in these columns:';
            app.ChannelnameCheckBox = uicheckbox(app.GridLayout5);
            app.ChannelnameCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterResultsTableEditFieldValueChanging, true);
            app.ChannelnameCheckBox.Text = 'Channel name';
            app.ChannelnameCheckBox.Value = true;
            app.ParameternameCheckBox = uicheckbox(app.GridLayout5);
            app.ParameternameCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterResultsTableEditFieldValueChanging, true);
            app.ParameternameCheckBox.Text = 'Parameter name';
            app.ParameternameCheckBox.Value = true;
            app.SourcetypeCheckBox = uicheckbox(app.GridLayout5);
            app.SourcetypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterResultsTableEditFieldValueChanging, true);
            app.SourcetypeCheckBox.Text = 'Source type';
            app.SourcetypeCheckBox.Value = true;
            app.showngraphswithoutscrollingDropDownLabel = uilabel(app.GridLayout);
            app.showngraphswithoutscrollingDropDownLabel.Text = 'shown graphs without scrolling';
            app.showngraphswithoutscrollingDropDown = uidropdown(app.GridLayout);
            app.showngraphswithoutscrollingDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.showngraphswithoutscrollingDropDown.ValueChangedFcn = createCallbackFcn(app, @changeNumberOfGraphsWithoutScrolling, true);
            app.showngraphswithoutscrollingDropDown.Value = '4';
            app.SelectedAreaLabel = uilabel(app.GridLayout);
            app.SelectedAreaLabel.Text = 'Selected Area:';
            app.SelectionstartLabel = uilabel(app.GridLayout);
            app.SelectionstartLabel.Text = 'Start:';
            app.SelectedAreaStartEditField = uieditfield(app.GridLayout, 'numeric');
            app.SelectedAreaStartEditField.ValueDisplayFormat = '%30.16g';
            app.SelectedAreaStartEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedAreaStartEditFieldValueChanged, true);
            app.SelectedAreaStartEditField.Editable = 'off';
            app.SelectedAreaStartEditField.Value = -Inf;
            app.SelectionendLabel = uilabel(app.GridLayout);
            app.SelectionendLabel.Text = 'End:';
            app.SelectedAreaEndEditField = uieditfield(app.GridLayout, 'numeric');
            app.SelectedAreaEndEditField.ValueDisplayFormat = '%30.16g';
            app.SelectedAreaEndEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedAreaEndEditFieldValueChanged, true);
            app.SelectedAreaEndEditField.Editable = 'off';
            app.SelectedAreaEndEditField.Value = Inf;
            app.DurationEditFieldLabel = uilabel(app.GridLayout);
            app.DurationEditFieldLabel.Text = 'Duration:';
            app.SelectedAreaDurationEditField = uieditfield(app.GridLayout, 'numeric');
            app.SelectedAreaDurationEditField.Limits = [0 Inf];
            app.SelectedAreaDurationEditField.ValueDisplayFormat = '%30.16g';
            app.SelectedAreaDurationEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedAreaDurationEditFieldValueChanged, true);
            app.SelectedAreaDurationEditField.Editable = 'off';
            app.DeleteselectedsignalsButton = uibutton(app.GridLayout, 'push');
            app.DeleteselectedsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedsignalsButtonPushed, true);
            app.DeleteselectedsignalsButton.Text = 'Delete selected signals';
            app.savewavandchannelnamestypesCheckBox = uicheckbox(app.GridLayout);
            app.savewavandchannelnamestypesCheckBox.Text = 'save wav and channel names/types';
            app.savewavandchannelnamestypesCheckBox.Value = true;
            app.DeletenonselectedsignalsButton = uibutton(app.GridLayout, 'push');
            app.DeletenonselectedsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @DeletenonselectedsignalsButtonPushed, true);
            app.DeletenonselectedsignalsButton.Text = 'Delete non selected signals';
            app.savechanneldatatoxlsxCheckBox = uicheckbox(app.GridLayout);
            app.savechanneldatatoxlsxCheckBox.Text = 'save channel data to xlsx';
            app.ParameterResultsTableContextMenu = uicontextmenu(app.UIFigure);
            app.ParameterResultsTableContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ParameterResultsTableContextMenuOpening, true);
            app.CopyleftclickedCellMenu = uimenu(app.ParameterResultsTableContextMenu);
            app.CopyleftclickedCellMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyleftclickedCellMenuSelected, true);
            app.CopyleftclickedCellMenu.Accelerator = 'C';
            app.CopyleftclickedCellMenu.Text = 'Copy left-clicked Cell';
            app.RowWindowdataMenu = uimenu(app.ParameterResultsTableContextMenu);
            app.RowWindowdataMenu.Text = 'Row Window data...';
            app.copyMenu = uimenu(app.RowWindowdataMenu);
            app.copyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopywindowdatafromrowofleftclickedcellMenuSelected, true);
            app.copyMenu.Accelerator = 'v';
            app.copyMenu.Text = 'copy';
            app.plotMenu = uimenu(app.RowWindowdataMenu);
            app.plotMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotwindowdatafromrowofleftclickedcellMenuSelected, true);
            app.plotMenu.Accelerator = 'p';
            app.plotMenu.Text = 'plot';
            app.copycompleterowMenu = uimenu(app.ParameterResultsTableContextMenu);
            app.copycompleterowMenu.Text = 'copy complete row...';
            app.withoutcolumnheaderMenu = uimenu(app.copycompleterowMenu);
            app.withoutcolumnheaderMenu.MenuSelectedFcn = createCallbackFcn(app, @withoutcolumnheaderMenuSelected, true);
            app.withoutcolumnheaderMenu.Accelerator = 'b';
            app.withoutcolumnheaderMenu.Text = 'without column header';
            app.withcolumnheaderMenu = uimenu(app.copycompleterowMenu);
            app.withcolumnheaderMenu.MenuSelectedFcn = createCallbackFcn(app, @withcolumnheaderMenuSelected, true);
            app.withcolumnheaderMenu.Text = 'with column header';
            % Assign app.ParameterResultsTableContextMenu
            app.parameterResultsUITable.ContextMenu = app.ParameterResultsTableContextMenu;
            app.channelTableContextMenu = uicontextmenu(app.UIFigure);
            app.SelectallMenu = uimenu(app.channelTableContextMenu);
            app.SelectallMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectallMenuSelected, true);
            app.SelectallMenu.Accelerator = 'A';
            app.SelectallMenu.Text = 'Select all';
            % Assign app.channelTableContextMenu
            app.availableChannelsTable.ContextMenu = app.channelTableContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vocaliscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end