classdef VocaliscopeCalculationSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        loadSettingsfromfileButton      matlab.ui.control.Button
        KeepSettingsoverSessionsCheckBox  matlab.ui.control.CheckBox
        calculateparametersButton       matlab.ui.control.Button
        previewcyclesButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        FilterParameterToCalculateEditField  matlab.ui.control.EditField
        channelusedasmaskLabel          matlab.ui.control.Label
        ChosenChannelEditField          matlab.ui.control.NumericEditField
        UsemaskCheckBox                 matlab.ui.control.CheckBox
        CheckingoruncheckingnodesbelowhasnoeffectyetLabel  matlab.ui.control.Label
        WindowOverlapmsEditField        matlab.ui.control.NumericEditField
        WindowOverlapmsEditFieldLabel   matlab.ui.control.Label
        WindowLengthmsEditField         matlab.ui.control.NumericEditField
        WindowLengthmsEditFieldLabel    matlab.ui.control.Label
        WindowedAnalysisCheckBox        matlab.ui.control.CheckBox
        UncheckallButton                matlab.ui.control.Button
        CheckallButton                  matlab.ui.control.Button
        CollapseallnodesButton          matlab.ui.control.Button
        ExpandallnodesButton            matlab.ui.control.Button
        parameterExplanation            matlab.ui.control.Label
        ParametersTree                  matlab.ui.container.CheckBoxTree
        FundamentalFrequencyMeasuresNode  matlab.ui.container.TreeNode
        FundamentalFrequencyHzNode      matlab.ui.container.TreeNode
        CycleDurationmsNode             matlab.ui.container.TreeNode
        MaximumHarmonicHzNode           matlab.ui.container.TreeNode
        MinimumSubharmonicHzNode        matlab.ui.container.TreeNode
        AmplitudePerturbationMeasuresNode  matlab.ui.container.TreeNode
        MeanShimmerdBNode               matlab.ui.container.TreeNode
        ShimmerNode                     matlab.ui.container.TreeNode
        AmplitudePerturbationQuotientAPQNode  matlab.ui.container.TreeNode
        AmplitudePerturbationFactorAPFNode  matlab.ui.container.TreeNode
        AmplitudeVariabilityIndexdBAVINode  matlab.ui.container.TreeNode
        PeriodPerturbationMeasuresNode  matlab.ui.container.TreeNode
        MeanJittermsNode                matlab.ui.container.TreeNode
        JitterNode                      matlab.ui.container.TreeNode
        JitterRatioNode                 matlab.ui.container.TreeNode
        JitterFactorNode                matlab.ui.container.TreeNode
        PeriodPerturbationQuotientPPQNode  matlab.ui.container.TreeNode
        PeriodPerturbationFactorPPFNode  matlab.ui.container.TreeNode
        RelativeAveragePerturbationV1RAPNode  matlab.ui.container.TreeNode
        RelativeAveragePerturbationV2RAPNode  matlab.ui.container.TreeNode
        PeriodVariabilityIndexPVINode   matlab.ui.container.TreeNode
        EnergyPerturbationMeasuresNode  matlab.ui.container.TreeNode
        EnergyPerturbationQuotientEPQNode  matlab.ui.container.TreeNode
        EnergyPerturbationFactorEPFNode  matlab.ui.container.TreeNode
        NoiseMeasuresNode               matlab.ui.container.TreeNode
        HarmonicsIntensityNode          matlab.ui.container.TreeNode
        HarmonicstoNoiseRatiodBHNRNode  matlab.ui.container.TreeNode
        NormalizedNoiseEnergydBNNENode  matlab.ui.container.TreeNode
        SpectralFlatnessNode            matlab.ui.container.TreeNode
        GlottaltoNoiseExcitationRatioGNENode  matlab.ui.container.TreeNode
        WaveformMatchingCoefcientWMCNode  matlab.ui.container.TreeNode
        MeanWaveformMatchingCoefcientNode  matlab.ui.container.TreeNode
        SignaltoNoiseRatioV1dBSNRNode   matlab.ui.container.TreeNode
        SignaltoNoiseRatioV2dBSNRNode   matlab.ui.container.TreeNode
        CepstralPeakProminenceIdBCPPNode  matlab.ui.container.TreeNode
        CepstralPeakProminenceIIdBCPPNode  matlab.ui.container.TreeNode
        MechanicalMeasuresNode          matlab.ui.container.TreeNode
        Stiffness1sNode                 matlab.ui.container.TreeNode
        PeakClosingVelocitypxsNode      matlab.ui.container.TreeNode
        PeakAccelerationpxs2Node        matlab.ui.container.TreeNode
        AmplitudetoLengthRatioNode      matlab.ui.container.TreeNode
        GlottalAreaMeasuresNode         matlab.ui.container.TreeNode
        OpenQuotientOQNode              matlab.ui.container.TreeNode
        ClosingQuotientClQNode          matlab.ui.container.TreeNode
        SpeedQuotientSQNode             matlab.ui.container.TreeNode
        SpeedIndexSINode                matlab.ui.container.TreeNode
        RateQuotientNode                matlab.ui.container.TreeNode
        AsymmetryQuotientNode           matlab.ui.container.TreeNode
        GlottisGapIndexGGINode          matlab.ui.container.TreeNode
        PlateauQuotientNode             matlab.ui.container.TreeNode
        GlottalAreaIndexGAINode         matlab.ui.container.TreeNode
        GlottalAreaPeriodicityMeasuresNode  matlab.ui.container.TreeNode
        AmplitudePeriodicityNode        matlab.ui.container.TreeNode
        TimePeriodicityNode             matlab.ui.container.TreeNode
        GlottalAreaDerivativeMeasuresNode  matlab.ui.container.TreeNode
        MaximumAreaDeclinationRateMpxsMADRNode  matlab.ui.container.TreeNode
        AmplitudeQuotientsNode          matlab.ui.container.TreeNode
        GlottalAreaSymmetryMeasuresNode  matlab.ui.container.TreeNode
        PhaseAsymmetryIndexNode         matlab.ui.container.TreeNode
        SpatialSymmetryIndexNode        matlab.ui.container.TreeNode
        DynamicRangeSymmetryIndexNode   matlab.ui.container.TreeNode
        AmplitudeSymmetryIndexNode      matlab.ui.container.TreeNode
        WaveformSymmetryIndexNode       matlab.ui.container.TreeNode
        PhaseAsymmetryNode              matlab.ui.container.TreeNode
        SpatialSymmetryNode             matlab.ui.container.TreeNode
        DynamicRangeSymmetryNode        matlab.ui.container.TreeNode
        AmplitudeSymmetryNode           matlab.ui.container.TreeNode
        BasicmeasurementsNode           matlab.ui.container.TreeNode
        SumNode                         matlab.ui.container.TreeNode
        MedianNode                      matlab.ui.container.TreeNode
        MeanarithmeticNode              matlab.ui.container.TreeNode
        HarmonicMeanNode                matlab.ui.container.TreeNode
        GeometricMeanNode               matlab.ui.container.TreeNode
        MinimumNode                     matlab.ui.container.TreeNode
        MaximumNode                     matlab.ui.container.TreeNode
        standarddeviationSTDNode        matlab.ui.container.TreeNode
        roommeansquareRMSNode           matlab.ui.container.TreeNode
        ModeNode                        matlab.ui.container.TreeNode
        Quantilesfrom5in5stepsto95Node  matlab.ui.container.TreeNode
        InterquartilerangeIQRNode       matlab.ui.container.TreeNode
        SkewnessNode                    matlab.ui.container.TreeNode
        KurtosisNode                    matlab.ui.container.TreeNode
        AdditionalspectraldescriptorsNode  matlab.ui.container.TreeNode
        spectralCrestNode               matlab.ui.container.TreeNode
        spectralFluxNode                matlab.ui.container.TreeNode
        spectralSpreadNode              matlab.ui.container.TreeNode
        spectralSkewnessspectralTiltNode  matlab.ui.container.TreeNode
        spectralKurtosisNode            matlab.ui.container.TreeNode
        spectralCentroidNode            matlab.ui.container.TreeNode
        spectralEntropyNode             matlab.ui.container.TreeNode
        spectralSlopeNode               matlab.ui.container.TreeNode
        spectralDecreaseNode            matlab.ui.container.TreeNode
        spectralRolloffPointNode        matlab.ui.container.TreeNode
        harmonicRatioNode               matlab.ui.container.TreeNode
        HarmonictoNoiseRatioHNRviabiasedxcorrNode  matlab.ui.container.TreeNode
        SignaltoNoiseRatiodBcarrierSNRNode  matlab.ui.container.TreeNode
        OthersNode                      matlab.ui.container.TreeNode
        zerocrossrateNode               matlab.ui.container.TreeNode
        Node                            matlab.ui.container.TreeNode
        minimumcycledurationmsEditField  matlab.ui.control.NumericEditField
        minimumcycledurationmsEditFieldLabel  matlab.ui.control.Label
        maximumcycledurationmsEditField  matlab.ui.control.NumericEditField
        maximumcycledurationmsEditFieldLabel  matlab.ui.control.Label
        foandcycledurationrangeLabel    matlab.ui.control.Label
        maximumfoHzEditField            matlab.ui.control.NumericEditField
        minimumfoHzEditField            matlab.ui.control.NumericEditField
        maximumfoHzEditFieldLabel       matlab.ui.control.Label
        minimumfoHzEditFieldLabel       matlab.ui.control.Label
        CyclesTab                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        CyclePeakProminenceTypeSwitch   matlab.ui.control.Switch
        relativepeakprominenceinreferencetowindowmaximumEditField  matlab.ui.control.NumericEditField
        relativepeakprominenceinreferencetowindowmaximumEditFieldLabel  matlab.ui.control.Label
        absoluitepeakprominenceEditField  matlab.ui.control.NumericEditField
        absoluitepeakprominenceEditFieldLabel  matlab.ui.control.Label
        GAWopenthresholdinoflocalmaximumEditField  matlab.ui.control.NumericEditField
        GAWopenthresholdinoflocalmaximumEditFieldLabel  matlab.ui.control.Label
        PlateauQuotientThresholdEditField  matlab.ui.control.NumericEditField
        PlateauQuotientThresholdEditFieldLabel  matlab.ui.control.Label
        showseparateplotof1cycledurationCheckBox  matlab.ui.control.CheckBox
        foTab                           matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        PitchanalysistimewindowoverlapSlider  matlab.ui.control.Slider
        PitchanalysistimewindowoverlapSliderLabel  matlab.ui.control.Label
        PitchAnalysisWindowOverlapEditField  matlab.ui.control.NumericEditField
        pitchAnalysisTimeLengthEditField  matlab.ui.control.NumericEditField
        PitchanalysistimewindowSlider   matlab.ui.control.Slider
        PitchanalysistimewindowLabel    matlab.ui.control.Label
        showseparateplotof1cycledurationCheckBox_2  matlab.ui.control.CheckBox
        SampleEntropyTab                matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ScaleSampEntorange0to1CheckBox  matlab.ui.control.CheckBox
        PlotrawsampleentropyCheckBox    matlab.ui.control.CheckBox
        RawSelamtzisSexSwitch           matlab.ui.control.Switch
        RawSelamtzisSexSwitchLabel      matlab.ui.control.Label
        SampEnFDnumberofharmonicstobeusedEditField  matlab.ui.control.NumericEditField
        NumberofharmonicstobeusedLabel  matlab.ui.control.Label
        SampEnFDfinalcombinationmethodDropDown  matlab.ui.control.DropDown
        FinalcombinationmethodofSampleEntropyDropDownLabel  matlab.ui.control.Label
        SampEnFDuserelativeFourierDescriptorsDropDown  matlab.ui.control.DropDown
        UserelativeFourierDescriptorsDropDownLabel  matlab.ui.control.Label
        SampEnFDtemplatefixedlengthsamplesEditField  matlab.ui.control.NumericEditField
        TemplatefixedlengthsamplesEditField_2Label  matlab.ui.control.Label
        SampEnFDtemplatelengthmethodDropDown  matlab.ui.control.DropDown
        SampEntemplatelengthmethodDropDown_2Label  matlab.ui.control.Label
        SampEnFDPhaseTemplatedistancethresholdofstdEditField  matlab.ui.control.NumericEditField
        SampEntemplatedistancethresholdofstdEditField_2Label_2  matlab.ui.control.Label
        SampEnFDMagnitudeTemplatedistancethresholdofstdEditField  matlab.ui.control.NumericEditField
        SampEntemplatedistancethresholdofstdEditField_2Label  matlab.ui.control.Label
        SampEnTimeNormalizationhorizontDropDown  matlab.ui.control.DropDown
        normalizationhorizontDropDownLabel  matlab.ui.control.Label
        SampleEntropyinTimeDomainLabel  matlab.ui.control.Label
        SampleEntropyinSpectralDomainLabel  matlab.ui.control.Label
        SampEnTimeNormalizationmethodDropDown  matlab.ui.control.DropDown
        normalizationmethodDropDownLabel  matlab.ui.control.Label
        SampEnTimeTemplatefixedlengthsamplesEditField  matlab.ui.control.NumericEditField
        TemplatefixedlengthsamplesEditFieldLabel  matlab.ui.control.Label
        SampEnTimeTemplatelengthmethodDropDown  matlab.ui.control.DropDown
        SampEntemplatelengthmethodDropDownLabel  matlab.ui.control.Label
        SampEnTimeTemplatedistancethresholdofstdEditField  matlab.ui.control.NumericEditField
        SampEntemplatedistancethresholdofstdEditFieldLabel  matlab.ui.control.Label
        CPPTab                          matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        BaselinefittingtypeDropDown     matlab.ui.control.DropDown
        BaselinefittingtypeDropDownLabel  matlab.ui.control.Label
        RobustfittingmethodDropDown     matlab.ui.control.DropDown
        RobustfittingmethodDropDownLabel  matlab.ui.control.Label
        CepstrumMaxInFitRegionOnlyDropDown  matlab.ui.control.DropDown
        SearchCepstrummaximuminfitregiononlyDropDownLabel  matlab.ui.control.Label
        CepstrumWindowTypeDropDown      matlab.ui.control.DropDown
        WindowTypeDropDownLabel         matlab.ui.control.Label
        UpperQuefrencyLimitsEditField   matlab.ui.control.NumericEditField
        UpperQuefrencyLimitsEditFieldLabel  matlab.ui.control.Label
        CepstrumBaselinefittingLabel    matlab.ui.control.Label
        LowerQuefrencyLimitsEditField   matlab.ui.control.NumericEditField
        LowerQuefrencyLimitsEditFieldLabel  matlab.ui.control.Label
        SettingsLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        outputSettings = []; % allSettings
        calledAsPreview = 0;
        keepVocaliscopeSettingsOverSession = 1;
        channelsSuitableAsMask = 1;
    end


    methods (Access = private)
        
       
        function assignParameterDescriptionToNodeData(app)
            parameterInfo=readcell("parameterDescription.xlsx");
            parameterInfo(cellfun(@isempty,parameterInfo(:,3)),:)=[];
            parameterInfo(:,2)=[];
            parameterInfo(:,1)=append('\bf',parameterInfo(:,1),':\rm ',parameterInfo(:,2));
            parameterInfo(:,2)=[];
            allNodes = findobj(app.ParametersTree, 'Type', 'uitreenode');
            allNodes(arrayfun(@(in)~isempty(in.Children),allNodes))=[];

            for nodeNr=1:numel(parameterInfo)/5
                allNodes(nodeNr).NodeData=parameterInfo((1:5)+5*(nodeNr-1));
            end

            
        end
        
        function collectSettings(app)
            app.outputSettings.useWindows=app.WindowedAnalysisCheckBox.Value;
            app.outputSettings.useMask=app.UsemaskCheckBox.Value;
            app.outputSettings.maskChannelNumber=app.ChosenChannelEditField.Value;
            app.outputSettings.windowLength=app.WindowLengthmsEditField.Value/1000; % to s
            app.outputSettings.windowOverlap=app.WindowOverlapmsEditField.Value/1000; % to s
            app.outputSettings.windowHopLength=(app.WindowLengthmsEditField.Value-app.WindowOverlapmsEditField.Value)/1000;

            app.outputSettings.fo.Min=app.minimumfoHzEditField.Value;
            app.outputSettings.fo.Max=app.maximumfoHzEditField.Value;
            app.outputSettings.fo.useAbsoluteCyclePeakProminence=app.CyclePeakProminenceTypeSwitch.Value;
            app.outputSettings.fo.absoluteCyclePeakProminence=app.absoluitepeakprominenceEditField.Value;
            app.outputSettings.fo.relativeCyclePeakProminence=app.relativepeakprominenceinreferencetowindowmaximumEditField.Value;

            app.outputSettings.pitch.timeWindow=app.PitchanalysistimewindowSlider.Value/1000;
            app.outputSettings.pitch.timeWindowOverlap=app.PitchanalysistimewindowoverlapSlider.Value/1000;

            
            app.outputSettings.cycleDuration.Min=app.minimumcycledurationmsEditField.Value/1000;
            app.outputSettings.cycleDuration.Max=app.maximumcycledurationmsEditField.Value/1000;
            app.outputSettings.cycleDuration.showSeparate1overCycleDurationPlot=app.showseparateplotof1cycledurationCheckBox.Value;

            app.outputSettings.plateauQuotientThreshold=app.PlateauQuotientThresholdEditField.Value/100;
            app.outputSettings.GAWopenThresholdPercentage=app.GAWopenthresholdinoflocalmaximumEditField.Value/100;

            app.outputSettings.SampleEntropyTime.distanceSTDthreshold=app.SampEnTimeTemplatedistancethresholdofstdEditField.Value/100;
            app.outputSettings.SampleEntropyTime.templateLengthMethod=app.SampEnTimeTemplatelengthmethodDropDown.Value;
            app.outputSettings.SampleEntropyTime.templateLengthFixed=app.SampEnTimeTemplatefixedlengthsamplesEditField.Value;
            app.outputSettings.SampleEntropyTime.normalizationMethod=app.SampEnTimeNormalizationmethodDropDown.Value;
            app.outputSettings.SampleEntropyTime.normalizationHorizont=app.SampEnTimeNormalizationhorizontDropDown.Value;

            app.outputSettings.SampleEntropyFD.distanceSTDthresholdMagnitude=app.SampEnFDMagnitudeTemplatedistancethresholdofstdEditField.Value/100;
            app.outputSettings.SampleEntropyFD.distanceSTDthresholdPhase=app.SampEnFDPhaseTemplatedistancethresholdofstdEditField.Value/100;
            app.outputSettings.SampleEntropyFD.templateLengthMethod=app.SampEnFDtemplatelengthmethodDropDown.Value;
            app.outputSettings.SampleEntropyFD.templateLengthFixed=app.SampEnFDtemplatefixedlengthsamplesEditField.Value;
            app.outputSettings.SampleEntropyFD.useRelativeFD=strcmpi(app.SampEnFDuserelativeFourierDescriptorsDropDown.Value,'Yes');
            app.outputSettings.SampleEntropyFD.combinationMethod=app.SampEnFDfinalcombinationmethodDropDown.Value;
            app.outputSettings.SampleEntropyFD.numberOfHarmonics=app.SampEnFDnumberofharmonicstobeusedEditField.Value;
            app.outputSettings.SampleEntropyFD.sex=app.RawSelamtzisSexSwitch.Value;


            app.outputSettings.SampleEntropyFDfixedNumberOfCycles.sex=app.RawSelamtzisSexSwitch.Value;
            app.outputSettings.SampleEntropyFDfixedNumberOfCycles.showInternalCycleDetection =app.PlotrawsampleentropyCheckBox.Value;
            app.outputSettings.SampleEntropyFDfixedNumberOfCycles.doNormalizeSampEn=app.ScaleSampEntorange0to1CheckBox.Value;

            app.outputSettings.CPPv1.windowType=app.CepstrumWindowTypeDropDown.Value;
            app.outputSettings.CPPv1.lowerQuefrencyLimit=app.LowerQuefrencyLimitsEditField.Value;
            app.outputSettings.CPPv1.upperQuefrencyLimit=app.UpperQuefrencyLimitsEditField.Value;
            app.outputSettings.CPPv1.maxInFitRegion=app.CepstrumMaxInFitRegionOnlyDropDown.Value;
            app.outputSettings.CPPv1.fitMethod=app.BaselinefittingtypeDropDown.Value;
            app.outputSettings.CPPv1.robustMethod=app.RobustfittingmethodDropDown.Value;

            app.keepVocaliscopeSettingsOverSession=app.KeepSettingsoverSessionsCheckBox.Value;
            
        end

        
        function addFilterPossibility(app)

            txtFilterEditField=app.FilterParameterToCalculateEditField;

            checkboxUItree=app.ParametersTree;

            mainNodes=app.ParametersTree.Children;

            for i = 1:numel(mainNodes)
                mainNodes(i).UserData.ChildNodes=gobjects(size(mainNodes(i).Children));

                for j = 1:numel(mainNodes(i).Children)
                    subNode=mainNodes(i).Children(j);

                    if i==1 && j<=2
                        subNode.UserData.Checked=true;
                    else
                        subNode.UserData.Checked=false;
                    end


                    mainNodes(i).UserData.ChildNodes(j)=subNode;

                end
            end

            txtFilterEditField.ValueChangingFcn = @(~, event) filterTreeNodes(checkboxUItree,event.Value);

            checkboxUItree.CheckedNodesChangedFcn=@(src,evt)keepTrackOfCheckedNotes(app,src,evt);

            function filterTreeNodes(tree,filterText)
                filterText = strtrim(filterText);
        
                checkboxUItree.CheckedNodes=[];
        
                allMainNodes = tree.Children;
                for mainNodeNr = 1:numel(allMainNodes)
                    currMainNode=allMainNodes(mainNodeNr);
                    mainNodeMatches=contains(currMainNode.Text,filterText,'IgnoreCase',true);
        
        
                    if mainNodeMatches==1
                        set(currMainNode.Children,'Parent',[]);
        
                        set(currMainNode.UserData.ChildNodes,'Parent',currMainNode);

                        expand(currMainNode);
        
                    else
                        subMatches=arrayfun(@(in) contains(in.Text,filterText,'IgnoreCase',true),currMainNode.UserData.ChildNodes);
            
                        set(currMainNode.UserData.ChildNodes(~subMatches),'Parent',[]);
                        set(currMainNode.UserData.ChildNodes(subMatches),'Parent',currMainNode);

                        if any(subMatches)
                            expand(currMainNode);
                        else% maybe not that useful or even getting on the nerves; we will see
                            collapse(currMainNode);
                        end
                    end
                    
                    for childNr=1:numel(currMainNode.Children)
                        if currMainNode.UserData.ChildNodes(currMainNode.UserData.ChildNodes==currMainNode.Children(childNr)).UserData.Checked
                            checkboxUItree.CheckedNodes=[checkboxUItree.CheckedNodes; currMainNode.Children(childNr)];
                        end
                    end
                    
                end
            end
        
            function keepTrackOfCheckedNotes(app,src,~)
                
                comingFromMainNode=ismember(src.SelectedNodes,mainNodes);
        
                nodeWasChecked=ismember(src.SelectedNodes,src.CheckedNodes);
        
                if comingFromMainNode
                    for nodeNr=1:numel(src.SelectedNodes.UserData.ChildNodes)
                        src.SelectedNodes.UserData.ChildNodes(nodeNr).UserData.Checked=nodeWasChecked;
                    end
                else
                    src.SelectedNodes.UserData.Checked=nodeWasChecked;
                end

                for chNr=1:numel(app.FundamentalFrequencyMeasuresNode.Children)
                    currChild=app.FundamentalFrequencyMeasuresNode.Children(chNr);
                    if ~ismember(currChild,app.ParametersTree.CheckedNodes) && (currChild ~= app.MaximumHarmonicHzNode && currChild ~= app.MinimumSubharmonicHzNode)
                        app.ParametersTree.CheckedNodes=[app.ParametersTree.CheckedNodes; currChild];
                    end
                end

        
            end
            
        end
    end
        
    methods (Access = public)

        function saveCurrentAppState(app,path)
            if nargin<2
                path='lastVocaliscopesettings.mat';
            end

            state = struct();
            props = properties(app);
            for i = 1:length(props)
                name = props{i};
                comp = app.(name);
                c = string(class(comp));

                if isprop(comp, 'Value')
                    state.(name).Value = comp.Value;
                end

                if isprop(comp, 'Enable')
                    state.(name).Enable = comp.Enable;
                end

                if c == "matlab.ui.container.CheckBoxTree"
                    checkedNodes = comp.CheckedNodes;
                    tags = arrayfun(@(node) string(node.Text), checkedNodes, 'UniformOutput', false);
                    state.(name).CheckedNodes = tags;
                elseif c== "matlab.ui.container.TabGroup"
                    state.(name).SelectedTabIdx=find(comp.Children==comp.SelectedTab);
                end
            end

            save(path, 'state');

        end

        function loadAppState(app,path,skipLoadingOfLastTab)
            if nargin<2
                path='lastVocaliscopesettings.mat';
            end

            if nargin<3
                skipLoadingOfLastTab=0;
            end
        
            if isfile(path)
                load(path, 'state');
        
                fields = fieldnames(state);
                for i = 1:length(fields)
                    name = fields{i};
                    try
                        comp = app.(name);
                        
                        if isfield(state.(name), 'Value') && isprop(comp, 'Value')
                            comp.Value = state.(name).Value;
                        end
                        
                        if isfield(state.(name), 'Enable') && isprop(comp, 'Enable')
                            comp.Enable = state.(name).Enable;
                        end

                        if isfield(state.(name), 'CheckedNodes') && strcmp(class(comp), "matlab.ui.container.CheckBoxTree")
                            tags = state.(name).CheckedNodes;
                            checkedNodes = [];
                            for ii = 1:numel(tags)
                                node = findall(comp, 'Text', tags{ii});
                                if ~isempty(node)
                                    checkedNodes = [checkedNodes; node];
                                end
                            end
                            comp.CheckedNodes = checkedNodes;
                        end

                        if isfield(state.(name), 'SelectedTabIdx') && strcmp(class(comp), "matlab.ui.container.TabGroup") && ~skipLoadingOfLastTab
                            comp.SelectedTab=comp.Children(state.(name).SelectedTabIdx);
                        end
                    catch err
                        disp(['Did not use saved field ', name, ': ', err.identifier])
                    end
                end
            end
            
            app.PitchanalysistimewindowSlider.Limits(2)=app.WindowLengthmsEditField.Value;
            %app.pitchAnalysisTimeLengthEditField.Value=app.WindowLengthmsEditField.Value;

            app.PitchanalysistimewindowoverlapSlider.Limits(2)=app.PitchanalysistimewindowSlider.Value-1;
            %app.PitchAnalysisWindowOverlapEditField.Value=app.WindowLengthmsEditField.Value-1;

        end


        function setMaximumWindowLength(app,val)
            if val*1000<=app.WindowOverlapmsEditField.Value
                app.WindowOverlapmsEditField.Value=val*1000-1;
            end

            app.WindowLengthmsEditField.Limits(2)=val*1000;
            app.WindowOverlapmsEditField.Limits(2)=val*1000-1;
            
        end
        
        function checkIfAnyChannelCanBeUsedAsMask(app)

            if isempty(app.channelsSuitableAsMask)
                app.UsemaskCheckBox.Value=0;
                set([app.UsemaskCheckBox app.channelusedasmaskLabel app.ChosenChannelEditField],'Enable','off');
                app.ChosenChannelEditField.Value=[];
            else
                set([app.UsemaskCheckBox app.channelusedasmaskLabel app.ChosenChannelEditField],'Enable','on');

                % we allow only to keep the mask channel value if the
                % channel is part of channelsSuitableAsMask, if not, remove
                % the entry and uncheck
                if ~ismember(app.ChosenChannelEditField.Value,app.channelsSuitableAsMask)
                    app.ChosenChannelEditField.Value=[];
                    app.UsemaskCheckBox.Value=0;
                    set([app.channelusedasmaskLabel app.ChosenChannelEditField],'Enable','off');
                end
            end
            
        end
    end % end public access
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             expand(app.ParametersTree,'all');
            collapse(app.ParametersTree,'all');

            movegui(app.UIFigure,'center');

            app.parameterExplanation.Interpreter='tex';

            % get parameter discription and assign to node data
            app.assignParameterDescriptionToNodeData();

            app.addFilterPossibility();
        end

        % Value changed function: minimumfoHzEditField
        function minimumfoHzEditFieldValueChanged(app, event)
            value = app.minimumfoHzEditField.Value;

            if value>app.maximumfoHzEditField.Value
                app.maximumfoHzEditField.Value=value;
                app.minimumcycledurationmsEditField.Value=1/value*1000;
            end

            calcval=1/value*1000;
            if calcval~=app.maximumcycledurationmsEditField.Value
                app.maximumcycledurationmsEditField.Value=calcval;
            end
        end

        % Value changed function: maximumfoHzEditField
        function maximumfoHzEditFieldValueChanged(app, event)
            value = app.maximumfoHzEditField.Value;

            if value<app.minimumfoHzEditField.Value
                app.minimumfoHzEditField.Value=value;
                app.maximumcycledurationmsEditField.Value=1/value*1000;
            end

            calcval=1/value*1000;
            if calcval~=app.minimumcycledurationmsEditField.Value
                app.minimumcycledurationmsEditField.Value=calcval;
            end
        end

        % Value changed function: maximumcycledurationmsEditField
        function maximumcycledurationmsEditFieldValueChanged(app, event)
            value = app.maximumcycledurationmsEditField.Value;

            if value<app.minimumcycledurationmsEditField.Value
                app.minimumcycledurationmsEditField.Value=value;
                app.maximumfoHzEditField.Value=1/(value/1000);
            end
            
            calcval=1/(value/1000);
            if calcval~=app.minimumfoHzEditField.Value
                app.minimumfoHzEditField.Value=calcval;
            end
        end

        % Value changed function: minimumcycledurationmsEditField
        function minimumcycledurationmsEditFieldValueChanged(app, event)
            value = app.minimumcycledurationmsEditField.Value;

            if value>app.maximumcycledurationmsEditField.Value
                app.maximumcycledurationmsEditField.Value=value;
                app.minimumfoHzEditField.Value=1/(value/1000);
            end
            
            calcval=1/(value/1000);
            if calcval~=app.maximumfoHzEditField.Value
                app.maximumfoHzEditField.Value=calcval;
            end
        end

        % Callback function: ParametersTree
        function ParametersTreeCheckedNodesChanged(app, event)
            % keep fundamental frequencies checked like in msa
            for chNr=1:numel(app.FundamentalFrequencyMeasuresNode.Children)
                currChild=app.FundamentalFrequencyMeasuresNode.Children(chNr);
                if ~ismember(currChild,app.ParametersTree.CheckedNodes) && (currChild ~= app.MaximumHarmonicHzNode && currChild ~= app.MinimumSubharmonicHzNode)
                    app.ParametersTree.CheckedNodes=[app.ParametersTree.CheckedNodes; currChild];
                end
            end


        end

        % Selection changed function: ParametersTree
        function ParametersTreeSelectionChanged(app, event)
            selectedNodes = app.ParametersTree.SelectedNodes;
            if isempty(app.ParametersTree.SelectedNodes.NodeData)
                app.parameterExplanation.Text='';
            else
                app.parameterExplanation.Text=app.ParametersTree.SelectedNodes.NodeData;
            end

        end

        % Button pushed function: ExpandallnodesButton
        function ExpandallnodesButtonPushed(app, event)
            expand(app.ParametersTree,'all');
        end

        % Button pushed function: CollapseallnodesButton
        function CollapseallnodesButtonPushed(app, event)
            collapse(app.ParametersTree,'all');
        end

        % Button pushed function: CheckallButton
        function CheckallButtonPushed(app, event)
            app.ParametersTree.CheckedNodes=findobj(app.ParametersTree,'type','uitreenode');
        end

        % Button pushed function: UncheckallButton
        function UncheckallButtonPushed(app, event)
            app.ParametersTree.CheckedNodes=[app.FundamentalFrequencyHzNode app.CycleDurationmsNode];
        end

        % Value changed function: WindowLengthmsEditField
        function WindowLengthmsEditFieldValueChanged(app, event)
            value = app.WindowLengthmsEditField.Value;
            if app.WindowOverlapmsEditField.Value>=value
                app.WindowOverlapmsEditField.Value=value-1;
            end

            app.PitchanalysistimewindowSlider.Limits(2)=app.WindowLengthmsEditField.Value;
        end

        % Value changed function: WindowedAnalysisCheckBox
        function WindowedAnalysisCheckBoxValueChanged(app, event)
            value = app.WindowedAnalysisCheckBox.Value;
            
            set([app.WindowLengthmsEditField app.WindowOverlapmsEditField app.WindowLengthmsEditFieldLabel app.WindowOverlapmsEditFieldLabel],'Enable',value);

            if value==1
                app.UsemaskCheckBox.Value=0;
                app.ChosenChannelEditField.Value=[];
                set([app.channelusedasmaskLabel app.ChosenChannelEditField],'Enable','off')
            end
            
        end

        % Button pushed function: previewcyclesButton
        function previewcyclesButtonPushed(app, event)
            app.calledAsPreview = 1;
            app.collectSettings();
        end

        % Value changed function: showseparateplotof1cycledurationCheckBox
        function showseparateplotof1cycledurationCheckBoxValueChanged(app, event)
            value = app.showseparateplotof1cycledurationCheckBox.Value;
            app.showseparateplotof1cycledurationCheckBox_2.Value=value;
        end

        % Value changed function: 
        % showseparateplotof1cycledurationCheckBox_2
        function showseparateplotof1cycledurationCheckBox_2ValueChanged(app, event)
            value = app.showseparateplotof1cycledurationCheckBox_2.Value;
            app.showseparateplotof1cycledurationCheckBox.Value=value;
        end

        % Button pushed function: calculateparametersButton
        function calculateparametersButtonPushed(app, event)
            app.calledAsPreview = 0;
            app.collectSettings();
        end

        % Value changed function: WindowOverlapmsEditField
        function WindowOverlapmsEditFieldValueChanged(app, event)
            value = app.WindowOverlapmsEditField.Value;

            
            if value>=app.WindowLengthmsEditField.Value
                app.WindowLengthmsEditField.Value=value+1;
            end
        end

        % Value changed function: SampEnTimeTemplatelengthmethodDropDown
        function SampEnTimeTemplatelengthmethodDropDownValueChanged(app, event)
            value = app.SampEnTimeTemplatelengthmethodDropDown.Value;
            if strcmp(value,'fixed length')
                app.SampEnTimeTemplatefixedlengthsamplesEditField.Enable='on';
            else
                app.SampEnTimeTemplatefixedlengthsamplesEditField.Enable='off';
            end
        end

        % Value changed function: SampEnTimeNormalizationmethodDropDown
        function SampEnTimeNormalizationmethodDropDownValueChanged(app, event)
            value = app.SampEnTimeNormalizationmethodDropDown.Value;
            if strcmp(value,'none')
                app.SampEnTimeNormalizationhorizontDropDown.Enable="off";
            else
                app.SampEnTimeNormalizationhorizontDropDown.Enable="on";
            end
        end

        % Value changed function: SampEnFDtemplatelengthmethodDropDown
        function SampEnFDtemplatelengthmethodDropDownValueChanged(app, event)
            value = app.SampEnFDtemplatelengthmethodDropDown.Value;
            if strcmp(value,'fixed length')
                app.SampEnFDtemplatefixedlengthsamplesEditField.Enable='on';
            else
                app.SampEnFDtemplatefixedlengthsamplesEditField.Enable='off';
            end
            
        end

        % Value changed function: CyclePeakProminenceTypeSwitch
        function CyclePeakProminenceTypeSwitchValueChanged(app, event)
            value = app.CyclePeakProminenceTypeSwitch.Value;
            
            if value
                app.absoluitepeakprominenceEditField.Enable="on";
                app.absoluitepeakprominenceEditFieldLabel.Enable='on';
                app.relativepeakprominenceinreferencetowindowmaximumEditField.Enable='off';
                app.relativepeakprominenceinreferencetowindowmaximumEditFieldLabel.Enable='off';
            else
                app.absoluitepeakprominenceEditField.Enable="off";
                app.absoluitepeakprominenceEditFieldLabel.Enable='off';
                app.relativepeakprominenceinreferencetowindowmaximumEditField.Enable='on';
                app.relativepeakprominenceinreferencetowindowmaximumEditFieldLabel.Enable='on';
            end
        end

        % Value changing function: PitchanalysistimewindowSlider
        function PitchanalysistimewindowSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pitchAnalysisTimeLengthEditField.Value=changingValue;

            if app.PitchanalysistimewindowoverlapSlider.Value>changingValue-1
                app.PitchanalysistimewindowoverlapSlider.Value=changingValue-1;
                app.PitchAnalysisWindowOverlapEditField.Value=changingValue-1;
            end
            app.PitchanalysistimewindowoverlapSlider.Limits(2)=changingValue-1;
        end

        % Value changing function: PitchanalysistimewindowoverlapSlider
        function PitchanalysistimewindowoverlapSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PitchAnalysisWindowOverlapEditField.Value=changingValue;
        end

        % Value changed function: UsemaskCheckBox
        function UsemaskCheckBoxValueChanged(app, event)
            value = app.UsemaskCheckBox.Value;
            
            set([app.WindowLengthmsEditField app.WindowOverlapmsEditField app.WindowLengthmsEditFieldLabel app.WindowOverlapmsEditFieldLabel],'Enable','off');

            if value==1
                app.WindowedAnalysisCheckBox.Value=0;
                

                [chosenChannelIdx,tf] = listdlg('PromptString',{'Choose channel' 'containing mask'},...
                'SelectionMode','single','ListString',strsplit(num2str(app.channelsSuitableAsMask)));

                if tf==0 % if user aborts selection
                    app.UsemaskCheckBox.Value=0;
                    app.ChosenChannelEditField.Value=[];
                    set([app.channelusedasmaskLabel app.ChosenChannelEditField],'Enable','off')
                    return;
                end

                app.ChosenChannelEditField.Value=app.channelsSuitableAsMask(chosenChannelIdx);
                set([app.channelusedasmaskLabel app.ChosenChannelEditField],'Enable','on')
            else
                app.ChosenChannelEditField.Value=[];
                set([app.channelusedasmaskLabel app.ChosenChannelEditField],'Enable','off')
            end


        end

        % Button pushed function: loadSettingsfromfileButton
        function loadSettingsfromfileButtonPushed(app, event)
            persistent lastPath;
                
            [file,folder]=uigetfile('lastVocaliscopesettings.mat','load settings from file',lastPath);

            if isequal(file,0)
                return;
            end

            lastPath=folder;

            path=fullfile(folder,file);
            availableVars=who(matfile(path));
            if ismember({'state'},availableVars)
                skipLastTabLoading=1;
                app.loadAppState(path,skipLastTabLoading);
                uialert(app.UIFigure,'Loading settings successfull','Success','Icon','success');
            else
                uialert(app.UIFigure,'No app state saved in this mat file','Error','Icon','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SettingsLabel = uilabel(app.GridLayout);
            app.SettingsLabel.Text = 'Settings';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout2 = uigridlayout(app.GeneralTab);
            app.minimumfoHzEditFieldLabel = uilabel(app.GridLayout2);
            app.minimumfoHzEditFieldLabel.Text = 'minimum fo (Hz)';
            app.maximumfoHzEditFieldLabel = uilabel(app.GridLayout2);
            app.maximumfoHzEditFieldLabel.Text = 'maximum fo (Hz)';
            app.minimumfoHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.minimumfoHzEditField.Limits = [0 Inf];
            app.minimumfoHzEditField.ValueChangedFcn = createCallbackFcn(app, @minimumfoHzEditFieldValueChanged, true);
            app.minimumfoHzEditField.Value = 50;
            app.maximumfoHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.maximumfoHzEditField.Limits = [0 Inf];
            app.maximumfoHzEditField.ValueChangedFcn = createCallbackFcn(app, @maximumfoHzEditFieldValueChanged, true);
            app.maximumfoHzEditField.Value = 250;
            app.foandcycledurationrangeLabel = uilabel(app.GridLayout2);
            app.foandcycledurationrangeLabel.Text = 'fo and cycle duration range';
            app.maximumcycledurationmsEditFieldLabel = uilabel(app.GridLayout2);
            app.maximumcycledurationmsEditFieldLabel.Text = 'maximum cycle duration (ms)';
            app.maximumcycledurationmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.maximumcycledurationmsEditField.Limits = [0 Inf];
            app.maximumcycledurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @maximumcycledurationmsEditFieldValueChanged, true);
            app.maximumcycledurationmsEditField.Value = 20;
            app.minimumcycledurationmsEditFieldLabel = uilabel(app.GridLayout2);
            app.minimumcycledurationmsEditFieldLabel.Text = 'minimum cycle duration (ms)';
            app.minimumcycledurationmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.minimumcycledurationmsEditField.Limits = [0 Inf];
            app.minimumcycledurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @minimumcycledurationmsEditFieldValueChanged, true);
            app.minimumcycledurationmsEditField.Value = 4;
            app.ParametersTree = uitree(app.GridLayout2, 'checkbox');
            app.ParametersTree.SelectionChangedFcn = createCallbackFcn(app, @ParametersTreeSelectionChanged, true);
            app.FundamentalFrequencyMeasuresNode = uitreenode(app.ParametersTree);
            app.FundamentalFrequencyMeasuresNode.Text = 'Fundamental Frequency Measures';
            app.FundamentalFrequencyHzNode = uitreenode(app.FundamentalFrequencyMeasuresNode);
            app.FundamentalFrequencyHzNode.Text = 'Fundamental Frequency (Hz)';
            app.CycleDurationmsNode = uitreenode(app.FundamentalFrequencyMeasuresNode);
            app.CycleDurationmsNode.Text = 'Cycle Duration (ms)';
            app.MaximumHarmonicHzNode = uitreenode(app.FundamentalFrequencyMeasuresNode);
            app.MaximumHarmonicHzNode.Text = 'Maximum Harmonic (Hz)';
            app.MinimumSubharmonicHzNode = uitreenode(app.FundamentalFrequencyMeasuresNode);
            app.MinimumSubharmonicHzNode.Text = 'Minimum Subharmonic (Hz)';
            app.AmplitudePerturbationMeasuresNode = uitreenode(app.ParametersTree);
            app.AmplitudePerturbationMeasuresNode.Text = 'Amplitude Perturbation Measures';
            app.MeanShimmerdBNode = uitreenode(app.AmplitudePerturbationMeasuresNode);
            app.MeanShimmerdBNode.Text = 'Mean Shimmer (dB)';
            app.ShimmerNode = uitreenode(app.AmplitudePerturbationMeasuresNode);
            app.ShimmerNode.Text = 'Shimmer (%)';
            app.AmplitudePerturbationQuotientAPQNode = uitreenode(app.AmplitudePerturbationMeasuresNode);
            app.AmplitudePerturbationQuotientAPQNode.Text = 'Amplitude Perturbation Quotient (APQ)';
            app.AmplitudePerturbationFactorAPFNode = uitreenode(app.AmplitudePerturbationMeasuresNode);
            app.AmplitudePerturbationFactorAPFNode.Text = 'Amplitude Perturbation Factor (APF)';
            app.AmplitudeVariabilityIndexdBAVINode = uitreenode(app.AmplitudePerturbationMeasuresNode);
            app.AmplitudeVariabilityIndexdBAVINode.Text = 'Amplitude Variability Index (dB) (AVI)';
            app.PeriodPerturbationMeasuresNode = uitreenode(app.ParametersTree);
            app.PeriodPerturbationMeasuresNode.Text = 'Period Perturbation Measures';
            app.MeanJittermsNode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.MeanJittermsNode.Text = 'Mean Jitter (ms)';
            app.JitterNode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.JitterNode.Text = 'Jitter (%)';
            app.JitterRatioNode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.JitterRatioNode.Text = 'Jitter Ratio';
            app.JitterFactorNode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.JitterFactorNode.Text = 'Jitter-Factor';
            app.PeriodPerturbationQuotientPPQNode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.PeriodPerturbationQuotientPPQNode.Text = 'Period Perturbation Quotient (PPQ)';
            app.PeriodPerturbationFactorPPFNode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.PeriodPerturbationFactorPPFNode.Text = 'Period Perturbation Factor (PPF)';
            app.RelativeAveragePerturbationV1RAPNode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.RelativeAveragePerturbationV1RAPNode.Text = 'Relative Average Perturbation V1 (RAP)';
            app.RelativeAveragePerturbationV2RAPNode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.RelativeAveragePerturbationV2RAPNode.Text = 'Relative Average Perturbation V2 (RAP)';
            app.PeriodVariabilityIndexPVINode = uitreenode(app.PeriodPerturbationMeasuresNode);
            app.PeriodVariabilityIndexPVINode.Text = 'Period Variability Index (PVI)';
            app.EnergyPerturbationMeasuresNode = uitreenode(app.ParametersTree);
            app.EnergyPerturbationMeasuresNode.Text = 'Energy Perturbation Measures';
            app.EnergyPerturbationQuotientEPQNode = uitreenode(app.EnergyPerturbationMeasuresNode);
            app.EnergyPerturbationQuotientEPQNode.Text = 'Energy Perturbation Quotient (EPQ)';
            app.EnergyPerturbationFactorEPFNode = uitreenode(app.EnergyPerturbationMeasuresNode);
            app.EnergyPerturbationFactorEPFNode.Text = 'Energy Perturbation Factor (EPF)';
            app.NoiseMeasuresNode = uitreenode(app.ParametersTree);
            app.NoiseMeasuresNode.Text = 'Noise Measures';
            app.HarmonicsIntensityNode = uitreenode(app.NoiseMeasuresNode);
            app.HarmonicsIntensityNode.Text = 'Harmonics Intensity';
            app.HarmonicstoNoiseRatiodBHNRNode = uitreenode(app.NoiseMeasuresNode);
            app.HarmonicstoNoiseRatiodBHNRNode.Text = 'Harmonics-to-Noise Ratio (dB) (HNR)';
            app.NormalizedNoiseEnergydBNNENode = uitreenode(app.NoiseMeasuresNode);
            app.NormalizedNoiseEnergydBNNENode.Text = 'Normalized Noise Energy (dB)  (NNE)';
            app.SpectralFlatnessNode = uitreenode(app.NoiseMeasuresNode);
            app.SpectralFlatnessNode.Text = 'Spectral Flatness';
            app.GlottaltoNoiseExcitationRatioGNENode = uitreenode(app.NoiseMeasuresNode);
            app.GlottaltoNoiseExcitationRatioGNENode.Text = 'Glottal-to-Noise Excitation Ratio (GNE)';
            app.WaveformMatchingCoefcientWMCNode = uitreenode(app.NoiseMeasuresNode);
            app.WaveformMatchingCoefcientWMCNode.Text = 'Waveform Matching Coefﬁcient (WMC)';
            app.MeanWaveformMatchingCoefcientNode = uitreenode(app.NoiseMeasuresNode);
            app.MeanWaveformMatchingCoefcientNode.Text = 'Mean Waveform Matching Coefﬁcient';
            app.SignaltoNoiseRatioV1dBSNRNode = uitreenode(app.NoiseMeasuresNode);
            app.SignaltoNoiseRatioV1dBSNRNode.Text = 'Signal-to-Noise Ratio V1 (dB) (SNR)';
            app.SignaltoNoiseRatioV2dBSNRNode = uitreenode(app.NoiseMeasuresNode);
            app.SignaltoNoiseRatioV2dBSNRNode.Text = 'Signal-to-Noise Ratio V2 (dB) (SNR)';
            app.CepstralPeakProminenceIdBCPPNode = uitreenode(app.NoiseMeasuresNode);
            app.CepstralPeakProminenceIdBCPPNode.Text = 'Cepstral Peak Prominence I (dB) (CPP)';
            app.CepstralPeakProminenceIIdBCPPNode = uitreenode(app.NoiseMeasuresNode);
            app.CepstralPeakProminenceIIdBCPPNode.Text = 'Cepstral Peak Prominence II (dB) (CPP)';
            app.MechanicalMeasuresNode = uitreenode(app.ParametersTree);
            app.MechanicalMeasuresNode.Text = 'Mechanical Measures';
            app.Stiffness1sNode = uitreenode(app.MechanicalMeasuresNode);
            app.Stiffness1sNode.Text = 'Stiffness (1/s)';
            app.PeakClosingVelocitypxsNode = uitreenode(app.MechanicalMeasuresNode);
            app.PeakClosingVelocitypxsNode.Text = 'Peak Closing Velocity (px/s)';
            app.PeakAccelerationpxs2Node = uitreenode(app.MechanicalMeasuresNode);
            app.PeakAccelerationpxs2Node.Text = 'Peak Acceleration (px/s^2)';
            app.AmplitudetoLengthRatioNode = uitreenode(app.MechanicalMeasuresNode);
            app.AmplitudetoLengthRatioNode.Text = 'Amplitude-to-Length Ratio';
            app.GlottalAreaMeasuresNode = uitreenode(app.ParametersTree);
            app.GlottalAreaMeasuresNode.Text = 'Glottal Area Measures';
            app.OpenQuotientOQNode = uitreenode(app.GlottalAreaMeasuresNode);
            app.OpenQuotientOQNode.Text = 'Open Quotient  (OQ)';
            app.ClosingQuotientClQNode = uitreenode(app.GlottalAreaMeasuresNode);
            app.ClosingQuotientClQNode.Text = 'Closing Quotient (ClQ)';
            app.SpeedQuotientSQNode = uitreenode(app.GlottalAreaMeasuresNode);
            app.SpeedQuotientSQNode.Text = 'Speed Quotient (SQ)';
            app.SpeedIndexSINode = uitreenode(app.GlottalAreaMeasuresNode);
            app.SpeedIndexSINode.Text = 'Speed-Index (SI)';
            app.RateQuotientNode = uitreenode(app.GlottalAreaMeasuresNode);
            app.RateQuotientNode.Text = 'Rate Quotient';
            app.AsymmetryQuotientNode = uitreenode(app.GlottalAreaMeasuresNode);
            app.AsymmetryQuotientNode.Text = 'Asymmetry Quotient';
            app.GlottisGapIndexGGINode = uitreenode(app.GlottalAreaMeasuresNode);
            app.GlottisGapIndexGGINode.Text = 'Glottis Gap Index  (GGI)';
            app.PlateauQuotientNode = uitreenode(app.GlottalAreaMeasuresNode);
            app.PlateauQuotientNode.Text = 'Plateau Quotient';
            app.GlottalAreaIndexGAINode = uitreenode(app.GlottalAreaMeasuresNode);
            app.GlottalAreaIndexGAINode.Text = 'Glottal Area Index (GAI)';
            app.GlottalAreaPeriodicityMeasuresNode = uitreenode(app.ParametersTree);
            app.GlottalAreaPeriodicityMeasuresNode.Text = 'Glottal Area Periodicity Measures';
            app.AmplitudePeriodicityNode = uitreenode(app.GlottalAreaPeriodicityMeasuresNode);
            app.AmplitudePeriodicityNode.Text = 'Amplitude-Periodicity';
            app.TimePeriodicityNode = uitreenode(app.GlottalAreaPeriodicityMeasuresNode);
            app.TimePeriodicityNode.Text = 'Time-Periodicity';
            app.GlottalAreaDerivativeMeasuresNode = uitreenode(app.ParametersTree);
            app.GlottalAreaDerivativeMeasuresNode.Text = 'Glottal Area Derivative Measures';
            app.MaximumAreaDeclinationRateMpxsMADRNode = uitreenode(app.GlottalAreaDerivativeMeasuresNode);
            app.MaximumAreaDeclinationRateMpxsMADRNode.Text = 'Maximum Area Declination Rate (Mpx/s) (MADR)';
            app.AmplitudeQuotientsNode = uitreenode(app.GlottalAreaDerivativeMeasuresNode);
            app.AmplitudeQuotientsNode.Text = 'Amplitude Quotient (s)';
            app.GlottalAreaSymmetryMeasuresNode = uitreenode(app.ParametersTree);
            app.GlottalAreaSymmetryMeasuresNode.Text = 'Glottal Area Symmetry Measures';
            app.PhaseAsymmetryIndexNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.PhaseAsymmetryIndexNode.Text = 'Phase Asymmetry Index';
            app.SpatialSymmetryIndexNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.SpatialSymmetryIndexNode.Text = 'Spatial Symmetry Index';
            app.DynamicRangeSymmetryIndexNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.DynamicRangeSymmetryIndexNode.Text = 'Dynamic Range Symmetry Index';
            app.AmplitudeSymmetryIndexNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.AmplitudeSymmetryIndexNode.Text = 'Amplitude Symmetry Index';
            app.WaveformSymmetryIndexNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.WaveformSymmetryIndexNode.Text = 'Waveform Symmetry Index';
            app.PhaseAsymmetryNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.PhaseAsymmetryNode.Text = 'Phase Asymmetry';
            app.SpatialSymmetryNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.SpatialSymmetryNode.Text = 'Spatial Symmetry';
            app.DynamicRangeSymmetryNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.DynamicRangeSymmetryNode.Text = 'Dynamic Range Symmetry';
            app.AmplitudeSymmetryNode = uitreenode(app.GlottalAreaSymmetryMeasuresNode);
            app.AmplitudeSymmetryNode.Text = 'Amplitude Symmetry';
            app.BasicmeasurementsNode = uitreenode(app.ParametersTree);
            app.BasicmeasurementsNode.Text = 'Basic measurements';
            app.SumNode = uitreenode(app.BasicmeasurementsNode);
            app.SumNode.Text = 'Sum';
            app.MedianNode = uitreenode(app.BasicmeasurementsNode);
            app.MedianNode.Text = 'Median';
            app.MeanarithmeticNode = uitreenode(app.BasicmeasurementsNode);
            app.MeanarithmeticNode.Text = 'Mean (arithmetic)';
            app.HarmonicMeanNode = uitreenode(app.BasicmeasurementsNode);
            app.HarmonicMeanNode.Text = 'Harmonic Mean';
            app.GeometricMeanNode = uitreenode(app.BasicmeasurementsNode);
            app.GeometricMeanNode.Text = 'Geometric Mean';
            app.MinimumNode = uitreenode(app.BasicmeasurementsNode);
            app.MinimumNode.Text = 'Minimum';
            app.MaximumNode = uitreenode(app.BasicmeasurementsNode);
            app.MaximumNode.Text = 'Maximum';
            app.standarddeviationSTDNode = uitreenode(app.BasicmeasurementsNode);
            app.standarddeviationSTDNode.Text = 'standard deviation STD';
            app.roommeansquareRMSNode = uitreenode(app.BasicmeasurementsNode);
            app.roommeansquareRMSNode.Text = 'room-mean-square RMS';
            app.ModeNode = uitreenode(app.BasicmeasurementsNode);
            app.ModeNode.Text = 'Mode';
            app.Quantilesfrom5in5stepsto95Node = uitreenode(app.BasicmeasurementsNode);
            app.Quantilesfrom5in5stepsto95Node.Text = 'Quantiles from 5% in 5% steps to 95%';
            app.InterquartilerangeIQRNode = uitreenode(app.BasicmeasurementsNode);
            app.InterquartilerangeIQRNode.Text = 'Inter-quartile-range IQR';
            app.SkewnessNode = uitreenode(app.BasicmeasurementsNode);
            app.SkewnessNode.Text = 'Skewness';
            app.KurtosisNode = uitreenode(app.BasicmeasurementsNode);
            app.KurtosisNode.Text = 'Kurtosis';
            app.AdditionalspectraldescriptorsNode = uitreenode(app.ParametersTree);
            app.AdditionalspectraldescriptorsNode.Text = 'Additional spectral descriptors';
            app.spectralCrestNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralCrestNode.Text = 'spectral Crest';
            app.spectralFluxNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralFluxNode.Text = 'spectral Flux';
            app.spectralSpreadNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralSpreadNode.Text = 'spectral Spread';
            app.spectralSkewnessspectralTiltNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralSkewnessspectralTiltNode.Text = 'spectral Skewness (spectral Tilt)';
            app.spectralKurtosisNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralKurtosisNode.Text = 'spectral Kurtosis';
            app.spectralCentroidNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralCentroidNode.Text = 'spectral Centroid';
            app.spectralEntropyNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralEntropyNode.Text = 'spectral Entropy';
            app.spectralSlopeNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralSlopeNode.Text = 'spectral Slope';
            app.spectralDecreaseNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralDecreaseNode.Text = 'spectral Decrease';
            app.spectralRolloffPointNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.spectralRolloffPointNode.Text = 'spectral Rolloff Point';
            app.harmonicRatioNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.harmonicRatioNode.Text = 'harmonic Ratio';
            app.HarmonictoNoiseRatioHNRviabiasedxcorrNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.HarmonictoNoiseRatioHNRviabiasedxcorrNode.Text = 'Harmonic-to-Noise Ratio (HNR) via biased xcorr';
            app.SignaltoNoiseRatiodBcarrierSNRNode = uitreenode(app.AdditionalspectraldescriptorsNode);
            app.SignaltoNoiseRatiodBcarrierSNRNode.Text = 'Signal-to-Noise Ratio (dBcarrier) (SNR)';
            app.OthersNode = uitreenode(app.ParametersTree);
            app.OthersNode.Text = 'Others';
            app.zerocrossrateNode = uitreenode(app.OthersNode);
            app.zerocrossrateNode.Text = 'zero cross rate';
            app.Node = uitreenode(app.OthersNode);
            app.Node.Text = 'Node';
            % Assign Checked Nodes
            app.ParametersTree.CheckedNodes = [app.FundamentalFrequencyHzNode, app.CycleDurationmsNode];
            % Assign Checked Nodes
            app.ParametersTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ParametersTreeCheckedNodesChanged, true);
            app.parameterExplanation = uilabel(app.GridLayout2);
            app.parameterExplanation.Text = '';
            app.ExpandallnodesButton = uibutton(app.GridLayout2, 'push');
            app.ExpandallnodesButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandallnodesButtonPushed, true);
            app.ExpandallnodesButton.Text = 'Expand all nodes';
            app.CollapseallnodesButton = uibutton(app.GridLayout2, 'push');
            app.CollapseallnodesButton.ButtonPushedFcn = createCallbackFcn(app, @CollapseallnodesButtonPushed, true);
            app.CollapseallnodesButton.Text = 'Collapse all nodes';
            app.CheckallButton = uibutton(app.GridLayout2, 'push');
            app.CheckallButton.ButtonPushedFcn = createCallbackFcn(app, @CheckallButtonPushed, true);
            app.CheckallButton.Text = 'Check all';
            app.UncheckallButton = uibutton(app.GridLayout2, 'push');
            app.UncheckallButton.ButtonPushedFcn = createCallbackFcn(app, @UncheckallButtonPushed, true);
            app.UncheckallButton.Text = 'Uncheck all';
            app.WindowedAnalysisCheckBox = uicheckbox(app.GridLayout2);
            app.WindowedAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @WindowedAnalysisCheckBoxValueChanged, true);
            app.WindowedAnalysisCheckBox.Text = 'Windowed Analysis';
            app.WindowedAnalysisCheckBox.Value = true;
            app.WindowLengthmsEditFieldLabel = uilabel(app.GridLayout2);
            app.WindowLengthmsEditFieldLabel.Text = 'Window Length (ms)';
            app.WindowLengthmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WindowLengthmsEditField.Limits = [1 Inf];
            app.WindowLengthmsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthmsEditFieldValueChanged, true);
            app.WindowLengthmsEditField.Value = 100;
            app.WindowOverlapmsEditFieldLabel = uilabel(app.GridLayout2);
            app.WindowOverlapmsEditFieldLabel.Text = 'Window Overlap (ms)';
            app.WindowOverlapmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WindowOverlapmsEditField.Limits = [0 Inf];
            app.WindowOverlapmsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowOverlapmsEditFieldValueChanged, true);
            app.WindowOverlapmsEditField.Value = 90;
            app.CheckingoruncheckingnodesbelowhasnoeffectyetLabel = uilabel(app.GridLayout2);
            app.CheckingoruncheckingnodesbelowhasnoeffectyetLabel.Text = 'Checking or unchecking nodes below has no effect yet';
            app.UsemaskCheckBox = uicheckbox(app.GridLayout2);
            app.UsemaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsemaskCheckBoxValueChanged, true);
            app.UsemaskCheckBox.Text = 'Use mask';
            app.ChosenChannelEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ChosenChannelEditField.ValueDisplayFormat = '%i';
            app.ChosenChannelEditField.AllowEmpty = 'on';
            app.ChosenChannelEditField.Editable = 'off';
            app.ChosenChannelEditField.Enable = 'off';
            app.ChosenChannelEditField.Value = [];
            app.channelusedasmaskLabel = uilabel(app.GridLayout2);
            app.channelusedasmaskLabel.Enable = 'off';
            app.channelusedasmaskLabel.Text = 'channel used as mask:';
            app.FilterParameterToCalculateEditField = uieditfield(app.GridLayout2, 'text');
            app.FilterParameterToCalculateEditField.Placeholder = 'Type to filter nodes...';
            app.CyclesTab = uitab(app.TabGroup);
            app.CyclesTab.Title = 'Cycles';
            app.GridLayout3 = uigridlayout(app.CyclesTab);
            app.showseparateplotof1cycledurationCheckBox = uicheckbox(app.GridLayout3);
            app.showseparateplotof1cycledurationCheckBox.ValueChangedFcn = createCallbackFcn(app, @showseparateplotof1cycledurationCheckBoxValueChanged, true);
            app.showseparateplotof1cycledurationCheckBox.Text = {'show separate plot of'; '1/(cycle duration)'};
            app.showseparateplotof1cycledurationCheckBox.Value = true;
            app.PlateauQuotientThresholdEditFieldLabel = uilabel(app.GridLayout3);
            app.PlateauQuotientThresholdEditFieldLabel.Tooltip = {'For calculation of the plateau quotient:In which amount of time the data is above this threshold, e.g. at 95% how many data points are in the upper 5% of the cycles data.'};
            app.PlateauQuotientThresholdEditFieldLabel.Text = 'Plateau Quotient Threshold';
            app.PlateauQuotientThresholdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PlateauQuotientThresholdEditField.Limits = [1 99];
            app.PlateauQuotientThresholdEditField.RoundFractionalValues = 'on';
            app.PlateauQuotientThresholdEditField.ValueDisplayFormat = '%i';
            app.PlateauQuotientThresholdEditField.Tooltip = {'For calculation of the plateau quotient:In which amount of time the data is above this threshold, e.g. at 95% how many data points are in the upper 5% of the cycles data.'};
            app.PlateauQuotientThresholdEditField.Value = 95;
            app.GAWopenthresholdinoflocalmaximumEditFieldLabel = uilabel(app.GridLayout3);
            app.GAWopenthresholdinoflocalmaximumEditFieldLabel.Tooltip = {'When to consider the glottis open when computing from GAW channel. Normally, this is 0. Value in percentage of local max.'};
            app.GAWopenthresholdinoflocalmaximumEditFieldLabel.Text = 'GAW open threshold in % of local maximum';
            app.GAWopenthresholdinoflocalmaximumEditField = uieditfield(app.GridLayout3, 'numeric');
            app.GAWopenthresholdinoflocalmaximumEditField.Limits = [0 99];
            app.GAWopenthresholdinoflocalmaximumEditField.RoundFractionalValues = 'on';
            app.GAWopenthresholdinoflocalmaximumEditField.ValueDisplayFormat = '%i';
            app.GAWopenthresholdinoflocalmaximumEditField.Tooltip = {'When to consider the glottis open when computing from GAW channel. Normally, this is 0. Value in percentage of local max.'};
            app.absoluitepeakprominenceEditFieldLabel = uilabel(app.GridLayout3);
            app.absoluitepeakprominenceEditFieldLabel.Text = 'absoluite peak prominence';
            app.absoluitepeakprominenceEditField = uieditfield(app.GridLayout3, 'numeric');
            app.absoluitepeakprominenceEditField.Limits = [0 Inf];
            app.relativepeakprominenceinreferencetowindowmaximumEditFieldLabel = uilabel(app.GridLayout3);
            app.relativepeakprominenceinreferencetowindowmaximumEditFieldLabel.Enable = 'off';
            app.relativepeakprominenceinreferencetowindowmaximumEditFieldLabel.Text = {'relative peak prominence'; 'in reference to window maximum'};
            app.relativepeakprominenceinreferencetowindowmaximumEditField = uieditfield(app.GridLayout3, 'numeric');
            app.relativepeakprominenceinreferencetowindowmaximumEditField.Limits = [0 1];
            app.relativepeakprominenceinreferencetowindowmaximumEditField.Enable = 'off';
            app.CyclePeakProminenceTypeSwitch = uiswitch(app.GridLayout3, 'slider');
            app.CyclePeakProminenceTypeSwitch.Items = {' ', ' '};
            app.CyclePeakProminenceTypeSwitch.ItemsData = [1 0];
            app.CyclePeakProminenceTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @CyclePeakProminenceTypeSwitchValueChanged, true);
            app.CyclePeakProminenceTypeSwitch.Value = 1;
            app.foTab = uitab(app.TabGroup);
            app.foTab.Title = 'fo';
            app.GridLayout4 = uigridlayout(app.foTab);
            app.showseparateplotof1cycledurationCheckBox_2 = uicheckbox(app.GridLayout4);
            app.showseparateplotof1cycledurationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @showseparateplotof1cycledurationCheckBox_2ValueChanged, true);
            app.showseparateplotof1cycledurationCheckBox_2.Text = {'show separate plot of'; '1/(cycle duration)'};
            app.showseparateplotof1cycledurationCheckBox_2.Value = true;
            app.PitchanalysistimewindowLabel = uilabel(app.GridLayout4);
            app.PitchanalysistimewindowLabel.Text = 'Pitch analysis time window';
            app.PitchanalysistimewindowSlider = uislider(app.GridLayout4);
            app.PitchanalysistimewindowSlider.Limits = [2 100];
            app.PitchanalysistimewindowSlider.ValueChangingFcn = createCallbackFcn(app, @PitchanalysistimewindowSliderValueChanging, true);
            app.PitchanalysistimewindowSlider.Step = 1;
            app.PitchanalysistimewindowSlider.Value = 52;
            app.pitchAnalysisTimeLengthEditField = uieditfield(app.GridLayout4, 'numeric');
            app.pitchAnalysisTimeLengthEditField.ValueDisplayFormat = '%i ms';
            app.pitchAnalysisTimeLengthEditField.Editable = 'off';
            app.pitchAnalysisTimeLengthEditField.Value = 52;
            app.PitchAnalysisWindowOverlapEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PitchAnalysisWindowOverlapEditField.RoundFractionalValues = 'on';
            app.PitchAnalysisWindowOverlapEditField.ValueDisplayFormat = '%i ms';
            app.PitchAnalysisWindowOverlapEditField.Editable = 'off';
            app.PitchAnalysisWindowOverlapEditField.Value = 42;
            app.PitchanalysistimewindowoverlapSliderLabel = uilabel(app.GridLayout4);
            app.PitchanalysistimewindowoverlapSliderLabel.Text = 'Pitch analysis time window overlap';
            app.PitchanalysistimewindowoverlapSlider = uislider(app.GridLayout4);
            app.PitchanalysistimewindowoverlapSlider.Limits = [0 51];
            app.PitchanalysistimewindowoverlapSlider.ValueChangingFcn = createCallbackFcn(app, @PitchanalysistimewindowoverlapSliderValueChanging, true);
            app.PitchanalysistimewindowoverlapSlider.Step = 1;
            app.PitchanalysistimewindowoverlapSlider.Value = 42;
            app.SampleEntropyTab = uitab(app.TabGroup);
            app.SampleEntropyTab.Title = 'Sample Entropy';
            app.GridLayout5 = uigridlayout(app.SampleEntropyTab);
            app.SampEntemplatedistancethresholdofstdEditFieldLabel = uilabel(app.GridLayout5);
            app.SampEntemplatedistancethresholdofstdEditFieldLabel.Text = 'SampEn template distance threshold (% of std)';
            app.SampEnTimeTemplatedistancethresholdofstdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SampEnTimeTemplatedistancethresholdofstdEditField.Limits = [10 30];
            app.SampEnTimeTemplatedistancethresholdofstdEditField.RoundFractionalValues = 'on';
            app.SampEnTimeTemplatedistancethresholdofstdEditField.ValueDisplayFormat = '%i';
            app.SampEnTimeTemplatedistancethresholdofstdEditField.Tooltip = {'Fabris et al. (2013): "To determine m and r, the same empirical suggestions made for '; 'ApEn in [16,17,35], i.e. choose m such that 10m is of the order of '; 'N and r between 0.1 and 0.25 times the sample signal standard '; 'deviation (SD), can be used."'; 'For window based analysis with 41ms and 2ms overlap, Selamtzis used 0.1'};
            app.SampEnTimeTemplatedistancethresholdofstdEditField.Value = 25;
            app.SampEntemplatelengthmethodDropDownLabel = uilabel(app.GridLayout5);
            app.SampEntemplatelengthmethodDropDownLabel.Text = 'SampEn template length method';
            app.SampEnTimeTemplatelengthmethodDropDown = uidropdown(app.GridLayout5);
            app.SampEnTimeTemplatelengthmethodDropDown.Items = {'log10(windowLength)', 'fixed length'};
            app.SampEnTimeTemplatelengthmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SampEnTimeTemplatelengthmethodDropDownValueChanged, true);
            app.SampEnTimeTemplatelengthmethodDropDown.Tooltip = {'Selamtzis used log10(windowLength) with window of 41ms and 2ms overlap'};
            app.SampEnTimeTemplatelengthmethodDropDown.Value = 'log10(windowLength)';
            app.TemplatefixedlengthsamplesEditFieldLabel = uilabel(app.GridLayout5);
            app.TemplatefixedlengthsamplesEditFieldLabel.Text = 'Template fixed length (samples)';
            app.SampEnTimeTemplatefixedlengthsamplesEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SampEnTimeTemplatefixedlengthsamplesEditField.Limits = [1 Inf];
            app.SampEnTimeTemplatefixedlengthsamplesEditField.RoundFractionalValues = 'on';
            app.SampEnTimeTemplatefixedlengthsamplesEditField.Enable = 'off';
            app.SampEnTimeTemplatefixedlengthsamplesEditField.Value = 1;
            app.normalizationmethodDropDownLabel = uilabel(app.GridLayout5);
            app.normalizationmethodDropDownLabel.Text = 'normalization method';
            app.SampEnTimeNormalizationmethodDropDown = uidropdown(app.GridLayout5);
            app.SampEnTimeNormalizationmethodDropDown.Items = {'none', 'zscore', 'norm', 'scale', 'range', 'center', 'medianiqr'};
            app.SampEnTimeNormalizationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SampEnTimeNormalizationmethodDropDownValueChanged, true);
            app.SampEnTimeNormalizationmethodDropDown.Tooltip = {'''zscore'''; 'z-score with mean 0 and standard deviation 1.'; ''; '''norm'''; '2-norm.'; ''; '''scale'''; 'Scale by standard deviation.'; ''; '''range'''; 'Rescale range of data to [0,1].'; ''; '''center'''; 'Center data to have mean 0.'; ''; '''medianiqr'''; 'Center and scale data to have median 0 and interquartile range 1.'};
            app.SampEnTimeNormalizationmethodDropDown.Value = 'zscore';
            app.SampleEntropyinSpectralDomainLabel = uilabel(app.GridLayout5);
            app.SampleEntropyinSpectralDomainLabel.Text = 'Sample Entropy in Spectral Domain';
            app.SampleEntropyinTimeDomainLabel = uilabel(app.GridLayout5);
            app.SampleEntropyinTimeDomainLabel.Tooltip = {'Fabris et al. 2013 "Voice disorders assessed by (cross-) Sample Entropy of '; 'electroglottogram and microphone signals"'};
            app.SampleEntropyinTimeDomainLabel.Text = 'Sample Entropy in Time Domain';
            app.normalizationhorizontDropDownLabel = uilabel(app.GridLayout5);
            app.normalizationhorizontDropDownLabel.Text = 'normalization horizont';
            app.SampEnTimeNormalizationhorizontDropDown = uidropdown(app.GridLayout5);
            app.SampEnTimeNormalizationhorizontDropDown.Items = {'window based', 'global/selection based'};
            app.SampEnTimeNormalizationhorizontDropDown.Tooltip = {'How to Decide:'; 'Global Comparisons Across Segments: If your goal is to compare entropy across windows, normalize the entire signal once to ensure uniform scaling.'; ''; 'Local Feature Analysis: If you’re more interested in the dynamics within each window and how they differ, normalize each window separately.'; ''; 'Recommendations:'; 'For signals with significant variability between segments (e.g., changing trends or amplitudes), normalizing each window separately is often preferred. However, if the signal is relatively consistent, normalizing the entire signal once can provide cleaner comparisons across windows without additional noise.'};
            app.SampEnTimeNormalizationhorizontDropDown.Value = 'window based';
            app.SampEntemplatedistancethresholdofstdEditField_2Label = uilabel(app.GridLayout5);
            app.SampEntemplatedistancethresholdofstdEditField_2Label.Text = {'SampEn template distance threshold for Magnitude of Fourier Descriptors'; '(% of std)'};
            app.SampEnFDMagnitudeTemplatedistancethresholdofstdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SampEnFDMagnitudeTemplatedistancethresholdofstdEditField.Limits = [10 90];
            app.SampEnFDMagnitudeTemplatedistancethresholdofstdEditField.RoundFractionalValues = 'on';
            app.SampEnFDMagnitudeTemplatedistancethresholdofstdEditField.ValueDisplayFormat = '%i';
            app.SampEnFDMagnitudeTemplatedistancethresholdofstdEditField.Tooltip = {'Selamtzis et al. (2014): "The matching tolerance r was '; 'adjusted by experimentation to be 0.2 for the amplitudes and '; '0.4 for the phases."'};
            app.SampEnFDMagnitudeTemplatedistancethresholdofstdEditField.Value = 20;
            app.SampEntemplatedistancethresholdofstdEditField_2Label_2 = uilabel(app.GridLayout5);
            app.SampEntemplatedistancethresholdofstdEditField_2Label_2.Text = {'SampEn template distance threshold for Phase of Fourier Descriptors'; '(% of std)'};
            app.SampEnFDPhaseTemplatedistancethresholdofstdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SampEnFDPhaseTemplatedistancethresholdofstdEditField.Limits = [10 90];
            app.SampEnFDPhaseTemplatedistancethresholdofstdEditField.RoundFractionalValues = 'on';
            app.SampEnFDPhaseTemplatedistancethresholdofstdEditField.ValueDisplayFormat = '%i';
            app.SampEnFDPhaseTemplatedistancethresholdofstdEditField.Tooltip = {'Selamtzis et al. (2014): "The matching tolerance r was '; 'adjusted by experimentation to be 0.2 for the amplitudes and '; '0.4 for the phases."'};
            app.SampEnFDPhaseTemplatedistancethresholdofstdEditField.Value = 40;
            app.SampEntemplatelengthmethodDropDown_2Label = uilabel(app.GridLayout5);
            app.SampEntemplatelengthmethodDropDown_2Label.Text = 'SampEn template length method';
            app.SampEnFDtemplatelengthmethodDropDown = uidropdown(app.GridLayout5);
            app.SampEnFDtemplatelengthmethodDropDown.Items = {'log10(signalLength)', 'fixed length'};
            app.SampEnFDtemplatelengthmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SampEnFDtemplatelengthmethodDropDownValueChanged, true);
            app.SampEnFDtemplatelengthmethodDropDown.Tooltip = {'Selamtzis used fixed length of 1 for whole signal analysis'};
            app.SampEnFDtemplatelengthmethodDropDown.Value = 'fixed length';
            app.TemplatefixedlengthsamplesEditField_2Label = uilabel(app.GridLayout5);
            app.TemplatefixedlengthsamplesEditField_2Label.Text = 'Template fixed length (samples)';
            app.SampEnFDtemplatefixedlengthsamplesEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SampEnFDtemplatefixedlengthsamplesEditField.Limits = [1 Inf];
            app.SampEnFDtemplatefixedlengthsamplesEditField.RoundFractionalValues = 'on';
            app.SampEnFDtemplatefixedlengthsamplesEditField.Tooltip = {'Selamtzis used 1 when using no windows'};
            app.SampEnFDtemplatefixedlengthsamplesEditField.Value = 1;
            app.UserelativeFourierDescriptorsDropDownLabel = uilabel(app.GridLayout5);
            app.UserelativeFourierDescriptorsDropDownLabel.Tooltip = {'Selamtzis Doctoral thesis:'; '"To avoid dependencies on the amplitude of the EGG waveform, '; 'due to changes in electrode position, the relative FD levels can be used, i.e., the diﬀerence between the level of the harmonics and the level of the fundamental '; '(∆Ln). Similarly, the phase diﬀerences relative to the phase of the fundamental '; '(∆φn) can be considered, to eliminate possible dependency on speciﬁc trigger points '; 'of the glottal cycle waveforms."'};
            app.UserelativeFourierDescriptorsDropDownLabel.Text = 'Use relative Fourier Descriptors';
            app.SampEnFDuserelativeFourierDescriptorsDropDown = uidropdown(app.GridLayout5);
            app.SampEnFDuserelativeFourierDescriptorsDropDown.Items = {'Yes', 'No'};
            app.SampEnFDuserelativeFourierDescriptorsDropDown.Tooltip = {'Selamtzis Doctoral thesis:'; '"To avoid dependencies on the amplitude of the EGG waveform, '; 'due to changes in electrode position, the relative FD levels can be used, i.e., the diﬀerence between the level of the harmonics and the level of the fundamental '; '(∆Ln). Similarly, the phase diﬀerences relative to the phase of the fundamental '; '(∆φn) can be considered, to eliminate possible dependency on speciﬁc trigger points '; 'of the glottal cycle waveforms."'};
            app.SampEnFDuserelativeFourierDescriptorsDropDown.Value = 'No';
            app.FinalcombinationmethodofSampleEntropyDropDownLabel = uilabel(app.GridLayout5);
            app.FinalcombinationmethodofSampleEntropyDropDownLabel.Text = 'Final combination method of Sample Entropy';
            app.SampEnFDfinalcombinationmethodDropDown = uidropdown(app.GridLayout5);
            app.SampEnFDfinalcombinationmethodDropDown.Items = {'add', 'max', 'min'};
            app.SampEnFDfinalcombinationmethodDropDown.Tooltip = {'For each fourier descriptor (FD) a sample entropy is calculated for the magitude and the phase. This results into 4 separate sample entropies (or 2 if using relative sample entropy) and needs to be combined to get one value. Selamtzis et al. summed up the sample entropies to get the final sample entropy called SampEn.'};
            app.SampEnFDfinalcombinationmethodDropDown.Value = 'add';
            app.NumberofharmonicstobeusedLabel = uilabel(app.GridLayout5);
            app.NumberofharmonicstobeusedLabel.Tooltip = {'Selamtzis et al. used e.g. 2 here, i.e. fo and 2*fo'; '"Here, the Sample Entropy '; 'was calculated with a MATLAB routine (Lake et al.) for the '; 'amplitudes and phases of the ﬁrst two harmonics D[1,c] and '; 'D[2,c]."'};
            app.NumberofharmonicstobeusedLabel.Text = {'Number of harmonics'; 'to be used'};
            app.SampEnFDnumberofharmonicstobeusedEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SampEnFDnumberofharmonicstobeusedEditField.Limits = [2 50];
            app.SampEnFDnumberofharmonicstobeusedEditField.RoundFractionalValues = 'on';
            app.SampEnFDnumberofharmonicstobeusedEditField.ValueDisplayFormat = '%i';
            app.SampEnFDnumberofharmonicstobeusedEditField.Tooltip = {'Selamtzis et al. used e.g. 2 here, i.e. fo and 2*fo'; '"Here, the Sample Entropy '; 'was calculated with a MATLAB routine (Lake et al.) for the '; 'amplitudes and phases of the ﬁrst two harmonics D[1,c] and '; 'D[2,c]."'; 'The more harmonics used, the higher threshold should be used for Magnitude and  (especially) phase tolerance'};
            app.SampEnFDnumberofharmonicstobeusedEditField.Value = 2;
            app.RawSelamtzisSexSwitchLabel = uilabel(app.GridLayout5);
            app.RawSelamtzisSexSwitchLabel.Text = {'Number of cycles to be considered'; '(male=10, overlap 9'; 'female=20, overlap 19)'};
            app.RawSelamtzisSexSwitch = uiswitch(app.GridLayout5, 'slider');
            app.RawSelamtzisSexSwitch.Items = {'female', 'male'};
            app.RawSelamtzisSexSwitch.Value = 'male';
            app.PlotrawsampleentropyCheckBox = uicheckbox(app.GridLayout5);
            app.PlotrawsampleentropyCheckBox.Visible = 'off';
            app.PlotrawsampleentropyCheckBox.Text = 'Plot raw sample entropy';
            app.ScaleSampEntorange0to1CheckBox = uicheckbox(app.GridLayout5);
            app.ScaleSampEntorange0to1CheckBox.Visible = 'off';
            app.ScaleSampEntorange0to1CheckBox.Text = 'Scale SampEn to range 0 to 1';
            app.CPPTab = uitab(app.TabGroup);
            app.CPPTab.Title = 'CPP';
            app.GridLayout6 = uigridlayout(app.CPPTab);
            app.LowerQuefrencyLimitsEditFieldLabel = uilabel(app.GridLayout6);
            app.LowerQuefrencyLimitsEditFieldLabel.Text = 'Lower Quefrency Limit (s)';
            app.LowerQuefrencyLimitsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LowerQuefrencyLimitsEditField.Limits = [0 Inf];
            app.LowerQuefrencyLimitsEditField.Value = 0.001;
            app.CepstrumBaselinefittingLabel = uilabel(app.GridLayout6);
            app.CepstrumBaselinefittingLabel.Text = 'Cepstrum Baseline fitting';
            app.UpperQuefrencyLimitsEditFieldLabel = uilabel(app.GridLayout6);
            app.UpperQuefrencyLimitsEditFieldLabel.Text = 'Upper Quefrency Limit (s)';
            app.UpperQuefrencyLimitsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.UpperQuefrencyLimitsEditField.Limits = [0 Inf];
            app.UpperQuefrencyLimitsEditField.Value = Inf;
            app.WindowTypeDropDownLabel = uilabel(app.GridLayout6);
            app.WindowTypeDropDownLabel.Tooltip = {'Why Use Windows?'; 'In both digital filter design and spectral estimation, the choice of a windowing function can play an important role in determining the quality of overall results. The main role of the window is to damp out the effects of the Gibbs phenomenon that results from truncation of an infinite series.'};
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.CepstrumWindowTypeDropDown = uidropdown(app.GridLayout6);
            app.CepstrumWindowTypeDropDown.Items = {'none (rectangular)', 'Hann', 'Hamming', 'Bartlett-Hann', 'Bartlett', 'Blackman', 'Blackman-Harris', 'Bohman', 'Chebyshev', 'Flat Top', 'Gaussian', 'Nuttall''s Blackman-Harris', 'Parzen', 'Triangular'};
            app.CepstrumWindowTypeDropDown.ItemsData = {'rectwin', 'hann', 'hamming', 'barthannwin', 'bartlett', 'blackman', 'blackmanharris', 'bohmanwin', 'chebwin', 'flattopwin', 'gausswin', 'nuttallwin', 'parzenwin', 'triang'};
            app.CepstrumWindowTypeDropDown.Tooltip = {'Why Use Windows?'; 'In both digital filter design and spectral estimation, the choice of a windowing function can play an important role in determining the quality of overall results. The main role of the window is to damp out the effects of the Gibbs phenomenon that results from truncation of an infinite series.'};
            app.CepstrumWindowTypeDropDown.Value = 'hann';
            app.SearchCepstrummaximuminfitregiononlyDropDownLabel = uilabel(app.GridLayout6);
            app.SearchCepstrummaximuminfitregiononlyDropDownLabel.Text = 'Search Cepstrum maximum in fit region only';
            app.CepstrumMaxInFitRegionOnlyDropDown = uidropdown(app.GridLayout6);
            app.CepstrumMaxInFitRegionOnlyDropDown.Items = {'No', 'Yes'};
            app.CepstrumMaxInFitRegionOnlyDropDown.ItemsData = {'0', '1'};
            app.CepstrumMaxInFitRegionOnlyDropDown.Value = '0';
            app.RobustfittingmethodDropDownLabel = uilabel(app.GridLayout6);
            app.RobustfittingmethodDropDownLabel.Text = 'Robust fitting method';
            app.RobustfittingmethodDropDown = uidropdown(app.GridLayout6);
            app.RobustfittingmethodDropDown.Items = {'bisquare weights', 'least absolute residual', 'both', 'none'};
            app.RobustfittingmethodDropDown.ItemsData = {'bisquare', 'LAR', 'all', 'off'};
            app.RobustfittingmethodDropDown.Value = 'off';
            app.BaselinefittingtypeDropDownLabel = uilabel(app.GridLayout6);
            app.BaselinefittingtypeDropDownLabel.Text = 'Baseline fitting type';
            app.BaselinefittingtypeDropDown = uidropdown(app.GridLayout6);
            app.BaselinefittingtypeDropDown.Items = {'linear', 'parabolic', 'exponential', 'all'};
            app.BaselinefittingtypeDropDown.ItemsData = {'poly1', 'poly2', 'exp1', 'all'};
            app.BaselinefittingtypeDropDown.Value = 'poly1';
            app.previewcyclesButton = uibutton(app.GridLayout, 'push');
            app.previewcyclesButton.ButtonPushedFcn = createCallbackFcn(app, @previewcyclesButtonPushed, true);
            app.previewcyclesButton.Text = 'preview cycles';
            app.calculateparametersButton = uibutton(app.GridLayout, 'push');
            app.calculateparametersButton.ButtonPushedFcn = createCallbackFcn(app, @calculateparametersButtonPushed, true);
            app.calculateparametersButton.Text = 'calculate parameters';
            app.KeepSettingsoverSessionsCheckBox = uicheckbox(app.GridLayout);
            app.KeepSettingsoverSessionsCheckBox.Tooltip = {'When unchecked, the "lastVocaliscopesettings.mat" file, which stores the settings, is deleted when the main window is closed'};
            app.KeepSettingsoverSessionsCheckBox.Text = 'Keep Settings over Sessions';
            app.KeepSettingsoverSessionsCheckBox.Value = true;
            app.loadSettingsfromfileButton = uibutton(app.GridLayout, 'push');
            app.loadSettingsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadSettingsfromfileButtonPushed, true);
            app.loadSettingsfromfileButton.Text = 'load Settings from file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VocaliscopeCalculationSettings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end